classdef Comparison < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        Panel            matlab.ui.container.Panel
        Import_Button    matlab.ui.control.Button
        DropDown         matlab.ui.control.DropDown
        Panel_2          matlab.ui.container.Panel
        Import_Button_2  matlab.ui.control.Button
        DropDown_3       matlab.ui.control.DropDown
        DropDown_4       matlab.ui.control.DropDown
        Import_Button_3  matlab.ui.control.Button
        DropDown_5       matlab.ui.control.DropDown
        DropDown_9       matlab.ui.control.DropDown
        DropDown_10      matlab.ui.control.DropDown
        DropDown_11      matlab.ui.control.DropDown
        Button_6         matlab.ui.control.Button
        Panel_3          matlab.ui.container.Panel
        DropDown_6Label  matlab.ui.control.Label
        DropDown_6       matlab.ui.control.DropDown
        DropDown_7Label  matlab.ui.control.Label
        DropDown_7       matlab.ui.control.DropDown
        DropDown_8Label  matlab.ui.control.Label
        DropDown_8       matlab.ui.control.DropDown
        Button           matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        RightPanel       matlab.ui.container.Panel
        TabGroup         matlab.ui.container.TabGroup
        Tab              matlab.ui.container.Tab
        UITable          matlab.ui.control.Table
        UITable_2        matlab.ui.control.Table
        UITable_3        matlab.ui.control.Table
        Table1Label      matlab.ui.control.Label
        Table2Label      matlab.ui.control.Label
        Table3Label      matlab.ui.control.Label
        Tab_2            matlab.ui.container.Tab
        UIAxes           matlab.ui.control.UIAxes
        UIAxes_2         matlab.ui.control.UIAxes
        Label_5          matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        Standard         matlab.ui.control.Switch
        Standard_2       matlab.ui.control.Switch
        EditField        matlab.ui.control.EditField
        Label_6          matlab.ui.control.Label
        Import_Button_5  matlab.ui.control.Button
        Button_3         matlab.ui.control.StateButton
        Tab_3            matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        h1
        h2
        h3 % Description
        fit % Description
        Path
    end
    
    methods (Access = private)
        
        function h=Compare(app,nbins,dropdown,uitable)
            
            app.TabGroup.SelectedTab = app.Tab_2;
            ax=app.UIAxes;
            %             ax.cla
            dropdown.ItemsData = 1:length(dropdown.Items) ;
            %             app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items) ;
            %             app.DropDown_5.ItemsData = 1:length(app.DropDown_5.Items) ;
            nn1= dropdown.Value-1;
            %             nn2= app.DropDown_4.Value-1;
            %             nn3= app.DropDown_5.Value-1;
            hold(ax,'on')
            box(ax,'on')
            dropdown.ItemsData=[];
            n1=dropdown.Value;
           legend(ax)
           
            try
               h = histogram(ax,(uitable.Data(:,nn1)),nbins,'DisplayName',n1);
            catch
            end

            %             try
            %                 app.h2 = histogram(ax,(app.UITable_2.Data(:,nn2)),nbins);
            %             catch
            %             end
            %             try
            %                 app.h3 = histogram(ax,(app.UITable_3.Data(:,nn3)),nbins);
            %             catch
            %             end
            %             hold(ax,'off')
            %             dropdown.ItemsData=[];
            %             app.DropDown_4.ItemsData=[];
            %             app.DropDown_5.ItemsData=[];
            
            %             n1=dropdown.Value;
            %             n2=app.DropDown_4.Value;
            %             n3=app.DropDown_5.Value;
            %             q='选择参数';
            %
            %             legend(ax,n1,n2,n3)
            %             if strcmp(n1,q)
            %                 legend(ax,n2,n3)
            %             end
            %             if strcmp(n2,q)
            %                 legend(ax,n1,n3)
            %             end
            %             if strcmp(n3,q)
            %                 legend(ax,n1,n2)
            %             end
            
            %             legend(ax,n1)
            
            xlabel(ax,n1)
            ylabel(ax,'频数')
            title(ax,'Comparison')
            axis(ax,'auto')
        end
        
      function Device(app)
            %             app.Path='C:\Users\dell\Desktop\参数一致性App\Data\DeviceSave.mat';
            %             app.Path='C:\Users\tiger\Desktop\参数一致性APP\Data\DeviceSave.mat';
            %             cd
            app.Path=('Data\初始化\DeviceSave.mat');
            
            load(app.Path,'f')
            
            t = DropdownItems(app,f);
            app.DropDown_6.Items=t;
            app.DropDown_6.ItemsData = 1:length(app.DropDown_6.Items); % 把ItemsdData变成数组
            n1=app.DropDown_6.Value;   %用数字表示下拉框的索引
            if isempty(n1)
                n1=1;
            end
            h=f.Children(n1);
            if ~isempty(h.Children)
                t = DropdownItems(app,h);
                app.DropDown_7.Items=t;
                app.DropDown_7.ItemsData = 1:length(app.DropDown_7.Items); % 把ItemsdData变成数组
                
                n2=app.DropDown_7.Value;
                if isempty(n2)
                    app.DropDown_7.Value=1;
                    n2=1;
                end
                
                g=h.Children(n2);
                
                if  ~isempty(g.Children)
                    t = DropdownItems(app,g);
                    app.DropDown_8.Items=t;
                else
                    app.DropDown_8.Items={''};
                end
            else
                app.DropDown_7.Items={''};
                app.DropDown_8.Items={''};
                return
            end
            
            
      end
        
        function t = DropdownItems(~,f)
            t=cell(1,length(f.Children));
            for i=1:length(f.Children)
                %             t=[t,cellstr(f.Children(i).Text)];
                t(i)=cellstr(f.Children(i).Text);
            end
        end
        
        function saveFig(app,ax,figname)
            app.DropDown_6.ItemsData=[];
            app.DropDown_7.ItemsData=[];
            
            p1=app.DropDown_6.Value ;
            %             app.DropDown_6.;
            p2=app.DropDown_7.Value ;
            p3=app.DropDown_8.Value;
            
            %             ax=app.UIAxes;
            
            
            dirname = string(strcat('Data\对比分析','\',p1,'\',p2,'\',p3,'\'));
            filename= strcat(dirname,figname);
            %             try
            mkdir (dirname)
            %             catch
            %             end
            
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.
            
            allChildren = ax.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            set(figAxes,'Box','on','Title', ax.Title,'XTick', ax.XTick, 'XTickLabel', ax.XTickLabel,'XLabel', ax.XLabel,'yLabel', ax.YLabel)
            
            figAxes.XLim = ax.XLim;
            figAxes.YLim = ax.YLim;
            %             figAxes.ZLim = ax.ZLim;
            %             figAxes.XScale = ax.XScale;
            %             figAxes.YScale = ax.YScale;
            %             figAxes.PlotBoxAspectRatio= ax.PlotBoxAspectRatio;
            %             figAxes.PlotBoxAspectRatioMode='Manual';
            %               figAxes.Position = [12 36 855 301];
            figAxes.DataAspectRatio = ax.DataAspectRatio;
            % Save as png and fig files.
            
            [file,path] = uiputfile({'*.png';'*.tif';'*.jpg';'*.fig';},'选择保存路径',filename);
            if file~=0
                saveas(fig, fullfile(path,file));
            end
            % filename=strcat(filename,'.png');
            %   saveas(fig,filename);
            
            %             savefig(fig, fullfile(path,file));
            % Delete the temporary figure.
            delete(fig);
            
            %             SaveFigures(app, ax, app.filename)
            app.DropDown_6.ItemsData = 1:length(app.DropDown_6.Items);
            app.DropDown_7.ItemsData = 1:length(app.DropDown_7.Items);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Device(app)
            app.DropDown.Items={'Table 1','Table 2','Table 3'};
            %             app.DropDown.ItemsData={'app.UITable','app.UITable_2','app.UITable_3'};
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {223, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            %             app.TabGroup.SelectedTab
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: Import_Button
        function Import_ButtonPushed(app, event)
            
            [FileName,PathName]=uigetfile({'*.xlsx';'*.txt';'*.csv';'*.xls'},'Choose a File');
            app.DropDown.ItemsData=1:length(app.DropDown.Items);
            m=app.DropDown.Value;
            
            table=[app.UITable,app.UITable_2,app.UITable_3];
            %             menu=[app.DropDown_3.Items,app.DropDown_4.Items,app.DropDown_5.Items];
            label=[app.Table1Label,app.Table2Label,app.Table3Label];
            %             switch  n
            %                 case 'Table 1'
            %                     %                     table='app.UITable';
            %                     m=1;
            %
            %                 case 'Table 2'
            %                     %                     table='app.UITable_2';
            %                     m=2;
            %                 case 'Table 3'
            %                     %                     table='app.UITable_3';
            %                     m=3;
            %             end
            
            if  (ischar(FileName))
                str=[PathName FileName];
                
                %                 if  contains(FileName,'xlsx')||contains(FileName,'xls')
                %
                %                     [Data,para]=xlsread(str);
                %                     para=para(1,:);
                %                 else
                Data=readtable(str);
                para= Data.Properties.VariableNames;
                Data=readmatrix(str);
                %                     end
                %                 end
                set((table(m)),'data',(Data),'ColumnName',para)
                %                set(app.DropDown_3.Items,'data',para1)
                
                app.DropDown_3.Items=['选择参数';app.UITable.ColumnName];
                app.DropDown_4.Items=['选择参数';app.UITable_2.ColumnName];
                app.DropDown_5.Items=['选择参数';app.UITable_3.ColumnName];
                
                app.DropDown_9.Items=['选择参数';app.UITable.ColumnName];
                app.DropDown_10.Items=['选择参数';app.UITable.ColumnName];
                
                set(label(m),'Text',FileName)
                app.TabGroup.SelectedTab = app.Tab;
                %                     value=strcat(app.DropDown.Value,'.Parent') ;
                %                     app.TabGroup.SelectedTab = eval(value);
            end
        end

        % Button pushed function: Import_Button_2
        function Import_Button_2Pushed(app, event)
            ax=app.UIAxes;
            ax.cla
            try
                app.h1= Compare(app,15,app.DropDown_3,app.UITable);
            catch
            end
            try
                app.h2= Compare(app,15,app.DropDown_4,app.UITable_2);
            catch
            end
            try
                app.h3= Compare(app,15,app.DropDown_5,app.UITable_3);
            catch
            end
            app.Standard.Value='原始数据';
            app.Standard_2.Value='直方图';
            app.Slider.Enable='on';
        end

        % Button pushed function: Import_Button_3
        function Import_Button_3Pushed(app, event)
            value = app.DropDown_11.Value;
            table=app.UITable;
            %             table=[app.UITable,app.UITable_2,app.UITable_3];
            switch value
                case 1
                    table=app.UITable;
                case 2
                    table=app.UITable_2;
                case 3
                    table=app.UITable_3;
            end
            
            app.TabGroup.SelectedTab = app.Tab_2;
            ax=app.UIAxes_2;
            box(ax,'on')
            
            app.DropDown_9.ItemsData = 1:length(app.DropDown_9.Items) ;
            app.DropDown_10.ItemsData = 1:length(app.DropDown_10.Items) ;
            
            nn1= app.DropDown_9.Value-1;
            nn2= app.DropDown_10.Value-1;
            
            sz=30;
            %             c=[0.4940 0.1840 0.5560];
            try
                s= scatter(ax,table.Data(:,nn1),table.Data(:,nn2),sz,'filled','MarkerEdgeColor','b','LineWidth',0.5);
                
                s.MarkerEdgeAlpha=0.5;
                s.MarkerFaceAlpha = 0.75;
                box(ax,'on')
                
                app.DropDown_9.ItemsData = [];
                app.DropDown_10.ItemsData = [];
                
                xlabel(ax,app.DropDown_9.Value)
                ylabel(ax,app.DropDown_10.Value)
                title(ax,'Relativity')
                legend(ax)
                hold(ax,'on')
                
                cov=corrcoef(table.Data(:,nn1),table.Data(:,nn2));
                app.EditField.Value=num2str(cov(2,1));
                app.fit=[];
            catch
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            %             ax=app.UIAxes;
            %             ax.cla
            changingValue = round(event.Value);
            try
                app.h1.NumBins=changingValue;
            catch
            end
            try
                app.h2.NumBins=changingValue;
            catch
            end
            try
                app.h3.NumBins=changingValue;
            catch
            end
            %             try
            %            app.h1= Compare(app,changingValue,app.DropDown_3,app.UITable);
            %             catch
            %             end
            %             try
            %             app.h2= Compare(app,changingValue,app.DropDown_4,app.UITable_2);
            %             catch
            %             end
            %             try
            %            app.h3= Compare(app,changingValue,app.DropDown_5,app.UITable_3);
            %             catch
            %             end
            %             app.TabGroup.SelectedTab = app.Tab_2;
            %             ax=app.UIAxes;
            %             box(ax,'on')
            %             ax.cla
            %             app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items) ;
            %             app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items) ;
            %             app.DropDown_5.ItemsData = 1:length(app.DropDown_5.Items) ;
            %             nn1= app.DropDown_3.Value;
            %             nn2= app.DropDown_4.Value;
            %             nn3= app.DropDown_5.Value;
            %             try
            %                 %
            %                 app.h1=  histogram(ax,(app.UITable.Data(:,nn1)),changingValue);
            %                 hold(ax,'on')
            %                 app.h2= histogram(ax,(app.UITable_2.Data(:,nn2)),changingValue);
            %
            %                 %             copyobj(h1,ax)
            %                 app.h3= histogram(ax,(app.UITable_3.Data(:,nn3)),changingValue);
            %
            %                 hold(ax,'off')
            %             catch
            %             end
            %
            %             %              histogram2(app.UITable.Data(:,nn1),app.UITable_2.Data(:,nn2))
            %             app.DropDown_3.ItemsData=[];
            %             app.DropDown_4.ItemsData=[];
            %             app.DropDown_5.ItemsData=[];
            %
            %             legend(ax,app.DropDown_3.Value,app.DropDown_4.Value,app.DropDown_5.Value)
            %             xlabel(ax,app.DropDown_3.Value)
            %             ylabel(ax,'频数')
            %             title(ax,'Comparison')


        end

        % Value changed function: Standard
        function StandardValueChanged(app, event)
            value = app.Standard.Value;
            
            if  strcmp(value,'归一化')
                try
                    app.h1.Normalization = 'probability';
                catch
                end
                %                 h1.BinWidth = 0.25;
                %                 app.h1.NumBins=round(app.Slider.Value);
                try
                    app.h2.Normalization = 'probability';
                catch
                end
                try
                    app.h3.Normalization = 'probability';
                catch
                end
            elseif  strcmp(value,'原始数据')
                try
                    app.h1.Normalization = 'count';
                catch
                end
                try
                    app.h2.Normalization = 'count';
                catch
                end
                try
                    app.h3.Normalization = 'count';
                catch
                end
            end
        end

        % Value changed function: Standard_2
        function Standard_2ValueChanged(app, event)
            value = app.Standard_2.Value;
            
            if  strcmp(value,'直方图')
              Import_Button_2Pushed(app, event)
                app.Slider.Enable='on';
            elseif strcmp(value,'波动图')
                ax=app.UIAxes;
                legend(ax,'AutoUpdate', 'off')
                box(ax,'on')
                ax.cla
                app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items) ;
                app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items) ;
                app.DropDown_5.ItemsData = 1:length(app.DropDown_5.Items) ;
                nn1= app.DropDown_3.Value-1;
                nn2= app.DropDown_4.Value-1;
                nn3= app.DropDown_5.Value-1;
                try
                    Data1= app.UITable.Data(:,nn1);
                catch
                end
                try
                    Data2= app.UITable_2.Data(:,nn2);
                catch
                end
                try
                    Data3= app.UITable_3.Data(:,nn3);
                catch
                end
                try
                    x1=1:length(Data1);
                catch
                end
                try
                    x2=1:length(Data2);
                catch
                end
                try
                    x3=1:length(Data3);
                catch
                end
                hold(ax,'on')
                
                try
                    plot(ax,x1,Data1,'Marker','.','color','b','MarkerSize',8)       % 折线图
                catch
                end
                try
                    plot(ax,x2,Data2,'Marker','.','color','[0.8500 0.3250 0.0980]','MarkerSize',8)
                catch
                end
                try
                    plot(ax,x3,Data3,'Marker','.','color','[0.4660 0.6740 0.1880]','MarkerSize',8)
                catch
                end
                
                app.DropDown_3.ItemsData=[];
                app.DropDown_4.ItemsData=[];
                app.DropDown_5.ItemsData=[];
                
                n1=app.DropDown_3.Value;
                n2=app.DropDown_4.Value;
                n3=app.DropDown_5.Value;
                q='选择参数';
                
                legend(ax,n1,n2,n3)
                if strcmp(n1,q)
                    legend(ax,n2,n3)
                end
                if strcmp(n2,q)
                    legend(ax,n1,n3)
                end
                if strcmp(n3,q)
                    legend(ax,n1,n2)
                end
                xlabel(ax,'Number')
                ylabel(ax,n1)
                title(ax,'Comparison')
                
                try
                    mean_ave1=mean(Data1);
                    mean_sigma1=std(Data1);
                    mean_UCL1= mean_ave1+3*mean_sigma1;
                    mean_LCL1= mean_ave1-3*mean_sigma1;
                catch
                end
                try
                    mean_ave2=mean(Data2);
                    mean_sigma2=std(Data2);
                    mean_UCL2= mean_ave2+3*mean_sigma2;
                    mean_LCL2= mean_ave2-3*mean_sigma2;
                catch
                end
                try
                    mean_ave3=mean(Data3);
                    mean_sigma3=std(Data3);
                    mean_UCL3= mean_ave3+3*mean_sigma3;
                    mean_LCL3= mean_ave3-3*mean_sigma3;
                catch
                end
                
                try
                    plot(ax,[min(x1),max(x1)],[mean_UCL1,mean_UCL1],'--.b','LineWidth', 1);       % 控制限
                    plot(ax,[min(x1),max(x1)],[mean_LCL1,mean_LCL1],'--.b','LineWidth', 1);
                catch
                end
                try
                    plot(ax,[min(x2),max(x2)],[mean_UCL2,mean_UCL2],'--.','color','[0.8500 0.3250 0.0980]','LineWidth', 1);
                    plot(ax,[min(x2),max(x2)],[mean_LCL2,mean_LCL2],'--.','color','[0.8500 0.3250 0.0980]','LineWidth', 1);
                catch
                end
                try
                    plot(ax,[min(x3),max(x3)],[mean_UCL3,mean_UCL3],'--.','color','[0.4660 0.6740 0.1880]','LineWidth', 1);
                    plot(ax,[min(x3),max(x3)],[mean_LCL3,mean_LCL3],'--.','color','[0.4660 0.6740 0.1880]','LineWidth', 1);
                catch
                end
                %                     legend(ax,'AutoUpdate', 'on')
                axis(ax,'auto')
                
                
                %
                %                 if strcmp(n1,q)
                %                     n1='';
                %                 end
                %                 if strcmp(n2,q)
                %                     n2='';
                %                 end
                %                 if strcmp(n3,q)
                %                     n3='';
                %                 end
                
                legend(ax,'AutoUpdate', 'on')
                hold(ax,'off')
                app.Slider.Enable='off';
            end
        end

        % Value changed function: DropDown_11
        function DropDown_11ValueChanged(app, event)
            app.DropDown_11.ItemsData=1:length(app.DropDown_11.Items);
            value = app.DropDown_11.Value;
            
            %             table=[app.UITable,app.UITable_2,app.UITable_3];
            
            if value==1
                app.DropDown_9.Items=['选择参数';app.UITable.ColumnName];
                app.DropDown_10.Items=['选择参数';app.UITable.ColumnName];
                %             app.DropDown_9.Items=['选择参数';app.(table(value)).ColumnName];
                %             app.DropDown_10.Items=['选择参数';app.(table(value)).ColumnName];
            elseif value==2
                app.DropDown_9.Items=['选择参数';app.UITable_2.ColumnName];
                app.DropDown_10.Items=['选择参数';app.UITable_2.ColumnName];
            elseif value==3
                app.DropDown_9.Items=['选择参数';app.UITable_3.ColumnName];
                app.DropDown_10.Items=['选择参数';app.UITable_3.ColumnName];
            end
            
        end

        % Button pushed function: Import_Button_5
        function Import_Button_5Pushed(app, event)
            app.UIAxes_2.cla
            legend(app.UIAxes_2,'off')
        end

        % Callback function
        function Import_Button_4Pushed(app, event)
            value = app.DropDown_11.Value;
            table=app.UITable;
            %             table=[app.UITable,app.UITable_2,app.UITable_3];
            switch value
                case 1
                    table=app.UITable;
                case 2
                    table=app.UITable_2;
                case 3
                    table=app.UITable_3;
            end
            
            app.TabGroup.SelectedTab = app.Tab_2;
            ax=app.UIAxes_2;
            box(ax,'on')
            
            app.DropDown_9.ItemsData = 1:length(app.DropDown_9.Items) ;
            app.DropDown_10.ItemsData = 1:length(app.DropDown_10.Items) ;
            
            nn1= app.DropDown_9.Value-1;
            nn2= app.DropDown_10.Value-1;
            
            p=polyfit(table.Data(:,nn1),table.Data(:,nn2),1);
            y=polyval(p,table.Data(:,nn1));
            plot(ax, table.Data(:,nn1), y,'r--','DisplayName','linearfit');
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            
            value = app.Button_3.Value;
            if value==1
                value = app.DropDown_11.Value;
                table=app.UITable;
                %             table=[app.UITable,app.UITable_2,app.UITable_3];
                switch value
                    case 1
                        table=app.UITable;
                    case 2
                        table=app.UITable_2;
                    case 3
                        table=app.UITable_3;
                end
                
                app.TabGroup.SelectedTab = app.Tab_2;
                ax=app.UIAxes_2;
                box(ax,'on')
                
                app.DropDown_9.ItemsData = 1:length(app.DropDown_9.Items) ;
                app.DropDown_10.ItemsData = 1:length(app.DropDown_10.Items) ;
                
                nn1= app.DropDown_9.Value-1;
                nn2= app.DropDown_10.Value-1;
                try
                    p=polyfit(table.Data(:,nn1),table.Data(:,nn2),1);
                    y=polyval(p,table.Data(:,nn1));
                    app.fit= plot(ax, table.Data(:,nn1), y,'r--','DisplayName','linearfit');
                catch
                end
            elseif value==0
                delete(app.fit)
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            saveFig(app,app.UIAxes,'分布对比')
            saveFig(app,app.UIAxes_2,'相关性分析')
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            DeviceManagement
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            delete(app.Tab_3.Children)
            
            value = app.DropDown_11.Value;
            
            table=app.UITable;
            %             table=[app.UITable,app.UITable_2,app.UITable_3];
            switch value
                case 1
                    table=app.UITable;
                case 2
                    table=app.UITable_2;
                case 3
                    table=app.UITable_3;
            end
            Data=table.Data;
            ColName=table.ColumnName;
            if size(table.Data,2)<2
                msgbox('参数数量应当>1','Message-Box','warn')
                %                 close(f)
                return
                %             elseif size(table.Data,2)>10
                %                 msgbox('参数数量太多，请确保≤10','Message-Box','warn')
                %                 return
            elseif all(isnan(table.Data(:,1)))||~any(rem(table.Data(:,1),1))
                Data=table.Data(:,2:end);
                ColName=table.ColumnName(2:end);
            end
            %            axes('Parent',app.Panel_4);
            
            
            [S,AX,BigAx,~,HAx]= plotmatrix(Data);
            title(BigAx,'Relativity of All Parameters','fontweight','bold')
            
            for i=1:size(Data,2)
                xlabel( AX(size(Data,2),i),  ColName(i),'fontweight','bold');
                ylabel( AX(i,1),  ColName(i),'fontweight','bold');
            end
            
            for i=1:size(Data,2)^2
                S(i).MarkerSize=4;
            end
            
            copyobj(AX,app.Tab_3)
            copyobj(HAx,app.Tab_3)
            copyobj(BigAx,app.Tab_3)
            
            app.TabGroup.SelectedTab = app.Tab_3;
            
            delete(gcf)
            
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
          Device(app)
            
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
         
             Device(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '数据输入';
            app.Import_Button = uibutton(app.Panel, 'push');
            app.Import_Button.ButtonPushedFcn = createCallbackFcn(app, @Import_ButtonPushed, true);
            app.Import_Button.Text = '导入数据';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Tooltip = {'选择待导入数据的位置'};
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '数据对比';
            app.Import_Button_2 = uibutton(app.Panel_2, 'push');
            app.Import_Button_2.ButtonPushedFcn = createCallbackFcn(app, @Import_Button_2Pushed, true);
            app.Import_Button_2.Text = '分布对比';
            app.DropDown_3 = uidropdown(app.Panel_2);
            app.DropDown_3.Tooltip = {''};
            app.DropDown_4 = uidropdown(app.Panel_2);
            app.DropDown_4.Tooltip = {''};
            app.Import_Button_3 = uibutton(app.Panel_2, 'push');
            app.Import_Button_3.ButtonPushedFcn = createCallbackFcn(app, @Import_Button_3Pushed, true);
            app.Import_Button_3.Text = '散点图对比';
            app.DropDown_5 = uidropdown(app.Panel_2);
            app.DropDown_5.Tooltip = {''};
            app.DropDown_9 = uidropdown(app.Panel_2);
            app.DropDown_9.Tooltip = {''};
            app.DropDown_10 = uidropdown(app.Panel_2);
            app.DropDown_10.Tooltip = {''};
            app.DropDown_11 = uidropdown(app.Panel_2);
            app.DropDown_11.Items = {'Table 1', 'Table 2', 'Table 3'};
            app.DropDown_11.ValueChangedFcn = createCallbackFcn(app, @DropDown_11ValueChanged, true);
            app.DropDown_11.Tooltip = {'选择待分析数据的位置'};
            app.DropDown_11.Value = 'Table 1';
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '相关性矩阵';
            app.Panel_3 = uipanel(app.LeftPanel);
            app.Panel_3.Title = '保存';
            app.DropDown_6Label = uilabel(app.Panel_3);
            app.DropDown_6Label.Text = '类型';
            app.DropDown_6 = uidropdown(app.Panel_3);
            app.DropDown_6.Editable = 'on';
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_7Label = uilabel(app.Panel_3);
            app.DropDown_7Label.Text = '系列';
            app.DropDown_7 = uidropdown(app.Panel_3);
            app.DropDown_7.Editable = 'on';
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_8Label = uilabel(app.Panel_3);
            app.DropDown_8Label.Text = '规格';
            app.DropDown_8 = uidropdown(app.Panel_3);
            app.DropDown_8.Editable = 'on';
            app.Button = uibutton(app.Panel_3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '元器件信息查询';
            app.Button_2 = uibutton(app.Panel_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '保存';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '测试数据';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.Tab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.Tab);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_3.RowName = {};
            app.Table1Label = uilabel(app.Tab);
            app.Table1Label.Text = 'Table 1';
            app.Table2Label = uilabel(app.Tab);
            app.Table2Label.Text = 'Table 2';
            app.Table3Label = uilabel(app.Tab);
            app.Table3Label.Text = 'Table 3';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '分析图';
            app.UIAxes = uiaxes(app.Tab_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.Tab_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Label_5 = uilabel(app.Tab_2);
            app.Label_5.Text = '组数';
            app.Slider = uislider(app.Tab_2);
            app.Slider.Limits = [5 50];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 15;
            app.Standard = uiswitch(app.Tab_2, 'slider');
            app.Standard.Items = {'原始数据', '归一化'};
            app.Standard.ValueChangedFcn = createCallbackFcn(app, @StandardValueChanged, true);
            app.Standard.Value = '原始数据';
            app.Standard_2 = uiswitch(app.Tab_2, 'slider');
            app.Standard_2.Items = {'直方图', '波动图'};
            app.Standard_2.ValueChangedFcn = createCallbackFcn(app, @Standard_2ValueChanged, true);
            app.Standard_2.Value = '直方图';
            app.EditField = uieditfield(app.Tab_2, 'text');
            app.Label_6 = uilabel(app.Tab_2);
            app.Label_6.Text = '相关系数';
            app.Import_Button_5 = uibutton(app.Tab_2, 'push');
            app.Import_Button_5.ButtonPushedFcn = createCallbackFcn(app, @Import_Button_5Pushed, true);
            app.Import_Button_5.Text = '清空';
            app.Button_3 = uibutton(app.Tab_2, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '线性回归';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '相关性矩阵';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Comparison

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end