classdef DeviceManagement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Menu       matlab.ui.container.Menu
        Menu_2     matlab.ui.container.Menu
        Menu_5     matlab.ui.container.Menu
        Menu_6     matlab.ui.container.Menu
        Tree       matlab.ui.container.Tree
        Node       matlab.ui.container.TreeNode
        Node_2     matlab.ui.container.TreeNode
        Node_5     matlab.ui.container.TreeNode
        Node_6     matlab.ui.container.TreeNode
        Node3      matlab.ui.container.TreeNode
        Node_7     matlab.ui.container.TreeNode
        Node2      matlab.ui.container.TreeNode
        Node_8     matlab.ui.container.TreeNode
        Node_3     matlab.ui.container.TreeNode
        Label_2    matlab.ui.control.Label
        Panel      matlab.ui.container.Panel
        Button     matlab.ui.control.Button
        Button_2   matlab.ui.control.Button
        EditField  matlab.ui.control.EditField
        Button_3   matlab.ui.control.Button
        Button_4   matlab.ui.control.Button
        Button_9   matlab.ui.control.Button
        Panel_2    matlab.ui.container.Panel
        UITable    matlab.ui.control.Table
        Button_5   matlab.ui.control.Button
        Button_6   matlab.ui.control.Button
        Button_8   matlab.ui.control.Button
        Button_10  matlab.ui.control.Button
    end

    properties (Access = private)
        
        
        indices % Description
    end
    
    %     methods (Access = public)
    %
    %         function MakeCmpNodes(app, parentNode, cmps)
    %             %go through all of the components in the parent
    %             for n = 1:1:length(cmps)
    %                 newNode = uitreenode(parentNode);%make new node
    %                 %make the node text by getting the name used in the code
    %                 newNode.Text = cmps(n).Text;
    %                 %set the userdata to be the component
    %                 newNode.UserData = cmps(n);
    %                 %see if there are any children
    %                 kids = cmps(n).Children;
    %                 if ~isempty(kids)
    %                     %make the nodes for the children
    %                     MakeCmpNodes(app, newNode, kids);
    %                 end
    %             end
    %
    %         end
    %     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Devicetree)
            %             delete(app.Tree.Children)
            %             load('DeviceSave.mat','f');
            %             %             load('DeviceSave.mat','f');
            %             f.Parent=app.Tree;
            %             %            f.Parent=app.UIFigure;
            %             %             app.Tree.Parent=app.UIFigure;
            %
            %             %
            %
            %             %          MakeCmpNodes(app, app.Tree,f)
            %
            %
            %             expand(app.Tree)
          
            delete(app.Tree.Children)
            %             [file,path]=uigetfile('*.mat');
            %             filename = fullfile(pwd,'DeviceSave.mat');
            %             load(filename,'f');
            
            load('Data\初始化\DeviceSave.mat','f');
            
            f.Parent=app.Tree;
            expand(app.Tree)
        end

        % Node expanded function: Tree
        function TreeNodeExpanded(app, event)
        
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            app.UITable.Data= app.Tree.SelectedNodes.NodeData ;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            TypeA = app.EditField.Value;
            if isempty(app.Tree.SelectedNodes)
                msgbox('请选择待添加的节点','Message-Box','help')
                %            category1 = uitreenode( app.Tree,'Text',TypeA,'NodeData',[]);
            elseif isempty(app.EditField.Value)
                msgbox('请输入元器件类型名称','Message-Box','help')
            else
                try  
                    uitreenode( app.Tree.SelectedNodes,'Text',TypeA,'NodeData',[],"Icon",'Data\初始化\Icons\Chip.png');
                catch
                    uitreenode( app.Tree.SelectedNodes,'Text',TypeA,'NodeData',[]);
                end
                %                 app.Tree.SelectedNodes.Icon='C:\Users\dell\Desktop\参数一致性App\Icons\Chip.png';
                expand(app.Tree.SelectedNodes)
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            %         global choice
            %             function btn1_callback(d,event)
            %           delete(gcf)
            %           choice = 1;
            %             end
            %             function btn2_callback(d,event)
            %           delete(gcf)
            %           choice = 2;
            %             end
            
            if isempty(app.Tree.SelectedNodes)
                uialert(app.UIFigure,'请选择待删除的节点','Message-Box','Icon','warning')
                %        elseif app.Tree.SelectedNodes==app.Node
                %              msgbox('删了这个就凉了','Message-Box','warn')
            elseif app.Tree.SelectedNodes== app.Tree.Children
                  uialert(app.UIFigure,' 不要删我 T_T','Message-Box','Icon','info')
%                 selection = uiconfirm(app.UIFigure,'别删我T_T','Message-Box',...
%                     'Icon','warning');
%                 if strcmp(selection,'确定')
%                    
%                 end
            else
                
                %     d = dialog('Position',[1050 600 250 150],'Name','Message-Box');
                %     txt = uicontrol('Parent',d,...
                %            'Style','text',...
                %            'Position',[20 75 210 40],...
                %            'String','确定删除吗');
                %
                %     btn1 = uicontrol('Parent',d,...
                %            'Position',[40 40 70 25],...
                %            'String','确定',...
                %            'Callback',@btn1_callback);
                %
                %     btn2 = uicontrol('Parent',d,...
                %            'Position',[150 40 70 25],...
                %            'String','放弃',...
                %            'Callback',@btn2_callback);
                %
                %
                % %      Wait for d to close before running to completion
                %     uiwait(d);
            
                    delete(app.Tree.SelectedNodes)
                                
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            TypeB = app.EditField.Value;
           
            if isempty(app.Tree.SelectedNodes)
                msgbox('请选择待修改的节点','Message-Box','help')
            elseif  app.Tree.SelectedNodes==app.Tree.Children
                app.Tree.SelectedNodes.Text=TypeB;
                %                    app.Tree.SelectedNodes.Icon='';
            else
                app.Tree.SelectedNodes.Text=TypeB;
                app.Tree.SelectedNodes.Icon='Data\初始化\Icons\Chip.png';
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            
            %          assignin('base','Devicetree',app.Tree);
            
            %function matlab.apps.AppBase.DeviceManagement
            
            f = app.Tree.Children;
            
            %             filename = fullfile(pwd,'DeviceSave.mat');
            %             save(filename,'f')
            %                               mkdir('Data\初始化')
            
            %              path
            
            %              mkdir('Data\初始化')
            %              app.EditField_7.Value=[userpath,'\Data\初始化\DeviceSave.mat'];
            try
                mkdir Data\初始化
            catch
            end
            save('Data\初始化\DeviceSave.mat','f')
            %             try
            %                 Device(app)
            %             catch
            %             end
            uialert(app.UIFigure,'元器件类型信息已保存','提示','Icon','success')
        end

        % Button pushed function: Button_5
        function Button_5Pushed2(app, event)
            
            app.UITable.Data= [];
            
            app.Tree.SelectedNodes.NodeData = app.UITable.Data;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
%             indices = event.Indices;
%             newData = event.NewData;
%             app.Tree.SelectedNodes.NodeData = newData;
        end

        % Callback function
        function Button_7Pushed(app, event)
            SelectedNode=app.Tree.SelectedNodes;
            if isempty(SelectedNode) || SelectedNode==app.Node
                msgbox('请选择一个器件类型','Message-Box','help')
            elseif isempty(app.UITable.Data)
                msgbox('请输入参数信息','Message-Box','help')
                
            else
                app.Tree.SelectedNodes.NodeData = app.UITable.Data;
            end
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
%           [file,path]=uigetfile('*.mat');
%          if ~isempty(path)
%           program=load(fullfile(path,file));
% %           fullfile(path,file)
%             open program;
%          end
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            delete(app.UIFigure)
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            %              program=app.Tree;
            %         save('program','myProgramme');
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
        
            app.indices = event.Indices;
      
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
               
%             if isempty(app.indices)
%                 msgbox('请选择要删除的参数行','Message-Box','help')
%             else
%                 %            indices
%                 app.UITable.Data(app.indices(1),:)=[];
%                 app.indices = [];
%                 app.Tree.SelectedNodes.NodeData = app.UITable.Data;
%             end
              [file, pathname] = uiputfile({'*.xlsx';'*.txt';'*.xls';},'选择保存路径',[app.Tree.SelectedNodes.Text,'-参数设置信息']);
                try
                    writetable( app.Tree.SelectedNodes.NodeData,fullfile(pathname,file))
                    winopen(pathname)
                catch
                end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            SelectedNode=app.Tree.SelectedNodes;
            if isempty(SelectedNode) || SelectedNode==app.Node
                msgbox('请选择一个器件类型','Message-Box','help')
            else
                selection = uiconfirm(app.UIFigure,'将此规格的参数信息复制到全部同类或子类中，是否继续？','Message-Box',...
                     'Options',{'确定','取消'},'Icon','warning');
                if strcmp(selection,'确定')
%                     app.Tree.SelectedNodes.Parent.NodeData= app.Tree.SelectedNodes.NodeData;
                if isempty(app.Tree.SelectedNodes.Children)
                    for i=1:size(app.Tree.SelectedNodes.Parent.Children)
                        app.Tree.SelectedNodes.Parent.Children(i).NodeData = app.Tree.SelectedNodes.NodeData;
                    end
                else    
                    for i=1:size(app.Tree.SelectedNodes.Children)
                        app.Tree.SelectedNodes.Children(i).NodeData = app.Tree.SelectedNodes.NodeData;
                    end
                end
                    
                    
                    expand(app.Tree.SelectedNodes)
                end
            end
        end

        % Callback function
        function Button_9Pushed(app, event)
%         Program = load( 'C:\Users\tiger\Desktop\参数一致性APP\DeviceFigure.mat'  );
%         app.Tree = Program;
        end

        % Button pushed function: Button_9
        function Button_9Pushed2(app, event)
            %#function matlab.apps.AppBase.DeviceManagement
            
            [file,path]=uigetfile('选择待读取的文件','*.mat');
            if file~=0
            %             program=load(fullfile(path,file));
            load(fullfile(path,file),'f');
            %          f.Parent=app.UIFigure;
            delete(app.Tree.Children)
            f.Parent=app.Tree;
            expand(app.Tree)
            end
            
            %             f.Parent=app.Tree;
            
            
            %           category = t.Children;
            %             for i=1:1:length(category)
            %             tree1(i) = uitreenode(app.Tree);
            %             tree1(i).Text = category(i).Text;
            %             content = category(i).Children;
            %                  for j =1:1:length(content)
            %                           tree2(i,j) = uitreenode(tree1(i));
            %                           tree2(i,j).Text = content(j).Text;
            %                  end
            %                  %if the branching is still needed, you need to add another loop here as what i did above
            %                  %get the children of content
            %                  %loop them
            %             end
            %
            %
            %              app.Tree = uitree(app.UIFigure);
            %             app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            %             app.Tree.NodeExpandedFcn = createCallbackFcn(app, @TreeNodeExpanded, true);
            %             app.Tree.FontSize = 18;
            %             app.Tree.Position = [9 13 387 582];
            
            %      [file,path]=  uigetfile;
            %       TableData= load([path,file]);
            %         app.UITable=TableData;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            f = app.Tree.Children;
            try
                mkdir Data\初始化
            catch
            end
%               cd
            save('Data\初始化\DeviceSave.mat','f')
         uialert(app.UIFigure,'元器件类型信息已保存','提示','Icon','success')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '元器件类型管理';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '打开';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '保存';
            app.Menu_6 = uimenu(app.Menu);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '退出';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.NodeExpandedFcn = createCallbackFcn(app, @TreeNodeExpanded, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = '济半所';
            app.Node_2 = uitreenode(app.Node);
            app.Node_2.Text = '二极管';
            app.Node_5 = uitreenode(app.Node_2);
            app.Node_5.Text = 'Node';
            app.Node_6 = uitreenode(app.Node_5);
            app.Node_6.Text = 'Node';
            app.Node3 = uitreenode(app.Node_2);
            app.Node3.Text = 'Node3';
            app.Node_7 = uitreenode(app.Node3);
            app.Node_7.Text = 'Node';
            app.Node2 = uitreenode(app.Node_2);
            app.Node2.Text = 'Node2';
            app.Node_8 = uitreenode(app.Node2);
            app.Node_8.Text = 'Node';
            app.Node_3 = uitreenode(app.Node);
            app.Node_3.Text = '三极管';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '元器件类型管理';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '添加/修改/删除';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {'选择待添加的节点，并输入名称后再点本按钮'};
            app.Button.Text = '添加';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tooltip = {''};
            app.Button_2.Text = '删除';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Tooltip = {''};
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Tooltip = {'选择待修改的节点，并输入名称后再点本按钮'};
            app.Button_3.Text = '修改';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Tooltip = {''};
            app.Button_4.Text = '保存';
            app.Button_9 = uibutton(app.Panel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed2, true);
            app.Button_9.Tooltip = {''};
            app.Button_9.Text = '读取';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '参数信息';
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'参数名称'; '是否一致性'; 'Method'; 'Limits'; '修正下限'; '修正上限'; '标称值'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed2, true);
            app.Button_5.Tooltip = {''};
            app.Button_5.Text = '清空';
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Tooltip = {'在参数信息中选择待删除的参数，整行删除'};
            app.Button_6.Text = '导出';
            app.Button_8 = uibutton(app.Panel_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Tooltip = {'将选中类型的参数信息传递给其全部子类型'};
            app.Button_8.Text = '复制';
            app.Button_10 = uibutton(app.Panel_2, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Tooltip = {''};
            app.Button_10.Text = '保存';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeviceManagement(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end