classdef MSA_IC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        Panel                  matlab.ui.container.Panel
        Label_3                matlab.ui.control.Label
        DropDown_2             matlab.ui.control.DropDown
        Label_4                matlab.ui.control.Label
        DropDown_3             matlab.ui.control.DropDown
        Label_5                matlab.ui.control.Label
        DropDown_4             matlab.ui.control.DropDown
        MSAButton              matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Label_11               matlab.ui.control.Label
        DatePicker             matlab.ui.control.DatePicker
        Label_13               matlab.ui.control.Label
        EditField              matlab.ui.control.EditField
        Label_14               matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        Button_6               matlab.ui.control.Button
        MSAPanel               matlab.ui.container.Panel
        Import_Button          matlab.ui.control.Button
        MSA_Button             matlab.ui.control.Button
        ControlChar_tButton    matlab.ui.control.Button
        Figure_Button          matlab.ui.control.Button
        DropDown               matlab.ui.control.DropDown
        ControlChar_tButton_2  matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        Tab_2                  matlab.ui.container.Tab
        UITable_1              matlab.ui.control.Table
        UITable_2              matlab.ui.control.Table
        UITable_3              matlab.ui.control.Table
        Label_8                matlab.ui.control.Label
        Label_9                matlab.ui.control.Label
        Label_10               matlab.ui.control.Label
        Tab                    matlab.ui.container.Tab
        UITable_4              matlab.ui.control.Table
        Label_7                matlab.ui.control.Label
        MSATab                 matlab.ui.container.Tab
        UITable_5              matlab.ui.control.Table
        UITable_6              matlab.ui.control.Table
        Label_12               matlab.ui.control.Label
        MSALabel               matlab.ui.control.Label
        TabGroup3              matlab.ui.container.TabGroup
        MSATab_2               matlab.ui.container.Tab
        UIAxes_2               matlab.ui.control.UIAxes
        Tab_12                 matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        MSALabel_2             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Data1
        Data2
        Data3
        Data4
        para
        ff % Description
        path % Description
        PathName % Description
        file % Description
    end
    
    methods (Access = private)


        function t = DropdownItems(~,f)
            t=cell(1,length(f.Children));
            for i=1:length(f.Children)
                %             t=[t,cellstr(f.Children(i).Text)];
                t(i)=cellstr(f.Children(i).Text);
            end
            
        end
        
        function Control(~,data,para,m,name,j)
            %             app.TabGroup.SelectedTab=app.Tab_5;
            %             app.TabGroup2.SelectedTab=tab;
            %             delete(tab.Children)
            a=length((data(1,:)))-1;
            %             b=ceil(a/2);
            app.ff(j)=figure('Name',name,'NumberTitle','off');
            set(app.ff(j),'position',[300,200,1000,800])
            
            for i=1:a
                pause(0.1)
                ax=scrollsubplot(5,2,i);
                
                ax.Parent=app.ff(j);
                %                 delete(gcf)
                if m==1
                    x=data(:,1);
                elseif m==0
                    x=1:length(data(:,1));
                end
                c=data(:,i+1);
                
                plot(ax,x,c,'Marker','.','MarkerSize',10)
                hold(ax,'on')
                
                ave=nanmean(c);
                sigma=nanstd(c);
                UCL=ave+3*sigma;
                LCL=ave-3*sigma;
                
                plot(ax,get(ax,'Xlim'),[UCL,UCL],'--.r','LineWidth', 1);
                plot(ax,get(ax,'Xlim'),[LCL,LCL],'--.r','LineWidth', 1);
                plot(ax,get(ax,'Xlim'),[ave,ave],'--','color','[0.1,0.6,0.1]','LineWidth', 1);
                
                %                 axis(ax,'auto')
                ylim=get(ax,'Ylim');
                axis(ax,[min(x),max(x),ylim(1),ylim(2)]);
                title(ax,para(i+1),'Interpreter','none')
                %                    xlabel(ax,'Number','FontSize',6)
                %             ylabel(ax,'Value','FontSize',6)
                %                 a=[min(x),max(x)];
                %                             b=get(ax,'Ylim');
                %                             axis(ax,[a(1),a(2),0.95*b(1),1.05*b(2)]);
                
                %                 waitbar(i/a,h,'Calculating...') ;
            end
            
        end
        
        function Control2(app,~,data,para,a,m)
            %         data=table2array(data)  ;
            
            b=ceil(a/2);
            %            h= waitbar(0,'Calculating...') ;
            for i=1:a
                ax=scrollsubplot(b,2,i);
                ax.Parent=app.Tab_8;
                if m==1
                    x=data(:,1);
                elseif m==0
                    x=1:length(data(:,1));
                end
                c=data(:,i+1);
                
                plot(ax,x,c,'Marker','.','MarkerSize',10)
                hold(ax,'on')
                
                ave=nanmean(c);
                sigma=nanstd(c);
                UCL=ave+3*sigma;
                LCL=ave-3*sigma;
                
                plot(ax,get(ax,'Xlim'),[UCL,UCL],'--.r','LineWidth', 1);
                plot(ax,get(ax,'Xlim'),[LCL,LCL],'--.r','LineWidth', 1);
                plot(ax,get(ax,'Xlim'),[ave,ave],'--','color','[0.1,0.6,0.1]','LineWidth', 1);
                
                %                 axis(ax,'auto')
                ylim=get(ax,'Ylim');
                axis(ax,[min(x),max(x),ylim(1),ylim(2)]);
                title(ax,para(i+1))
                %                    xlabel(ax,'Number','FontSize',6)
                %             ylabel(ax,'Value','FontSize',6)
                %                 a=[min(x),max(x)];
                %                             b=get(ax,'Ylim');
                %                             axis(ax,[a(1),a(2),0.95*b(1),1.05*b(2)]);
                
                %                 waitbar(i/a,h,'Calculating...') ;
            end
            %             close(h)
        end
        
        function saveFig(app,ax1,ax2)
            
            %             fig = figure('Position',[0,0,500,200]);
            %             fig.Visible = 'off';
            %             figAxes = axes(fig);
            %             % Copy all UIAxes children, take over axes limits and aspect ratio.
            %             allChildren = ax.Children;
            %             copyobj(allChildren, figAxes)
            %             set(figAxes,'Box','on','Title', ax.Title,'XTick', ax.XTick, 'XTickLabel', ax.XTickLabel,'XLabel', ax.XLabel,'yLabel', ax.YLabel,'FontSize',6)
            %
            %
            %             figAxes.XLim = ax.XLim;
            %             figAxes.YLim = ax.YLim;
            %
            %             saveas(fig, fullfile(app.path,figname));
            %
            %             fig2 = figure('Position',[0,0,500,200]);
            %             figAxes2 = axes(fig2);
            %             fig2.Visible = 'off';
            %
            %             figname2='各参数分散性分布.png';
            %             % Copy all UIAxes children, take over axes limits and aspect ratio.
            %             ax2=app.UIAxes;
            %             allChildren = ax2.Children;
            %             copyobj(allChildren, figAxes2)
            %             set(figAxes2,'Box','on','Title', ax2.Title,'XTick', ax2.XTick, 'XTickLabel', ax2.XTickLabel,'XLabel', ax2.XLabel,'yLabel', ax2.YLabel,'FontSize',6)
            %             legend(figAxes2,{'重复性','再现性','仪器精密度','工艺分散性'})
            %             figAxes2.XLim = ax2.XLim;
            %             figAxes2.YLim = ax2.YLim;
            %
            %             saveas(fig2, fullfile(app.path,figname2));
            
            exportgraphics(ax1, fullfile(app.path,['各参数MSA结果','.png']),'Resolution',90);
            exportgraphics(ax2, fullfile(app.path,['各参数分散性分布','.png']),'Resolution',90);
            
        end
        
        function savexls(app,xls,xlsname,n)
            app.DropDown_2.ItemsData=[];
            app.DropDown_3.ItemsData=[];
            
            p1=app.DropDown_2.Value ;
            p2=app.DropDown_3.Value ;
            p3=app.DropDown_4.Value;
            
            %             dirname = string(strcat('Data\测量系统分析','\',p1,'\',p2,'\',p3,'\'));
            
            dirname= strcat(app.PathName,strcat(p1,'-',p2,'-',p3,'-','测量系统分析','\'));
            
            filename= strcat(dirname,xlsname);
            
            mkdir (dirname)
            %             filename
            if n==1
                %                 [file,app.path] = uiputfile('*.txt','选择保存路径',filename);
                %                 if file~=0
                
                
                fileID = fopen(fullfile(app.path,xlsname),'w');
                
                fprintf(fileID,'%s\n','试验日期：', string(app.DatePicker.Value),'', '仪器：', string(app.EditField.Value),'',  '备注：',  string(app.TextArea.Value));
                
                %                 end
            elseif n==2
                
                [app.file,app.path] = uiputfile('*.xlsx','选择保存路径', filename);
                if app.file~=0
                    %                     mkdir (dirname)
                    try
                        xls.Data.Properties.VariableNames=xls.ColumnName;
                        xls.Data.Properties.RowNames=xls.RowName;
                        app.UITable_5.Data.Properties.VariableNames=app.UITable_5.ColumnName;
                        app.UITable_5.Data.Properties.RowNames=app.UITable_5.RowName;
                    catch
                    end
                    try
                        writetable(xls.Data,fullfile(app.path,app.file),'Sheet',1,'WriteRowNames',true)
                        writetable( app.UITable_5.Data,fullfile(app.path,app.file),'Sheet',2,'WriteRowNames',true)
                        T=table( string(app.DatePicker.Value), string(app.EditField.Value), string(app.TextArea.Value),'VariableNames',{'试验日期','仪器', '备注'}) ;
                        writetable( T,fullfile(app.path,app.file),'Sheet',3,'WriteRowNames',true)
                    catch
                    end
                end
                %                     sheets.sheetName_1 = 'sheetName_1';
                %                     sheets.sheetName_2 = 'sheetName_2';
                %                     sheets.sheetName_3 = 'sheetName_3';
                
            end
            
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            
        end
        
        function Device(app)
            %             Path='C:\Users\dell\Desktop\参数一致性App\Data\DeviceSave.mat';
            %             Path='C:\Users\tiger\Desktop\参数一致性APP\Data\DeviceSave.mat';
            %             cd
            Path=('Data\初始化\DeviceSave.mat');
            
            load(Path,'f')
            
            t = DropdownItems(app,f);
            app.DropDown_2.Items=t;
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items); % 把ItemsdData变成数组
            n1=app.DropDown_2.Value;   %用数字表示下拉框的索引
            if isempty(n1)
                n1=1;
            end
            h=f.Children(n1);
            if ~isempty(h.Children)
                t = DropdownItems(app,h);
                app.DropDown_3.Items=t;
                app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
                
                n2=app.DropDown_3.Value;
                if isempty(n2)
                    app.DropDown_3.Value=1;
                    n2=1;
                end
                
                g=h.Children(n2);
                
                if  ~isempty(g.Children)
                    t = DropdownItems(app,g);
                    app.DropDown_4.Items=t;
                else
                    app.DropDown_4.Items={''};
                end
            else
                app.DropDown_3.Items={''};
                app.DropDown_4.Items={''};
                return
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %         movegui(app.UIFigure,'center')
            app.DropDown.Items={'再现性测试1','再现性测试2','再现性测试3','重复性测试'};
            app.DropDown.ItemsData={'app.UITable_1','app.UITable_2','app.UITable_3','app.UITable_4'};
            
            %             Path='Data\DeviceSave.mat';
            %             %             Path='C:\Users\tiger\Desktop\参数一致性APP\Data\DeviceSave.mat';
            %
            %             load(Path,'f')
            %
            %             t = DropdownItems(app,ff);
            %             app.DropDown_2.Items=t;
            %             app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items); % 把ItemsdData变成数组
            %             n1=app.DropDown_2.Value;   %用数字表示下拉框的索引
            %
            %             h=f.Children(n1);
            %             if ~isempty(h.Children)
            %                 t = DropdownItems(app,h);
            %                 app.DropDown_3.Items=t;
            %                 app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
            %                 n2=app.DropDown_3.Value;
            %             else
            %                 app.DropDown_3.Items={};
            %                 app.DropDown_4.Items={};
            %                 return
            %             end
            %
            %             g=h.Children(n2);
            %             if  ~isempty(g.Children)
            %                 t = DropdownItems(app,g);
            %                 app.DropDown_4.Items=t;
            %             else
            %                 app.DropDown_4.Items={};
            %             end
            Device(app)
            app.DatePicker.Value = datetime('today');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {866, 866};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {226, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value=strcat(app.DropDown.Value,'.Parent') ;
            %             app.TabGroup.SelectedTab
            app.TabGroup.SelectedTab = eval(value);
            
            %             set(app.TabGroup,'SelectedTab', app.UITable_4.Parent)
            %             sprintf('de')
            %             app.Tab.Children
        end

        % Button pushed function: Import_Button
        function Import_ButtonPushed(app, event)
            Selection =   uiconfirm(app.UIFigure,'选择导入数据方式','Message-Box',...
                'Options',{'整体导入','分栏导入','取消'},'DefaultOption',3,'CancelOption',3 ,'Icon','question');
            
            if strcmp(Selection,'分栏导入')
                
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls'},'Choose a File');
                
                if  (ischar(FileName))
                    str=[app.PathName FileName];
                    %                     if  contains(FileName,'xlsx')||contains(FileName,'xls')
                    %
                    %                         [Data,para1]=xlsread(str,-1);
                    %                         %                         [Data,txt,raw]=xlsread(str,-1);
                    %                         para1=para1(1,:);
                    %                         Data=array2table(Data);
                    %                     else
                    Data=readtable(str,"PreserveVariableNames",true);
                    para1= Data.Properties.VariableNames;
                    Data=readmatrix(str);
                    %                     end
                    set(eval(app.DropDown.Value),'data',array2table(Data),'ColumnName',para1)
                    %                     value=strcat(app.DropDown.Value,'.Parent') ;
                    %                     app.TabGroup.SelectedTab = eval(value);
                end
            elseif strcmp(Selection,'整体导入')
                [FileName,app.PathName]=uigetfile({'*.xlsx';'*.xls'},'Choose a File');
                
                if  (ischar(FileName))
                    f=uiprogressdlg(app.UIFigure,"Title",'Message-Box',"Message",'读取数据中...') ;
                    str=[app.PathName FileName];
                    try
                        %                         [nData1,para1]=xlsread(str,1);
                        %                         [nData2,para2]=xlsread(str,2);
                        %                         [nData3,para3]=xlsread(str,3);
                        %                         [nData4,para4]=xlsread(str,4);
                        
                        nData1=readtable(str,"PreserveVariableNames",true,'sheet',1);
                        nData2=readtable(str,"PreserveVariableNames",true,'sheet',2);
                        nData3=readtable(str,"PreserveVariableNames",true,'sheet',3);
                        nData4=readtable(str,"PreserveVariableNames",true,'sheet',4);
                        para1= nData1.Properties.VariableNames;
                        para2= nData2.Properties.VariableNames;
                        para3= nData3.Properties.VariableNames;
                        para4= nData4.Properties.VariableNames;
                        
                    catch
                        uialert(app.UIFigure,'请确保文件中有足够的sheet','Message-Box','icon','warn')
                        close(f)
                        return
                    end
                    para1=para1(1,:);
                    para2=para2(1,:);
                    para3=para3(1,:);
                    para4=para4(1,:);
                    
                    %                     nData1=array2table(nData1);
                    %                     nData2=array2table(nData2);
                    %                     nData3=array2table(nData3);
                    %                     nData4=array2table(nData4);
                    f.Value=0.2;
                    set(app.UITable_1,'data',nData1,'ColumnName',para1)
                    f.Value=0.4;
                    set(app.UITable_2,'data',nData2,'ColumnName',para2)
                    f.Value=0.6;
                    set(app.UITable_3,'data',nData3,'ColumnName',para3)
                    f.Value=0.8;
                    set(app.UITable_4,'data',nData4,'ColumnName',para4)
                    f.Value=1;
                    f.Message='succeed!...';
                    close(f)
                    app.TabGroup.SelectedTab=app.Tab_2;
                end
            elseif strcmp(Selection,'取消')
            end
            try
                app.para=para1;
                
                app.UITable_5.Data=[];
                app.UITable_6.Data=[];
                app.UIAxes.cla;
                delete(app.Tab_8.Children)
                delete(app.Tab_9.Children)
                delete(app.Tab_10.Children)
                delete(app.Tab_11.Children)
            catch
            end
            
            %              app.Data1=(app.UITable_1.Data) ;
            %             app.Data2=(app.UITable_2.Data) ;
            %             app.Data3=(app.UITable_3.Data) ;
            %              app.Data4=(app.UITable_4.Data) ;
        end

        % Button pushed function: MSA_Button
        function MSA_ButtonPushed(app, event)
            app.Data1=table2array(app.UITable_1.Data) ;
            app.Data2=table2array(app.UITable_2.Data) ;
            app.Data3=table2array(app.UITable_3.Data) ;
            app.Data4=table2array(app.UITable_4.Data) ;
            %             a1= array2table(mean(app.UITable_1.Data));
            %             a2= array2table(mean(app.UITable_2.Data));
            %             a3= array2table(mean(app.UITable_3.Data));
            %             b1= array2table(std(app.UITable_1.Data));
            %             b2= array2table(std(app.UITable_2.Data));
            %             b3= array2table(std(app.UITable_3.Data));
            %             a0=app.UITable_1.Data(:,1);
            app.TabGroup.SelectedTab=app.MSATab;
            f= uiprogressdlg(app.UIFigure,'Message','计算中...','Title','Message-Box') ;
            nData1=app.Data1(:,2:end);
            nData2=app.Data2(:,2:end);
            nData3=app.Data3(:,2:end);
            a1= nanmean(nData1);
            a2= nanmean(nData2);
            a3= nanmean(nData3);
            b1= nanstd(nData1);
            b2= nanstd(nData2);
            b3= nanstd(nData3);
            %             waitbar(0.25,f,'Calculating...') ;
            f.Value=0.5;
            try
                set(app.UITable_5,'data',array2table([a1;a2;a3;b1;b2;b3]),'Columnname', app.para(2:end),'RowName',{'μ再现性1';'μ再现性2';'μ再现性3';...
                    'σ再现性1'; 'σ再现性2'; 'σ再现性3'})
                
                %              'data',array2table(mean(app.UITable_1.Data)),
                %                 waitbar(0.5,f,'Calculating...') ;
                f.Value=0.7;
                c1= nanstd(app.Data4(:,2:end));                    %计算MSA
                c2= std([a1;a2;a3]);
                c3= (c1.^2+c2.^2).^0.5;
                c4= mean([b1;b2;b3]);
                c5= (c3.^2+c4.^2).^0.5;
                c6= c3./c5;
                %             c7=false(1, length(app.para)-1);
                % c7=repmat([false],,length(app.para)-1);
                %                 waitbar(0.75,f,'Calculating...') ;
                
                %             array2table([c1;c2;c3;c4;c5;c6])
                %             c7=ones(length(app.UITable_4.Data(:,2:end)),0);
                set(app.UITable_6,'data',array2table([c1;c2;c3;c4;c5;c6]),'Columnname', app.para(2:end),'RowName',{'重复性';'再现性';'仪器精密度';...
                    '工艺分散性'; '总分散性'; 'R&R精密度'})
                %             data = get(app.UITable_6,'Data');
                f.Value=0.9;
            catch
                uialert(app.UIFigure,'请检查不同数据栏中的参数数量是否一致','提示','icon','warning')
            end
            %             waitbar(1,f,'Calculating...') ;
            f.Value=1;
            close(f)
            
        end

        % Button pushed function: ControlChar_tButton
        function ControlChar_tButtonPushed(app, event)
            %             app.UITable_1.Data
            app.Data1=table2array(app.UITable_1.Data) ;
            app.Data2=table2array(app.UITable_2.Data) ;
            app.Data3=table2array(app.UITable_3.Data) ;
            app.Data4=table2array(app.UITable_4.Data) ;


            Control(app,app.Data1,app.para,1,'再现性测试',1)
            Control(app,app.Data4,app.para,0,'重复性测试',2)
            %             app.TabGroup2.Children = sort(app.TabGroup2.Children);
        end

        % Button pushed function: Figure_Button
        function Figure_ButtonPushed(app, event)
            
            app.UIAxes.cla                     % 画柱状图
            app.UIAxes_2.cla
            app.TabGroup3.SelectedTab=app.MSATab_2;
            a=app.UITable_6.Data;
            a=table2array(a);
            hold(app.UIAxes,'on')
            hold(app.UIAxes_2,'on')
            
            bar(app.UIAxes_2,a(6,:),'FaceColor','[0 0.4470 0.7410]');
            %             copyobj(q,app.UIAxes_2);
            
            set( app.UIAxes_2,'XTick',1:length(a(1,:)),'XTickLabel', app.para(2:end))
            %          xlabel( app.UIAxes_2,'Interpreter','none');
            app.UIAxes_2.XLabel.String='参数';
            app.UIAxes_2.YLabel.String='R&R精密度';
            app.UIAxes_2.Title.String='各参数R&R精密度分布';
            plot(app.UIAxes_2,get(app.UIAxes_2,'Xlim'),[0.3,0.3],'--.r','LineWidth', 0.5);
            %              app.TabGroup3.SelectedTab=app.MSATab_2;


            %              l=length(a(1,:));
            r1= a(1,:)./a(5,:);
            r2= a(2,:)./a(5,:);
            r3= a(3,:)./a(5,:);
            r4= a(4,:)./a(5,:);
            d=[r1;r2;r3;r4];
            
            d=transpose(d);
            %             d=flip(d,2);                % 翻转矩阵，抵消copyobj的影响
            
            p=bar(app.UIAxes,d);
            p(4).FaceColor='[0 0.4470 0.7410]';
            p(3).FaceColor='[0.8500 0.3250 0.0980]';
            p(2).FaceColor='[0.9290 0.6940 0.1250]';
            p(1).FaceColor='[0.4940 0.1840 0.5560]';
            %             copyobj(p,app.UIAxes)


            set( app.UIAxes,'XTick',1:length(a(1,:)),'XTickLabel', app.para(2:end))
            %          xlabel( app.UIAxes,'Interpreter','none');
            %            app.UIAxes.XTickLabel= app.para(2:end);
            app.UIAxes.XLabel.String='参数';
            app.UIAxes.YLabel.String='比例';
            app.UIAxes.Title.String='各参数分散性分布';
            box(app.UIAxes,'on')
            plot(app.UIAxes,get(app.UIAxes,'Xlim'),[0.3,0.3],'--.r','LineWidth', 0.5);
            legend(app.UIAxes,{'重复性','再现性','仪器精密度','工艺分散性'})
            
            %             close
            
            pause(0.75)
            app.TabGroup3.SelectedTab=app.Tab_12;
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            try
                savexls(app,app.UITable_6,'MSA计算结果',2)
                if app.file~=0
                    f= uiprogressdlg(app.UIFigure,'Message','保存中...','Title','Message-Box') ;
                    f.Value=0.4;
                    %                 savexls(app,app.TextArea,'MSA相关信息',1)
                    %                 saveFig(app,app.UIAxes_2,'各参数MSA分布.png')
                    saveFig(app,app.UIAxes_2,app.UIAxes)
                    f.Value=0.8;
                    pause(0.1)
                    f.Value=1;
                    close(f)
                    winopen(app.path)
                end
            catch
                uialert(app.UIFigure,'请先完成分析过程','提示','Icon','Info')
            end
        end

        % Button pushed function: ControlChar_tButton_2
        function ControlChar_tButton_2Pushed(app, event)
            try
                d11 =  table2array(app.UITable_1.Data(:,1));
                d21 =  table2array(app.UITable_2.Data(:,1));
                d31 =  table2array(app.UITable_3.Data(:,1));
                d41 =  table2array(app.UITable_4.Data(:,1));
                d1 =  table2array(app.UITable_1.Data);
                d2 =  table2array(app.UITable_2.Data);
                d3 =  table2array(app.UITable_3.Data);
                d4 =  table2array(app.UITable_4.Data);
                l1=length(d1(1,:));
                l2=length(d2(1,:));
                l3=length(d3(1,:));
                l4=length(d4(1,:));
                
                if any(rem(d11,1))||any(rem(d21,1))||any(rem(d31,1))||any(rem(d41,1))
                    uialert(app.UIFigure,'请确保数据第一列为器件编号','Message-Box','icon','warn')
                elseif (max([l1,l2,l3,l4])-min([l1,l2,l3,l4]))~=0
                    uialert(app.UIFigure,'请确保所有数据列数相同','Message-Box','icon','warn')
                elseif any(any(isnan(d1)))||any(any(isnan(d2)))||any(any(isnan(d3)))||any(any(isnan(d4)))
                    uialert(app.UIFigure,'检测到非数字格式，已将其替换为缺省值','Message-Box','icon','success')
                else
                    uialert(app.UIFigure,'数据格式无误','Message-Box','icon','success')
                end
                
            catch
                uialert(app.UIFigure,'请先导入全部测试数据','Message-Box','icon','info')
            end
            
            app.TabGroup.SelectedTab=app.Tab_2;
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            Device(app)
            %             load(Path,'f')
            %             %             t = DropdownItems(app,ff);
            %             %             app.DropDown_2.Items=t;
            %             %             app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            %             n1=app.DropDown_2.Value;   %用数字表示下拉框的索引
            %             %             app.DropDown_2.ItemsData
            %
            %             if any(~ismember(n1,app.DropDown_2.ItemsData))||isempty(n1)  % 手动输入DropDown_2
            %
            %                 return
            %             end
            %             h=f.Children(n1);
            %
            %             if ~isempty(h.Children)
            %                 t = DropdownItems(app,h);
            %                 app.DropDown_3.Items=t;
            %                 %                 app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
            %                 n2=1;
            %                 app.DropDown_3.Value=1;
            %             else
            %
            %                 app.DropDown_3.Items={};
            %                 app.DropDown_4.Items={};
            %                 return
            %             end
            %
            %             g=h.Children(n2);
            %
            %             if  ~isempty(g.Children)
            %                 t = DropdownItems(app,g);
            %                 app.DropDown_4.Items=t;
            %                 app.DropDown_4.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
            %                 %                 app.DropDown_4.Items
            %                 app.DropDown_4.Value=1;
            %             else
            %                 app.DropDown_4.Items={};
            %             end
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            Device(app)
            %             load( Path,'f')
            %             n1=app.DropDown_2.Value;
            %             n2=app.DropDown_3.Value;   %用数字表示下拉框的索引
            %             if any(~ismember(n2,app.DropDown_3.ItemsData))||isempty(n2)  % 手动输入DropDown_2
            %
            %                 return
            %             end
            %             h=f.Children(n1);
            %             g=h.Children(n2);
            %             if  ~isempty(g.Children)
            %                 t = DropdownItems(app,g);
            %                 app.DropDown_4.Items=t;
            %                 app.DropDown_4.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
            %                 app.DropDown_4.Value=1;
            %             else
            %                 app.DropDown_4.Items={};
            %             end
            
        end

        % Button pushed function: MSAButton
        function MSAButtonPushed(app, event)
            RreMSA
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            import mlreportgen.dom.*
            import mlreportgen.report.*
            try
                %                 f = waitbar(0,'生成报告...','name','Message-Box') ;
                f= uiprogressdlg(app.UIFigure,'Message','生成报告...','Title','Message-Box') ;
                app.DropDown_2.ItemsData=[];                     % 生成报告
                app.DropDown_3.ItemsData=[];
                app.DropDown_4.ItemsData=[];
                
                a1=app.DropDown_2.Value ;
                a2=app.DropDown_3.Value ;
                a3=app.DropDown_4.Value;
                
                
                %                 waitbar(0.2,f,'报告题目...') ;
                f.Value=0.2;
                makeDOMCompilable();
                
                %                 docname = string(strcat(app.path,strcat(a3,'_MSA报告.docx')));
                docname = string(strcat(app.path,strcat(a3,'_批次内一致性报告.docx')));
                subtitle=(a3);
                if strcmp(a2,'')
                    docname = string(strcat(app.path,strcat(a1,'_批次内一致性报告.docx')));
                    subtitle=(a1);
                elseif  strcmp(a3,'')
                    docname = string(strcat(app.path,strcat(a2,'_批次内一致性报告.docx')));
                    subtitle=(a2);
                end
                
                %               try
                rpt = Report(docname,'docx');
                %                 add(app.rpt,TitlePage('Title',strcat(a3,' lot#',a4,' Parameter Consistency Document')));
                tp = TitlePage();
                tp.Title = strcat('Measurement System Analysis','-',subtitle);
                
                tp.Author = '';
                tp.PubDate = date();
                add(rpt,tp);
                
                %                 waitbar(0.4,f,'MSA结果...') ;
                f.Value=0.4;
                title1=Text('MSA结果');
                sec1=Section(title1);
                sec1.Numbered = false;
                
                t=rows2vars(app.UITable_6.Data);
                t.Properties.VariableNames={'参数','重复性','再现性','仪器精密度','工艺分散性', '总分散性', 'R&R精密度'};
                
                table1=(Table(t));
                %                 table1=Table( app.UITable_6.Data);
                table1.Style = {...
                    RowSep('solid','black','1px'),...
                    ColSep('solid','black','1px'),};
                table1.Border = 'single';
                table1.TableEntriesStyle = {HAlign('center')};
                add(sec1,table1)
                add(rpt,sec1);
                
                
                %                 waitbar(0.6,f,'生成MSA分布图...') ;
                f.Value=0.6;
                title2=Text('MSA分布图');
                sec2=Section(title2);
                
                sec2.Numbered = false;
                %             fig = Figure(app.UIAxes_2);
                %             fig.Snapshot.Height = '4in';
                %             fig.Snapshot.Width = '6in';
                %             fig.Snapshot.Caption = sprintf('MSA分布图');
                
                image=Image(fullfile(app.path,'各参数MSA结果.png'));
                
                add(sec2,image)
                add(rpt,sec2);
                
                %                 waitbar(0.8,f,'生成分散性分布图...') ;  Paragraph
                f.Value=0.8;
                title3=Text('分散性分布图');
                sec3=Section(title3);
                sec3.Numbered = false;
                
                
                image2=Image(fullfile(app.path,'各参数分散性分布.png'));
                
                add(sec3,image2)
                
                add(rpt,sec3);
                
                title4=Text('相关信息');
                sec4=Section(title4);
                sec4.Numbered = false;
                x1=['测试仪器：',app.EditField.Value];
                x2=['备注：',app.TextArea.Value];
                Para1=(x1);
                Para2=(x2);
                add(sec4,Para1);
                add(sec4,Para2);
                add(rpt,sec4);
                
                f.Value=1;
                close(rpt);
                
                close(f)
                
                winopen(docname)
                
            catch
                uialert(app.UIFigure,'请先进行保存并关闭已打开的报告文件','提示','Icon','Info')
                close(f)
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '测量系统分析';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '保存';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '类型';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Editable = 'on';
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '系列';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Editable = 'on';
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '规格';
            app.DropDown_4 = uidropdown(app.Panel);
            app.DropDown_4.Editable = 'on';
            app.MSAButton = uibutton(app.Panel, 'push');
            app.MSAButton.ButtonPushedFcn = createCallbackFcn(app, @MSAButtonPushed, true);
            app.MSAButton.Text = 'MSA查询';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '保存';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '测试日期';
            app.DatePicker = uidatepicker(app.Panel);
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = '测试仪器';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = '备注';
            app.TextArea = uitextarea(app.Panel);
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '生成报告';
            app.MSAPanel = uipanel(app.LeftPanel);
            app.MSAPanel.Title = 'MSA';
            app.Import_Button = uibutton(app.MSAPanel, 'push');
            app.Import_Button.ButtonPushedFcn = createCallbackFcn(app, @Import_ButtonPushed, true);
            app.Import_Button.Text = '导入数据';
            app.MSA_Button = uibutton(app.MSAPanel, 'push');
            app.MSA_Button.ButtonPushedFcn = createCallbackFcn(app, @MSA_ButtonPushed, true);
            app.MSA_Button.Text = 'MSA计算结果';
            app.ControlChar_tButton = uibutton(app.MSAPanel, 'push');
            app.ControlChar_tButton.ButtonPushedFcn = createCallbackFcn(app, @ControlChar_tButtonPushed, true);
            app.ControlChar_tButton.Text = '受控状态分析';
            app.Figure_Button = uibutton(app.MSAPanel, 'push');
            app.Figure_Button.ButtonPushedFcn = createCallbackFcn(app, @Figure_ButtonPushed, true);
            app.Figure_Button.Text = '分散性分布图';
            app.DropDown = uidropdown(app.MSAPanel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Tooltip = {'选择待导入数据的位置'};
            app.ControlChar_tButton_2 = uibutton(app.MSAPanel, 'push');
            app.ControlChar_tButton_2.ButtonPushedFcn = createCallbackFcn(app, @ControlChar_tButton_2Pushed, true);
            app.ControlChar_tButton_2.Text = '检查数据格式';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '再现性测试';
            app.UITable_1 = uitable(app.Tab_2);
            app.UITable_1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_1.ColumnEditable = true;
            app.UITable_2 = uitable(app.Tab_2);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.ColumnEditable = true;
            app.UITable_3 = uitable(app.Tab_2);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_3.ColumnEditable = true;
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '再现性测试结果1';
            app.Label_9 = uilabel(app.Tab_2);
            app.Label_9.Text = '再现性测试结果2';
            app.Label_10 = uilabel(app.Tab_2);
            app.Label_10.Text = '再现性测试结果3';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '重复性测试';
            app.UITable_4 = uitable(app.Tab);
            app.UITable_4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_4.ColumnEditable = true;
            app.Label_7 = uilabel(app.Tab);
            app.Label_7.Text = '重复性测试结果';
            app.MSATab = uitab(app.TabGroup);
            app.MSATab.Title = 'MSA结果';
            app.UITable_5 = uitable(app.MSATab);
            app.UITable_5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_5.RowName = {};
            app.UITable_5.ColumnEditable = true;
            app.UITable_6 = uitable(app.MSATab);
            app.UITable_6.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_6.RowName = {};
            app.UITable_6.ColumnEditable = true;
            app.Label_12 = uilabel(app.MSATab);
            app.Label_12.Text = '过程统计量';
            app.MSALabel = uilabel(app.MSATab);
            app.MSALabel.Text = 'MSA结果';
            app.TabGroup3 = uitabgroup(app.MSATab);
            app.MSATab_2 = uitab(app.TabGroup3);
            app.MSATab_2.Title = 'MSA结果';
            app.UIAxes_2 = uiaxes(app.MSATab_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Tab_12 = uitab(app.TabGroup3);
            app.Tab_12.Title = '分散性贡献';
            app.UIAxes = uiaxes(app.Tab_12);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MSALabel_2 = uilabel(app.MSATab);
            app.MSALabel_2.Text = 'MSA分布图';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSA_IC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end