classdef Main_IC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParaControlUIFigure         matlab.ui.Figure
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        v201Label                   matlab.ui.control.Label
        Button_5                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        Button_6                    matlab.ui.control.Button
        LiJiantao_13811920580Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        AButton % Description
        BButton
        CButton
        DButton
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             cd
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%            load('C:\Users\tiger\Desktop\参数一致性APP\DeviceFigure','Program')
%            open C:\Users\tiger\Desktop\参数一致性APP\DeviceFigure.fig
            DeviceManagement
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            MSA_IC
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %      opts.Interpreter='tex';
            %      opts.Default='多参数';
            %             answer = questdlg('请选择对应模块', ...
            % 	'一致性控制', ...
            % 	'多参数','多批次','标准',opts);
            % % Handle response
            % switch answer
            %     case '多参数'
            %        ParaConsistency_IC
            %     case '多批次'
            % %         disp([answer ' coming right up.'])
            %     case '标准'
            %          ParaConsistency
            % end
            
            f1 = uifigure('Name','选择模块','Position',[630 550 360 120]);
            
            app.AButton=uibutton(f1,'push','Position',[60 65 100 30], 'Text', '批次内');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            
            app.BButton=uibutton(f1,'push','Position',[200 65 100 30], 'Text', '批次间');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            
            app.CButton=uibutton(f1,'push','Position',[60 25 100 30], 'Text', '变化率');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            
            app.DButton=uibutton(f1,'push','Position',[200 25 100 30], 'Text', '微调');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            
            function AButtonPushed(~,~)
                ParaConsistency_IC
                close(f1)
            end
            
            function BButtonPushed(~,~)
                ParaConsistency_Lot
                close(f1)
            end
            
            function CButtonPushed(~,~)
                ParaConsistency_Change
                close(f1)
            end
            
            function DButtonPushed(~,~)
                ParaConsistency
                close(f1)
            end
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
           delete(app)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
           Comparison
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            Transformer_autoreflow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParaControlUIFigure = uifigure('Visible', 'off');
            app.ParaControlUIFigure.Name = 'ParaControl';
            app.Button = uibutton(app.ParaControlUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'New5.png';
            app.Button.Text = '元器件类型管理';
            app.Button_2 = uibutton(app.ParaControlUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'New2.png';
            app.Button_2.Text = '参数一致性控制';
            app.Button_3 = uibutton(app.ParaControlUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '退出';
            app.v201Label = uilabel(app.ParaControlUIFigure);
            app.v201Label.Text = '参数一致性分析与控制工具 v2.01';
            app.Button_5 = uibutton(app.ParaControlUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'New1.png';
            app.Button_5.Text = '  测量系统分析 ';
            app.Button_4 = uibutton(app.ParaControlUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'New3.png';
            app.Button_4.Text = '  数据对比分析 ';
            app.Image = uiimage(app.ParaControlUIFigure);
            app.Image.ImageSource = '图片8.png';
            app.Button_6 = uibutton(app.ParaControlUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'New4.png';
            app.Button_6.Text = '   数据清洗转换';
            app.LiJiantao_13811920580Label = uilabel(app.ParaControlUIFigure);
            app.LiJiantao_13811920580Label.Text = 'Li Jiantao_13811920580';
            % Show the figure after all components are created
            app.ParaControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_IC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParaControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParaControlUIFigure)
        end
    end
end