classdef ParaConsistency < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        Panel                matlab.ui.container.Panel
        Button_2             matlab.ui.control.Button
        Label_2              matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        Button_7             matlab.ui.control.Button
        SliderLabel          matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        Label_13             matlab.ui.control.Label
        DropDown_5           matlab.ui.control.DropDown
        Label_14             matlab.ui.control.Label
        DropDown_6           matlab.ui.control.DropDown
        Button_10            matlab.ui.control.Button
        Label_19             matlab.ui.control.Label
        BinWidthSlider       matlab.ui.control.Slider
        Panel_5              matlab.ui.container.Panel
        Label_15             matlab.ui.control.Label
        EditField_19         matlab.ui.control.EditField
        Label_16             matlab.ui.control.Label
        EditField_20         matlab.ui.control.EditField
        Panel_6              matlab.ui.container.Panel
        Label_17             matlab.ui.control.Label
        EditField_21         matlab.ui.control.EditField
        Label_18             matlab.ui.control.Label
        EditField_22         matlab.ui.control.EditField
        Label_21             matlab.ui.control.Label
        EditField_23         matlab.ui.control.EditField
        Label_22             matlab.ui.control.Label
        EditField_24         matlab.ui.control.EditField
        Button_18            matlab.ui.control.StateButton
        EditField_18         matlab.ui.control.EditField
        EditField_9          matlab.ui.control.EditField
        Button_22            matlab.ui.control.Button
        Panel_7              matlab.ui.container.Panel
        EditField_25Label    matlab.ui.control.Label
        EditField_25         matlab.ui.control.EditField
        EditField_26Label    matlab.ui.control.Label
        EditField_26         matlab.ui.control.EditField
        EditField_29         matlab.ui.control.EditField
        EditField_30Label    matlab.ui.control.Label
        EditField_30         matlab.ui.control.EditField
        EditField_31Label    matlab.ui.control.Label
        EditField_31         matlab.ui.control.EditField
        CpkButton            matlab.ui.control.Button
        Label_33             matlab.ui.control.Label
        DropDown_8           matlab.ui.control.DropDown
        MSAButton            matlab.ui.control.Button
        Button_33            matlab.ui.control.Button
        Button_39            matlab.ui.control.StateButton
        Panel_2              matlab.ui.container.Panel
        Button               matlab.ui.control.Button
        Button_32            matlab.ui.control.Button
        Label_37             matlab.ui.control.Label
        TextArea_2           matlab.ui.control.TextArea
        CenterPanel          matlab.ui.container.Panel
        TabGroup             matlab.ui.container.TabGroup
        Tab_3                matlab.ui.container.Tab
        UITable              matlab.ui.control.Table
        Button_34            matlab.ui.control.Button
        Button_35            matlab.ui.control.Button
        Label_39             matlab.ui.control.Label
        Tab_2                matlab.ui.container.Tab
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        Label_3              matlab.ui.control.Label
        EditField_2          matlab.ui.control.NumericEditField
        Label_4              matlab.ui.control.Label
        EditField_3          matlab.ui.control.NumericEditField
        LCLEditFieldLabel    matlab.ui.control.Label
        LCLEditField         matlab.ui.control.NumericEditField
        UCLEditFieldLabel    matlab.ui.control.Label
        UCLEditField         matlab.ui.control.NumericEditField
        EditField_10Label    matlab.ui.control.Label
        EditField_10         matlab.ui.control.NumericEditField
        EditField_11Label    matlab.ui.control.Label
        EditField_11         matlab.ui.control.NumericEditField
        LCLEditField_2Label  matlab.ui.control.Label
        LCLEditField_2       matlab.ui.control.NumericEditField
        UCLEditField_2Label  matlab.ui.control.Label
        UCLEditField_2       matlab.ui.control.NumericEditField
        UITable2             matlab.ui.control.Table
        UITable2_2           matlab.ui.control.Table
        PEditFieldLabel      matlab.ui.control.Label
        PEditField           matlab.ui.control.NumericEditField
        PEditField_2Label    matlab.ui.control.Label
        PEditField_2         matlab.ui.control.NumericEditField
        EditField_27Label    matlab.ui.control.Label
        EditField_27         matlab.ui.control.NumericEditField
        EditField_28Label    matlab.ui.control.Label
        EditField_28         matlab.ui.control.NumericEditField
        Button_36            matlab.ui.control.StateButton
        Label_38             matlab.ui.control.Label
        EditField_35         matlab.ui.control.EditField
        Button_38            matlab.ui.control.StateButton
        Tab_8                matlab.ui.container.Tab
        UITable2_3           matlab.ui.control.Table
        UITable2_4           matlab.ui.control.Table
        UITable2_5           matlab.ui.control.Table
        Label_27             matlab.ui.control.Label
        Label_28             matlab.ui.control.Label
        Label_29             matlab.ui.control.Label
        Tab_9                matlab.ui.container.Tab
        UITable2_6           matlab.ui.control.Table
        Label_34             matlab.ui.control.Label
        Button_31            matlab.ui.control.Button
        Label_35             matlab.ui.control.Label
        EditField_33         matlab.ui.control.EditField
        Label_36             matlab.ui.control.Label
        EditField_34         matlab.ui.control.EditField
        RightPanel           matlab.ui.container.Panel
        Panel_4              matlab.ui.container.Panel
        Label_11             matlab.ui.control.Label
        EditField_8          matlab.ui.control.EditField
        Label_23             matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
        Button_20            matlab.ui.control.Button
        Label_30             matlab.ui.control.Label
        DropDown_7           matlab.ui.control.DropDown
        CheckBox_7           matlab.ui.control.CheckBox
        CheckBox_6           matlab.ui.control.CheckBox
        CheckBox_5           matlab.ui.control.CheckBox
        ButtonGroup          matlab.ui.container.ButtonGroup
        Button_25            matlab.ui.control.ToggleButton
        Button_28            matlab.ui.control.ToggleButton
        Button_29            matlab.ui.control.ToggleButton
        Label_31             matlab.ui.control.Label
        DatePicker           matlab.ui.control.DatePicker
        Panel_8              matlab.ui.container.Panel
        Button_12            matlab.ui.control.Button
        Button_13            matlab.ui.control.Button
        Button_14            matlab.ui.control.Button
        Label_32             matlab.ui.control.Label
        EditField_32         matlab.ui.control.EditField
        Panel_3              matlab.ui.container.Panel
        Label_7              matlab.ui.control.Label
        DropDown_2           matlab.ui.control.DropDown
        Label_8              matlab.ui.control.Label
        DropDown_3           matlab.ui.control.DropDown
        Label_12             matlab.ui.control.Label
        DropDown_4           matlab.ui.control.DropDown
        Button_8             matlab.ui.control.Button
        Button_Save          matlab.ui.control.Button
        Label_20             matlab.ui.control.Label
        CheckBox2            matlab.ui.control.CheckBox
        CheckBox3            matlab.ui.control.CheckBox
        CheckBox1            matlab.ui.control.CheckBox
        Button_Save_2        matlab.ui.control.Button
        CheckBox4            matlab.ui.control.CheckBox
        TabGroup2            matlab.ui.container.TabGroup
        Tab_5                matlab.ui.container.Tab
        UITable4             matlab.ui.control.Table
        UITable4_2           matlab.ui.control.Table
        Label_24             matlab.ui.control.Label
        Label_25             matlab.ui.control.Label
        Button_30            matlab.ui.control.Button
        Button_16            matlab.ui.control.Button
        Button_37            matlab.ui.control.Button
        Tab_6                matlab.ui.container.Tab
        Tab_7                matlab.ui.container.Tab
        UIAxes4              matlab.ui.control.UIAxes
        Label_26             matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        p1
        p2                % 3σ上下限句柄
        p3
        p4
        p5
        p6
        p7
        p8
        USL1              % 分布转换后的3σ上下限（过渡）
        LSL1
        parameter
        transdata
        sigma1
        ndata              % 导入的数据
        nn                % 选中的参数列
        hh
        %         filename
        Path                % 元器件类型存储路径
        indice1
        indice2
        UIAxes2
        UIAxes2_2
        UIAxes2_3
        subax
        subax2      % Description
        subax3
        mean_UCL
        mean_LCL
        sigma_UCL
        sigma_LCL
        ratio_UCL
        ratio_LCL
        rpt % 章
        ch % 节
        outline % 总离群器件
        single_outline % 单参数离群器件
        % Description
        out1 % Description
        out2 % Description
        path1 % Description
    end
    
    
    methods (Access = private)


        function Initiate(app)
            app.UIAxes.cla;
            app.UIAxes_2.cla;
            app.UIAxes4.cla;
            app.EditField_2.Value=0;
            app.EditField_3.Value=0;
            app.LCLEditField.Value=0;
            app.UCLEditField.Value=0;
            app.EditField_10.Value=0;
            app.EditField_11.Value=0;
            app.LCLEditField_2.Value=0;
            app.UCLEditField_2.Value=0;
            app.EditField_9.Value='';
            app.EditField_18.Value='';
            app.EditField_19.Value='';
            app.EditField_20.Value='';
            app.EditField_21.Value='';
            app.EditField_22.Value='';
            app.EditField_23.Value='';
            app.EditField_24.Value='';
            app.EditField_25.Value='';
            app.EditField_26.Value='';
            app.EditField_29.Value='';
            app.EditField_30.Value='';
            app.EditField_31.Value='';
            app.EditField_32.Value='';
            app.Slider.Enable='Off';
            app.BinWidthSlider.Enable='Off';
            app.UITable2.Data=[];
            app.UITable2_2.Data=[];
            app.sigma1=[];
            app.USL1=[];
            app.LSL1=[];
            app.Button_18.Value=0;
            delete(app.Tab_6.Children);
            %             app.UITable4.Data=[];
            %             app.UITable4_2.Data=[];
            app.TabGroup2.SelectedTab = app.Tab_5;
            app.DropDown_5.Value='初始分布';
            app.DropDown_6.Value='3σ双侧';
            app.Lamp.Color=[0.8 0.8 0.8];
            app.p1=NaN;
            app.p2=NaN;
            app.p3=NaN;
            app.p4=NaN;
            app.p5=NaN;
            app.p6=NaN;
            legend(app.UIAxes,'off')
            app.ButtonGroup.SelectedObject=app.Button_28;
            app.Button_30.Visible='off';
            app.ch=[];
            app.UITable2_3.Data=[];
            app.UITable2_4.Data=[];
            app.UITable2_5.Data=[];
            app.Button_39.Value=0;
            app.Button_36.Value=0;
            app.Button_38.Value=0;
            app.UITable4.Data=[];
            app.UITable4_2.Data=[];
        end
        
        
        function saveFig(app,ax,figname,m)
            app.DropDown_2.ItemsData=[];
            app.DropDown_3.ItemsData=[];
            app.DropDown.ItemsData=[];
            a1=app.DropDown_2.Value ;
            %             app.DropDown_2.;
            a2=app.DropDown_3.Value ;
            a3=app.DropDown_4.Value;
            a4=app.EditField_8.Value; % 批次
            a5=app.DropDown.Value ; % 参数
            a6=app.DropDown_8.Value; % 控制环节
            
            import mlreportgen.dom.*
            import mlreportgen.report.*
            
            dirname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\',a4,'批','\',a6,'\',a5,'\'));
            filename= strcat(dirname,figname,a5);
            try
                mkdir (dirname)
            catch
            end
            if m==1
                fig = figure;
                fig.Visible = 'off';
                ax1=ax;
                ax2= app.UIAxes_2;
                
                aa1=subplot(2,1,1,'Parent',fig);
                aa2=subplot(2,1,2,'Parent',fig);
                
                copyobj(ax1.Children, aa1)
                copyobj(ax2.Children, aa2)
                
                set(aa2,'Box','on','Title',ax2.Title,'XTick',ax2.XTick, 'XTickLabel',ax2.XTickLabel,'XLabel',ax2.XLabel,'yLabel',ax2.YLabel)
                set(aa1,'Box','on','Title',ax1.Title,'XTick',ax1.XTick, 'XTickLabel',ax1.XTickLabel,'XLabel',ax1.XLabel,'yLabel',ax1.YLabel)
                
                
                axis(aa2,axis(ax2));
                axis(aa1,axis(ax1));
                %                 figAxes = axes(fig);
                
                %                 allChildren = ax.XAxis.Parent.Children;
                %                 copyobj(allChildren, figAxes)
                %                 set(figAxes,'Box','on','Title', ax.Title,'XTick', ax.XTick, 'XTickLabel', ax.XTickLabel,'XLabel', ax.XLabel,'yLabel', ax.YLabel)
                %
                %                 figAxes.XLim = ax.XLim;
                %                 figAxes.YLim = ax.YLim;
                %                 figAxes.ZLim = ax.ZLim;
                %                 figAxes.DataAspectRatio = ax.DataAspectRatio;


                %                 axis normal
            elseif m==0
                fig = figure;
                fig.Visible = 'off';
                %             figAxes = axes(fig);
                aa1=subplot(3,1,1,'Parent',fig);
                aa2=subplot(3,1,2,'Parent',fig);
                aa3=subplot(3,1,3,'Parent',fig);
                % Copy all UIAxes children, take over axes limits and aspect ratio.
                
                %                  aa1=imresize(aa1,[50,100]);
                
                copyobj(ax(3).Children, aa1)
                copyobj(ax(2).Children, aa2)
                copyobj(ax(1).Children, aa3)
                
                set(aa1,'Box','on','Title',ax(3).Title,'XTick',ax(3).XTick, 'XTickLabel',ax(3).XTickLabel,'XLabel',ax(3).XLabel,'yLabel',ax(3).YLabel,'FontSize',8)
                set(aa2,'Box','on','Title',ax(2).Title,'XTick',ax(2).XTick, 'XTickLabel',ax(2).XTickLabel,'XLabel',ax(2).XLabel,'yLabel',ax(2).YLabel,'FontSize',8)
                set(aa3,'Box','on','Title',ax(1).Title,'XTick',ax(1).XTick, 'XTickLabel',ax(1).XTickLabel,'XLabel',ax(1).XLabel,'yLabel',ax(1).YLabel,'FontSize',8)
                
                axis(aa1,axis(ax(3)));
                axis(aa2,axis(ax(2)));
                axis(aa3,axis(ax(1)));


                %                 aa1.XLabel.FontSize=4;
                %                 aa2.XLabel.FontSize=4;
                %                 aa3.XLabel.FontSize=4;
                %
                %                 aa1.XTickLabelRotation = 45;
                %                 aa2.XTickLabelRotation = 45;
                %                 aa3.XTickLabelRotation = 45;
                % Save as png and fig files.
            end
            
            [file,app.path1] = uiputfile({'*.png';'*.tif';'*.jpg';'*.fig';},'选择保存路径',filename);
            if file~=0
                %                 F= getframe(fig);
                %                 img = F.cdata;
                %                 fig=getframe(fig);
                %                 imwrite(fig,fullfile(path,file))
                
                saveas(fig, fullfile(app.path1,file));
                %                 imwrite(fig0,fullfile(path,file),'png')
            end
            delete(fig);
            
            %             report MIDTERM_report;
            % Generate Report
            try
                
                makeDOMCompilable();
                
                
                if isempty(app.rpt)
                    docname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\',a4,'批','\',a6,'\',strcat(a3,'参数一致性报告')));
                    %               try
                    app.rpt = Report(docname,'docx');
                    %                 add(app.rpt,TitlePage('Title',strcat(a3,' lot#',a4,' Parameter Consistency Document')));
                    tp = TitlePage();
                    tp.Title = strcat(' Parametric Consistency Document');
                    tp.Subtitle = strcat(a3,' lot#',a4);
                    tp.Author = '';
                    tp.PubDate = date();
                    add(app.rpt,tp);
                    %               catch
                    %                   msgbox('请先关闭已打开的报告','Message-Box','help')
                    %               end
                    %                 br = PageBreak();
                    %                 add(app.rpt,br);
                end
                
                image=Image(fullfile(app.path1,file));
                image.Style = {Height('2in'), HAlign('center')};
                image.Width='500';
                image.Height='360';
                
                if isempty(app.ch)||  ~strcmp(app.ch.Title,a5)
                    app.ch = Section(a5);
                    app.ch.Numbered = false;
                    %                 app.ch = Chapter(a5);
                    %                 chtitle = Text('Change Chapter Title Font');
                    %                 app.ch.Title = chtitle;
                    %                 app.ch.Numbered = false;
                    
                    
                    if m==1
                        
                        title=Text('Distribution Chart');
                        sec1=Section(title);
                        sec1.Numbered = false;
                        %                     add(app.ch,sec1);
                        
                    elseif m==0
                        
                        %                     add(app.ch,"(2) Control Chart Between Batches");
                        title=Text('Control Chart');
                        sec1=Section(title);
                        sec1.Numbered = false;
                        %                     add(app.ch,sec1);
                    end
                    
                    add(sec1,image);
                    add(app.ch,sec1);
                    %  add(app.ch,image);
                    
                else                                             % 给这一章继续加东西（批次间控制图）
                    
                    
                    if m==1
                        
                        title=Text('Distribution Chart');
                        sec1=Section(title);
                        sec1.Numbered = false;
                        %                     add(app.ch,sec1);
                        %                     add(app.ch,"(1) Control Chart Within Batch");
                    elseif m==0
                        title=Text('Control Chart');
                        sec1=Section(title);
                        sec1.Numbered = false;
                        %                     add(app.ch,sec1);
                        %                     add(app.ch,"(2) Control Chart Between Batches");
                    end
                    
                    %                 add(app.ch,image);
                    add(sec1,image);
                    add(app.ch,sec1);
                    %                 add(app.rpt,app.ch);
                    
                    %                 add(app.rpt, image);
                end
                
                %             image=imread(fullfile(path,file));
                
                %             append(d,plot1);


                %             image=imread(fullfile(path,file));
                
                
                %             import mlreportgen.dom.*
                %             import mlreportgen.report.*
                %
                %             fig4Img = Figure(fig);
                %             fig4Img = Image(getSnapshotImage(fig4Img, app.rpt));
                %             fig4Img.Style = [fig4Img.Style {ScaleToFit}];
                %             add(app.rpt,fig4Img);
                
                %   saveas(fig,filename);
                %   savefig(fig, fullfile(path,file));
            catch
            end
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            app.DropDown.ItemsData = 1:length(app.DropDown.Items);
        end
        
        function savexls(app,xls,xlsname,m,n,p)
            makeDOMCompilable();
            import mlreportgen.dom.*
            import mlreportgen.report.*
            
            app.DropDown_2.ItemsData=[];
            app.DropDown_3.ItemsData=[];
            app.DropDown.ItemsData=[];
            a1=app.DropDown_2.Value ;
            %             app.DropDown_2.;
            a2=app.DropDown_3.Value ;
            a3=app.DropDown_4.Value;
            a4=app.EditField_8.Value; % 批次
            a5=app.DropDown.Value ; % 参数
            a6=app.DropDown_8.Value;
            
            if m==1                               % 选择存储路径
                dirname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\',a4,'批','\',a6,'\',a5,'\'));
                filename= strcat(dirname,xlsname,a5);
            elseif m==0
                dirname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\',a4,'批','\',a6,'\'));
                filename= strcat(dirname,a4,'批',xlsname);
            end
            try
                mkdir (dirname)
            catch
            end
            
            if n==1                           %选择存储方式（批次间数据、总离群数据）;'*.csv'
                [file,path] = uiputfile({'*.xlsx';'*.txt';'*.csv'},'选择保存路径',filename);
                if file~=0
                    
                    %                     xls.Data.Properties.VariableNames=xls.ColumnName;
                    %                     xls.Properties.VariableNames
                    try
                        writetable(xls,fullfile(path,file),'WriteVariableNames',true)
                    catch
                    end
                end
            elseif n==2                            %异常情况说明
                [file,path] = uiputfile('*.txt','选择保存路径',filename);
                if file~=0
                    %                     mkdir (dirname)
                    %                     fprintf(fullfile(path,file),'%s',string(xls.Value));
                    try
                        writecell(xls.Value,fullfile(path,file))
                    catch
                    end
                end
            elseif n==3                           %单参数离群器件
                
                [file,path] = uiputfile({'*.xlsx';'*.txt';'*.csv'},'选择保存路径',filename);
                if file~=0
                    %                     mkdir (dirname)
                    
                    try
                        writetable(xls,fullfile(path,file))
                    catch
                    end
                end
                %                     %                     try
                %                     %                         xls.Data.Properties.VariableNames=xls.ColumnName;
                %                     %                         app.UITable2_4.Data.Properties.VariableNames=app.UITable2_4.ColumnName;
                %                     %                         app.UITable2_5.Data.Properties.VariableNames=app.UITable2_5.ColumnName;
                %                     %                     catch
                %                     %                     end
                %                     try
                %                         writetable(xls.Data,fullfile(path,file),'Sheet',1)
                %                         writetable( app.UITable2_4.Data,fullfile(path,file),'Sheet',2)
                %                         writetable( app.UITable2_5.Data,fullfile(path,file),'Sheet',3)
                %                     catch
                %                     end
                %                     %                     sheets.sheetName_1 = 'sheetName_1';
                %                     %                     sheets.sheetName_2 = 'sheetName_2';
                %                     %                     sheets.sheetName_3 = 'sheetName_3';
                %                 end
            end
            
            try
                
                if p==1
                    
                    title=Text('Batch Data History');
                    sec2=Section(title);
                    sec2.Numbered = false;
                    %                 add(app.ch,sec1);
                    
                    %                 add(app.ch,image);
                    
                    
                    %                 sec = Section("(3)  Batch Data History");
                    
                    %                 h1 = Text(strcat(a5,'(3) Batch Data History'));
                    %                 HAlign('center')                                 h1.HAlign='center';
                    %                 add(app.rpt, h1)
                    %                 add(app.ch,"(3) Batch Data History");
                    tableStyle = { ...
                        Width('100%'), ...
                        Border('solid','black','1px'), ...
                        ColSep('solid','black','1px'), ...
                        RowSep('solid','black','1px') ...
                        };
                    
                    %                 format short
                    %             'Format','%s%.3f%.3f%.3f%.3f%.3f%s%.3f%.3f%s%s%s%s%s'   t(:,1:9) 'VariableTypes',{'double','double','double','double','double','double','double','double'}
                    %               ,'Format','%c%c%5.3f%s%s%s%s%s%s%s%s%s%s%s'
                    t= readtable(fullfile(path,file));
                    
                    %                 t=table2array(t(:,2:8));
                    
                    %                 t=array2table(t);
                    
                    %             table1=compose('%.3f', table2array(t(:,2:8)));
                    %             table1=table(table2dataset(t(:,1)),table1,table2dataset(t(:,9:14)));
                    %                          table1 = varfun(@(var) sprintf('%.3f',var), t(:,1:9));
                    %                     table1=Table(t);  'NumericFormat','%.3f'
                    %
                    xStr1 = arrayfun(@(val) num2str(val, "%.3f"), table2array(t(:,3:7)) , 'UniformOutput',false);
                    xStr2 = arrayfun(@(val) num2str(val, "%.0f"), table2array(t(:,11)) , 'UniformOutput',false);
                    %                    xStr
                    %                    table2array(t(:,1))
                    %                 xStr=[table2array(t(:,1)),xStr];
                    %                     [table2array(t(:,1)),xStr,table2array(t(:,12))]
                    xStr=array2table([table2array(t(:,1:2)),xStr1,xStr2],'VariableNames',t.Properties.VariableNames([1:7,9]));
                    %                 Size=[length(Str(:,1)),8];
                    %                 Vartype=repmat({'double'},1,8);
                    %                 xStr= table('size',Size,'VariableTypes',Vartype,'VariableNames',t.Properties.VariableNames(1:8));
                    %                 xStr.Data=[table2array(t(:,1)),Str];
                    %                                    xStr=table2array(xStr);
                    table1=Table(xStr);
                    %                 table1.ColumnName=t.Properties.VariableNames(1:8);
                    table1.TableEntriesHAlign = 'center';
                    table1.Style = tableStyle;
                    table1.Style = [table1.Style, {ResizeToFitContents(false)}];
                    
                    ncols = table1.NCols;
                    grps(1) = TableColSpecGroup;
                    grps(1).Span = ncols;
                    grps(1).Style = {Width('1cm')};
                    
                    table1.ColSpecGroups = grps;
                    add(sec2,table1);
                    %                 add(app.ch,table1);
                    add(app.ch,sec2);
                    add(app.rpt,app.ch);
                end
                %             h1 = Heading1('一致性异常分析');
                %             p = Paragraph(string(app.TextArea.Value));
                %             %             p.Style = {OuterMargin('0.5in','0in','0in','12pt')};
                %             add(app.rpt, h1)
                %             add(app.rpt, p)
                
                if p==2
                    
                    sec3 = Section("Total Outliers");
                    sec3.Numbered = false;
                    tableStyle = { ...
                        Width('100%'), ...
                        Border('solid','black','1px'), ...
                        ColSep('solid','black','1px'), ...
                        RowSep('solid','black','1px') ...
                        };
                    
                    t= readtable(fullfile(path,file));
                    xStr1 = arrayfun(@(val) num2str(val, "%.0f"), table2array(t(:,1)), 'UniformOutput',false);
                    xStr2 = arrayfun(@(val) num2str(val, "%.3f"), table2array(t(:,2:end)), 'UniformOutput',false);
                    
                    xStr=array2table([ xStr1,xStr2],'VariableNames',t.Properties.VariableNames);
                    
                    table1=Table(xStr);
                    
                    table1.TableEntriesHAlign = 'center';
                    table1.Style = tableStyle;
                    table1.Style = [table1.Style, {ResizeToFitContents(false)}];
                    
                    ncols = table1.NCols;
                    grps(1) = TableColSpecGroup;
                    grps(1).Span = ncols;
                    grps(1).Style = {Width('1cm')};
                    
                    table1.ColSpecGroups = grps;
                    
                    add(sec3,table1);
                    add(app.rpt,sec3);
                end
            catch
            end
            
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            app.DropDown.ItemsData = 1:length(app.DropDown.Items);
        end
        
        function Sub(app,ax,x,y,ylb,tit,m)
            
            app.DropDown.ItemsData=[];
            n=1:length(x);
            if iscell(y)
                y=str2double(y);
            end
            plot(ax,n,y,'Marker','.','MarkerSize',12)
            set(ax,'XTick',n, 'XTickLabel',x,'FontSize',10)
            %             xlabel(ax,'Batch')
            
            ylabel(ax,ylb)
            ax.XTickLabelRotation = 45;
            
            para=app.DropDown.Value;
            if ~isempty(para) && m==1
                tit=strcat(tit,para);
            end
            
            title(ax,tit,'FontWeight','bold')
            a=[min(n),max(n)];
            b=get(ax,'Ylim');
            %                           axis(ax,'auto')  ,b(1),b(2)
            axis(ax,[a(1),a(2),b(1),b(2)]);
            %                  set(ax,'Box','on')  %???not working
            app.DropDown.ItemsData = 1:length(app.DropDown.Items);
            
        end
        
        function [mean_UCL, mean_LCL]=SubControl(~,ax,x,BatchData,m,UCL,LCL)
            x=1:length(x);
            
            if m==1
                BatchEnd=BatchData(end);
                BatchData= BatchData(1:end-1);
                BatchData=[transpose(BatchData(BatchData>LCL&BatchData<UCL)),BatchEnd];
                
            end
            mean_ave=mean(BatchData);
            mean_sigma=std(BatchData);
            mean_UCL= mean_ave+3*mean_sigma;
            mean_LCL= mean_ave-3*mean_sigma;
            mean_UCL1= mean_ave+mean_sigma;
            mean_LCL1= mean_ave-mean_sigma;
            mean_UCL2= mean_ave+2*mean_sigma;
            mean_LCL2= mean_ave-2*mean_sigma;
            
            plot(ax,get(ax,'Xlim'),[mean_UCL1,mean_UCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL1,mean_LCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_UCL2,mean_UCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL2,mean_LCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            
            plot(ax,get(ax,'Xlim'),[mean_UCL,mean_UCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[mean_LCL,mean_LCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[mean_ave, mean_ave],'--.','color','[0.1 0.7 0.1]','LineWidth', 1);
            axis(ax,'auto')
            %             a=get(ax,'Xlim');
            b=get(ax,'Ylim');
            axis(ax,[min(x),max(x),b(1),b(2)]);
            
            %             text(ax,a(2), mean_UCL1,'+1σ')
            %             text(ax,a(2), mean_UCL2,'+2σ')
            
            %             text(ax,a(2), mean_UCL,'UCL')
            %             %             text(ax,a(2), mean_LCL1,' -1σ')
            %             %             text(ax,a(2), mean_LCL2,' -2σ')
            %             text(ax,a(2), mean_LCL,'LCL')
            %             text(ax,a(2), mean_ave,'CL')
            
            if m==1
                axis(ax,[min(x),max(x),mean_LCL,mean_UCL]);
            end
            
        end
        
        function [UCL,LCL]=SubControl2(app,ax,x,BatchData,m,UCL,LCL)
            
            if iscell(BatchData)
                BatchData=str2double(BatchData);
            end
            Number=table2array(app.UITable4_2.Data(:,11));
            if iscell(Number)
                Number=str2double(Number);
            end
            x=1:length(x);
            if m==1
                BatchEnd=BatchData(end);
                BatchData0= BatchData(1:end-1);
                BatchData=transpose([transpose(BatchData0(BatchData0>LCL&BatchData0<UCL)),BatchEnd]);
                
                NumberEnd=Number(end);
                Number= Number(1:end-1);
                Number=transpose([transpose(Number(BatchData0>LCL&BatchData0<UCL)), NumberEnd]);
            end
            
            mean_ratio=(transpose(BatchData)*(Number)/sum(Number))/100;
            %              mean_ratio
            
            mean_number=mean( Number);
            
            sigma=((mean_ratio*(1-mean_ratio))/mean_number)^(1/2);
            
            UCL=mean_ratio+3*sigma;
            LCL=mean_ratio-3*sigma;
            mean_UCL1=  mean_ratio+sigma;
            mean_LCL1=  mean_ratio-sigma;
            mean_UCL2=  mean_ratio+2*sigma;
            mean_LCL2=  mean_ratio-2*sigma;
            
            UCL=UCL*100;
            LCL=LCL*100;
            mean_UCL1=   mean_UCL1*100;
            mean_LCL1=   mean_LCL1*100;
            mean_UCL2=   mean_UCL2*100;
            mean_LCL2=   mean_LCL2*100;
            mean_ratio=  mean_ratio*100;
            %             get(ax,'Xlim')
            %             [mean_UCL1,mean_UCL1]*100
            %             get(ax,'Xlim')
            %             [mean_UCL1,mean_UCL1]*100
            plot(ax,get(ax,'Xlim'),[mean_UCL1,mean_UCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL1,mean_LCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_UCL2,mean_UCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL2,mean_LCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            
            plot(ax,get(ax,'Xlim'),[UCL,UCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[LCL,LCL],'--.','color','[0.8 0.8 0.8]','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[ mean_ratio, mean_ratio],'--.','color','[0.1 0.7 0.1]','LineWidth', 1);
            axis(ax,'auto')
            %             a=get(ax,'Xlim');
            b=get(ax,'Ylim');
            axis(ax,[min(x),max(x),0,b(2)]);
            
            %             %             text(ax,a(2), mean_UCL1*100,'+1σ')
            %             %             text(ax,a(2), mean_UCL2*100,'+2σ')
            %             text(ax,a(2), UCL,' UCL')
            %             %             text(ax,a(2), mean_LCL1*100,' -1σ')
            %             %             text(ax,a(2), mean_LCL2*100,' -2σ')
            %             text(ax,a(2), 0,' LCL')
            %             text(ax,a(2), mean_ratio,' CL')
            
            if m==1
                try
                    axis(ax,[min(x),max(x),0,1.08*UCL]);
                catch
                end
            end
        end
        
        function Transform(app,lamda)
            app.UIAxes_2.cla;
            if  ~isempty(app.nn)
                try
                    data=app.ndata(:,app.nn);
                    c=0;
                    if min(data)<=0
                        c=-min(data)+1;
                    end
                    app.transdata=((data+c).^lamda-1)/lamda;
                    %                     app.transdata
                    %                                     app.transdata=boxcox(app.ndata(:,app.nn),lamda);
                    if ~isempty(app.EditField_18.Value)
                        h = histfit(app.transdata,round(app.BinWidthSlider.Value));
                    else
                        h = histfit(app.transdata);
                    end
                    ax=app.UIAxes_2;
                    copyobj([h(2),h(1)],ax);
                    title(ax,['转换后分布拟合 ',app.EditField_8.Value],'Interpreter','none');
                    xlabel(ax,app.parameter(app.nn),'Interpreter','none');
                    ylabel(ax,'频数');
                    
                    close ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    
                    ave1=nanmean(app.transdata);
                    app.sigma1=nanstd(app.transdata);
                    app.USL1=ave1+3*app.sigma1';
                    app.LSL1=ave1-3*app.sigma1';
                    
                    %                     ave=  (ave1*lamda+1).^(1/lamda);
                    
                    USL=(app.USL1*lamda+1).^(1/lamda)-c;
                    LSL=(app.LSL1*lamda+1).^(1/lamda)-c;
                    %                     if ~isreal(LSL)
                    %                         LSL=abs(LSL);
                    %                     end
                    %                      if ~isreal(USL)
                    %                         USL=abs(USL);
                    %                     end
                    ave=(USL+LSL)/2;
                    sigma= (USL-LSL)/6;
                    USL=USL+0.2*sigma;
                    LSL=LSL-0.2*sigma;
                    if ~isreal(LSL) &&max(data)<0
                        LSL=-abs(LSL);
                    elseif ~isreal(LSL)&&max(data)>=0
                        LSL=0;
                    end
                    
                    if ~isreal(USL)&&max(data)<0
                        USL=-abs(USL);
                    elseif ~isreal(LSL)&&max(data)>=0
                        USL=abs(USL);
                    end
                    
                    app.EditField_10.Value=ave;
                    app.EditField_11.Value=sigma;
                    app.LCLEditField_2.Value=LSL;
                    app.UCLEditField_2.Value=USL;
                    
                    [~,k]=jbtest(app.transdata);
                    app.PEditField_2.Value=k;
                    s= skewness(app.transdata);
                    app.EditField_28.Value=s;
                    
                    
                    box(app.UIAxes_2,'on')
                    app.Slider.Enable='On';
                catch
                end
            else
                
                s = sprintf('请导入数据先\n');
                msgbox(s,'Message-Box','warn' )
                
            end
        end
        
        function BatchChart(app,x,y1,y2,y3)
            %             try
            %                 app.Tab_6.AutoResizeChildren='off';
            app.Tab_6.Scrollable='off';
            
            %                         x=table2array(app.UITable4_2.Data(:,1));
            %                         y1=table2array(app.UITable4_2.Data(:,3));
            %                         y2=table2array(app.UITable4_2.Data(:,4));
            %                         y3=table2array(app.UITable4_2.Data(:,7));
            
            app.subax=subplot(3,1,1,'Parent',app.Tab_6);
            Sub(app,app.subax,x,y1,'Mean','批次间均值 ',1)
            
            app.subax2=subplot(3,1,2,'Parent',app.Tab_6);
            Sub(app,app.subax2,x,y2,'Sigma','批次间标准差 ',1)
            
            app.subax3=subplot(3,1,3,'Parent',app.Tab_6);
            Sub(app,app.subax3,x,y3,'Ratio(%)','批次间离群率 ',1)
            
            app.TabGroup2.SelectedTab = app.Tab_6;
            
            app.Lamp.Color=[0.8 0.8 0.8];
            
            %             catch
            %                 msgbox('需要有历史批次数据','Message-Box','warn')
            %             end
        end
        
        function results = isMono(~,ax,x,a,m)
            %             b=diff(a);
            %             Inc=b>0;
            %             Dec=b<0;
            %             c=zeros(1,length(b)-n);
            %             d=zeros(1,length(b)-n);
            %             for i=1:(length(b)-n)
            %                 c(i)=all(Inc(i:i+n));
            %                 d(i)=all(Dec(i:i+n));
            %             end
            %             results=any(c)||any(d);
            
            x=1:length(x);
            n=m-2;
            b=diff(a);
            Inc=b>0;
            Dec=b<0;
            c=zeros(1,length(b)-n);
            d=zeros(1,length(b)-n);
            for i=1:(length(b)-n)
                c(i)=all(Inc(i:i+n));
                if c(i)
                    plot(ax,x(i:i+n+1),a(i:i+n+1),'color',[1,0.8,0.1],'Marker','.','MarkerSize',12)
                    %                     text(ax,x(i+n-3),a(i+n+1)*1.01,'连续6点以上递增')
                end
                d(i)=all(Dec(i:i+n));
                if d(i)
                    plot(ax,x(i:i+n+1),a(i:i+n+1),'color',[1,0.8,0.1],'Marker','.','MarkerSize',12)
                    %                     text(ax,x(i+n-3),a(i+n+1)*0.98,'连续6点以上递减')
                end
            end
            results=any(c)||any(d);


        end
        
        function t = DropdownItems(~,f)
            t=cell(1,length(f.Children));
            for i=1:length(f.Children)
                %             t=[t,cellstr(f.Children(i).Text)];
                t(i)=cellstr(f.Children(i).Text);
            end
        end
        
        function Device(app)
            %             app.Path='C:\Users\dell\Desktop\参数一致性App\Data\DeviceSave.mat';
            %             app.Path='C:\Users\tiger\Desktop\参数一致性APP\Data\DeviceSave.mat';
            %             cd
            app.Path=('Data\初始化\DeviceSave.mat');
            
            load(app.Path,'f')
            
            t = DropdownItems(app,f);
            app.DropDown_2.Items=t;
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items); % 把ItemsdData变成数组
            n1=app.DropDown_2.Value;   %用数字表示下拉框的索引
            if isempty(n1)
                n1=1;
            end
            h=f.Children(n1);
            if ~isempty(h.Children)
                t = DropdownItems(app,h);
                app.DropDown_3.Items=t;
                app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
                
                n2=app.DropDown_3.Value;
                if isempty(n2)
                    app.DropDown_3.Value=1;
                    n2=1;
                end
                
                g=h.Children(n2);
                
                if  ~isempty(g.Children)
                    t = DropdownItems(app,g);
                    app.DropDown_4.Items=t;
                else
                    app.DropDown_4.Items={''};
                end
            else
                app.DropDown_3.Items={''};
                app.DropDown_4.Items={''};
                return
            end
            
            
        end
    end
    methods (Access = public)
        
        function  ParaTable(app,Data)
            set( app.UITable,'data',Data,'columnname',Data.Properties.VariableNames);
            
            app.parameter= app.UITable.Data.Properties.VariableNames;
            
            app.ndata=str2double(table2array(app.UITable.Data));
            
            app.UITable.RowName='numbered';
            
            app.DropDown.Items=  app.parameter;
            
            app.DropDown.ItemsData = 1:length(app.DropDown.Items);
            try
                app.DropDown.Value=2;
            catch
            end
            app.nn = app.DropDown.Value;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.TextArea_2.Value=string(importdata('Data\初始化\默认路径.txt'));
            catch
            end
            Device(app)
            
            app.Button_25.Enable='off';
            app.Button_28.Enable='off';
            app.Button_29.Enable='off';
            
            app.DatePicker.Value = datetime('today');
            app.rpt=[];
            app.ch=[];
            
            %             format short
            %             app.DropDown_2.ItemsData=[];
            %             app.DropDown_3.ItemsData=[];
            %             app.DropDown_4.ItemsData=[];
            %
            %             a1=app.DropDown_2.Value ;
            %             a2=app.DropDown_3.Value ;
            %             a3=app.DropDown_4.Value;
            %             a4=app.EditField_8.Value; % 批次
            
            
            %             app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            %             app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            %             app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {955, 955, 955};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {955, 955};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {289, '1x', 837};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            Dir=string(app.TextArea_2.Value);
            
            
            if isfolder(Dir)
                
                [FileName,PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls'},'Choose a File',Dir);
            else
                [FileName,PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls'},'Choose a File');
            end
            
            if  (ischar(FileName))
                str=[PathName FileName];
                
                if strcmp(Dir,'')
                    app.TextArea_2.Value=PathName;
                end
                set(app.Label_39,'Text',FileName)
                f = waitbar(0,'Importing data...') ;
                %                 if  contains(FileName,'xlsx')||contains(FileName,'xls')
                %
                %                     [app.ndata,para]=xlsread(str);
                %                       app.parameter=para(1,:);
                %
                %                     %                     Data=array2table(app.ndata,'variablenames',app.parameter);
                %
                %                     %                     pause(0.2)
                %                 else
                
                %                 Data=readtable(str);
                Data=readtable(str,'PreserveVariableNames',true);
                app.parameter= Data.Properties.VariableNames;
                
                app.ndata=readmatrix(str);
                
                %                 end
                
                %                       app.ndata=Data;
                %                 end
                
                waitbar(0.33,f,'Importing data...') ;
                
                %                 nndata=array2table(app.ndata);
                %                 set(app.UITable,'data', Data);
                %                 Vartype=repmat({'numeric'},1,length(app.parameter));
                set(app.UITable,'data',Data,'columnname',app.parameter);
                app.UITable.RowName='numbered';
                %                   app.ndata=table2array(app.UITable.Data);
                
                waitbar(0.66,f,'Importing data...') ;
                
                app.DropDown.Items=  app.parameter;
                app.DropDown.ItemsData = 1:length(app.DropDown.Items);
                try
                    app.DropDown.Value=2;
                catch
                end
                app.nn = app.DropDown.Value;
                %                 app.ndata=table2array( app.ndata);
                %                 app.EditField.Value=str;
                %             guidata(hObject,handles);
                waitbar(1,f,'Importing data...') ;
                close(f)
                Initiate(app)
                app.rpt=[];
                app.TabGroup.SelectedTab = app.Tab_3;
                
                %                 ColumnName=['SN',app.parameter];
                %                 size=[0,length(app.parameter)+1];
                %                 Vartype=repmat({'double'},1,length(app.parameter)+1);
                %                 app.outline=table('size',size,'VariableTypes',Vartype,'VariableNames',ColumnName);
                %                 app.outline=table('size',size,'VariableNames',ColumnName);
                
                app.UITable2_6.Data=[];
                %                'VariableNames',['Number',app.parameter]
                %                  'VariableNames',ColumnName
            end
            
            try
                mkdir('Data\初始化')
                writecell(app.TextArea_2.Value,'Data\初始化\默认路径.txt')
            catch
            end
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            Initiate(app)
            app.DropDown.ItemsData = 1:length(app.DropDown.Items) ;
            app.nn = app.DropDown.Value;
            app.EditField_35.Value='';
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
       
            
            if   ~isempty(app.ndata)
                
                app.TabGroup.SelectedTab = app.Tab_2;
                app.UIAxes.cla;
                %                 app.ndata=app.UITable.Data;
                %       f=uifigure('name','批次内分布');
                try
                    h = histfit(app.ndata(:,app.nn));
                    %                 set(h,'nbins',3);
                    
                    ax=app.UIAxes;
                    app.hh=copyobj([h(2),h(1)],ax);
                    
                    %                hold(ax,'on')
                    
                    close
                    %                 delete(h)
                    
                    %       view(ax)
                    title(ax,['原始分布拟合 ',app.EditField_8.Value],'Interpreter','none');
                    
                    %                     if isempty(app.EditField_35.Value)
                    xlabel(ax,app.parameter(app.nn),'Interpreter','none');
            
                    
                    ylabel(ax,'频数');
                    %                  hold(ax,'on')
                    axis(ax,'auto')
            
                    
                    ave=nanmean(app.ndata(:,app.nn));
                    sigma=nanstd(app.ndata(:,app.nn));
                    USL=ave+3*sigma;
                    LSL=ave-3*sigma;
                    app.EditField_2.Value=ave;
                    app.EditField_3.Value=sigma;
                    app.LCLEditField.Value=LSL;
                    app.UCLEditField.Value=USL;
                    [~,k]=jbtest(app.ndata(:,app.nn));
                    app.PEditField.Value=k;
                    s= skewness(app.ndata(:,app.nn));
                    app.EditField_27.Value=s;
                    
                    app.BinWidthSlider.Enable='on';
                    box(app.UIAxes,'on')
                    legend(app.UIAxes,'off')
                    
                catch
                end
            else
                
                s = sprintf('请导入数据先\n');
                msgbox(s,'Message-Box','warn' )
                %       uiaxes(app.UIAxes);
                %         histfit(t(:,nn));
                
                %             app.BinWidthSlider = uislider(uifigure);
                %             app.BinWidthSlider.Limits = [1 11];
                %             app.BinWidthSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
                %             app.BinWidthSlider.ValueChangedFcn = createCallbackFcn(app, @refreshplot, true);
                %             app.BinWidthSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
                %             app.BinWidthSlider.MinorTicks = [];
                %             app.BinWidthSlider.Position = [183 46 192 3];
                %             app.BinWidthSlider.Value = 5;
                
            end
            %             [D, PD] = allfitdist(app.ndata(:,app.nn), 'PDF')
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.indice1 = event.Indices;
            %             app.DropDown.Value=app.indice1(2);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            
            %             if lamda~=0
            %             a=[];b=[];
            %             if isgraphics(app.p5)
            %                 a=1;
            %             end
            %             if ~isgraphics(app.p3)
            %                 b=1;
            %             end
            
            %             delete(app.hh)
            
            lamda = event.Value;
            app.EditField_9.Value= num2str(lamda);
            
            Transform(app,lamda)
            %                Button_39ValueChanged(app, event)
            
            %             if a==1
            %                Button_39ValueChanged(app, event)
            %             end
            %             if b==1
            %                 try
            %                     delete(app.p3)
            %                     delete(app.p4)
            %                     %                     legend(app.UIAxes,'off')
            %                 catch
            %                 end
            %             end
            %             end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %             app.ndata=app.UITable.Data;
            data=app.ndata(:,app.nn);
            c=0;
            if min(data)<=0
                c=-min(data)+1;
            end
            [~,b]=boxcox(data+c);
           
            %              b=-2.5;
            lamda=b*0.5;
            if b==0
                lamda=1;
            end
            %             if skewness(data)>=0
            %                 lamda=b+0.5;
            %             elseif skewness(data)<0
            %                 lamda=b-0.5;
            %             end
            if lamda>10
                lamda=8;
            elseif lamda<-3
                lamda=-2;
            end
            lamda2=round(lamda,1);
            
            app.Slider.Limits=[lamda2-0.5,lamda2+1];
            app.Slider.MajorTicks=lamda2-0.5:0.1:lamda2+1;
            app.Slider.MajorTickLabels={num2str(lamda2-0.5,'%10.1f'),'','',num2str(lamda2-0.2,'%10.1f'),'','',num2str(lamda2+0.1,'%10.1f'),'','',num2str(lamda2+0.4,'%10.1f'),'','',num2str(lamda2+0.7,'%10.1f'),'','',...
                num2str(lamda2+1,'%10.1f')};
            
            %             app.Slider.Limits=[lamda2-0.3*abs(lamda2),lamda2+1.2*abs(lamda2)];
            %             app.Slider.MajorTicks=lamda2*abs(lamda2)-0.3:0.1*abs(lamda2):lamda2+1.2*abs(lamda2);
            %             app.Slider.MajorTickLabels={num2str(lamda2-0.3*abs(lamda2),'%10.1f'),'','',num2str(lamda2-0,'%10.1f'),'','',num2str(lamda2+0.3*abs(lamda2),'%10.1f'),'','',num2str(lamda2+0.6*abs(lamda2),'%10.1f'),'','',num2str(lamda2+0.9*abs(lamda2),'%10.1f'),'','',...
            %                 num2str(lamda2+1.2*abs(lamda2),'%10.1f')};
            
            app.Slider.Value = lamda;
            
            %             app.Slider.MajorTicks      ,'','',num2str(lamda2+1.3,'%10.1f'),'','',num2str(lamda2+1.6,'%10.1f')
            
            app.EditField_9.Value= num2str(lamda);
            
            Transform(app,lamda)
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            
            Device(app)
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            
            Device(app)
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            
            %             app.ndata=app.UITable.Data;
            if isempty(app.ndata)
                msgbox('请导入数据先','Message-Box','warn')
                return
            end
            outlier1=[];
            outlier2=[];
            f = waitbar(0,'Calculating...') ;
            if strcmp(app.DropDown_6.Value,app.DropDown_6.Items{1})
                if app.EditField_3.Value ~= 0
                    
                    outlier1=find(app.ndata(:,app.nn)>app.UCLEditField.Value|app.ndata(:,app.nn)<app.LCLEditField.Value);
                    
                end
                if ~isempty(app.sigma1)
                    
                    outlier2=(find(app.transdata>app.USL1|app.transdata<app.LSL1));
                    
                end
                
            elseif strcmp(app.DropDown_6.Value,app.DropDown_6.Items{2})
                if app.EditField_3.Value ~= 0
                    
                    outlier1=find(app.ndata(:,app.nn)>app.UCLEditField.Value);
                    
                end
                if ~isempty(app.sigma1)
                    
                    outlier2=find(app.transdata>app.USL1);
                    
                end
                
            elseif strcmp(app.DropDown_6.Value,app.DropDown_6.Items{3})
                if app.EditField_3.Value ~= 0
                    
                    outlier1=find(app.ndata(:,app.nn)<app.LCLEditField.Value);
                    
                end
                if ~isempty(app.sigma1)
                    
                    outlier2=find(app.transdata<app.LSL1);
                    
                end
            end
            %             if ~isempty(outlier1)
            try
                waitbar(0.33,f,'Calculating...')
                app.EditField_19.Value=string(length(outlier1));
                x=length(outlier1)/length(app.ndata);
                app.EditField_20.Value=sprintf('%2.2f%%',x*100);
                
                %                 app.UITable2.Data=[outlier1,app.ndata(outlier1,:)];
                
                app.UITable2.Data=app.UITable.Data(outlier1,:);
                app.UITable2.ColumnName=app.parameter;
                
                ColumnName1=app.parameter;
                
                %                 t=array2table([outlier1,app.ndata(outlier1,:)],"VariableNames",ColumnName1);
                t=app.UITable.Data(outlier1,:);
                set(app.UITable2_3,'data',t, 'ColumnEditable',true,'ColumnWidth','auto','ColumnName',ColumnName1,'RowName','numbered');
                
                %         app.UITable2_3.Data=[outlier1,app.ndata(outlier1,:)];
                %         app.UITable2_3.ColumnName=['器件序号',app.parameter];
                %             end
                %             if ~isempty(outlier2)
                waitbar(0.66,f,'Calculating...')
                app.EditField_21.Value=string(length(outlier2));
                x=length(outlier2)/length(app.ndata);
                app.EditField_22.Value=sprintf('%2.2f%%',x*100);
                
                app.UITable2_2.Data=app.UITable.Data(outlier2,:);
                app.UITable2_2.ColumnName=app.parameter;
                
                ColumnName2=app.parameter;
                
                %                 t=array2table([outlier2,app.UITable.Data(outlier2,:)],"VariableNames",ColumnName2);
                t=app.UITable.Data(outlier2,:);
                
                set(app.UITable2_4,'data',t, 'ColumnEditable',true,'ColumnWidth','auto','ColumnName',ColumnName2,'RowName','numbered');
            catch
            end
            
            try
                if   strcmp(app.DropDown_5.Value,app.DropDown_5.Items{1})
                    app.single_outline=app.UITable2_3.Data;
                elseif  strcmp(app.DropDown_5.Value,app.DropDown_5.Items{2})
                    app.single_outline=app.UITable2_4.Data;
                end
            catch
            end
            
            waitbar(1,f,'Calculating...')
            close(f)
            
            app.DropDown.ItemsData=[];
            app.Label_27.Text=[app.DropDown.Value,'-原始控制限'];
            app.Label_28.Text=[app.DropDown.Value,'-转换控制限'];
            app.Label_29.Text=[app.DropDown.Value,'-修正控制限'];
            
            %         app.UITable2_4.Data=[outlier2,app.ndata(outlier2,:)];       'ColumnName', ColumnName2,
            %         app.UITable2_4.ColumnName=['器件序号',app.parameter];
            
            
            %             end
        end

        % Value changing function: BinWidthSlider
        function BinWidthSliderValueChanging(app, event)
            %             a=[];
            %             if   isgraphics(app.p1)
            %                 a=1;
            %             end
            app.UIAxes.cla;
            changingValue = round(event.Value);
            
            app.EditField_18.Value=num2str(changingValue);
            
            
            h = histfit(app.ndata(:,app.nn),changingValue);
            %                 set(h,'nbins',3);
            
            ax=app.UIAxes;
            
            app.hh=copyobj([h(2),h(1)],ax);
            
            %                hold(ax,'on')
            app.TabGroup.SelectedTab = app.Tab_2;
            
            legend(ax,'off')
            
            close
            
            %             if   a==1  %初始分布3σ控制限
            %
            %                 %                 delete(app.p3)
            %                 %                 delete(app.p4)
            %                 app.p1=plot(ax,[app.LCLEditField.Value,app.LCLEditField.Value],get(ax,'Ylim'),'--.b','LineWidth', 1);
            %                 app.p2=plot(ax,[app.UCLEditField.Value,app.UCLEditField.Value],get(ax,'Ylim'),'--.b','LineWidth', 1);
            %             end
        end

        % Button pushed function: Button_Save
        function Button_SavePushed(app, event)
            %             saveFig(app,app.UIAxes,'批次内初始分布')
            %             saveFig(app,app.UIAxes_2,'批次内转换分布')
            if app.CheckBox1.Value==true
                savexls(app,app.UITable.Data,'原始数据',0,1,0)
            end
            if app.CheckBox2.Value==true
                saveFig(app,app.UIAxes,'批次内分布图',1)
                %                 saveFig(app,app.UIAxes_2,'批次内转换分布',1)
                savexls(app,app.single_outline,'离群器件',1,3,0)
            end
            if app.CheckBox3.Value==true
                %             app.Tab_6.Children
                try
                    saveFig(app,app.Tab_6.Children,'批次间控制图',0)
                catch
                end
                savexls(app,app.UITable4_2.Data,'批次间参数数据',1,1,1)
                
            end
            if app.CheckBox4.Value==true
                
                savexls(app,app.UITable2_6.Data,'总离群器件',0,1,2)
                
            end
            if   ~isequal(app.TextArea.Value,{''})
                savexls(app,app.TextArea,'异常分析',1,2,0)
            end
            try
                winopen(app.path1)
            catch
                
            end
            %                add(app.rpt,app.ch);
            %                 savexls(app,app.UITable4_2,'批次间数据')
        end

        % Button pushed function: Button_12
        function Button_12Pushed2(app, event)
            app.TabGroup2.SelectedTab = app.Tab_5;
            if strcmp(app.EditField_19.Value,'')&&strcmp(app.EditField_25.Value,'')
                msgbox('请完成离群值识别','Message-Box','warn')
                return
            elseif isempty(app.EditField_8.Value)
                msgbox('请输入批次号并选择待统计的数据','Message-Box','warn')
                return
            end
            ColumnName=app.parameter;
            if isempty(app.UITable2_6.Data)           % 根据初始/转换/自定义确定离群数据并通过求并集得到总利群率
                
                if app.CheckBox_5.Value==1
                    LCL=app.LCLEditField.Value;
                    UCL=app.UCLEditField.Value;
                    Ratio=str2double(erase(app.EditField_20.Value,'%'));
                    Method='初始';
                    app.single_outline=app.UITable2_3.Data;
                    %                     q=union(app.UITable2_3.Data, app.UITable2_6.Data);
                    set(app.UITable2_6,'data',app.UITable2_3.Data,'ColumnName',ColumnName,'RowName','numbered')
                elseif  app.CheckBox_6.Value==1
                    LCL=app.LCLEditField_2.Value;
                    UCL=app.UCLEditField_2.Value;
                    Ratio=str2double(erase(app.EditField_22.Value,'%'));
                    Method='转换';
                    app.single_outline=app.UITable2_4.Data;
                    %                      q=union(app.UITable2_4.Data, app.UITable2_6.Data);
                    set(app.UITable2_6,'data', app.UITable2_4.Data,'ColumnName',ColumnName,'RowName','numbered')
                elseif  app.CheckBox_7.Value==1
                    LCL=str2double(app.EditField_23.Value);
                    UCL=str2double(app.EditField_24.Value);
                    Ratio=str2double(erase(app.EditField_26.Value,'%'));
                    Method='修正';
                    app.single_outline=app.UITable2_5.Data;
                    set(app.UITable2_6,'data',app.UITable2_5.Data,'ColumnName',ColumnName,'RowName','numbered')
                end
            elseif ~isempty(app.UITable2_6.Data)
                if app.CheckBox_5.Value==1
                    LCL=app.LCLEditField.Value;
                    UCL=app.UCLEditField.Value;
                    Ratio=str2double(erase(app.EditField_20.Value,'%'));
                    Method='初始';
                    app.single_outline=app.UITable2_3.Data;
                    app.UITable2_6.Data=union(app.UITable2_3.Data, app.UITable2_6.Data);
                elseif  app.CheckBox_6.Value==1
                    LCL=app.LCLEditField_2.Value;
                    UCL=app.UCLEditField_2.Value;
                    Ratio=str2double(erase(app.EditField_22.Value,'%'));
                    Method='转换';
                    app.single_outline=app.UITable2_4.Data;
                    
                    app.UITable2_6.Data=union(app.UITable2_4.Data, app.UITable2_6.Data);
                    
                elseif  app.CheckBox_7.Value==1
                    LCL=str2double(app.EditField_23.Value);
                    UCL=str2double(app.EditField_24.Value);
                    Ratio=str2double(erase(app.EditField_26.Value,'%'));
                    Method='修正';
                    app.single_outline=app.UITable2_5.Data;
                    app.UITable2_5.Data
                    app.UITable2_6.Data
                    app.UITable2_6.Data=union(app.UITable2_5.Data, app.UITable2_6.Data);
                    %                     app.UITable2_6.Data=unique(app.UITable2_6.Data);
                end
            end
            
            %             set(app.UITable2_6,'data',app.outline,'ColumnName',ColumnName,'RowName','numbered');
            %                 app.outline
            try
                n=height((app.UITable2_6.Data(:,1)));
                app.EditField_33.Value=string(n);      %离群数
                x=n/length(app.ndata);
                app.EditField_34.Value=sprintf('%2.2f%%',x*100);       %离群率
            catch
            end
            
            app.DropDown.ItemsData=[];
%             if strcmp(app.EditField_35.Value,'')
%                 unit='-';
%             else
%                 unit= app.EditField_35.Value;
%             end
            if strcmp(app.EditField_29.Value,'')
                Cpk='-';
            else
                Cpk=(app.EditField_29.Value);
            end
            %             date =   app.DatePicker.Value;                                  % 从批次内结果提取该批次的参数数据，
            %                RowName={app.EditField_8.Value}; str2double
            %                 ColumnName = {'均值','标准差','3σ上限','3σ下限','控制方法','离群率','离群率(转换)','转换系数','单位','备注'};
            %             ColumnName = {'Name','Batch','Mean','Sigma','Ratio','LCL','UCL','Cpk','Kurtosis','Skewness','Amount','Method','Date','Unit','Remarks'};
            ColumnName = {'参数名称','批次','均值','标准差','离群率','UCL','LCL','P值','偏度','控制方式','器件总数','Cpk','成品率','漏率'};
            %                 digits(3)
            %                  t=cell2table({(app.EditField_8.Value), vpa(app.EditField_2.Value), vpa(app.EditField_3.Value), vpa(Ratio), vpa(LCL), vpa(UCL), vpa(str2double(app.EditField_29.Value)), ...
            
            %                     length(app.ndata),vpa(str2double(app.EditField_9.Value)),vpa(app.PEditField_2.Value),Method,d,'-','-'},'VariableNames',ColumnName);
            %                       app.UIAxes.XLabel.String,
            t=cell2table({app.DropDown.Value,(app.EditField_8.Value), (app.EditField_2.Value), app.EditField_3.Value, Ratio, LCL, UCL, ...
                app.PEditField.Value,app.EditField_27.Value, Method,length(app.ndata), Cpk,'-','-'},'VariableNames',ColumnName);
            set(app.UITable4,'data',t, 'ColumnEditable',false,'Columnname',  ColumnName);
            %             app.UITable4.Data
            %                 app.UITable4.Data     'ColumnName', ColumnName,
            %                Data=cell2table(app.UITable4.Data);
            %             set(app.UITable4_2,'data',Data)
            %                 num2str(app.UITable2.Data(:,1)), num2str(app.UITable2_2.Data(:,1)),
            %                 {'','','','','','','','','','','',''},...
            %                    {'','','','','','','','','','','',''},...
            %                       {'','','','','','','','','','','',''},...
            
            %             elseif  isnan(str2double(app.EditField_8.Value))
            %                 msgbox('请输入仅含有数字的批次号','Message-Box','warn')


        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            
            app.DropDown_2.ItemsData=[];
            app.DropDown_3.ItemsData=[];                  % 读取历史批次数据
            
            a1=app.DropDown_2.Value ;
            %             app.DropDown_2.;
            a2=app.DropDown_3.Value ;
            a3=app.DropDown_4.Value;
            
            dirname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\'));
            
            [file,path] = uigetfile({'*.xlsx';'*.xls';'*.txt'},'选择历史批次',dirname);
            
            Filename=fullfile(path,file);
            
            if Filename~=0
                f=waitbar(0,'Importing...');
                Data=readtable(Filename,'PreserveVariableNames',true);
                %                                 nData=importdata(Filename);
                %                   nData=readmatrix(Filename);
                waitbar(0.33,f,'Importing...')
                BatchCol=Data.Properties.VariableNames;
                BatchRow=Data.Properties.RowNames;
                waitbar(0.66,f,'Importing...')
                set(app.UITable4_2,'data',Data,'Columnname', BatchCol,'Rowname', BatchRow)
%                 app.DropDown_7.Items={'Cpk','LCL','UCL','P值','偏度','控制方式','器件总数','成品率','漏率'};
  app.DropDown_7.Items=app.UITable_13.ColumnName;
                %                 app.Button_21.Value=0;
                app.Lamp.Color=[0.8 0.8 0.8];
                
                delete(app.Tab_6.Children)
                app.TabGroup2.SelectedTab = app.Tab_5;
                
                waitbar(1,f,'Importing...')
                close(f)
            end
            
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);


            %         app.Button_25.Enable='off';
            %         app.Button_28.Enable='off';
            %         app.Button_29.Enable='off';
            %         app.ButtonGroup.SelectedObject=app.Button_25;
            app.Button_30.Visible='off';
            app.Button_25.Enable='on';
            app.Button_28.Enable='on';
            app.Button_29.Enable='on';
            app.ButtonGroup.SelectedObject=app.Button_29;
            app.EditField_32.Value='';
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            %            app.UITable4_2.Data
            %            app.UITable4.Data
            app.TabGroup2.SelectedTab = app.Tab_5;
            Data1=(app.UITable4_2.Data);
            
            Data2=app.UITable4.Data;
            %             Data2.Properties.VariableNames=app.UITable4.ColumnName;       %也可以在array2table()时设置列名
            %              Data1
            %              Data2
            try
                Data=vertcat(Data1,Data2);
                set(app.UITable4_2,'data',Data,'Columnname',app.UITable4.ColumnName)
            catch
                msgbox('请保证历史批次数据格式与当前批次相同','Message-Box','help')
            end
            if length(table2array(app.UITable4_2.Data(:,1)))>1
                app.Button_25.Enable='on';
                app.Button_28.Enable='on';
                app.Button_29.Enable='on';
            end
            app.DropDown_7.Items=app.UITable4.ColumnName;
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.TabGroup2.SelectedTab = app.Tab_5;
            if isempty(app.indice2)
                msgbox('请在历史数据表中选择要删除的批次','Message-Box','help')
            else
                %            indices
                selection = uiconfirm(app.UIFigure,'将删除该批次的记录，继续?','Message-Box',...
                    'Options',{'确定','取消'},'Icon','warning');
                if strcmp(selection,'确定')
                    
                    app.UITable4_2.Data(app.indice2(1),:)=[];
                end
                %                 indices = [];
                
            end
        end

        % Cell selection callback: UITable4_2
        function UITable4_2CellSelection(app, event)
            app.indice2 = event.Indices;
        end

        % Value changed function: Button_18
        function Button_18ValueChanged(app, event)
            value = app.Button_18.Value;
            
            ax1=app.UIAxes;
            hold(ax1,'on')
            if value == 1
                if isempty(app.EditField_23.Value)&&isempty(app.EditField_24.Value)
                    msgbox('请在下方输入修正控制限','Message-Box','help')
                    app.Button_18.Value = 0;
                else
                    
                    delete(app.p7)
                    delete(app.p8)
                    a=str2double(app.EditField_23.Value);
                    b=str2double(app.EditField_24.Value);
                    if                 (~isnan(a) && ~isnan(b))
                        app.p7=plot(ax1,[a,a],get(ax1,'Ylim'),'--.','color','[0.1 0.6 0.1]','LineWidth', 1,'DisplayName','修正');
                        legend(ax1,'AutoUpdate', 'off')
                        app.p8=plot(ax1,[b,b],get(ax1,'Ylim'),'--.','color','[0.1 0.6 0.1]','LineWidth', 1,'DisplayName','修正');
                        legend(ax1,'AutoUpdate', 'on')
                    elseif ~isnan(a)
                        app.p7=plot(ax1,[a,a],get(ax1,'Ylim'),'--.','color','[0.1 0.6 0.1]','LineWidth', 1,'DisplayName','修正');
                    elseif ~isnan(b)
                        app.p8=plot(ax1,[b,b],get(ax1,'Ylim'),'--.','color','[0.1 0.6 0.1]','LineWidth', 1,'DisplayName','修正');
                    end
                    %                     if ~isnan(a) ||   (~isnan(a) && ~isnan(b))
                    %                         legend(ax1,app.p7,'自定义')
                    %                     elseif ~isnan(b)
                    %                         legend(ax1,app.p8,'自定义')
                    %                         app.TabGroup.SelectedTab = app.Tab_2;
                    %                     end
                end
            else
                delete(app.p7)
                delete(app.p8)
                %                 legend(ax1,'off')
            end
            
        end

        % Value changed function: EditField_23
        function EditField_23ValueChanged(app, event)
            
            Button_18ValueChanged(app, event)
        end

        % Value changed function: EditField_24
        function EditField_24ValueChanged(app, event)
            Button_18ValueChanged(app, event)
            
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            %             app.UIAxes4.cla
            item=app.DropDown_7.Value;
            x=table2array(app.UITable4_2.Data(:,1));
            y=table2array(app.UITable4_2.Data(:,item));
            Sub(app,app.UIAxes4,x,y,item,item,0)
            axis(app.UIAxes4,'auto')
            box(app.UIAxes4,'on')
            a=get(app.UIAxes4,'Xlim');
            b=get(app.UIAxes4,'Ylim');
            axis(app.UIAxes4,[a,b(1),1.02*b(2)]);
            box(app.UIAxes4,'on')
            app.TabGroup2.SelectedTab = app.Tab_7;
        end

        % Callback function
        function Button_22ValueChanged(app, event)
            
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            %             isfield(app,'p1')      %?
            if isgraphics(app.p1)||isgraphics(app.p2)||isgraphics(app.p3)||isgraphics(app.p4)||isgraphics(app.p5)||isgraphics(app.p6)
                Button_39ValueChanged(app, event)
            end
            if  strcmp(app.DropDown_5.Value,'初始分布')
                app.CheckBox_5.Value=1;
                app.CheckBox_6.Value=0;
                app.CheckBox_7.Value=0;
            else
                app.CheckBox_5.Value=0;
                app.CheckBox_6.Value=1;
                app.CheckBox_7.Value=0;
            end
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            if isgraphics(app.p1)||isgraphics(app.p2)||isgraphics(app.p3)||isgraphics(app.p4)||isgraphics(app.p5)||isgraphics(app.p6)
                Button_39ValueChanged(app, event)
            end
            if ~strcmp(app.EditField_19.Value,'')
                Button_10Pushed(app, event)
            end
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            %             selection = uiconfirm(app.UIFigure,'确定使用自定义控制限（将覆盖初始离群数据）?','Message-Box',...
            %                 'Icon','warning');
            %             if selection=='确定'
            
            outlier=find(app.ndata(:,app.nn)>str2double(app.EditField_24.Value)|app.ndata(:,app.nn)<str2double(app.EditField_23.Value));
            
            
            app.EditField_25.Value=string(length(outlier));      %离群数
            x=length(outlier)/length(app.ndata);
            app.EditField_26.Value=sprintf('%2.2f%%',x*100);       %离群率
            
            %             app.UITable2_5.Data=[outlier,app.ndata(outlier,:)];
            %             app.UITable2_5.ColumnName=['器件序号',app.parameter];
            ColumnName=app.parameter;
            %             t=array2table([outlier,app.ndata(outlier,:)],'VariableNames',ColumnName);
            t=app.UITable.Data(outlier,:);
            set(app.UITable2_5,'data',t, 'ColumnName', ColumnName,'ColumnEditable',true,'ColumnWidth','auto','RowName','numbered');
            
            app.CheckBox_5.Value=0;
            app.CheckBox_6.Value=0;
            app.CheckBox_7.Value=1;
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            if value==1
                app.CheckBox_6.Value=0;
                app.CheckBox_7.Value=0;
            end
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            if value==1
                app.CheckBox_5.Value=0;
                app.CheckBox_7.Value=0;
            end
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            if value==1
                app.CheckBox_5.Value=0;
                app.CheckBox_6.Value=0;
            end
        end

        % Button pushed function: Button_30
        function Button_24Pushed(app, event)
            app.TabGroup2.SelectedTab = app.Tab_6;
            %             value = app.Button_21.Value;
            %             if value==1
            if length(table2array(app.UITable4_2.Data(:,1))) <5
                msgbox('需积累5批以上数据','Message-Box','help')
            else
                t1=table2array(app.UITable4_2.Data(:,1));
                t2=table2array(app.UITable4_2.Data(:,2));
                t3=table2array(app.UITable4_2.Data(:,3));
                t4=table2array(app.UITable4_2.Data(:,4));
                
                BatchChart(app,t1,t2,t3,t4)
                
                hold (app.subax,'on')
                hold (app.subax2,'on')
                hold (app.subax3,'on')
                
                pause(0.05)
                [a,b]=SubControl(app,app.subax,t1,t2,1,app.mean_UCL,app.mean_LCL);
                pause(0.05)
                [c,d]=SubControl(app,app.subax2,t1,t3,1,app.sigma_UCL,app.sigma_LCL);
                pause(0.05)
                [e,f]=SubControl2(app,app.subax3,t1,t4,1,app.ratio_UCL,app.ratio_LCL);
                %                 e=e*100;
                %                 f=f*100;
                
                t20=t2(1:end-1);
                t30=t3(1:end-1);
                t40=t4(1:end-1);
                
                o2=[find(t20<=b),find(t20>=a)];
                o3=[find(t30<=d),find(t30>=c)];
                o4=find(t40>=e);
                
                plot(app.subax,t1(o2),t20(o2),'x','color','r','LineWidth',1,'MarkerSize',8);
                plot(app.subax2,t1(o3),t30(o3),'x','color','r','LineWidth',1,'MarkerSize',8);
                plot(app.subax3,t1(o4),t40(o4),'x','color','r','LineWidth',1,'MarkerSize',8);
                
                if  b>t2(end) || t2(end)>a
                    plot(app.subax,t1(end),t2(end),'o','color','r','LineWidth',1,'MarkerSize',8);
                end
                if  d>t3(end) || t3(end)>c
                    plot(app.subax2,t1(end),t3(end),'o','color','r','LineWidth',1,'MarkerSize',8);
                end
                if t4(end)>e
                    plot(app.subax3,t1(end),t4(end),'o','color','r','LineWidth',1,'MarkerSize',8);
                end
                
                if all( b<t2(end) & t2(end)<a & d<t3(end) & t3(end)<c & t4(end)<e)
                    app.Lamp.Color='g';
                elseif  any( b==t2(end) | t2==a(end) | d==t3(end) | t3(end)==c | e==t4(end))
                    app.Lamp.Color='y';
                else
                    app.Lamp.Color='r';
                end
                
                app.mean_UCL=a;
                app.mean_LCL=b;
                app.sigma_UCL=c;
                app.sigma_LCL=d;
                app.ratio_UCL=e;
                app.ratio_LCL=f;
                
                hold (app.subax,'off')
                hold (app.subax2,'off')
                hold (app.subax3,'off')
                
            end
            %             else
            %                     Button_23Pushed(app, event)
            %             end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged2(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            delete(app.Tab_6.Children)
            Data=table2array(app.UITable4_2.Data(:,3:5));
            if iscell(Data)
                Data=str2double(Data);
            end
            t1=table2array(app.UITable4_2.Data(:,2));
            t2=Data(:,1);
            t3=Data(:,2);
            t4=Data(:,3);
            
%             t1=table2array(app.UITable4_2.Data(:,2));
%             t2=table2array(app.UITable4_2.Data(:,3));
%             t3=table2array(app.UITable4_2.Data(:,4));
%             t4=table2array(app.UITable4_2.Data(:,5));
            
            switch selectedButton
                case app.Button_25
                    
                    
                    BatchChart(app,t1,t2,t3,t4)
                    app.EditField_32.Value='';
                case app.Button_28
                    app.TabGroup2.SelectedTab = app.Tab_6;
                    if length(table2array(app.UITable4_2.Data(:,1))) <5
                        msgbox('需积累5批以上数据','Message-Box','help')
                    else
                        %                         Button_15Pushed(app, event)
                        %                         t1=table2array(app.UITable4_2.Data(:,1));
                        %                         t2=table2array(app.UITable4_2.Data(:,2));
                        %                         t3=table2array(app.UITable4_2.Data(:,3));
                        %                         t4=table2array(app.UITable4_2.Data(:,4));
                        
                        BatchChart(app,t1,t2,t3,t4)
                        
                        hold (app.subax,'on')
                        hold (app.subax2,'on')
                        hold (app.subax3,'on')
                        
                        pause(0.05)
                        [a,b]=SubControl(app,app.subax,t1,t2,0);
                        pause(0.05)
                        [c,d]=SubControl(app,app.subax2,t1,t3,0);
                        pause(0.05)
                        [e,f]=SubControl2(app,app.subax3,t1,t4,0);
                        %                         e=e*100;
                        %                         f=f*100;
                        results1 = isMono(app,app.subax,t1,t2,6);
                        results2 = isMono(app,app.subax2,t1,t3,6);
                        results3 = isMono(app,app.subax3,t1,t4,6);
                        
                        o2=[find(t2<=b),find(t2>=a)];
                        o3=[find(t3<=d),find(t3>=c)];
                        o4=find(t4>e);
                        
                        plot(app.subax,(o2),t2(o2),'o','color','r','LineWidth',1,'MarkerSize',8);
                        plot(app.subax2,(o3),t3(o3),'o','color','r','LineWidth',1,'MarkerSize',8);
                        plot(app.subax3,(o4),t4(o4),'o','color','r','LineWidth',1,'MarkerSize',8);
                        
                        p=any( t2<b | t2>a | t3<d | t3>c | t4>e);
                        q=results1==1||results2==1||results3==1;
                        
                        if  p
                            app.Lamp.Color='r';
                        elseif q
                            app.Lamp.Color='y';
                        else
                            app.Lamp.Color='g';
                        end
                        
                        
                        if  p && q
                            app.EditField_32.Value='(1)超出控制限；(2)连续上升或下降';
                        elseif p
                            app.EditField_32.Value='超出控制限';
                        elseif   q
                            app.EditField_32.Value='连续上升或下降';
                        else
                            app.EditField_32.Value='';
                        end
                        
                        app.mean_UCL=a;
                        app.mean_LCL=b;
                        app.sigma_UCL=c;
                        app.sigma_LCL=d;
                        app.ratio_UCL=e;
                        app.ratio_LCL=f;
                        
                        hold (app.subax,'off')
                        hold (app.subax2,'off')
                        hold (app.subax3,'off')
                    end
                    
                case app.Button_29
                    app.TabGroup2.SelectedTab = app.Tab_6;
                    %             value = app.Button_21.Value;
                    %             if value==1
                    app.TabGroup2.SelectedTab = app.Tab_6;
                    if length(table2array(app.UITable4_2.Data(:,1))) <5
                        msgbox('需积累5批以上数据','Message-Box','help')
                    else
                        %                         Button_15Pushed(app, event)
                        %                         t1=table2array(app.UITable4_2.Data(:,1));
                        %                         t2=table2array(app.UITable4_2.Data(:,2));
                        %                         t3=table2array(app.UITable4_2.Data(:,3));
                        %                         t4=table2array(app.UITable4_2.Data(:,4));
                        
                        BatchChart(app,t1,t2,t3,t4)
                        hold (app.subax,'on')
                        hold (app.subax2,'on')
                        hold (app.subax3,'on')
                        
                        pause(0.05)
                        [a,b]=SubControl(app,app.subax,t1,t2,1,app.mean_UCL,app.mean_LCL);
                        pause(0.05)
                        [c,d]=SubControl(app,app.subax2,t1,t3,1,app.sigma_UCL,app.sigma_LCL);
                        pause(0.05)
                        [e,f]=SubControl2(app,app.subax3,t1,t4,1,app.ratio_UCL,app.ratio_LCL);
                        %                         e=e*100;
                        %                         f=f*100;
                        results1 = isMono(app,app.subax,t1,t2,6);
                        results2 = isMono(app,app.subax2,t1,t3,6);
                        results3 = isMono(app,app.subax3,t1,t4,6);
                        
                        t20=t2(1:end-1);
                        t30=t3(1:end-1);
                        t40=t4(1:end-1);
                        
                        o2=[find(t20<=b),find(t20>=a)];
                        o3=[find(t30<=d),find(t30>=c)];
                        o4=find(t40>e);
                        
                        plot(app.subax,(o2),t20(o2),'x','color','r','LineWidth',1,'MarkerSize',8);
                        plot(app.subax2,(o3),t30(o3),'x','color','r','LineWidth',1,'MarkerSize',8);
                        plot(app.subax3,(o4),t40(o4),'x','color','r','LineWidth',1,'MarkerSize',8);
                        
                        if  b>t2(end) || t2(end)>a
                            plot(app.subax,length(t1),t2(end),'o','color','r','LineWidth',1,'MarkerSize',8);
                        end
                        if  d>t3(end) || t3(end)>c
                            plot(app.subax2,length(t1),t3(end),'o','color','r','LineWidth',1,'MarkerSize',8);
                        end
                        if t4(end)>e
                            plot(app.subax3,length(t1),t4(end),'o','color','r','LineWidth',1,'MarkerSize',8);
                        end
                        
                        p=any( t2(end)<b | t2(end)>a | t3(end)<d | t3(end)>c | t4(end)>e);
                        q= results1==1||results2==1||results3==1;
                        
                        if p
                            app.Lamp.Color='r';
                        elseif q
                            app.Lamp.Color='y';
                        else
                            app.Lamp.Color='g';
                        end
                        
                        if  p && q
                            app.EditField_32.Value='超出控制限/连续上升或下降';
                        elseif p
                            app.EditField_32.Value='超出控制限';
                        elseif   q
                            app.EditField_32.Value='连续上升或下降';
                        else
                            app.EditField_32.Value='';
                        end
                        
                        app.mean_UCL=a;
                        app.mean_LCL=b;
                        app.sigma_UCL=c;
                        app.sigma_LCL=d;
                        app.ratio_UCL=e;
                        app.ratio_LCL=f;
                        
                        hold (app.subax,'off')
                        hold (app.subax2,'off')
                        hold (app.subax3,'off')
                        
                        app.Button_30.Visible='on';
                    end
            end
        end

        % Callback function
        function Button_26Pushed(app, event)
            app.Button_25.Enable='on';
            app.Button_28.Enable='on';
            app.Button_29.Enable='on';
            %             app.ButtonGroup.SelectedObject= app.ButtonGroup.SelectedObject;
            %             app.ButtonGroup.SelectedObject=app.Button_25;
            ButtonGroupSelectionChanged2(app, event)
            
        end

        % Button pushed function: Button_Save_2
        function Button_Save_2Pushed(app, event)
            import mlreportgen.dom.*
            import mlreportgen.report.*
            app.DropDown_2.ItemsData=[];                     % 生成报告
            app.DropDown_3.ItemsData=[];
            
            app.DropDown.ItemsData=[];
            a1=app.DropDown_2.Value ;
            %             app.DropDown_2.;
            a2=app.DropDown_3.Value ;
            a3=app.DropDown_4.Value;
            a4=app.EditField_8.Value; % 批次
            %             a5=app.DropDown.Value ; % 参数
            a6=app.DropDown_8.Value; % 控制环节
            
            dirname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\',a4,'批','\',a6,'\'));
            
            
            selection = uiconfirm(app.UIFigure,'请确认已分析完全部参数并已保存','Message-Box',...
               'Options',{'确定','取消'}, 'Icon','question');
            if strcmp(selection,'确定')
                try
                    f=waitbar(0,'Generating...');
                    
                    title=Text('Analysis of Anomaly');
                    sec4=Section(title);
                    sec4.Numbered = false;
                    
                    para=Text(string(app.TextArea.Value));
                    fontFamily = FontFamily('Microsoft YaHei UI Light');
                    %                 fontSizeObj = FontSize('25px');
                    para.Style = {fontFamily};
                    
                    add(sec4,para);
                    add(app.rpt,sec4);
                    
                    waitbar(0.25,f,'Generating...');
                    
                    close(app.rpt);
                    waitbar(0.5,f,'Generating...');
                    rptview(app.rpt.OutputPath);
                    
                    pause(0.2)
                    waitbar(0.75,f,'Generating...');
                    app.rpt=[];
                    app.ch=[];
                    waitbar(1,f,'Generating...');
                    close(f)
                    winopen(dirname)
                catch
                    msg = '请先保存数据并关闭之前生成的报告';
                    msgbox(msg,'Message-Box','help')
                    close(f)
                end
            end
            
            
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            
            app.DropDown.ItemsData = 1:length(app.DropDown.Items);
            %             wdapp = mlreportgen.utils.WordApp.instance();
            %             mlreportgen.utils.WordApp.close(wdapp);
            
            %             rpt = mlreportgen.report.Report("figureSnapshotSideBySideLandscape","docx");
            %             chapter2 = Chapter("Title", "Types of Cosine Value Plots with Random Noise");
            %
            %             fig4Img = Figure(app.fig);
            %             fig4Img = Image(getSnapshotImage(fig4Img, rpt));
            %             fig4Img.Style = [fig4Img.Style {ScaleToFit}];
            %             %             fig4Img.Style = imgStyle;
            %             add(chapter2, fig4Img);
            %             add(rpt, chapter2);
            %             close(rpt);
            %             rptview(rpt);
            
            
            %             d = Document('imageArea','docx');
            %             open('imageArea.docx');
            %
            %             p = Paragraph('Plot 1');
            %             p.Bold = true;
            %             append(d,p);
            %
            %             saveas(app.fig,'myPlot_img.png');
            %
            %             plot1 = Image('myPlot_img.png');
            %             plot1.Style = [plot1.Style {ScaleToFit}];
            %             append(d,plot1);
            %
            %             close(d);
            %             delete('myPlot_img.png')
            %             rptview(d.OutputPath);
            
        end

        % Button pushed function: CpkButton
        function CpkButtonPushed(app, event)
            TL=app.EditField_30.Value;
            TU=app.EditField_31.Value;
            u=app.EditField_2.Value;
            sigma=app.EditField_3.Value;
            if isempty(TL) &&  isempty(TU)
                msgbox('请输入标称值控制上/下限','Message-Box','help')
                return
            elseif isempty(TL)
                Cpk=(str2double(TU)-u)/(3*sigma);
            elseif isempty(TU)
                Cpk=-(str2double(TL)-u)/(3*sigma);
            else
                Cpk=min([(u-str2double(TL))/(3*sigma),(str2double(TU)-u)/(3*sigma)]);
            end
            app.EditField_29.Value=num2str(Cpk);
        end

        % Button pushed function: MSAButton
        function MSAButtonPushed(app, event)
            RreMSA
        end

        % Button pushed function: Button_31
        function Button_31Pushed(app, event)
            %             size=[0,length(app.parameter)+1];
            %             Vartype=repmat({'double'},1,length(app.parameter)+1);
            %             ColumnName=['SN',app.parameter];
            %             app.outline=table('size',size,'VariableTypes',Vartype,'VariableNames',ColumnName);
            %             set(app.UITable2_6,'data',  app.outline,'ColumnName', ColumnName);
            %           delete(app.UITable2_6.Children)
            app.UITable2_6.Data=[];
            %             app.UITable2_6.ColumnName=[];
            app.EditField_33.Value='';
            app.EditField_34.Value='';
            %                  ,'ColumnName', ColumnName
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            DeviceManagement
        end

        % Button pushed function: Button_32
        function Button_32Pushed(app, event)
            %             try
            %                 str= app.TextArea_2.Value;
            %                 Dir=  string(str);
            %
            %                 if strcmp(Dir,'')
            %
            %                     msgbox('请先填入存放数据的文件夹路径','Message-Box','help')
            %
            %                 elseif isfolder(Dir)
            %
            %                     latestfile = getlatestfileall(Dir);
            %
            %                      f = waitbar(0,'Importing data...') ;
            %
            %                     if  contains(latestfile,'xlsx')||contains(latestfile,'xls')
            %
            %                         [app.ndata,app.parameter]=xlsread(latestfile);
            %
            %                     else
            %                         %                 Filename=strcat(Dir,'\',latestfile);
            %
            %                         %             app.EditField.Value=string(importdata(Filename));
            %
            %                         Data=readtable(latestfile);
            %
            %                         app.parameter=Data.Properties.VariableNames;
            %
            %                         app.ndata=readmatrix(latestfile);
            %
            %
            %                     end
            %
            %                 elseif isfile(Dir)
            %
            %                     f = waitbar(0,'Importing data...') ;
            %
            %                     if  contains(Dir,'xlsx')||contains(Dir,'xls')
            %
            %                         [app.ndata,app.parameter]=xlsread(Dir);
            %
            %                     else
            %
            %                         Data=readtable(Dir);
            %
            %                         app.parameter=Data.Properties.VariableNames;
            %
            %                         app.ndata=readmatrix(Dir);
            %
            %                     end
            %
            %                 end
            %
            %                 waitbar(0.25,f,'Importing data...') ;
            %
            %                 set(app.UITable,'data', array2table(app.ndata),'columnname',app.parameter);
            %                 app.UITable.RowName='numbered';
            %
            %                 waitbar(0.5,f,'Importing data...') ;
            %
            %                 app.DropDown.Items=  app.parameter;
            %                 app.DropDown.ItemsData = 1:length(app.DropDown.Items) ;
            %                 app.nn = app.DropDown.Value;
            %
            %                 Initiate(app)
            %                 app.rpt=[];
            %                 app.TabGroup.SelectedTab = app.Tab_3;
            %
            %                 waitbar(0.75,f,'Importing data...') ;
            %
            %                 ColumnName=['Number',app.parameter];
            %                 size=[0,length(app.parameter)+1];
            %                 Vartype=repmat({'double'},1,length(app.parameter)+1);
            %                 app.outline=table('size',size,'VariableTypes',Vartype,'VariableNames',ColumnName);
            %
            %                 app.UITable2_6.Data=[];
            %                 mkdir('Data\初始化')
            %                 writecell(str,'Data\初始化\默认路径.txt') ;
            %
            %                 waitbar(1,f,'Importing data...') ;
            %                 close (f)
            %             catch
            %
            %                 msgbox('请确保文件夹中刚保存了正确格式的文件','Message-Box','help')
            %             end
            try
                mkdir('Data\初始化')
                writecell(app.TextArea_2.Value,'Data\初始化\默认路径.txt')
            catch
            end
            try
                str= app.TextArea_2.Value;
                Dir=  string(str);
                
                if isfolder(Dir)
                    
                    latestfile = getlatestfileall(Dir);
                    
                    f = waitbar(0,'Importing data...') ;
                    
                    %                 Filename=strcat(Dir,'\',latestfile);
                    
                    %             app.EditField.Value=string(importdata(Filename));
                    waitbar(0.2,f,'Importing data...') ;
                    
                    %                     if  contains(latestfile,'xlsx')||contains(latestfile,'xls')
                    %
                    %                         [app.ndata,app.parameter]=xlsread(latestfile);
                    %                            waitbar(0.4,f,'Importing data...') ;
                    %                     else
                    
                    Data=readtable(latestfile);
                    
                    app.parameter=Data.Properties.VariableNames;
                    
                    app.ndata=readmatrix(latestfile);
                    
                    waitbar(0.4,f,'Importing data...') ;
                    %                     end
                    
                elseif isfile(Dir)
                    
                    f = waitbar(0,'Importing data...') ;
                    
                    Data=readtable(Dir);
                    
                    waitbar(0.2,f,'Importing data...') ;
                    
                    app.parameter=Data.Properties.VariableNames;
                    
                    app.ndata=readmatrix(Dir);
                    
                    waitbar(0.4,f,'Importing data...') ;
                    
                elseif strcmp(Dir,'')
                    msgbox('请先填入存放数据的文件夹路径','Message-Box','help')
                    return
                end
                
                
                set(app.UITable,'data', Data,'columnname',app.parameter);
                app.UITable.RowName='numbered';
                
                waitbar(0.6,f,'Importing data...') ;
                
                app.DropDown.Items=  app.parameter;
                app.DropDown.ItemsData = 1:length(app.DropDown.Items) ;
                try
                    app.DropDown.Value=2;
                catch
                end
                app.nn = app.DropDown.Value;
                
                Initiate(app)
                app.rpt=[];
                app.TabGroup.SelectedTab = app.Tab_3;
                
                waitbar(0.8,f,'Importing data...') ;
                
                %                 ColumnName=['SN',app.parameter];
                %                 size=[0,length(app.parameter)+1];
                %                 Vartype=repmat({'double'},1,length(app.parameter)+1);
                %                 app.outline=table('size',size,'VariableTypes',Vartype,'VariableNames',ColumnName);
                
                app.UITable2_6.Data=[];
                mkdir('Data\初始化')
                writecell(str,'Data\初始化\默认路径.txt') ;
                
                waitbar(1,f,'Importing data...') ;
                close (f)
            catch
                
                msgbox('请确保文件夹中刚保存了正确格式的文件','Message-Box','help')
            end
            
        end

        % Button pushed function: Button_33
        function Button_33Pushed(app, event)
            %             ffq = waitbar(0,'Importing data...') ;
            %             pause(0.1)
            %             waitbar(0.25,ffq,'Importing data...')
            %             pause(0.1)
            %             waitbar(0.5,ffq,'Importing data...')
            %             pause(0.1)
            %             waitbar(0.75,ffq,'Importing data...')
            %             pause(0.1)
            %             waitbar(1,ffq,'Importing data...')
            %             close(ffq)
            
            Button_2Pushed(app)
            
            Button_7Pushed(app)
            
            app.Button_39.Value=1;
            Button_39ValueChanged(app, event)
            %             Button_9Pushed(app)
            
            app.DropDown_5.Value='转换后';
            
            Button_39ValueChanged(app, event)
            
            Button_10Pushed(app)
            
        end

        % Button pushed function: Button_34
        function Button_34Pushed(app, event)
            if isempty(app.indice1)
                msgbox('请先选择要删除的行','Message-Box','help')
            else
                app.UITable.Data(app.indice1(1),:)=[];
                app.ndata(app.indice1(1),:)=[];
            end
            
        end

        % Button pushed function: Button_35
        function Button_35Pushed(app, event)
            if isempty(app.indice1)
                msgbox('请先选择要删除的列','Message-Box','help')
            else
                n=app.indice1(2);
                
                app.UITable.Data(:,n)=[];
                app.parameter(n)=[];
                
                set( app.UITable,'data',app.UITable.Data,'ColumnName',app.parameter)
                app.DropDown.Items=  app.parameter;
                app.DropDown.ItemsData = 1:length(app.DropDown.Items) ;
                app.nn = app.DropDown.Value;
                app.ndata(:,n)=[];
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)


        end

        % Value changed function: Button_36
        function Button_36ValueChanged(app, event)
            value = app.Button_36.Value;
            
            
            %             try
            if value==1
                ax1=app.UIAxes;
                %             ax2=app.UIAxes_2;
                hold(ax1,'on')
                %             hold(ax2,'on')
                if app.Button_18.Value==1
                    data = (app.UITable2_5.Data);
                elseif  strcmp(app.DropDown_5.Value,'初始分布')
                    data = (app.UITable2_3.Data);
                elseif  strcmp(app.DropDown_5.Value,'转换后')
                    data = (app.UITable2_4.Data);
                end
                
                l=length(app.ndata(:,app.nn));
                if l>1000
                    h=45;
                elseif l>500 && l<=1000
                    h=25;
                elseif l>200 && l<=500
                    h=15;
                elseif l<=200
                    h=5;
                end
                
                a=repmat(h,height(data(:,app.nn)),1);
                %                     app.DropDown.ItemsData=[];
                %                 app.DropDown.ItemsData = 1:length(app.DropDown.Items) ;
                %                 n=app.DropDown.Value;
                
                app.out1=scatter(ax1,table2array(data(:,app.nn)),a,15,'filled','DisplayName','outliers','MarkerFaceColor','b');
                %                 app.DropDown.ItemsData = 1:length(app.DropDown.Items) ;
                %                     app.out1.MarkerEdgeAlpha=0.5;
                app.out1.MarkerFaceAlpha = 0.6;
                
            elseif value==0
                delete(app.out1)
            end
            %             catch
            %             end
        end

        % Button pushed function: Button_37
        function Button_37Pushed(app, event)
            app.UITable4_2.Data=[];
            app.UITable4_2.ColumnName=[];
            app.Button_25.Enable='off';
            app.Button_28.Enable='off';
            app.Button_29.Enable='off';
            
        end

        % Value changed function: EditField_35
        function EditField_35ValueChanged(app, event)
            %             value = app.EditField_35.Value;
            %             app.DropDown.ItemsData=[];
            %             xlabel(app.UIAxes,[app.DropDown.Value,' (',value,')'])
        end

        % Value changed function: Button_38
        function Button_38ValueChanged(app, event)
            value = app.Button_38.Value;
            %             try
            if value==1
                ax1=app.UIAxes;
                %             ax2=app.UIAxes_2;
                hold(ax1,'on')
                %             hold(ax2,'on')
                data = (app.UITable2_6.Data);
                l=length(app.ndata(:,app.nn));
                %             tic
                %                 app.DropDown.ItemsData = 1:length(app.DropDown.Items) ;
                %                 app.nn = app.DropDown.Value;             ,'BinWidth',0.5
                %                 histogram(ax1,data(:,app.nn+1),'facecolor','r');
                %                 c = linspace(1,10,length(data(:,app.nn+1)));
                if l>1000
                    h=50;
                elseif l>500 && l<=1000
                    h=30;
                elseif l>200 && l<=500
                    h=20;
                elseif l<=200
                    h=10;
                end
                a=repmat(h,height(data(:,app.nn)),1);
                %                  app.DropDown.ItemsData = 1:length(app.DropDown.Items) ;
                
                %                 n=app.DropDown.Value;
                app.out2=scatter(ax1,table2array(data(:,app.nn)),a,15,'filled','DisplayName','ex-outliers','MarkerFaceColor','r');
                %                    app.DropDown.ItemsData=[];
                %                     app.out2.MarkerEdgeAlpha=0.5;
                app.out2.MarkerFaceAlpha = 0.6;
                
            elseif value==0
                delete(app.out2)
            end
            %             catch
            %             end
            
        end

        % Value changing function: EditField_35
        function EditField_35ValueChanging(app, event)
            changingValue = event.Value;
            app.DropDown.ItemsData=[];
            xlabel(app.UIAxes,[app.DropDown.Value,' (',changingValue,')'])
        end

        % Value changed function: Button_39
        function Button_39ValueChanged(app, event)
            value = app.Button_39.Value;
            ax1=app.UIAxes;
            ax2=app.UIAxes_2;
            hold(ax1,'on')
            hold(ax2,'on')
            if value==1
                
                if   strcmp(app.DropDown_5.Value,app.DropDown_5.Items{1}) && ~isempty(app.UCLEditField.Value)  %初始分布3σ控制限
                    %                 if isgraphics(app.p1)||isgraphics(app.p2)||isgraphics(app.p3)||isgraphics(app.p4)||isgraphics(app.p5)||isgraphics(app.p6)
                    try
                        delete(app.p1)
                        delete(app.p2)
                        delete(app.p3)
                        delete(app.p4)
                        delete(app.p5)
                        delete(app.p6)
                    catch
                    end
                    
                    app.p1=plot(ax1,[app.LCLEditField.Value,app.LCLEditField.Value],get(ax1,'Ylim'),'--.b','LineWidth', 1);
                    app.p2=plot(ax1,[app.UCLEditField.Value,app.UCLEditField.Value],get(ax1,'Ylim'),'--.b','LineWidth', 1);
                    %                 text(ax1,app.UCLEditField.Value, 0.75*a(2),' 3σ',"FontSize",10)
                    legend(app.p2,'初始')
                    
                    %                   legend(ax1,'boxoff')
                elseif  strcmp(app.DropDown_5.Value,app.DropDown_5.Items{2}) && ~isempty(app.sigma1)   %转换后3σ控制限
                    %                 if isgraphics(app.p3)||isgraphics(app.p4)||isgraphics(app.p5)||isgraphics(app.p6)
                    try
%                         delete(app.p1)
%                         delete(app.p2)
                        delete(app.p3)
                        delete(app.p4)
                        delete(app.p5)
                        delete(app.p6)
                    catch
                    end
                    
                    if  isreal([app.LCLEditField_2.Value,app.UCLEditField_2.Value])
                        app.p3 = plot(ax1,[app.LCLEditField_2.Value,app.LCLEditField_2.Value],get(ax1,'Ylim'),'--.r','LineWidth', 1);      % 原始分布的转换后控制限
                        app.p4 = plot(ax1,[app.UCLEditField_2.Value,app.UCLEditField_2.Value],get(ax1,'Ylim'),'--.r','LineWidth', 1);
                    end
                    
                    app.p5 = plot(ax2,[app.LSL1,app.LSL1],get(ax2,'Ylim'),'--.r','LineWidth', 1);
                    app.p6 = plot(ax2,[app.USL1,app.USL1],get(ax2,'Ylim'),'--.r','LineWidth', 1);
                    
                    
                    try
                        legend(ax1,app.p4,'转换')
                    catch
                    end
                    try
                        legend(ax1,app.p2,'初始')
                    catch
                    end
                    try
                        legend(ax1,[app.p2 app.p4],{'初始','转换'})
                    catch
                    end
                    %                   legend(ax2,'boxoff')
                    
                end
                %             legend(ax1,'AutoUpdate','off')
                if  strcmp(app.DropDown_6.Value,app.DropDown_6.Items{1})
                    try                                                            % 防止app.px不存在报错
                        set(app.p1,'color','b')
                        set(app.p2,'color','b')
                        set(app.p3,'color','r')
                        set(app.p4,'color','r')
                        set(app.p5,'color','r')
                        set(app.p6,'color','r')
                    catch
                    end
                elseif  strcmp(app.DropDown_6.Value,app.DropDown_6.Items{2})
                    try
                        set(app.p1,'color',[0.90,0.90,0.90])
                        set(app.p2,'color','b')
                    catch
                    end
                    try
                        set(app.p5,'color',[0.90,0.90,0.90])
                        set(app.p3,'color',[0.90,0.90,0.90])
                    catch
                    end
                    try
                        set(app.p6,'color','r')
                        set(app.p4,'color','r')
                    catch
                    end
                    
                elseif  strcmp(app.DropDown_6.Value,app.DropDown_6.Items{3})
                    
                    try
                        set(app.p2,'color',[0.90,0.90,0.90])
                        set(app.p1,'color','b')
                        
                        %                     legend(ax1,[app.p1 app.p3],{'3σ初始','3σ转换'})
                    catch
                    end
                    try
                        set(app.p6,'color',[0.90,0.90,0.90])
                        set(app.p4,'color',[0.90,0.90,0.90])
                        
                    catch
                    end
                    try
                        set(app.p5,'color','r')
                        set(app.p3,'color','r')
                    catch
                    end
                    try
                        legend(ax1,app.p1,'初始')
                    catch
                    end
                    try
                        legend(ax1,app.p3,'转换')
                    catch
                    end
                    try
                        legend(ax1,[app.p1 app.p3],{'初始','转换'})
                    catch
                    end
                end
            else
                try
                    delete(app.p1)
                    delete(app.p2)
                    delete(app.p3)
                    delete(app.p4)
                    delete(app.p5)
                    delete(app.p6)
                catch
                end
                legend(ax1,'off')
            end
            %             close
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '参数一致性';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '批次内一致性';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '初始分布';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '选择参数';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.Button_7 = uibutton(app.Panel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '分布转换';
            app.SliderLabel = uilabel(app.Panel);
            app.SliderLabel.Text = '转换系数 λ';
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [1e-07 1];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Enable = 'off';
            app.Slider.Value = 1;
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = '控制对象';
            app.DropDown_5 = uidropdown(app.Panel);
            app.DropDown_5.Items = {'初始分布', '转换后'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = '初始分布';
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = '控制方法';
            app.DropDown_6 = uidropdown(app.Panel);
            app.DropDown_6.Items = {'3σ双侧', '3σ上限', '3σ下限'};
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Value = '3σ双侧';
            app.Button_10 = uibutton(app.Panel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '离群值识别';
            app.Label_19 = uilabel(app.Panel);
            app.Label_19.Text = '组数';
            app.BinWidthSlider = uislider(app.Panel);
            app.BinWidthSlider.Limits = [5 30];
            app.BinWidthSlider.ValueChangingFcn = createCallbackFcn(app, @BinWidthSliderValueChanging, true);
            app.BinWidthSlider.Enable = 'off';
            app.BinWidthSlider.Value = 18;
            app.Panel_5 = uipanel(app.Panel);
            app.Panel_5.Title = '原始控制限';
            app.Label_15 = uilabel(app.Panel_5);
            app.Label_15.Text = '离群数';
            app.EditField_19 = uieditfield(app.Panel_5, 'text');
            app.EditField_19.Editable = 'off';
            app.Label_16 = uilabel(app.Panel_5);
            app.Label_16.Text = '离群率';
            app.EditField_20 = uieditfield(app.Panel_5, 'text');
            app.EditField_20.Editable = 'off';
            app.Panel_6 = uipanel(app.Panel);
            app.Panel_6.Title = '转换控制限';
            app.Label_17 = uilabel(app.Panel_6);
            app.Label_17.Text = '离群数';
            app.EditField_21 = uieditfield(app.Panel_6, 'text');
            app.EditField_21.Editable = 'off';
            app.Label_18 = uilabel(app.Panel_6);
            app.Label_18.Text = '离群率';
            app.EditField_22 = uieditfield(app.Panel_6, 'text');
            app.EditField_22.Editable = 'off';
            app.Label_21 = uilabel(app.Panel);
            app.Label_21.Text = '下限';
            app.EditField_23 = uieditfield(app.Panel, 'text');
            app.EditField_23.ValueChangedFcn = createCallbackFcn(app, @EditField_23ValueChanged, true);
            app.Label_22 = uilabel(app.Panel);
            app.Label_22.Text = '上限';
            app.EditField_24 = uieditfield(app.Panel, 'text');
            app.EditField_24.ValueChangedFcn = createCallbackFcn(app, @EditField_24ValueChanged, true);
            app.Button_18 = uibutton(app.Panel, 'state');
            app.Button_18.ValueChangedFcn = createCallbackFcn(app, @Button_18ValueChanged, true);
            app.Button_18.Text = '修正控制限';
            app.EditField_18 = uieditfield(app.Panel, 'text');
            app.EditField_18.Editable = 'off';
            app.EditField_9 = uieditfield(app.Panel, 'text');
            app.EditField_9.Editable = 'off';
            app.Button_22 = uibutton(app.Panel, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '离群值识别';
            app.Panel_7 = uipanel(app.Panel);
            app.Panel_7.Title = '修正控制限';
            app.EditField_25Label = uilabel(app.Panel_7);
            app.EditField_25Label.Text = '离群数';
            app.EditField_25 = uieditfield(app.Panel_7, 'text');
            app.EditField_25.Editable = 'off';
            app.EditField_26Label = uilabel(app.Panel_7);
            app.EditField_26Label.Text = '离群率';
            app.EditField_26 = uieditfield(app.Panel_7, 'text');
            app.EditField_26.Editable = 'off';
            app.EditField_29 = uieditfield(app.Panel, 'text');
            app.EditField_29.Editable = 'off';
            app.EditField_30Label = uilabel(app.Panel);
            app.EditField_30Label.Text = '标称值下限';
            app.EditField_30 = uieditfield(app.Panel, 'text');
            app.EditField_31Label = uilabel(app.Panel);
            app.EditField_31Label.Text = '标称值上限';
            app.EditField_31 = uieditfield(app.Panel, 'text');
            app.CpkButton = uibutton(app.Panel, 'push');
            app.CpkButton.ButtonPushedFcn = createCallbackFcn(app, @CpkButtonPushed, true);
            app.CpkButton.Text = '计算 Cpk';
            app.Label_33 = uilabel(app.Panel);
            app.Label_33.Text = '控制环节';
            app.DropDown_8 = uidropdown(app.Panel);
            app.DropDown_8.Items = {'筛选', '补充筛选', '质量一致性'};
            app.DropDown_8.Value = '筛选';
            app.MSAButton = uibutton(app.Panel, 'push');
            app.MSAButton.ButtonPushedFcn = createCallbackFcn(app, @MSAButtonPushed, true);
            app.MSAButton.Text = '查询MSA';
            app.Button_33 = uibutton(app.Panel, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @Button_33Pushed, true);
            app.Button_33.Text = '自动处理';
            app.Button_39 = uibutton(app.Panel, 'state');
            app.Button_39.ValueChangedFcn = createCallbackFcn(app, @Button_39ValueChanged, true);
            app.Button_39.Text = '添加控制限';
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '数据输入';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入数据';
            app.Button_32 = uibutton(app.Panel_2, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @Button_32Pushed, true);
            app.Button_32.Text = '自动识别';
            app.Label_37 = uilabel(app.Panel_2);
            app.Label_37.Text = '预设路径';
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '测试数据';
            app.UITable = uitable(app.Tab_3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.Button_34 = uibutton(app.Tab_3, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Button_34Pushed, true);
            app.Button_34.Text = '删除行';
            app.Button_35 = uibutton(app.Tab_3, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Button_35Pushed, true);
            app.Button_35.Text = '删除列';
            app.Label_39 = uilabel(app.Tab_3);
            app.Label_39.Text = '数据表';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '批次内控制图';
            app.UIAxes = uiaxes(app.Tab_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.Tab_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Label_3 = uilabel(app.Tab_2);
            app.Label_3.Text = '均值';
            app.EditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%.3f';
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = '标准差';
            app.EditField_3 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.3f';
            app.LCLEditFieldLabel = uilabel(app.Tab_2);
            app.LCLEditFieldLabel.Text = 'LCL';
            app.LCLEditField = uieditfield(app.Tab_2, 'numeric');
            app.LCLEditField.ValueDisplayFormat = '%.3f';
            app.UCLEditFieldLabel = uilabel(app.Tab_2);
            app.UCLEditFieldLabel.Text = 'UCL';
            app.UCLEditField = uieditfield(app.Tab_2, 'numeric');
            app.UCLEditField.ValueDisplayFormat = '%.3f';
            app.EditField_10Label = uilabel(app.Tab_2);
            app.EditField_10Label.Text = '均值';
            app.EditField_10 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_10.ValueDisplayFormat = '%.3f';
            app.EditField_11Label = uilabel(app.Tab_2);
            app.EditField_11Label.Text = '标准差';
            app.EditField_11 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_11.ValueDisplayFormat = '%.3f';
            app.LCLEditField_2Label = uilabel(app.Tab_2);
            app.LCLEditField_2Label.Text = 'LCL';
            app.LCLEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.LCLEditField_2.ValueDisplayFormat = '%.3f';
            app.UCLEditField_2Label = uilabel(app.Tab_2);
            app.UCLEditField_2Label.Text = 'UCL';
            app.UCLEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.UCLEditField_2.ValueDisplayFormat = '%.3f';
            app.UITable2 = uitable(app.Tab_2);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2_2 = uitable(app.Tab_2);
            app.UITable2_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PEditFieldLabel = uilabel(app.Tab_2);
            app.PEditFieldLabel.Text = 'P值';
            app.PEditField = uieditfield(app.Tab_2, 'numeric');
            app.PEditField.ValueDisplayFormat = '%.3f';
            app.PEditField_2Label = uilabel(app.Tab_2);
            app.PEditField_2Label.Text = 'P值';
            app.PEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.PEditField_2.ValueDisplayFormat = '%.3f';
            app.EditField_27Label = uilabel(app.Tab_2);
            app.EditField_27Label.Text = '偏度';
            app.EditField_27 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_27.ValueDisplayFormat = '%.3f';
            app.EditField_28Label = uilabel(app.Tab_2);
            app.EditField_28Label.Text = '偏度';
            app.EditField_28 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_28.ValueDisplayFormat = '%.3f';
            app.Button_36 = uibutton(app.Tab_2, 'state');
            app.Button_36.ValueChangedFcn = createCallbackFcn(app, @Button_36ValueChanged, true);
            app.Button_36.Text = '离群值标注';
            app.Label_38 = uilabel(app.Tab_2);
            app.Label_38.Text = '单位';
            app.EditField_35 = uieditfield(app.Tab_2, 'text');
            app.EditField_35.ValueChangedFcn = createCallbackFcn(app, @EditField_35ValueChanged, true);
            app.EditField_35.ValueChangingFcn = createCallbackFcn(app, @EditField_35ValueChanging, true);
            app.Button_38 = uibutton(app.Tab_2, 'state');
            app.Button_38.ValueChangedFcn = createCallbackFcn(app, @Button_38ValueChanged, true);
            app.Button_38.Text = '前参数离群值';
            app.Tab_8 = uitab(app.TabGroup);
            app.Tab_8.Title = '单参数离群器件';
            app.UITable2_3 = uitable(app.Tab_8);
            app.UITable2_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2_4 = uitable(app.Tab_8);
            app.UITable2_4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2_5 = uitable(app.Tab_8);
            app.UITable2_5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Label_27 = uilabel(app.Tab_8);
            app.Label_27.Text = '原始控制限';
            app.Label_28 = uilabel(app.Tab_8);
            app.Label_28.Text = '转换控制限';
            app.Label_29 = uilabel(app.Tab_8);
            app.Label_29.Text = '修正控制限';
            app.Tab_9 = uitab(app.TabGroup);
            app.Tab_9.Title = '总离群器件';
            app.UITable2_6 = uitable(app.Tab_9);
            app.UITable2_6.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Label_34 = uilabel(app.Tab_9);
            app.Label_34.Text = '总离群器件';
            app.Button_31 = uibutton(app.Tab_9, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Button_31Pushed, true);
            app.Button_31.Text = '清空';
            app.Label_35 = uilabel(app.Tab_9);
            app.Label_35.Text = '总离群数';
            app.EditField_33 = uieditfield(app.Tab_9, 'text');
            app.Label_36 = uilabel(app.Tab_9);
            app.Label_36.Text = '总离群率';
            app.EditField_34 = uieditfield(app.Tab_9, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel_4 = uipanel(app.RightPanel);
            app.Panel_4.Title = '批次间一致性';
            app.Label_11 = uilabel(app.Panel_4);
            app.Label_11.Text = '批次';
            app.EditField_8 = uieditfield(app.Panel_4, 'text');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.Label_23 = uilabel(app.Panel_4);
            app.Label_23.Text = '受控状态';
            app.Lamp = uilamp(app.Panel_4);
            app.Button_20 = uibutton(app.Panel_4, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '附加控制图';
            app.Label_30 = uilabel(app.Panel_4);
            app.Label_30.Text = '选择';
            app.DropDown_7 = uidropdown(app.Panel_4);
            app.DropDown_7.Items = {};
            app.DropDown_7.Value = {};
            app.CheckBox_7 = uicheckbox(app.Panel_4);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '修正';
            app.CheckBox_6 = uicheckbox(app.Panel_4);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '转换';
            app.CheckBox_6.Value = true;
            app.CheckBox_5 = uicheckbox(app.Panel_4);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '原始';
            app.ButtonGroup = uibuttongroup(app.Panel_4);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged2, true);
            app.ButtonGroup.Title = '批次间控制';
            app.Button_25 = uitogglebutton(app.ButtonGroup);
            app.Button_25.Text = '波动图';
            app.Button_25.Value = true;
            app.Button_28 = uitogglebutton(app.ButtonGroup);
            app.Button_28.Text = '控制图';
            app.Button_29 = uitogglebutton(app.ButtonGroup);
            app.Button_29.Text = '排除历史异常';
            app.Label_31 = uilabel(app.Panel_4);
            app.Label_31.Text = '日期';
            app.DatePicker = uidatepicker(app.Panel_4);
            app.Panel_8 = uipanel(app.Panel_4);
            app.Panel_8.Title = '批次间数据';
            app.Button_12 = uibutton(app.Panel_8, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed2, true);
            app.Button_12.Text = '提取本批数据';
            app.Button_13 = uibutton(app.Panel_8, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '读取历史数据';
            app.Button_14 = uibutton(app.Panel_8, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '批次积累';
            app.Label_32 = uilabel(app.Panel_4);
            app.Label_32.Text = '异常类型';
            app.EditField_32 = uieditfield(app.Panel_4, 'text');
            app.EditField_32.Editable = 'off';
            app.Panel_3 = uipanel(app.RightPanel);
            app.Panel_3.Title = '保存';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '大类';
            app.DropDown_2 = uidropdown(app.Panel_3);
            app.DropDown_2.Editable = 'on';
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = '系列';
            app.DropDown_3 = uidropdown(app.Panel_3);
            app.DropDown_3.Editable = 'on';
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.Label_12 = uilabel(app.Panel_3);
            app.Label_12.Text = '规格';
            app.DropDown_4 = uidropdown(app.Panel_3);
            app.DropDown_4.Editable = 'on';
            app.Button_8 = uibutton(app.Panel_3, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '元器件类型查询';
            app.Button_Save = uibutton(app.Panel_3, 'push');
            app.Button_Save.ButtonPushedFcn = createCallbackFcn(app, @Button_SavePushed, true);
            app.Button_Save.Text = '保存';
            app.Label_20 = uilabel(app.Panel_3);
            app.Label_20.Text = '元器件信息';
            app.CheckBox2 = uicheckbox(app.Panel_3);
            app.CheckBox2.Text = '批次内数据';
            app.CheckBox2.Value = true;
            app.CheckBox3 = uicheckbox(app.Panel_3);
            app.CheckBox3.Text = '批次间数据';
            app.CheckBox3.Value = true;
            app.CheckBox1 = uicheckbox(app.Panel_3);
            app.CheckBox1.Text = '原始数据';
            app.Button_Save_2 = uibutton(app.Panel_3, 'push');
            app.Button_Save_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Save_2Pushed, true);
            app.Button_Save_2.Text = '生成报告';
            app.CheckBox4 = uicheckbox(app.Panel_3);
            app.CheckBox4.Text = '总离群器件';
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '批次间数据';
            app.UITable4 = uitable(app.Tab_5);
            app.UITable4.ColumnName = {''};
            app.UITable4.RowName = {};
            app.UITable4_2 = uitable(app.Tab_5);
            app.UITable4_2.ColumnName = {''};
            app.UITable4_2.RowName = {};
            app.UITable4_2.ColumnEditable = true;
            app.UITable4_2.CellSelectionCallback = createCallbackFcn(app, @UITable4_2CellSelection, true);
            app.Label_24 = uilabel(app.Tab_5);
            app.Label_24.Text = '本批数据';
            app.Label_25 = uilabel(app.Tab_5);
            app.Label_25.Text = '批次间数据';
            app.Button_30 = uibutton(app.Tab_5, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_30.Visible = 'off';
            app.Button_30.Text = '排除历史异常';
            app.Button_16 = uibutton(app.Tab_5, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '删除批次';
            app.Button_37 = uibutton(app.Tab_5, 'push');
            app.Button_37.ButtonPushedFcn = createCallbackFcn(app, @Button_37Pushed, true);
            app.Button_37.Text = '清空';
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = '批次间控制图';
            app.Tab_7 = uitab(app.TabGroup2);
            app.Tab_7.Title = '附加控制图';
            app.UIAxes4 = uiaxes(app.Tab_7);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.Label_26 = uilabel(app.Tab_7);
            app.Label_26.Text = '异常情况分析';
            app.TextArea = uitextarea(app.Tab_7);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParaConsistency

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end