classdef ParaConsistency_Change < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        Panel             matlab.ui.container.Panel
        Label_40          matlab.ui.control.Label
        Label_42          matlab.ui.control.Label
        DropDown_2        matlab.ui.control.DropDown
        DropDown_10Label  matlab.ui.control.Label
        DropDown_3        matlab.ui.control.DropDown
        DropDown_11Label  matlab.ui.control.Label
        DropDown_4        matlab.ui.control.DropDown
        Button_8          matlab.ui.control.Button
        Button_38         matlab.ui.control.Button
        Button_42         matlab.ui.control.Button
        Button_43         matlab.ui.control.Button
        Button_49         matlab.ui.control.Button
        Label_45          matlab.ui.control.Label
        EditField_35      matlab.ui.control.EditField
        Button_51         matlab.ui.control.Button
        Panel_2           matlab.ui.container.Panel
        Button            matlab.ui.control.Button
        Label_37          matlab.ui.control.Label
        TextArea_2        matlab.ui.control.TextArea
        Label_41          matlab.ui.control.Label
        ListBox           matlab.ui.control.ListBox
        Button_39         matlab.ui.control.Button
        Button_41         matlab.ui.control.Button
        Button_44         matlab.ui.control.Button
        SheetButton       matlab.ui.control.Button
        Button_50         matlab.ui.control.Button
        CenterPanel       matlab.ui.container.Panel
        TabGroup          matlab.ui.container.TabGroup
        Tab_3             matlab.ui.container.Tab
        TabGroup2         matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        UITable           matlab.ui.control.Table
        Tab_8             matlab.ui.container.Tab
        UITable2          matlab.ui.control.Table
        Label_46          matlab.ui.control.Label
        EditField_39      matlab.ui.control.EditField
        Label_47          matlab.ui.control.Label
        EditField_40      matlab.ui.control.EditField
        Label_48          matlab.ui.control.Label
        EditField_41      matlab.ui.control.EditField
        UITable2_2        matlab.ui.control.Table
        Label_49          matlab.ui.control.Label
        Tab_9             matlab.ui.container.Tab
        UITable3          matlab.ui.control.Table
        RightPanel        matlab.ui.container.Panel
        TabGroup3         matlab.ui.container.TabGroup
        Tab_10            matlab.ui.container.Tab
        Tab_11            matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        ndata              % 导入的数据
        hh
        %         filename
        Path                % 元器件类型存储路径
        
        
        % Description
        
        ParaButton % Description
        CancelButton % Description
        Info % Description
        ReloadButton % Description
        fig % Description
        PathName % Description
        AllCheckbox % Description
        subax
        subax2
        subax3
        PathName1 % Description
    end
    
    
    methods (Access = private)


        function saveFig(app,ax,figname,c,n)
            app.DropDown.ItemsData=[];
            app.DropDown_2.ItemsData=[];
            app.DropDown_3.ItemsData=[];
            app.DropDown_4.ItemsData=[];
            
            a=app.DropDown.Value;
            a1=app.DropDown_2.Value ;
            a2=app.DropDown_3.Value ;
            a3=app.DropDown_4.Value;
            a4=app.EditField_35.Value;
            %             a4=app.DatePicker.Value; % 日期
            %             a4=datestr(a4,'yyyy-mm-dd');
            
            
            dirname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\',a4,'批','\'));
            filename= strcat(dirname,figname);
            try
                mkdir (dirname)
            catch
            end
            [file, app.PathName] = uiputfile({'*.png';'*.tif';'*.jpg';'*.fig';},'选择保存路径',filename);
            fz=6;
            
            if n-1<=2
                l=450;
                p=120;
                c=1;
                k=2;
            elseif n-1<=4
                l=450;
                p=220;
                c=2;
                k=2;
            elseif n-1<=6
                l=450;
                p=300;
                c=3;
                k=2;
            elseif n-1<=9
                l=450;
                p=260;
                c=3;
                k=3;
            elseif n-1<=12
                l=480;
                p=350;
                c=4;
                k=3;
            elseif n-1<=18
                l=480;
                p=500;
                c=6;
                k=3;
            elseif n-1<=21
                l=480;
                p=505;
                c=7;
                k=3;
                fz=4;
            else
                l=500;
                p=505;
                k=4;
                fz=4;
            end
            fig1 = figure('Position',[0,0,l,p],'Units','normalized');
            fig1.Visible = 'off';
            f = uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'保存数据中...') ;
            for i=2:n
                
                aa1=subplot(c,k,i-1,'Parent',fig1);
                
                f.Value=(i-1)/n;
                f.Message='待保存结束后生成报告...';
                %                 waitbar(i-1/n-1,f,'待保存结束后生成报告...') ;
                
                copyobj(ax(i).Children, aa1)
                
                set(aa1,'Box','on','Title',ax(i).Title,'fontsize',fz)
                
                axis(aa1,axis(ax(i)));
                %                 figAxes = axes(fig);  ('Position',[0,0,l,p],'Units','normalized')
                
                %                 allChildren = ax.XAxis.Parent.Children;
                %                 copyobj(allChildren, figAxes)
                %                 set(figAxes,'Box','on','Title', ax.Title,'XTick', ax.XTick, 'XTickLabel', ax.XTickLabel,'XLabel', ax.XLabel,'yLabel', ax.YLabel)
                %
                %                 figAxes.XLim = ax.XLim;
                %                 figAxes.YLim = ax.YLim;
                %                 figAxes.ZLim = ax.ZLim;
                %                 figAxes.DataAspectRatio = ax.DataAspectRatio;
            end
            
            
            %                 axis normal
            
            %             fig2 = figure;
            %             fig2.Visible = 'off';
            %             %             figAxes = axes(fig);
            %             aa1=subplot(3,1,1,'Parent',fig2);
            %             aa2=subplot(3,1,2,'Parent',fig2);
            %             aa3=subplot(3,1,3,'Parent',fig2);
            %             % Copy all UIAxes children, take over axes limits and aspect ratio.
            %
            %             %                  aa1=imresize(aa1,[50,100]);
            %             ax=app.Tab_10.Children;
            %
            %             copyobj(ax(3).Children, aa1)
            %             copyobj(ax(2).Children, aa2)
            %             copyobj(ax(1).Children, aa3)
            %
            %             set(aa1,'Box','on','Title',ax(3).Title,'XTick',ax(3).XTick, 'XTickLabel',ax(3).XTickLabel,'XLabel',ax(3).XLabel,'yLabel',ax(3).YLabel,'FontSize',8)
            %             set(aa2,'Box','on','Title',ax(2).Title,'XTick',ax(2).XTick, 'XTickLabel',ax(2).XTickLabel,'XLabel',ax(2).XLabel,'yLabel',ax(2).YLabel,'FontSize',8)
            %             set(aa3,'Box','on','Title',ax(1).Title,'XTick',ax(1).XTick, 'XTickLabel',ax(1).XTickLabel,'XLabel',ax(1).XLabel,'yLabel',ax(1).YLabel,'FontSize',8)
            %
            %             axis(aa1,axis(ax(3)));
            %             axis(aa2,axis(ax(2)));
            %             axis(aa3,axis(ax(1)));


            if file~=0
                %                 F= getframe(fig);
                %                 img = F.cdata;
                %                 fig=getframe(fig);
                %                 imwrite(fig,fullfile(path,file))
                
                
                saveas(fig1, fullfile(app.PathName,file));
                
                exportgraphics(app.Tab_10.Children, fullfile(app.PathName,['批次间控制图-',a,'.png']),'Resolution',105);
                
                exportgraphics(app.Tab_11.Children, fullfile(app.PathName,['批次间控制图2-',a,'.png']),'Resolution',105)
                %                 imwrite(fig0,fullfile(path,file),'png')
                %                 exportgraphics(app.Tab_10.Children(1),fullfile(app.PathName,file))
                
                
            end
            delete(fig1);
            
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            f.Value=1;
            close(f)
        end
        
        function savexls(app,xls,xlsname)
            
            
            filename= strcat(xlsname,'.xlsx');
            
            %                     xls.Data.Properties.VariableNames=xls.ColumnName;
            try
                writetable(xls,fullfile(app.PathName,filename))
            catch
            end
            
        end


        function Transform(app,lamda)
            app.UIAxes_2.cla;
            if  ~isempty(nn)
                try
                    data=app.ndata(:,nn);
                    c=0;
                    if min(data)<=0
                        c=-min(data)+1;
                    end
                    app.transdata=((data+c).^lamda-1)/lamda;
                    %                     app.transdata
                    %                                     app.transdata=boxcox(app.ndata(:,app.nn),lamda);
                    if ~isempty(app.EditField_18.Value)
                        h = histfit(app.transdata,round(app.BinWidthSlider.Value));
                    else
                        h = histfit(app.transdata);
                    end
                    ax=app.UIAxes_2;
                    copyobj([h(2),h(1)],ax);
                    title(ax,['转换后分布拟合 ',app.EditField_8.Value]);
                    xlabel(ax,parameter(nn),"Interpreter","none");
                    ylabel(ax,'频数');
                    
                    close ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    
                    ave1=nanmean(app.transdata);
                    app.sigma1=nanstd(app.transdata);
                    app.USL1=ave1+3*app.sigma1';
                    app.LSL1=ave1-3*app.sigma1';
                    
                    %                     ave=  (ave1*lamda+1).^(1/lamda);
                    
                    USL=(app.USL1*lamda+1).^(1/lamda)-c;
                    LSL=(app.LSL1*lamda+1).^(1/lamda)-c;
                    ave=(USL+LSL)/2;
                    sigma= (USL-LSL)/6;
                    
                    app.EditField_10.Value=ave;
                    app.EditField_11.Value=sigma;
                    app.LCLEditField_2.Value=LSL;
                    app.UCLEditField_2.Value=USL;
                    
                    k=kurtosis(app.transdata);
                    app.EditField_37.Value=k;
                    s= skewness(app.transdata);
                    app.EditField_28.Value=s;
                    
                    
                    box(app.UIAxes_2,'on')
                    app.Slider.Enable='On';
                catch
                end
            else
                
                s = sprintf('请导入数据先\n');
                msgbox(s,'Message-Box','warn' )
                
            end
        end
        
        function BatchChart(app,x,y1,y2,y3)
            try
                app.Tab_10.AutoResizeChildren='off';
                app.Tab_10.Scrollable='off';
                
                %                         x=table2array(app.UITable4_2.Data(:,1));
                %                         y1=table2array(app.UITable4_2.Data(:,3));
                %                         y2=table2array(app.UITable4_2.Data(:,4));
                %                         y3=table2array(app.UITable4_2.Data(:,7));
                bb=tiledlayout(app.Tab_10,3,1,"TileSpacing","compact","Padding","compact");
                
                app.subax = nexttile(bb);
                %                 app.subax=subplot(3,1,1,'Parent',app.Tab_10);
                Sub(app,app.subax,x,y1,'Mean','批次间均值 - ',1)
                
                app.subax2 = nexttile(bb);
                %                 app.subax2=subplot(3,1,2,'Parent',app.Tab_10);
                Sub(app,app.subax2,x,y2,'Sigma','批次间标准差 - ',1)
                
                app.subax3 = nexttile(bb);
                %                 app.subax3=subplot(3,1,3,'Parent',app.Tab_10);
                Sub(app,app.subax3,x,y3,'Ratio(%)','批次间离群率 - ',1)
                
                %                 hold on
                app.TabGroup3.SelectedTab =app.Tab_10;
                
                app.Lamp.Color=[0.8 0.8 0.8];
                %
            catch
                msgbox('需要有历史批次数据','Message-Box','warn')
            end
        end
        
        function results = isMono(~,ax,x,a,m)
            %             b=diff(a);
            %             Inc=b>0;
            %             Dec=b<0;
            %             c=zeros(1,length(b)-n);
            %             d=zeros(1,length(b)-n);
            %             for i=1:(length(b)-n)
            %                 c(i)=all(Inc(i:i+n));
            %                 d(i)=all(Dec(i:i+n));
            %             end
            %             results=any(c)||any(d);
            
            x=1:length(x);
            n=m-2;
            b=diff(a);
            Inc=b>0;
            Dec=b<0;
            c=zeros(1,length(b)-n);
            d=zeros(1,length(b)-n);
            for i=1:(length(b)-n)
                c(i)=all(Inc(i:i+n));
                if c(i)
                    plot(ax,x(i:i+n+1),a(i:i+n+1),'color',[1,0.8,0.1],'Marker','.','MarkerSize',12)
                    %                     text(ax,x(i+n-3),a(i+n+1)*1.01,'连续6点以上递增')
                end
                d(i)=all(Dec(i:i+n));
                if d(i)
                    plot(ax,x(i:i+n+1),a(i:i+n+1),'color',[1,0.8,0.1],'Marker','.','MarkerSize',12)
                    %                     text(ax,x(i+n-3),a(i+n+1)*0.98,'连续6点以上递减')
                end
            end
            results=any(c)||any(d);


        end
        
        function t = DropdownItems(~,f)
            t=cell(1,length(f.Children));
            for i=1:length(f.Children)
                %             t=[t,cellstr(f.Children(i).Text)];
                t(i)=cellstr(f.Children(i).Text);
            end
        end
        
        function Device(app)
            
            load(app.Path,'f')
            
            t = DropdownItems(app,f);
            app.DropDown_2.Items=t;
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items); % 把ItemsdData变成数组
            n1=app.DropDown_2.Value;   %用数字表示下拉框的索引
            if isempty(n1)
                n1=1;
            end
            h=f.Children(n1);
            
            if ~isempty(h.Children)
                t = DropdownItems(app,h);
                app.DropDown_3.Items=t;
                app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
                
                n2=app.DropDown_3.Value;
                if isempty(n2)
                    app.DropDown_3.Value=1;
                    n2=1;
                end
                
                g=h.Children(n2);
                
                if  ~isempty(g.Children)
                    t = DropdownItems(app,g);
                    app.DropDown_4.Items=t;
                else
                    app.DropDown_4.Items={''};
                end
            else
                app.DropDown_3.Items={''};
                app.DropDown_4.Items={''};
                %                 return
            end
            
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
            n1=app.DropDown_2.Value;
            n2=app.DropDown_3.Value;
            n3=app.DropDown_4.Value;
            
            if  isempty(f.Children(n1).Children)
                ControlData=f.Children(n1);
                
            elseif isempty(f.Children(n1).Children(n2).Children)
                ControlData=f.Children(n1).Children(n2);
                
            else
                ControlData=f.Children(n1).Children(n2).Children(n3);
            end
            app.Info= ControlData.NodeData;
            
        end
        
        function [m,s,r,U,L,P,ske,method,outlier,Cpk,badtit] = ParaHist(app,data,i,bb,tit,method,limit,Low,High,Stan1,Stan2,badtit,lot,Coef)
            
            pause(0.01)
            
            ax = nexttile(bb);
            if i==1
                title(ax, app.EditField_35.Value)
            end
            %             ax=scrollsubplot(3,1,j);
            %             ax.Parent=f;
            
            if all(isnan(data))
                badtit=lot;
                m='-';s='-';r='-';U='-';L='-';P='-';ske='-';outlier=[];Cpk='-';
                return
            end
            
            h =  histfit(data);
            copyobj([h(2),h(1)],ax);
            box(ax,'on')
            close
            %             title(ax,lot,'Interpreter','none');
            xlb=strcat(tit,'-',lot);
            xlabel(ax,xlb,"Interpreter","none");
            ylabel(ax,'频数')
            m=nanmean(data);
            s=nanstd(data);
            [~,P] = jbtest(data);
            ske = skewness(data);
            
            hold(ax,'on')
            switch method
                case '3σ-BoxCox'
                    if abs(skewness(data))<=0.3
                        [~,L,U,~]=isoutlier(data,"mean");
                        s=std(data);
                        L=L-Coef*s;
                        U=U+Coef*s;
                    else
                        [U,L] = sigma_boxcox(app,data,Coef);
                        
                    end
                    %                     c='[0 0.4470 0.7410]';
                    c='blue';
                    %                     leg='3σ-BoxCox';
                case '3σ'
                    %                     [~,L,U,~]=isoutlier(data,"quartiles");
                    [~,L,U,~]=isoutlier(data,"mean");
                    %                     ave=median(data);
                    %                     RS=(quantile(data,0.75)-quantile(data,0.25))/1.35;
                    %                     U=ave+6*RS;
                    %                     L=ave-6*RS;
                    c= '[0.99 0.9 0.03]';
                    %                     c='[0.9590 0.8840 0.0050]';
                    %                      c='yellow';
                    %                     leg='AEC-PAT';
                case'AEC-PAT'
                    ave=median(data);
                    RS=(quantile(data,0.75)-quantile(data,0.25))/1.35;
                    U=ave+6*RS;
                    L=ave-6*RS;
                    c='[0.4240 0.1840 0.5560]';
                case '修正'
                    L=str2double(Low);
                    U=str2double(High);
                    c='[0.4660 0.6740 0.1880]';
                    %                      leg='修正';
            end
            
            switch limit
                case '双侧'
                    plot(ax,[L,L],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    plot(ax,[U,U],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    
                    outlier=find(data>U|data<L);
                    %                     legend(ax,a,leg)
                    %                      Ot=
                    
                case '上限'
                    plot(ax,[U,U],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    outlier=find(data>U);
                    %                     legend(ax,a,leg)
                case '下限'
                    plot(ax,[L,L],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    outlier=find(data<L);
                    %                      legend(ax,a,leg)
            end
            
            if i~=3
                
                plot(ax,[Stan1,Stan1],get(ax,'Ylim'),':','LineWidth', 1,'color','r');
                plot(ax,[Stan2,Stan2],get(ax,'Ylim'),':','LineWidth', 1,'color','r');
                
                if isnan(Stan1) &&  isnan(Stan2)          %Cpk
                    Cpk='-';
                elseif isnan(Stan1)
                    Cpk=(Stan2-m)/(3*s);
                elseif isnan(Stan2)
                    Cpk=-(Stan1-m)/(3*s);
                else
                    Cpk=min([(m-Stan1)/(3*s),(Stan2-m)/(3*s)]);
                end
            else
                Cpk='-';
            end
            
            a=repmat(20,length(outlier),1);
            
            out=scatter(ax,data(outlier),a,10,'filled','DisplayName','Outliers','MarkerFaceColor','r');
            
            out.MarkerFaceAlpha = 0.5;
            
            axis(ax,'auto')
            
            r=length(outlier)/length(data);
            
        end
        
        
        function [USL,LSL] = sigma_boxcox(~,data,Coef)
            c=0;
            if min(data)<=0
                c=-min(data)+1;
            end
            [~,b]=boxcox(data+c);
            lamda=b*0.5;
            
            if b==0
                lamda=1;
            end
            
            %             if skewness(data)>=0
            %                 lamda=b+0.5;
            %             elseif skewness(data)<0
            %                 lamda=b-0.5;
            %             end
            if lamda>10
                lamda=8;
            elseif lamda<-2
                lamda=-2;
            end
            
            transdata=((data+c).^lamda-1)/lamda;
            
            ave=nanmean(transdata);
            sigma=nanstd(transdata);
            USL1=ave+3*sigma;
            LSL1=ave-3*sigma';
            
            USL=(USL1*lamda+1).^(1/lamda)-c;
            LSL=(LSL1*lamda+1).^(1/lamda)-c;
            
            %                ave1=(ave*lamda+1).^(1/lamda)-c;
            sigma1=(USL-LSL)/6;
            
            USL=USL+Coef*sigma1;
            LSL=LSL-Coef*sigma1;
            
            if ~isreal(LSL) &&max(data)<0
                LSL=-abs(LSL);
            elseif ~isreal(LSL)&&max(data)>=0
                LSL=0;
            end
            if ~isreal(USL)&&max(data)<0
                USL=-abs(USL);
            elseif ~isreal(LSL)&&max(data)>=0
                USL=abs(USL);
            end
            
        end
        
        
        function Sub(app,ax,x,y,ylb,tit,m)
            
            app.DropDown.ItemsData=[];
            n=1:length(x);
            
            %             try
            %                 y=str2double(y);
            %             catch
            %             end
            
            plot(ax,n,y,'Marker','.','MarkerSize',12)
            set(ax,'XTick',n, 'XTickLabel',x,'FontSize',8)
            %             xlabel(ax,'Batch')
            
            ylabel(ax,ylb)
            ax.XTickLabelRotation = 45;
            
            para=app.DropDown.Value;
            if ~isempty(para) && m==1
                tit=[tit,para];
            end
            
            title(ax,tit,'FontWeight','bold')
            a=[min(n),max(n)];
            b=get(ax,'Ylim');
            %                           axis(ax,'auto')  ,b(1),b(2)
            axis(ax,[a(1),a(2),b(1),b(2)]);
            %                  set(ax,'Box','on')  %???not working
            app.DropDown.ItemsData = 1:length(app.DropDown.Items);
            
        end
        
        function [mean_UCL, mean_LCL]=SubControl(~,ax,x,BatchData,m,UCL,LCL)
            x=1:length(x);
            
            if m==1
                BatchEnd=BatchData(end);
                BatchData= BatchData(1:end-1);
                BatchData=[transpose(BatchData(BatchData>LCL&BatchData<UCL)),BatchEnd];
                
            end
            
            mean_ave=mean(BatchData);
            mean_sigma=std(BatchData);
            mean_UCL= mean_ave+3*mean_sigma;
            mean_LCL= mean_ave-3*mean_sigma;
            mean_UCL1= mean_ave+mean_sigma;
            mean_LCL1= mean_ave-mean_sigma;
            mean_UCL2= mean_ave+2*mean_sigma;
            mean_LCL2= mean_ave-2*mean_sigma;
            
            plot(ax,get(ax,'Xlim'),[mean_UCL1,mean_UCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL1,mean_LCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_UCL2,mean_UCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL2,mean_LCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            
            plot(ax,get(ax,'Xlim'),[mean_UCL,mean_UCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[mean_LCL,mean_LCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[mean_ave, mean_ave],'--.','color','[0.1 0.7 0.1]','LineWidth', 1);
            axis(ax,'auto')
            %             a=get(ax,'Xlim');
            b=get(ax,'Ylim');
            axis(ax,[min(x),max(x),b(1),b(2)]);
            
            
            if m==1
                axis(ax,[min(x),max(x),mean_LCL,mean_UCL]);
            end
            
        end
        
        function [UCL,LCL]=SubControl2(app,ax,x,BatchData,m,UCL,LCL)
            
            if iscell(BatchData)
                BatchData=str2double(BatchData);
            end
            Number=table2array(app.UITable2.Data(:,11));
            try
                Number=str2double(Number);
            catch
            end
            x=1:length(x);
            if m==1
                BatchEnd=BatchData(end);
                BatchData0= BatchData(1:end-1);
                BatchData=transpose([transpose(BatchData0(BatchData0>LCL&BatchData0<UCL)),BatchEnd]);
                
                NumberEnd=Number(end);
                Number= Number(1:end-1);
                Number=transpose([transpose(Number(BatchData0>LCL&BatchData0<UCL)), NumberEnd]);
            end
            
            mean_ratio=(transpose(BatchData)*(Number)/sum(Number))/100;
            %              mean_ratio
            
            mean_number=mean( Number);
            
            sigma=((mean_ratio*(1-mean_ratio))/mean_number)^(1/2);
            
            UCL=mean_ratio+3*sigma;
            LCL=mean_ratio-3*sigma;
            mean_UCL1=  mean_ratio+sigma;
            mean_LCL1=  mean_ratio-sigma;
            mean_UCL2=  mean_ratio+2*sigma;
            mean_LCL2=  mean_ratio-2*sigma;
            
            UCL=UCL*100;
            LCL=LCL*100;
            mean_UCL1=   mean_UCL1*100;
            mean_LCL1=   mean_LCL1*100;
            mean_UCL2=   mean_UCL2*100;
            mean_LCL2=   mean_LCL2*100;
            mean_ratio=  mean_ratio*100;
            %             get(ax,'Xlim')
            %             [mean_UCL1,mean_UCL1]*100
            %             get(ax,'Xlim')
            %             [mean_UCL1,mean_UCL1]*100
            plot(ax,get(ax,'Xlim'),[mean_UCL1,mean_UCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL1,mean_LCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_UCL2,mean_UCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL2,mean_LCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            
            plot(ax,get(ax,'Xlim'),[UCL,UCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[LCL,LCL],'--.','color','[0.8 0.8 0.8]','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[ mean_ratio, mean_ratio],'--.','color','[0.1 0.7 0.1]','LineWidth', 1);
            axis(ax,'auto')
            %             a=get(ax,'Xlim');
            b=get(ax,'Ylim');
            axis(ax,[min(x),max(x),0,b(2)]);
            
            %             %             text(ax,a(2), mean_UCL1*100,'+1σ')
            %             %             text(ax,a(2), mean_UCL2*100,'+2σ')
            %             text(ax,a(2), UCL,' UCL')
            %             %             text(ax,a(2), mean_LCL1*100,' -1σ')
            %             %             text(ax,a(2), mean_LCL2*100,' -2σ')
            %             text(ax,a(2), 0,' LCL')
            %             text(ax,a(2), mean_ratio,' CL')
            
            if m==1
                try
                    axis(ax,[min(x),max(x),0,1.08*UCL]);
                catch
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Path=('Data\初始化\DeviceSave.mat');
            try
                app.TextArea_2.Value=string(importdata('Data\初始化\默认路径.txt'));
            catch
            end
            Dir=string(app.TextArea_2.Value);
            dirc = dir(Dir);
            dirc= dirc(~cellfun(@isfolder,{dirc(:).name}));
            dirc=dirc(contains({dirc.name},{'.xlsx','xls','.txt','.csv','.TXT'}));
            %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
            %             string(struct2cell(dirc))
            
            for i=1:length(dirc)
                app.ListBox.Items(i)=  cellstr(dirc(i).name);
            end
            app.ListBox.Multiselect='on';
            Device(app)
            %             app.DatePicker.Value = datetime('today');
            
            %             format short
            %             app.DropDown_2.ItemsData=[];
            %             app.DropDown_3.ItemsData=[];
            %             app.DropDown_4.ItemsData=[];
            %
            %             a1=app.DropDown_2.Value ;
            %             a2=app.DropDown_3.Value ;
            %             a3=app.DropDown_4.Value;
            %             a4=app.EditField_8.Value; % 批次
            
            
            %             app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            %             app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            %             app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {835, 835, 835};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {835, 835};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {280, '1x', 575};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Callback function: Button, EditField_39
        function ButtonPushed(app, event)
            
            Dir=string(app.TextArea_2.Value);
            
            if isfolder(Dir)
                
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls';'*.TXT'},'Choose a File',Dir,'multiselect','on');
            else
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls';'*.TXT'},'Choose a File','multiselect','on');
            end
            
            if   iscell(FileName)|| ischar(FileName)
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'读取数据...') ;
                %  -------------  set listbox  -------------
                
                app.TextArea_2.Value=app.PathName;
                Dirc = dir(string(app.TextArea_2.Value));
                Dirc= Dirc(~cellfun(@isfolder,{Dirc(:).name}));
                Dirc=Dirc(contains({Dirc.name},{'.xlsx','xls','.txt','.csv'}));
                %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
                %             string(struct2cell(dirc))
                app.ListBox.Items={};
                for i=1:length(Dirc)
                    app.ListBox.Items(i)=  cellstr(Dirc(i).name);
                end
                app.ListBox.Multiselect='on';
                
                %  --------------------------
                
                
                app.UITable(:)=[];
                delete(app.TabGroup2.Children)
                %                 delete(app.UITable)
                %                 delete(app.Tab)
                if  iscell(FileName)
                    x=zeros(1,length(FileName));
                    
                    for i= 1:length(FileName)
                        str=string(strcat(app.PathName, FileName(i)));
                        
                        Data=readtable(str,"PreserveVariableNames",true);
                        
                        parameter= Data.Properties.VariableNames;
                        Tabname=string(FileName(i));
                        
                        
                        app.Tab(i) = uitab(app.TabGroup2);
                        app.Tab(i).Title = Tabname;
                        
                        app.UITable(i) = uitable(app.Tab(i));
                        app.UITable(i).ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                        app.UITable(i).RowName = {};
                        app.UITable(i).Position = [10,11,530,719];
                        
                        set(app.UITable(i),'data',Data,'columnname',parameter,'columnsortable',true,'columneditable',true)
                        app.UITable(i).RowName='numbered';
                        
                        x(i)=width(app.UITable(i).Data);
                        
                        f.Value=i/(length(FileName)+1);
                    end
                    if max(x)-min(x)~=0
                        uialert(app.UIFigure,'请确保所有批次具有相同的测试参数','Message-Box','Icon','warning')
                    end
                    
                else
                    
                    str=string(strcat(app.PathName, FileName));
                    
                    Data=readtable(str,"PreserveVariableNames",true,'columnsortable',true,'columneditable',true);
                    
                    parameter= Data.Properties.VariableNames;
                    Tabname=string(FileName);
                    
                    app.Tab = uitab(app.TabGroup2);
                    app.Tab.Title = Tabname;
                    
                    app.UITable = uitable(app.Tab);
                    app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                    app.UITable.RowName = {};
                    app.UITable.Position = [10,11,530,719];
                    
                    set(app.UITable,'data',Data,'columnname',parameter)
                    app.UITable.RowName='numbered';
                    
                    f.Value=0.5;
                end
                %                 app.DropDown.Items=parameter;
                %                 app.DropDown.ItemsData=1:length(parameter);
                %                 app.DropDown.Value=2;
                pause(0.1)
                f.Value=1;
                close(f)
            end
            %             try
            %                  if contains(FileName,'xlsx')
            %                     app.EditField_35.Value=string(Tabname(1:end-5));
            %                 else
            %                     app.EditField_35.Value=string(Tabname(1:end-4));
            %                 end
            %                 app.EditField_35.Value=string(Tabname);
            app.TabGroup.SelectedTab=app.Tab_3;
            %             catch
            %             end
            %             length(app.UITable)
            
            
        end

        % Button pushed function: Button_43
        function Button_43Pushed(app, event)
            %             RreMSA
            makeDOMCompilable();
            import mlreportgen.dom.*
            import mlreportgen.report.*
            try
                f = waitbar(0,'生成报告...','name','Message-Box') ;
                %                 app.DropDown.ItemsData=[];
                app.DropDown_2.ItemsData=[];                     % 生成报告
                app.DropDown_3.ItemsData=[];
                app.DropDown_4.ItemsData=[];
                
                %                 a=app.DropDown.Value ;
                a1=app.DropDown_2.Value ;
                a2=app.DropDown_3.Value ;
                a3=app.DropDown_4.Value;
                a4=app.EditField_35.Value;
                %             a5=app.DropDown.Value ; % 参数
                %                 a6=app.DropDown_8.Value; % 控制环节
                
                waitbar(0.2,f,'报告题目...') ;
                
                
                %                 docname = string(strcat(app.PathName1,strcat(a3,'_',a4,'_',' 变化量一致性报告.docx')));
                
                docname = string(strcat(app.PathName1,strcat(a3,'_',a4,'_批次内一致性报告.docx')));
                subtitle=strcat(a3,' - ',a4);
                if strcmp(a2,'')
                    docname = string(strcat(app.PathName1,strcat(a1,'_',a4,'_批次内一致性报告.docx')));
                    subtitle=strcat(a1,' - ',a4);
                elseif  strcmp(a3,'')
                    docname = string(strcat(app.PathName1,strcat(a2,'_',a4,'_批次内一致性报告.docx')));
                    subtitle=strcat(a2,' - ',a4);
                end
                %               try
                rpt = Report(docname,'docx');
                %                 add(app.rpt,TitlePage('Title',strcat(a3,' lot#',a4,' Parameter Consistency Document')));
                tp = TitlePage();
                tp.Title = strcat('Parametric Shifting Consistency Document');
                tp.Subtitle = subtitle;
                %                 tp.Subtitle = strcat(a3,' lot#',a4);
                tp.Author = '';
                tp.PubDate = date();
                add(rpt,tp);
                
                waitbar(0.4,f,'生成分布图...') ;
                
                
                title1=Text('变化量分布图');
                sec1=Section(title1);
                sec1.Numbered = false;
                for i=1:numel(app.TabGroup3.Children)
                    image=Image(fullfile(app.PathName1,['参数',num2str(i),'.png']));
                    %             image.Width='700';
                    %             image.Height='450';
                    add(sec1,image)
                end
                add(rpt,sec1);
                
                
                waitbar(0.6,f,'生成批次信息...') ;
                
                title2=Text('批次信息');
                sec2=Section(title2);
                sec2.Numbered = false;
                
                table1=Table(app.UITable2.Data);
                table1.Style = {...
                    RowSep('solid','black','1px'),...
                    ColSep('solid','black','1px'),};
                table1.Border = 'single';
                table1.TableEntriesStyle = {HAlign('center')};
                add(sec2,table1)
                
                add(rpt,sec2);


                title3=Text('离群器件');
                sec4=Section(title3);
                sec4.Numbered = false;
                
                if ~isempty(app.UITable3.Data)
                    t=(app.UITable3.Data(:,1:3));
                    
                    table2=Table(t);
                    table2.Style = {...
                        RowSep('solid','black','1px'),...
                        ColSep('solid','black','1px'),};
                    table2.Border = 'single';
                    table2.TableEntriesStyle = {HAlign('center')};
                    
                    add(sec4,table2)
                    add(rpt,sec4);
                else
                    add(rpt,sec4);
                end


                title5=Text('异常分析');
                sec5=Section(title5);
                sec5.Numbered = false;
                add(rpt,sec5);
                
                close(rpt);
                
                close(f)
                try
                    winopen(docname)
                catch
                end
                %                 winopen(app.PathName)
            catch
                uialert(app.UIFigure,'请先进行保存并关闭已打开的报告文件','提示','Icon','Info')
                close(f)
            end
            %                         rptview(rpt.OutputPath);
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
            
            f1 = uifigure('Name','参数信息','Position',[500 500 555 480]);
            A = uitable(f1,'Position',[25 50 510 405],'ColumnEditable',true,'RowName','numbered');
            app.ParaButton=uibutton(f1,'push','Position',[260 10 80 30], 'Text', '保存');
            app.ParaButton.ButtonPushedFcn = createCallbackFcn(app, @ParaButtonPushed, true);
            
            app.CancelButton=uibutton(f1,'push','Position',[355 10 80 30], 'Text', '取消');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            
            app.ReloadButton=uibutton(f1,'push','Position',[450 10 80 30], 'Text', '重载');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            
            app.AllCheckbox=uicheckbox(f1, 'Text','  全选',...
                'Value', 0,...
                'Position',[180 10 80 30]);
            app.AllCheckbox.ValueChangedFcn=createCallbackFcn(app, @AllCheckboxValueChanged, true);
            
            load(app.Path,'f')
            
            n1=app.DropDown_2.Value;
            n2=app.DropDown_3.Value;
            n3=app.DropDown_4.Value;
            
            if  isempty(f.Children(n1).Children)
                ControlData=f.Children(n1);
                
            elseif isempty(f.Children(n1).Children(n2).Children)
                ControlData=f.Children(n1).Children(n2);
                
            else
                ControlData=f.Children(n1).Children(n2).Children(n3);
            end
            
            if   isempty(ControlData.NodeData)
                n=length(app.UITable(1).ColumnName);
                a1=(app.UITable.ColumnName);
                a2=num2cell(false(n,1));
                a3=cellstr(repmat('3σ-BoxCox',[n,1]));
                a4=cellstr(repmat('双侧',[n,1]));
                a5=cellstr(repmat(' ',[n,1]));
                a6=cellstr(repmat(' ',[n,1]));
                a7=cellstr(repmat(' ',[n,1]));
                a8=cellstr(repmat(' ',[n,1]));
                a9=cellstr(repmat(' ',[n,1]));
                a10=cellstr(repmat('0.5',[n,1]));
                %                 Method='控制方法';
                %                 Limits='控制限';
                
                vars={'参数名称','是否一致性','Method','Limits','修正下限','修正上限','规范下限','规范上限','单位','调整系数'};
                
                B=table(a1,a2,a3,a4,a5,a6,a7,a8,a9,a10,'VariableNames',vars);
                
                valueset1 = {'3σ-BoxCox', '3σ','AEC-PAT','修正'};
                B.Method = categorical(  B.Method ,valueset1);
                valueset2 = {'双侧', '上限', '下限'};
                B.Limits = categorical(  B.Limits,valueset2);
                A.Data=B;
            else
                A.Data=ControlData.NodeData;
            end
            
            
            function ParaButtonPushed(app,~)
                
                %                 n1=app.DropDown_2.Value;
                %                 n2=app.DropDown_3.Value;
                %                 n3=app.DropDown_4.Value;
                
                %              .Children(n3)
                ControlData.NodeData= A.Data;
                app.Info=A.Data;
                %                 if ~isfolder('Data\初始化')
                %                 mkdir Data\初始化
                %                 end
                save('Data\初始化\DeviceSave.mat','f')
                
                close(f1)
            end
            
            function CancelButtonPushed(~,~)
                close(f1)
            end
            
            function ReloadButtonPushed(~,~)
                n=length(app.UITable(1).ColumnName);
                a1=(app.UITable.ColumnName);
                a2=num2cell(false(n,1));
                a3=cellstr(repmat('3σ-BoxCox',[n,1]));
                a4=cellstr(repmat('双侧',[n,1]));
                a5=cellstr(repmat(' ',[n,1]));
                a6=cellstr(repmat(' ',[n,1]));
                a7=cellstr(repmat(' ',[n,1]));
                a8=cellstr(repmat(' ',[n,1]));
                a9=cellstr(repmat(' ',[n,1]));
                a10=cellstr(repmat('0.5',[n,1]));
                %                 Method='控制方法';
                %                 Limits='控制限';
                
                vars={'参数名称','是否一致性','Method','Limits','修正下限','修正上限','规范下限','规范上限','单位','调整系数'};
                
                B=table(a1,a2,a3,a4,a5,a6,a7,a8,a9,a10,'VariableNames',vars);
                
                valueset1 = {'3σ-BoxCox', '3σ','AEC-PAT', '修正'};
                B.Method = categorical(  B.Method ,valueset1);
                valueset2 = {'双侧', '上限', '下限'};
                B.Limits = categorical(  B.Limits,valueset2);
                A.Data=B;
            end
            
            function AllCheckboxValueChanged(~,~)
                n=height(A.Data);
                
                %                     for i=1:n              ones(n,1,'logical')
                if app.AllCheckbox.Value==1
                    
                    %                     A.Data(:,2)= {'1'};
                    a2=num2cell(true(n,1));
                    B=table(a2);
                    A.Data(:,2)=B;
                else
                    %                     A.Data(:,2)= {'0'};
                    a2=num2cell(false(n,1));
                    B=table(a2);
                    A.Data(:,2)=B;
                end
                %                 A.Data.VariableTypes(2)={'logical'};
                %                     end
            end
        end

        % Button pushed function: Button_39
        function Button_39Pushed(app, event)
            try
                
                Dir=string(app.TextArea_2.Value);
                app.PathName=Dir;
                FileName=app.ListBox.Value ;
                
                app.UITable(:)=[];
                delete(app.TabGroup2.Children)
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'读取数据...') ;
                
                if  size(FileName,2)~=1
                    
                    x=zeros(1,length(FileName));
                    
                    for i= 1:length(FileName)
                        
                        str=string(strcat( Dir,'\', FileName(i)));
                        
                        Data=readtable(str,"PreserveVariableNames",true);
                        
                        parameter= Data.Properties.VariableNames;
                        Tabname=string(FileName(i));
                        
                        app.Tab(i) = uitab(app.TabGroup2);
                        app.Tab(i).Title = Tabname;
                        
                        app.UITable(i) = uitable(app.Tab(i));
                        app.UITable(i).ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                        app.UITable(i).RowName = {};
                        app.UITable(i).Position = [10,11,530,719];
                        
                        set(app.UITable(i),'data',Data,'columnname',parameter,'columnsortable',true,'columneditable',true)
                        app.UITable(i).RowName='numbered';
                        
                        x(i)=width(app.UITable(i).Data);
                        
                        f.Value=i/(length(FileName)+1);
                        
                    end
                    if max(x)-min(x)~=0
                        uialert(app.UIFigure,'请确保所有批次具有相同的测试参数','Message-Box','Icon','warning')
                    end
                    
                else
                    str=string(strcat(Dir, FileName));
                    sheetname=sheetnames(str);
                    n=length(sheetnames(str));
                    x=zeros(1,n);
                    
                    for i= 1:n
                        f.Value=1/(n+1);
                        
                        Data=readtable(str,"PreserveVariableNames",true,'sheet',i);
                        
                        parameter= Data.Properties.VariableNames;
                        Tabname=string(sheetname(i));
                        
                        
                        app.Tab(i) = uitab(app.TabGroup2);
                        app.Tab(i).Title = Tabname;
                        
                        app.UITable(i) = uitable(app.Tab(i));
                        app.UITable(i).ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                        app.UITable(i).RowName = {};
                        app.UITable(i).Position = [10,12,562,710];
                        
                        set(app.UITable(i),'data',Data,'columnname',parameter,'columnsortable',true,'columneditable',true)
                        app.UITable(i).RowName='numbered';
                        
                        x(i)=width(app.UITable(i).Data);
                        
                    end
                    if max(x)-min(x)~=0
                        uialert(app.UIFigure,'请确保所有批次具有相同的测试参数','Message-Box','Icon','warning')
                    end
                    
                    
                    %                     Data=readtable(str,"PreserveVariableNames",true);
                    %
                    %                     parameter= Data.Properties.VariableNames;
                    %                 Tabname=string(FileName);
                    
                    %                 app.Tab = uitab(app.TabGroup2);
                    %                 app.Tab.Title = Tabname;
                    
                    %                 app.UITable = uitable(app.Tab);
                    %                 app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                    %                 app.UITable.RowName = {};
                    %                 app.UITable.Position = [10,11,530,719];
                    
                    %                     set(app.UITable,'data',Data,'columnname',parameter)
                    %                     app.UITable.RowName='numbered';
                    
                    %                 f.Value=0.5;
                end
                %                 app.DropDown.Items=parameter;
                %                 app.DropDown.ItemsData=1:length(parameter);
                %                 app.DropDown.Value=2;
                pause(0.1)
                f.Value=1;
                close(f)
            catch
                uialert(app.UIFigure,'请确认路径正确并选择相应文件','Message-Box','Icon','Info')
                close(f)
            end
            try
                FileName=erase(FileName,'.xls');
                FileName=erase(FileName,'.xlsx');
                FileName=erase(FileName,'.txt');
                FileName=string(FileName);
                
                app.EditField_35.Value=(FileName);
                
            catch
            end
            app.TabGroup.SelectedTab=app.Tab_3;
        end

        % Button pushed function: Button_38
        function Button_38Pushed(app, event)
            try
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'计算中，请稍等...') ;
                parameter=app.UITable(1).ColumnName;
                %             app.DropDown.ItemsData=1:length(app.DropDown.Items);
                %             x=app.DropDown.Value;
                %             Data = app.UITable.Data;
                ParaInfo = app.Info;
                %             tit=char(table2cell(ParaInfo(1,1)));
                
                
                %             j=1;
                %             outlier=zeros(1,height(app.Info));
                %             Outlier=[];
                badtit=[];
                lotname=[];
                testname=[];
                degree=[];
                nnndata=[];
                name=[];
                totalOT=[];
                %                 f=waitbar(0,'Processing');
                nn=width(app.UITable(1).Data);
                delete(app.TabGroup3.Children)
                %                 delete(app.UITable)
                %                 delete(app.Tab)
                %                 n=length(app.UITable);
                
                %                n=length(app.UITable);
                stage={'试验前','试验后','变化量'};
                k=1;


                for j=1:nn % 参数个数
                    Outlier=[];
                    %                 lotname=[];
                    %                 testname=[];
                    %                 degree=[];
                    if strcmp(  (string(table2array(ParaInfo(j,2)))),'true')
                        
                        f.Value=j/(nn);
                        tit=string(parameter(j));
                        Tabname=string(parameter(j));
                        
                        app.Tab(j) = uitab(app.TabGroup3);
                        app.Tab(j).Title = Tabname;
                        bb=tiledlayout(app.Tab(j),4,1,"TileSpacing","compact","Padding","compact");
                        
                        
                        for i=1:3    % 老炼前后
                            
                            try
                                nndata=app.UITable(i).Data(:,j);
                            catch
                                uialert(app.UIFigure,'先计算变化量','Message-Box','Icon','warning')
                                close(f)
                                return
                            end
                            
                            nndata=table2array(nndata);
                            %                     end
                            %                             batch=app.EditField_35.Value;
                            
                            lot= stage(i); % 阶段
                            
                            nnn=height(app.UITable(i).Data);
                            %                     waitbar(i/height(app.Info),f,'djek')
                            %                     {'Processing Parameter No.',i} 'name','一致性分析'


                            if iscell(nndata)
                                nndata=str2double(nndata);
                            end
                            
                            method = string(table2array(ParaInfo(j,3)));
                            limit = string(table2array(ParaInfo(j,4)));
                            Low = (table2array(ParaInfo(j,5)));
                            High = (table2array(ParaInfo(j,6)));
                            Standard1= (table2array(ParaInfo(j,7)));
                            Standard2= (table2array(ParaInfo(j,8)));
                            %                              unit=(table2array(ParaInfo(i,9)));
                            Coeficient=(table2array(ParaInfo(j,10)));
                            
                            Stan1=str2double(Standard1);
                            Stan2=str2double(Standard2);
                            Coef=str2double(Coeficient);
                            
                            [m,s,r,U,L,P,ske,method,Ot,Cpk,badtits] = ParaHist(app,nndata,i,bb,tit,method,limit,Low,High,Stan1,Stan2,badtit,lot,Coef);    % 画图


                            %                             if i>1                     %在评价中仅计算老炼后离群
                            totalOT=[totalOT; table2array(app.UITable(i).Data(transpose(Ot),:))];
                            Outlier=[Outlier; table2array(app.UITable(i).Data(transpose(Ot),:))];
                            
                            
                            lotname=[lotname repmat(string(lot) ,1,length(Ot))];
                            testname=[testname repmat((tit) ,1,length(Ot))];
                            dd=((nndata(Ot))-(U+L)/2)/((U-L)/6);
                            degree=[degree  transpose(dd)];
                            %                             end
                            tabledata(i,:)=[tit,lot,m,s,r,U,L,P,ske,method,nnn,Cpk];
                            nnndata=[nnndata;nndata];
                            
                            name=[name;string(repmat(lot,length(nndata),1))];
                            
                            badtit=[badtit,badtits];
                        end
                        
                        ax = nexttile(bb);
                        
                        histogram(ax, table2array(app.UITable(1).Data(:,j)))
                        
                        hold(ax,'on')
                        histogram(ax, table2array(app.UITable(2).Data(:,j)))
                        legend(ax,{'试验前','试验后'})
                        xlabel(ax, '试验前后对比')
                        ylabel(ax, '频数')
                        %             delete(app.TabGroup5.Children)
                        %
                        %             app.Tab(j) = uitab(app.TabGroup5);
                        %             app.Tab(j).Title = string(lot);
                        %
                        %             app.UITable(j) = uitable(app.Tab(j));
                        %             app.UITable(j).ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                        %             app.UITable(j).RowName = {};
                        %             app.UITable(j).Position = [10,11,530,719];
                        msdata=str2double(tabledata(:,[3,4]));
                        
                        CName=tabledata(1,1);
                        Cmean=(msdata(2,1)-msdata(1,1))/msdata(1,1);
                        CSigma=(msdata(2,2)-msdata(1,2))/msdata(1,2);
                        %                         if CSigma<0
                        %                             CSigma='negative';
                        %                         end
                        Cout= size(Outlier,1)/nnn;
                        Cnum=nnn;
                        Accu=msdata(2,2)/msdata(2,1);
                        Cpk=tabledata(2,12);
                        Cdata(k,:)=[CName,msdata(2,1),msdata(2,2),Cmean,CSigma,Cout,Cnum,Accu,Cpk];


                        k=k+1;
                    end
                    
                end
                
                %             iData=str2double(table2array(app.UITable2.Data));                                           % 评价
                %             nn=size(iData,1);
                %             Change1=zeros(1,nn);
                %
                %             Ratio=zeros(1,nn);
                %             Kd=zeros(1,nn);
                
                %             for k=1:nn
                %                 Change(k)=1 - (abs(iData(k,2))+ abs(iData(k,3)))/2;
                %                 Ratio(k)=iData(k,4);
                %                 Kd(k)= iData(k,6);
                %             end
                
                Change1= nanmean(abs(str2double(Cdata(:,4))));
                Change2= nanmean((str2double(Cdata(:,5))));
                if Change2<0
                    Change2=0;
                end
                Ratio=size(unique(totalOT(:,1)),1)/nnn;
                Kd= mean(str2double(Cdata(:,9)));
                Accum=mean(str2double(Cdata(:,8)));
                try
                    index=(1-Change1)*(1-Change2)*(1-Accum);
                    app.EditField_41.Value=num2str(index);
                catch
                end
                
                
                ColName={'参数名称','均值','标准差','均值变化率','标准差变化率','离群率','器件总数','参数精度','Kd'};
                ColName2={'均值变化率_μ','标准差变化率_μ','总离群率','参数精度_μ','Kd_μ','参数能力指数'};
                
                
                Cdata=array2table(Cdata,"VariableNames",ColName);
                Xdata=array2table([Change1,Change2,Ratio,Accum,Kd,index],"VariableNames",ColName2);
                
                
                set(app.UITable2,'data',Cdata,'columnname', ColName,'rowname','numbered')
                set(app.UITable2_2,'data',Xdata,'columnname', ColName2)
                
                try
                    app.TabGroup.SelectedTab=app.Tab_8;
                    Zdata=array2table([transpose(testname),transpose(lotname),transpose(degree),(totalOT)],"VariableNames",['参数','阶段','离群程度 (σ)',app.UITable(1).Data.Properties.VariableNames(1:end)]);
                    %                     app.UITable(1).Data.Properties.VariableNames(2:end)
                    %                     set(app.UITable3,'data',[table(transpose(testname),transpose(lotname),transpose(degree)),array2table(totalOT)],'columnname',['参数','阶段','离群程度 (σ)',app.UITable(1).Data.Properties.VariableNames(1:end)],'rowname','numbered')
                    set(app.UITable3,'data',Zdata,'columnname',['参数','阶段','离群程度 (σ)',app.UITable(1).Data.Properties.VariableNames(1:end)],'rowname','numbered')
                    
                    %                 app.UITable3.Data.Properties.VariableNames(1)={'批次'};
                    %                    'columnname',ColName,  'columnname',ColName2
                    %                     app.UITable3.Data.Properties.VariableNames(2)={'离群程度 (σ)'};
                catch
                end
                
                %                        Kd*
                
                f.Value=1;
                close(f)
                
                try
                    if isempty(badtit)
                        msg=['分析了',sprintf('%d',i),'个批次参数 ',tit,'的批次间波动，', '受控状态为：',condition];
                        
                        uialert(app.UIFigure,msg,'提示','icon','success')
                    else
                        msg=['共分析',sprintf('%d',i),'个批次，','其中 ',badtit,' 数据格式可能存在异常'];
                        
                        uialert(app.UIFigure,msg,'提示','icon','info')
                    end
                catch
                end
                %
            catch
                close( app.fig)
                uialert(app.UIFigure,'请先选择相应的参数控制方法.','Message-Box','Icon','warning')
                close(f)
            end
        end

        % Button pushed function: Button_41
        function Button_41Pushed(app, event)
            Dirc = dir(string(app.TextArea_2.Value));
            Dirc= Dirc(~cellfun(@isfolder,{Dirc(:).name}));
            Dirc=Dirc(contains({Dirc.name},{'.xlsx','xls','.txt','.csv','.TXT'}));
            %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
            %             string(struct2cell(dirc))
            app.ListBox.Items={};
            for i=1:length(Dirc)
                app.ListBox.Items(i)=  cellstr(Dirc(i).name);
            end
            
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            Device(app)
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            Device(app)
            
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            Device(app)
            
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            %             value = app.TextArea_2.Value;
            %             try
            %                 mkdir('Data\初始化')
            %                 writecell(value,'Data\初始化\默认路径.txt')
            %             catch
            %             end
        end

        % Button pushed function: Button_42
        function Button_42Pushed(app, event)
            %             app.DropDown.ItemsData=[];
            app.DropDown_2.ItemsData=[];
            app.DropDown_3.ItemsData=[];
            app.DropDown_4.ItemsData=[];
            
            %             a=app.DropDown.Value;
            %             a1=app.DropDown_2.Value ;
            %             a2=app.DropDown_3.Value ;
            %             a3=app.DropDown_4.Value;
            a4=app.EditField_35.Value;
            %             a4=app.DatePicker.Value; % 日期
            %             a4=datestr(a4,'yyyy-mm-dd');
            
            
            %             dirname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\',a4,'批','\'));
            dirname=string(strcat(app.PathName,a4,'\'));
            filename= strcat(dirname,[a4,'-变化量信息.xlsx']);
            
            
            mkdir (dirname)
            
            [Filename, app.PathName1] = uiputfile({'*.xlsx';'*.txt'},'选择保存路径', filename);
            if Filename~=0
                %                 app.PathName=app.TextArea_2.Value;
                
                %                     xls.Data.Properties.VariableNames=xls.ColumnName;
                
                writetable(app.UITable2.Data,fullfile(app.PathName1,Filename))
                writetable(app.UITable2_2.Data,fullfile(app.PathName1,'评价结果.xlsx'))
                writetable(app.UITable3.Data,fullfile(app.PathName1,'离群器件.xlsx'))
                
                
                for i=1:numel(app.TabGroup3.Children)
                    des=strcat('参数',num2str(i),'.png');
                    
                    exportgraphics(app.TabGroup3.Children(i).Children, fullfile(app.PathName1,des),'Resolution',90);
                    
                    %                 exportgraphics(app.Tab_11.Children, fullfile(app.PathName,['批次间控制图2-',a,'.png']),'Resolution',100)
                end
                
                
                winopen(app.PathName1)
            end
        end

        % Button pushed function: Button_44
        function Button_44Pushed(app, event)
            try
                mkdir('Data\初始化')
                writecell(app.TextArea_2.Value,'Data\初始化\默认路径.txt')
                uialert(app.UIFigure,'已保存为预设路径','提示','Icon','success')
            catch
            end
            
        end

        % Callback function
        function Button_40Pushed(app, event)
            DeviceManagement
        end

        % Button pushed function: SheetButton
        function SheetButtonPushed(app, event)
            Dir=string(app.TextArea_2.Value);
            
            if isfolder(Dir)
                
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls';'*.TXT'},'Choose a File',Dir,'multiselect','off');
            else
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls';'*.TXT'},'Choose a File','multiselect','off');
            end
            
            if   ischar(FileName)
                
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'读取数据中...') ;
                %  -------------  set listbox  -------------
                
                app.TextArea_2.Value=app.PathName;
                Dirc = dir(string(app.TextArea_2.Value));
                Dirc= Dirc(~cellfun(@isfolder,{Dirc(:).name}));
                Dirc=Dirc(contains({Dirc.name},{'.xlsx','xls','.txt','.csv'}));
                %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
                %             string(struct2cell(dirc))
                app.ListBox.Items={};
                for i=1:length(Dirc)
                    app.ListBox.Items(i)=  cellstr(Dirc(i).name);
                end
                app.ListBox.Multiselect='on';
                
                %  --------------------------
                app.UITable(:)=[];
                delete(app.TabGroup2.Children)
                %                 delete(app.UITable)
                %                 delete(app.Tab)
                
                str=string(strcat(app.PathName, FileName));
                
                
                sheetname=sheetnames(str);
                n=length(sheetnames(str));
                x=zeros(1,n);
                
                for i= 1:n
                    f.Value=1/(n+1);
                    
                    Data=readtable(str,"PreserveVariableNames",true,'sheet',i);
                    
                    parameter= Data.Properties.VariableNames;
                    Tabname=string(sheetname(i));
                    
                    
                    app.Tab(i) = uitab(app.TabGroup2);
                    app.Tab(i).Title = Tabname;
                    
                    app.UITable(i) = uitable(app.Tab(i));
                    app.UITable(i).ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                    app.UITable(i).RowName = {};
                    app.UITable(i).Position = [10,11,530,719];
                    
                    set(app.UITable(i),'data',Data,'columnname',parameter,'columnsortable',true,'columneditable',true)
                    app.UITable(i).RowName='numbered';
                    
                    x(i)=width(app.UITable(i).Data);
                    
                end
                if max(x)-min(x)~=0
                    uialert(app.UIFigure,'请确保所有批次具有相同的测试参数','Message-Box','Icon','warning')
                end
                
                %                 Data=readtable(str,"PreserveVariableNames",true,'sheet',1);
                
                %                 parameter= Data.Properties.VariableNames;
                
                %                 app.DropDown.Items=parameter;
                %                 app.DropDown.ItemsData=1:length(parameter);
                %                 app.DropDown.Value=2;
                pause(0.1)
                f.Value=1;
                close(f)
                
                FileName=erase(FileName,'.xls');
                FileName=erase(FileName,'.xlsx');
                FileName=erase(FileName,'.txt');
                app.EditField_35.Value=FileName;
            end
            app.TabGroup.SelectedTab=app.Tab_3;
            %             length(app.UITable)
            
            %             a4=app.EditField_35.Value;
            %             app.PathName
            %             string(strcat(app.PathName,a4,'\'))
        end

        % Callback function
        function MenuSelected(app, event)
            delete(app.TabGroup2.SelectedTab)
        end

        % Button pushed function: Button_50
        function Button_50Pushed(app, event)
            try
                Dir=string(app.TextArea_2.Value);
                FileName=app.ListBox.Value ;
                str=string(strcat( Dir,'\', FileName(1)));
                
                winopen(str);
            catch
                uialert(app.UIFigure,'先选择列表中的一个文件','Msg-Box','Icon','Info')
            end
            %              set(app.UITable,'Data',open(str))
        end

        % Callback function
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            ax3=app.Tab_11.Children.Children(1);
            t=str2double(table2array(app.UITable2.Data(:,value)));
            m=1:height(app.UITable2.Data);
            t1=table2array(app.UITable2.Data(:,2));
            
            
            plot(ax3,m,t,'Marker','.','MarkerSize',12);
            set(ax3,'XTick',m, 'XTickLabel',t1,'FontSize',8)
            
            
            ylabel(ax3,value)
            ax3.XTickLabelRotation = 45;
            
            title(ax3,value,'FontWeight','bold')
            
        end

        % Button pushed function: Button_51
        function Button_51Pushed(app, event)
            try
                delete(app.Tab(3))
            catch
            end
            app.Tab(3) = uitab(app.TabGroup2);
            app.Tab(3).Title = '变化量';
            
            app.UITable(3) = uitable(app.Tab(3));
            app.UITable(3).ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable(3).RowName = {};
            app.UITable(3).Position = [10,11,530,719];
            %            try
            %             Data=((app.UITable(2).Data)-(app.UITable(1).Data))./(app.UITable(2).Data);
            %                ./table2array(app.UITable(1).Data)
            
            
            Data1=table2array(app.UITable(1).Data);
            Data2=table2array(app.UITable(2).Data);
            Loc =transpose(intersect(Data2(:,1),Data1(:,1)));
            %                Loc=strfind(transpose(Data2(:,1)),transpose(Data1(:,1)));
%             Loc1=strfind(transpose(Data1(:,1)),transpose(Loc));
%             Loc2=strfind(transpose(Data2(:,1)),transpose(Loc));
%          size(Data1) 
%           size(Data2)
%          size(Loc)
            Data1=Data1(any(Data1(:,1)==Loc,2),:);
            Data2=Data2(any(Data2(:,1)==Loc,2),:);
         
            %            size(Data1)
            %            size(Data2)
            Data=Data2-Data1;
           
            %            catch
            %            end
            Data(:,1)=(Data1(:,1));
            
            parameter=app.UITable(1).ColumnName;
            
            Data=array2table(Data,'VariableNames',parameter);
            
            set(app.UITable(3),'data',Data,'columnname',parameter,'ColumnSortable',true)
            %              app.UITable(n+1).Data.Properties.VariableNames=parameter;
            
            app.UITable(3).RowName='numbered';
            
            app.TabGroup.SelectedTab= app.Tab_3;
            app.TabGroup2.SelectedTab=app.Tab(3);
            
        end

        % Value changed function: EditField_39
        function EditField_39ValueChanged(app, event)
            value = app.EditField_39.Value;
            app.EditField_40.Value=num2str(str2double(value)*app.EditField_41.Value*100);
        end

        % Value changed function: EditField_40
        function EditField_40ValueChanged(app, event)
            
            
        end

        % Button pushed function: Button_49
        function Button_49Pushed(app, event)
            DeviceManagement
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '变化率一致性';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '批次内一致性';
            app.Label_40 = uilabel(app.Panel);
            app.Label_40.Text = '元器件信息';
            app.Label_42 = uilabel(app.Panel);
            app.Label_42.Text = '类型';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Editable = 'on';
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_10Label = uilabel(app.Panel);
            app.DropDown_10Label.Text = '系列';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Editable = 'on';
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_11Label = uilabel(app.Panel);
            app.DropDown_11Label.Text = '规格';
            app.DropDown_4 = uidropdown(app.Panel);
            app.DropDown_4.Editable = 'on';
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '控制方法';
            app.Button_38 = uibutton(app.Panel, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @Button_38Pushed, true);
            app.Button_38.Text = '变化量一致性';
            app.Button_42 = uibutton(app.Panel, 'push');
            app.Button_42.ButtonPushedFcn = createCallbackFcn(app, @Button_42Pushed, true);
            app.Button_42.Text = '保存';
            app.Button_43 = uibutton(app.Panel, 'push');
            app.Button_43.ButtonPushedFcn = createCallbackFcn(app, @Button_43Pushed, true);
            app.Button_43.Text = '生成报告';
            app.Button_49 = uibutton(app.Panel, 'push');
            app.Button_49.ButtonPushedFcn = createCallbackFcn(app, @Button_49Pushed, true);
            app.Button_49.Text = '元器件类型信息';
            app.Label_45 = uilabel(app.Panel);
            app.Label_45.Text = '批次名称';
            app.EditField_35 = uieditfield(app.Panel, 'text');
            app.Button_51 = uibutton(app.Panel, 'push');
            app.Button_51.ButtonPushedFcn = createCallbackFcn(app, @Button_51Pushed, true);
            app.Button_51.Text = '计算变化量';
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '数据输入';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '多文件导入';
            app.Label_37 = uilabel(app.Panel_2);
            app.Label_37.Text = '预设路径';
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.Label_41 = uilabel(app.Panel_2);
            app.Label_41.Text = '文件列表';
            app.ListBox = uilistbox(app.Panel_2);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.Button_39 = uibutton(app.Panel_2, 'push');
            app.Button_39.ButtonPushedFcn = createCallbackFcn(app, @Button_39Pushed, true);
            app.Button_39.Text = '导入';
            app.Button_41 = uibutton(app.Panel_2, 'push');
            app.Button_41.ButtonPushedFcn = createCallbackFcn(app, @Button_41Pushed, true);
            app.Button_41.Text = '刷新';
            app.Button_44 = uibutton(app.Panel_2, 'push');
            app.Button_44.ButtonPushedFcn = createCallbackFcn(app, @Button_44Pushed, true);
            app.Button_44.Text = '保存路径';
            app.SheetButton = uibutton(app.Panel_2, 'push');
            app.SheetButton.ButtonPushedFcn = createCallbackFcn(app, @SheetButtonPushed, true);
            app.SheetButton.Text = '多Sheet导入';
            app.Button_50 = uibutton(app.Panel_2, 'push');
            app.Button_50.ButtonPushedFcn = createCallbackFcn(app, @Button_50Pushed, true);
            app.Button_50.Text = '打开文件';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '测试数据';
            app.TabGroup2 = uitabgroup(app.Tab_3);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.ColumnSortable = true;
            app.Tab_8 = uitab(app.TabGroup);
            app.Tab_8.Title = '变化率一致性';
            app.UITable2 = uitable(app.Tab_8);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.Label_46 = uilabel(app.Tab_8);
            app.Label_46.Text = '过程能力指数';
            app.EditField_39 = uieditfield(app.Tab_8, 'text');
            app.EditField_39.ValueChangedFcn = createCallbackFcn(app, @EditField_39ValueChanged, true);
            app.EditField_39.ValueChangingFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label_47 = uilabel(app.Tab_8);
            app.Label_47.Text = '质量水平指数';
            app.EditField_40 = uieditfield(app.Tab_8, 'text');
            app.EditField_40.ValueChangedFcn = createCallbackFcn(app, @EditField_40ValueChanged, true);
            app.Label_48 = uilabel(app.Tab_8);
            app.Label_48.Text = '参数能力指数';
            app.EditField_41 = uieditfield(app.Tab_8, 'text');
            app.UITable2_2 = uitable(app.Tab_8);
            app.UITable2_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnEditable = true;
            app.Label_49 = uilabel(app.Tab_8);
            app.Label_49.Text = '汇总信息';
            app.Tab_9 = uitab(app.TabGroup);
            app.Tab_9.Title = '离群器件';
            app.UITable3 = uitable(app.Tab_9);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.Tab_10 = uitab(app.TabGroup3);
            app.Tab_10.Title = '控制图1';
            app.Tab_11 = uitab(app.TabGroup3);
            app.Tab_11.Title = '控制图2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParaConsistency_Change

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end