classdef ParaConsistency_IC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Panel               matlab.ui.container.Panel
        Button_43           matlab.ui.control.Button
        Label_40            matlab.ui.control.Label
        Label_42            matlab.ui.control.Label
        DropDown_2          matlab.ui.control.DropDown
        DropDown_10Label    matlab.ui.control.Label
        DropDown_3          matlab.ui.control.DropDown
        DropDown_11Label    matlab.ui.control.Label
        DropDown_4          matlab.ui.control.DropDown
        Button_8            matlab.ui.control.Button
        Button_38           matlab.ui.control.Button
        Button_40           matlab.ui.control.Button
        Button_42           matlab.ui.control.Button
        EditField_35Label   matlab.ui.control.Label
        EditField_8         matlab.ui.control.EditField
        Panel_2             matlab.ui.container.Panel
        Button              matlab.ui.control.Button
        Label_37            matlab.ui.control.Label
        TextArea_2          matlab.ui.control.TextArea
        Label_41            matlab.ui.control.Label
        ListBox             matlab.ui.control.ListBox
        Button_39           matlab.ui.control.Button
        Button_41           matlab.ui.control.Button
        Button_44           matlab.ui.control.Button
        Button_45           matlab.ui.control.Button
        SheetDropDownLabel  matlab.ui.control.Label
        SheetDropDown       matlab.ui.control.DropDown
        CenterPanel         matlab.ui.container.Panel
        TabGroup            matlab.ui.container.TabGroup
        Tab_3               matlab.ui.container.Tab
        UITable             matlab.ui.control.Table
        Button_34           matlab.ui.control.Button
        Button_35           matlab.ui.control.Button
        Label_39            matlab.ui.control.Label
        Button_46           matlab.ui.control.Button
        Tab_8               matlab.ui.container.Tab
        UITable2            matlab.ui.control.Table
        Label_43            matlab.ui.control.Label
        Tab_9               matlab.ui.container.Tab
        UITable3            matlab.ui.control.Table
        Label_34            matlab.ui.control.Label
        Button_31           matlab.ui.control.Button
        Label_35            matlab.ui.control.Label
        EditField_33        matlab.ui.control.EditField
        Label_36            matlab.ui.control.Label
        EditField_34        matlab.ui.control.EditField
        RightPanel          matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        parameter
        ndata              % 导入的数据
        nn                % 选中的参数列
        hh
        %         filename
        Path                % 元器件类型存储路径
        indice1
        indice2
        
        % Description
        
        ParaButton % Description
        CancelButton % Description
        Info % Description
        ReloadButton % Description
        fig % Description
        PathName % Description
        PathName1 % Description
        AllCheckbox % Description
        FileName1 % Description
    end
    
    
    methods (Access = private)


        function saveFig(app,ax,figname,m,c,n)
            %             app.DropDown_2.ItemsData=[];
            %             app.DropDown_3.ItemsData=[];
            %             app.DropDown_4.ItemsData=[];
            %             app.DropDown.ItemsData=[];
            %             a1=app.DropDown_2.Value ;
            %             %             app.DropDown_2.;
            %             a2=app.DropDown_3.Value ;
            %             a3=app.DropDown_4.Value;
            a4=app.EditField_8.Value; % 批次
            
            %             a6=app.DropDown_8.Value; % 控制环节
            
            dirname=string(strcat(app.PathName,a4,'\'));
            filename= strcat(dirname,[a4,'-',figname]);
            %             dirname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\',a4,'批','\'));
            %             filename= strcat(dirname,figname);
            try
                mkdir (dirname)
            catch
            end
            [app.FileName1, app.PathName1] = uiputfile({'*.png';'*.tif';'*.jpg';'*.fig';},'选择保存路径',filename);
            if app.FileName1~=0
                fz=6;
                if m==1
                    if n-1<=2
                        l=450;
                        p=120;
                        c=1;
                        k=2;
                    elseif n-1<=4
                        l=450;
                        p=220;
                        c=2;
                        k=2;
                    elseif n-1<=6
                        l=450;
                        p=300;
                        c=3;
                        k=2;
                    elseif n-1<=9
                        l=450;
                        p=260;
                        c=3;
                        k=3;
                    elseif n-1<=12
                        l=480;
                        p=350;
                        c=4;
                        k=3;
                    elseif n-1<=18
                        l=480;
                        p=500;
                        c=6;
                        k=3;
                    elseif n-1<=21
                        l=480;
                        p=505;
                        c=7;
                        k=3;
                        fz=4;
                    else
                        l=500;
                        p=505;
                        k=4;
                        fz=4;
                    end
                    fig1 = figure('Position',[0,0,l,p],'Units','normalized');
                    fig1.Visible = 'off';
                    %                 f = waitbar(0,'待保存结束后生成报告...','name','Saving data') ;
                    f = uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'保存数据中...') ;
                    for i=2:n
                        
                        aa1=subplot(c,k,i-1,'Parent',fig1);
                        
                        %                     waitbar(i-1/n-1,f,'待保存结束后生成报告...') ;
                        f.Value=(i-1)/n;
                        f.Message='待保存结束后生成报告...';
                        
                        copyobj(ax(n-i+2).Children, aa1)
                        
                        set(aa1,'Box','on','Title',ax(n-i+2).Title,'fontsize',fz,'Xlabel',ax(n-i+2).XLabel)
                        
                        axis(aa1,axis(ax(n-i+2)));
                        %                 figAxes = axes(fig);  ('Position',[0,0,l,p],'Units','normalized')
                        
                        %                 allChildren = ax.XAxis.Parent.Children;
                        %                 copyobj(allChildren, figAxes)
                        %                 set(figAxes,'Box','on','Title', ax.Title,'XTick', ax.XTick, 'XTickLabel', ax.XTickLabel,'XLabel', ax.XLabel,'yLabel', ax.YLabel)
                        %
                        %                 figAxes.XLim = ax.XLim;
                        %                 figAxes.YLim = ax.YLim;
                        %                 figAxes.ZLim = ax.ZLim;
                        %                 figAxes.DataAspectRatio = ax.DataAspectRatio;
                    end
                    
                    
                    %                 axis normal
                elseif m==0
                    fig1 = figure;
                    fig1.Visible = 'off';
                    %             figAxes = axes(fig);
                    aa1=subplot(3,1,1,'Parent',fig1);
                    aa2=subplot(3,1,2,'Parent',fig1);
                    aa3=subplot(3,1,3,'Parent',fig1);
                    % Copy all UIAxes children, take over axes limits and aspect ratio.
                    
                    %                  aa1=imresize(aa1,[50,100]);
                    
                    copyobj(ax(3).Children, aa1)
                    copyobj(ax(2).Children, aa2)
                    copyobj(ax(1).Children, aa3)
                    
                    set(aa1,'Box','on','Title',ax(3).Title,'XTick',ax(3).XTick, 'XTickLabel',ax(3).XTickLabel,'XLabel',ax(3).XLabel,'yLabel',ax(3).YLabel,'FontSize',8)
                    set(aa2,'Box','on','Title',ax(2).Title,'XTick',ax(2).XTick, 'XTickLabel',ax(2).XTickLabel,'XLabel',ax(2).XLabel,'yLabel',ax(2).YLabel,'FontSize',8)
                    set(aa3,'Box','on','Title',ax(1).Title,'XTick',ax(1).XTick, 'XTickLabel',ax(1).XTickLabel,'XLabel',ax(1).XLabel,'yLabel',ax(1).YLabel,'FontSize',8)
                    
                    axis(aa1,axis(ax(3)));
                    axis(aa2,axis(ax(2)));
                    axis(aa3,axis(ax(1)));


                    %                 aa1.XLabel.FontSize=4;
                    %                 aa2.XLabel.FontSize=4;
                    %                 aa3.XLabel.FontSize=4;
                    %
                    %                 aa1.XTickLabelRotation = 45;
                    %                 aa2.XTickLabelRotation = 45;
                    %                 aa3.XTickLabelRotation = 45;
                    % Save as png and fig files.
                end


                %                 F= getframe(fig);
                %                 img = F.cdata;
                %                 fig=getframe(fig);
                %                 imwrite(fig,fullfile(path,file))
                
                saveas(fig1, fullfile(app.PathName1,app.FileName1));
                %                 imwrite(fig0,fullfile(path,file),'png')
                
                delete(fig1);
                
                close(f)
            end
        end
        
        function savexls(app,xls,xlsname)
            if app.FileName1~=0
                a4=app.EditField_8.Value; % 批次
                
                filename= strcat(a4,'-',xlsname,'.xlsx');
                
                %                     xls.Data.Properties.VariableNames=xls.ColumnName;
                try
                    writetable(xls,fullfile(app.PathName1,filename))
                catch
                end
            end
        end


        function Transform(app,lamda)
            app.UIAxes_2.cla;
            if  ~isempty(app.nn)
                try
                    data=app.ndata(:,app.nn);
                    c=0;
                    if min(data)<=0
                        c=-min(data)+1;
                    end
                    app.transdata=((data+c).^lamda-1)/lamda;
                    %                     app.transdata
                    %                                     app.transdata=boxcox(app.ndata(:,app.nn),lamda);
                    if ~isempty(app.EditField_18.Value)
                        h = histfit(app.transdata,round(app.BinWidthSlider.Value));
                    else
                        h = histfit(app.transdata);
                    end
                    ax=app.UIAxes_2;
                    copyobj([h(2),h(1)],ax);
                    title(ax,['转换后分布拟合 ',app.EditField_8.Value]);
                    xlabel(ax,app.parameter(app.nn));
                    ylabel(ax,'频数');
                    
                    close ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    
                    ave1=nanmean(app.transdata);
                    app.sigma1=nanstd(app.transdata);
                    app.USL1=ave1+3*app.sigma1';
                    app.LSL1=ave1-3*app.sigma1';
                    
                    %                     ave=  (ave1*lamda+1).^(1/lamda);
                    
                    USL=(app.USL1*lamda+1).^(1/lamda)-c;
                    LSL=(app.LSL1*lamda+1).^(1/lamda)-c;
                    ave=(USL+LSL)/2;
                    sigma= (USL-LSL)/6;
                    
                    app.EditField_10.Value=ave;
                    app.EditField_11.Value=sigma;
                    app.LCLEditField_2.Value=LSL;
                    app.UCLEditField_2.Value=USL;
                    
                    k=kurtosis(app.transdata);
                    app.EditField_37.Value=k;
                    s= skewness(app.transdata);
                    app.EditField_28.Value=s;
                    
                    
                    box(app.UIAxes_2,'on')
                    app.Slider.Enable='On';
                catch
                end
            else
                
                s = sprintf('请导入数据先\n');
                msgbox(s,'Message-Box','warn' )
                
            end
        end
        
        function BatchChart(app,x,y1,y2,y3)
            try
                %                 app.Tab_6.AutoResizeChildren='off';
                app.Tab_6.Scrollable='off';
                
                %                         x=table2array(app.UITable4_2.Data(:,1));
                %                         y1=table2array(app.UITable4_2.Data(:,3));
                %                         y2=table2array(app.UITable4_2.Data(:,4));
                %                         y3=table2array(app.UITable4_2.Data(:,7));
                
                app.subax=subplot(3,1,1,'Parent',app.Tab_6);
                Sub(app,app.subax,x,y1,'Mean','批次间均值 ',1)
                
                app.subax2=subplot(3,1,2,'Parent',app.Tab_6);
                Sub(app,app.subax2,x,y2,'Sigma','批次间标准差 ',1)
                
                app.subax3=subplot(3,1,3,'Parent',app.Tab_6);
                Sub(app,app.subax3,x,y3,'Ratio(%)','批次间离群率 ',1)
                
                app.TabGroup2.SelectedTab = app.Tab_6;
                
                app.Lamp.Color=[0.8 0.8 0.8];
                
            catch
                msgbox('需要有历史批次数据','Message-Box','warn')
            end
        end
        
        function results = isMono(~,ax,x,a,m)
            %             b=diff(a);
            %             Inc=b>0;
            %             Dec=b<0;
            %             c=zeros(1,length(b)-n);
            %             d=zeros(1,length(b)-n);
            %             for i=1:(length(b)-n)
            %                 c(i)=all(Inc(i:i+n));
            %                 d(i)=all(Dec(i:i+n));
            %             end
            %             results=any(c)||any(d);
            
            x=1:length(x);
            n=m-2;
            b=diff(a);
            Inc=b>0;
            Dec=b<0;
            c=zeros(1,length(b)-n);
            d=zeros(1,length(b)-n);
            for i=1:(length(b)-n)
                c(i)=all(Inc(i:i+n));
                if c(i)
                    plot(ax,x(i:i+n+1),a(i:i+n+1),'color',[1,0.8,0.1],'Marker','.','MarkerSize',12)
                    %                     text(ax,x(i+n-3),a(i+n+1)*1.01,'连续6点以上递增')
                end
                d(i)=all(Dec(i:i+n));
                if d(i)
                    plot(ax,x(i:i+n+1),a(i:i+n+1),'color',[1,0.8,0.1],'Marker','.','MarkerSize',12)
                    %                     text(ax,x(i+n-3),a(i+n+1)*0.98,'连续6点以上递减')
                end
            end
            results=any(c)||any(d);


        end
        
        function t = DropdownItems(~,f)
            t=cell(1,length(f.Children));
            for i=1:length(f.Children)
                %             t=[t,cellstr(f.Children(i).Text)];
                t(i)=cellstr(f.Children(i).Text);
            end
        end
        
        function Device(app)
            
            load(app.Path,'f')
            
            t = DropdownItems(app,f);
            app.DropDown_2.Items=t;
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items); % 把ItemsdData变成数组
            n1=app.DropDown_2.Value;   %用数字表示下拉框的索引
            
            if isempty(n1)
                n1=1;
            end
            h=f.Children(n1);
            
            if ~isempty(h.Children)
                t = DropdownItems(app,h);
                app.DropDown_3.Items=t;
                app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
                
                n2=app.DropDown_3.Value;
                if isempty(n2)
                    app.DropDown_3.Value=1;
                    n2=1;
                end
                
                g=h.Children(n2);
                
                if  ~isempty(g.Children)
                    t = DropdownItems(app,g);
                    app.DropDown_4.Items=t;
                    %                     n3=0;
                else
                    app.DropDown_4.Items={''};
                end
            else
                app.DropDown_3.Items={''};
                app.DropDown_4.Items={''};
                %                 n2=0;
                %                 return
            end
            
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
            n1=app.DropDown_2.Value;
            n2=app.DropDown_3.Value;
            n3=app.DropDown_4.Value;
            
            if  isempty(f.Children(n1).Children)
                ControlData=f.Children(n1);
                
            elseif isempty(f.Children(n1).Children(n2).Children)
                ControlData=f.Children(n1).Children(n2);
                
            else
                ControlData=f.Children(n1).Children(n2).Children(n3);
            end
            app.Info= ControlData.NodeData;
            
        end
        
        function [m,s,r,U,L,P,ske,method,outlier,Cpk,badtit] = ParaHist(app,data,j,f,tit,method,limit,Low,High,Stan1,Stan2,badtit,unit,Coef,lot)
            
            pause(0.01)
            ax=scrollsubplot(4,3,j);
            ax.Parent=f;
            
            if all(isnan(data))
                badtit=tit;
                m='-';s='-';r='-';U='-';L='-';P='-';ske='-';outlier=[];Cpk='-';
                return
            end
            
            h =  histfit(data);
            app.hh=copyobj([h(2),h(1)],ax);
            title(ax,lot,'Interpreter','none');
            if ~strcmp(unit,'')
                tit=strcat(tit,' (',unit,')');
            end
            xlabel(ax,tit,'Interpreter','none');
            m=nanmean(data);
            s=nanstd(data);
            [~,P] = jbtest(data);
            ske = skewness(data);
            
            hold(ax,'on')
            switch method
                case '3σ-BoxCox'
                    if abs(skewness(data))<=0.3
                        [~,L,U,~]=isoutlier(data,"mean");
                        s=std(data);
                        L=L- Coef*s;
                        U=U+ Coef*s;
                    else
                        [U,L] = sigma_boxcox(app,data, Coef);
                        
                    end
                    %                     c='[0 0.4470 0.7410]';
                    c='blue';
                    %                     leg='3σ-BoxCox';
                case '3σ'
                    %                     [~,L,U,~]=isoutlier(data,"quartiles");
                    [~,L,U,~]=isoutlier(data,"mean");
                    %                     ave=median(data);
                    %                     RS=(quantile(data,0.75)-quantile(data,0.25))/1.35;
                    %                     U=ave+6*RS;
                    %                     L=ave-6*RS;
                    c= '[0.99 0.9 0.03]';
                    %                     c='[0.9590 0.8840 0.0050]';
                    %                      c='yellow';
                    %                     leg='AEC-PAT';
                case'AEC-PAT'
                    ave=median(data);
                    RS=(quantile(data,0.75)-quantile(data,0.25))/1.35;
                    U=ave+6*RS;
                    L=ave-6*RS;
                    c='[0.4240 0.1840 0.5560]';
                case '修正'
                    L=str2double(Low);
                    U=str2double(High);
                    c='[0.4660 0.6740 0.1880]';
                    %                      leg='修正';
            end
            
            switch limit
                case '双侧'
                    plot(ax,[L,L],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    plot(ax,[U,U],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    %                       legend(ax,a,'控制限')
                    outlier=find(data>U|data<L);
                    
                case '上限'
                    plot(ax,[U,U],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    outlier=find(data>U);
                    %                       legend(ax,a,'控制限')
                    %                     legend(ax,a,leg)
                case '下限'
                    plot(ax,[L,L],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    outlier=find(data<L);
                    %                       legend(ax,a,'控制限')
                    %                      legend(ax,a,leg)
            end
            %               legend(ax,"AutoUpdate","off")


            plot(ax,[Stan1,Stan1],get(ax,'Ylim'),':','LineWidth', 1,'color','r');
            plot(ax,[Stan2,Stan2],get(ax,'Ylim'),':','LineWidth', 1,'color','r');
            
            if isnan(Stan1) &&  isnan(Stan2)          %Cpk
                Cpk='-';
            elseif isnan(Stan1)
                Cpk=(Stan2-m)/(3*s);
            elseif isnan(Stan2)
                Cpk=-(Stan1-m)/(3*s);
            else
                Cpk=min([(m-Stan1)/(3*s),(Stan2-m)/(3*s)]);
            end
            
            a=repmat(20,length(outlier),1);
            
            out=scatter(ax,data(outlier),a,10,'filled','DisplayName','Outliers','MarkerFaceColor','r');
            
            out.MarkerFaceAlpha = 0.5;
            
            axis(ax,'auto')
            
            r=length(outlier)/length(data);
            
        end
        
        
        function [USL,LSL] = sigma_boxcox(~,data,Coef)
            c=0;
            if min(data)<=0
                c=-min(data)+1;
            end
            [~,b]=boxcox(data+c);
            lamda=b*0.5;
            if b==0
                lamda=1;
            end
            %             if skewness(data)>=0
            %                 lamda=b+0.5;
            %             elseif skewness(data)<0
            %                 lamda=b-0.5;
            %             end
            
            if lamda>10
                lamda=8;
            elseif lamda<-2
                lamda=-2;
            end
            
            transdata=((data+c).^lamda-1)/lamda;
            
            ave=nanmean(transdata);
            sigma=nanstd(transdata);
            USL1=ave+3*sigma;
            LSL1=ave-3*sigma';
            
            USL=(USL1*lamda+1).^(1/lamda)-c;
            LSL=(LSL1*lamda+1).^(1/lamda)-c;
            
            %                ave1=(ave*lamda+1).^(1/lamda)-c;
            sigma1=(USL-LSL)/6;
            
            USL=USL+ Coef*sigma1;
            LSL=LSL- Coef*sigma1;
            
            if ~isreal(LSL) &&max(data)<0
                LSL=-abs(LSL);
            elseif ~isreal(LSL)&&max(data)>=0
                LSL=0;
            end
            if ~isreal(USL)&&max(data)<0
                USL=-abs(USL);
            elseif ~isreal(LSL)&&max(data)>=0
                USL=abs(USL);
            end
            
        end
        
    end
    
    methods (Access = public)
        
        function  ParaTable(app,Data)
            set( app.UITable,'data',Data,'columnname',Data.Properties.VariableNames);
            
            app.parameter= app.UITable.Data.Properties.VariableNames;
            
            %             app.ndata=str2double(table2array(app.UITable.Data));
            
            app.UITable.RowName='numbered';
            
            %             app.DropDown.Items=  app.parameter;
            %
            %             app.DropDown.ItemsData = 1:length(app.DropDown.Items);
            %             try
            %                 app.DropDown.Value=2;
            %             catch
            %             end
            %             app.nn = app.DropDown.Value;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Path=('Data\初始化\DeviceSave.mat');
            try
                app.TextArea_2.Value=string(importdata('Data\初始化\默认路径.txt'));
            catch
            end
            Dir=string(app.TextArea_2.Value);
            dirc = dir(Dir);
            dirc= dirc(~cellfun(@isfolder,{dirc(:).name}));
            dirc=dirc(contains({dirc.name},{'.xlsx','xls','.txt','.csv','.TXT'}));
            %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
            %             string(struct2cell(dirc))
            
            for i=1:length(dirc)
                app.ListBox.Items(i)=  cellstr(dirc(i).name);
            end
            
            Device(app)
            app.PathName=app.TextArea_2.Value;
            
            %             format short
            %             app.DropDown_2.ItemsData=[];
            %             app.DropDown_3.ItemsData=[];
            %             app.DropDown_4.ItemsData=[];
            %
            %             a1=app.DropDown_2.Value ;
            %             a2=app.DropDown_3.Value ;
            %             a3=app.DropDown_4.Value;
            %             a4=app.EditField_8.Value; % 批次
            
            
            %             app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            %             app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            %             app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {818, 818, 818};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {818, 818};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {288, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            Dir=string(app.TextArea_2.Value);
            
            if isfolder(Dir)
                
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls';'.TXT'},'Choose a File',Dir);
            else
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls';'.TXT'},'Choose a File');
            end
            
            if  (ischar(FileName))
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'读取数据...') ;
                
                str=[app.PathName FileName];
                
                %                 if strcmp(Dir,'')
                app.TextArea_2.Value=app.PathName;
                %                 end
                
                Dirc = dir(string(app.TextArea_2.Value));
                Dirc= Dirc(~cellfun(@isfolder,{Dirc(:).name}));
                Dirc=Dirc(contains({Dirc.name},{'.xlsx','xls','.txt','.csv'}));
                %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
                %             string(struct2cell(dirc))
                app.ListBox.Items={};
                for i=1:length(Dirc)
                    app.ListBox.Items(i)=  cellstr(Dirc(i).name);
                end
                
                
                set(app.Label_39,'Text',FileName)
                f.Value=0.2;
                %                 if  contains(FileName,'xlsx')||contains(FileName,'xls')
                %
                %                     [app.ndata,para]=xlsread(str);
                %                       app.parameter=para(1,:);
                %
                %                     %                     Data=array2table(app.ndata,'variablenames',app.parameter);
                %
                %                     %                     pause(0.2)
                %                 else
                
                Data=readtable(str,"PreserveVariableNames",true,'Sheet',str2double(app.SheetDropDown.Value));
                
                app.parameter= Data.Properties.VariableNames;
                
                app.ndata=readmatrix(str);
                
                %                 end
                
                %                       app.ndata=Data;
                %                 end
                
                f.Value=0.4;
                
                %                 nndata=array2table(app.ndata);
                %                 set(app.UITable,'data', Data);
                %                 Vartype=repmat({'numeric'},1,length(app.parameter));
                set(app.UITable,'data',Data,'columnname',app.parameter,'columnsortable',true,'columneditable',true);
                
                app.UITable.RowName='numbered';
                %                   app.ndata=table2array(app.UITable.Data);
                
                f.Value=0.6;
                
                %                 app.DropDown.Items=  app.parameter;
                %                 app.DropDown.ItemsData = 1:length(app.DropDown.Items);
                %                 try
                %                     app.DropDown.Value=2;
                %                 catch
                %                 end
                %                 app.nn = app.DropDown.Value;
                %                 app.ndata=table2array( app.ndata);
                %                 app.EditField.Value=str;
                %             guidata(hObject,handles);
                f.Value=0.8;
                
                %                 Initiate(app)
                %                 app.rpt=[];
                app.TabGroup.SelectedTab = app.Tab_3;
                
                %                 ColumnName=['SN',app.parameter];
                %                 size=[0,length(app.parameter)+1];
                %                 Vartype=repmat({'double'},1,length(app.parameter)+1);
                %                 app.outline=table('size',size,'VariableTypes',Vartype,'VariableNames',ColumnName);
                %                 app.outline=table('size',size,'VariableNames',ColumnName);
                app.UITable2.Data=[];
                app.UITable3.Data=[];
                %                'VariableNames',['Number',app.parameter]
                %                  'VariableNames',ColumnName
                f.Value=1;
                pause(0.1)
                close(f)
                FileName=erase(FileName,'.xls');
                FileName=erase(FileName,'.xlsx');
                FileName=erase(FileName,'.txt');
                app.EditField_8.Value=FileName;
            end
            
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.indice1 = event.Indices;
            %             app.DropDown.Value=app.indice1(2);
        end

        % Button pushed function: Button_43
        function Button_43Pushed(app, event)
            makeDOMCompilable();
            import mlreportgen.dom.*
            import mlreportgen.report.*
            
            try
                %             f = waitbar(0,'生成报告...','name','Message-Box') ;
                f = uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'生成报告...') ;
                app.DropDown_2.ItemsData=[];                     % 生成报告
                app.DropDown_3.ItemsData=[];
                app.DropDown_4.ItemsData=[];
                
                a1=app.DropDown_2.Value ;
                a2=app.DropDown_3.Value ;
                a3=app.DropDown_4.Value;
                a4=app.EditField_8.Value; % 批次
                
                
                %             a5=app.DropDown.Value ; % 参数
                %             a6=app.DropDown_8.Value; % 控制环节
                
                %             waitbar(0.2,f,'报告题目...') ;
                f.Value=0.2;
                f.Message='生成报告...';
                
                docname = string(strcat(app.PathName1,strcat(a3,'_',a4,'_批次内一致性报告.docx')));
                subtitle=strcat(a3,' - ',a4);
                if strcmp(a2,'')
                    docname = string(strcat(app.PathName1,strcat(a1,'_',a4,'_批次内一致性报告.docx')));
                    subtitle=strcat(a1,' - ',a4);
                elseif  strcmp(a3,'')
                    docname = string(strcat(app.PathName1,strcat(a2,'_',a4,'_批次内一致性报告.docx')));
                    subtitle=strcat(a2,' - ',a4);
                end
                
                %             try
                rpt = Report(docname,'docx');
                %                 add(app.rpt,TitlePage('Title',strcat(a3,' lot#',a4,' Parameter Consistency Document')));
                tp = TitlePage();
                tp.Title = Text('Parametric Consistency Document');
                %                 title=Text('参数一致性报告');
                %                 title.FontFamilyName='宋体';
                %                 tp.Title = title;
                
                tp.Subtitle = subtitle;
                tp.Author = '';
                tp.PubDate = date();
                add(rpt,tp);
                
                %                 waitbar(0.4,f,'生成控制图...') ;
                f.Value=0.4;
                f.Message='添加分布图...';
                
                title1=Text('分布图');
                %                 title1.FontFamilyName='等线';
                sec1=Section(title1);
                sec1.Numbered = false;
                
                title2=Text('批次内信息');
                %                 title2.FontFamilyName='微软雅黑';
                sec2=Section(title2);
                sec2.Numbered = false;
                title3=Text('离群器件');
                sec3=Section(title3);
                sec3.Numbered = false;
                title4=Text('异常分析');
                sec4=Section(title4);
                sec4.Numbered = false;
                
                image=Image(fullfile(app.PathName1,app.FileName1));
                %             image.Width='700';
                %             image.Height='450';
                add(sec1,image)
                add(rpt,sec1);
                
                %                 waitbar(0.6,f,'生成参数信息...') ;
                f.Value=0.6;
                f.Message='添加参数信息...';
                
                %             title2=Text('参数信息');
                %             sec2=Section(title2);
                %             sec2.Numbered = false;
                
                table1=Table(app.UITable2.Data(:,[1:7,10:12]));
                table1.Style = {...
                    RowSep('solid','black','1px'),...
                    ColSep('solid','black','1px'),};
                table1.Border = 'single';
                table1.TableEntriesStyle = {HAlign('center')};
                add(sec2,table1)
                n=app.EditField_33.Value;
                r=app.EditField_34.Value;
                
                para=['总离群数：' n  '  总离群率：' r ];
                
                add(sec2,para)
                add(rpt,sec2);
                
                %                 waitbar(0.8,f,'生成离群器件...') ;
                f.Value=0.8;
                f.Message='添加离群器件...';
                
                %                             if width(app.UITable3.Data)>5
                %                                 t=(app.UITable3.Data(:,[1:5,end]));
                %                             else
                %                                t=(app.UITable3.Data);
                %                             end
                if ~isempty(app.UITable3.Data)
                    
                    t=(app.UITable3.Data(:,1:3));
                    
                    %             if iscell(t)
                    %                 t=str2double(t);
                    %             end
                    
                    %                   xStr1 = arrayfun(@(val) num2str(val, "%.3f"), table2array(t(:,2:end-1)) , 'UniformOutput',false);
                    %                     xStr=array2table([table2array(t(:,1)),xStr1,table2array(t(:,end))],'VariableNames',t.Properties.VariableNames);
                    table2=Table(t);
                    table2.Style = {...
                        RowSep('solid','black','1px'),...
                        ColSep('solid','black','1px'),};
                    table2.Border = 'single';
                    table2.TableEntriesStyle = {HAlign('center')};
                    add(sec3,table2)
                    add(rpt,sec3);
                else
                    add(rpt,sec3);
                end
                
                
                add(rpt,sec4);
                
                close(rpt);
                
                winopen(docname)
                
                %                 winopen(app.PathName)
                f.Value=1;
                close(f)
                
            catch
                uialert(app.UIFigure,'请先进行保存并关闭已打开的报告文件','提示','Icon','Info')
                close(f)
            end
            %                         rptview(rpt.OutputPath);
            
        end

        % Button pushed function: Button_31
        function Button_31Pushed(app, event)
            %             size=[0,length(app.parameter)+1];
            %             Vartype=repmat({'double'},1,length(app.parameter)+1);
            %             ColumnName=['SN',app.parameter];
            %             app.outline=table('size',size,'VariableTypes',Vartype,'VariableNames',ColumnName);
            %             set(app.UITable3,'data',  app.outline,'ColumnName', ColumnName);
            %           delete(app.UITable3.Children)
            app.UITable3.Data=[];
            %             app.UITable3.ColumnName=[];
            app.EditField_33.Value='';
            app.EditField_34.Value='';
            %                  ,'ColumnName', ColumnName
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
            
            f1 = uifigure('Name','参数信息','Position',[500 500 555 480]);
            A = uitable(f1,'Position',[25 50 510 405],'ColumnEditable',true,'RowName','numbered');
            app.ParaButton=uibutton(f1,'push','Position',[260 10 80 30], 'Text', '保存');
            app.ParaButton.ButtonPushedFcn = createCallbackFcn(app, @ParaButtonPushed, true);
            
            app.CancelButton=uibutton(f1,'push','Position',[355 10 80 30], 'Text', '导出');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            
            app.ReloadButton=uibutton(f1,'push','Position',[450 10 80 30], 'Text', '重载');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            
            app.AllCheckbox=uicheckbox(f1, 'Text','  全选',...
                'Value', 0,...
                'Position',[180 10 80 30]);
            app.AllCheckbox.ValueChangedFcn=createCallbackFcn(app, @AllCheckboxValueChanged, true);
            
            load(app.Path,'f')
            
            n1=app.DropDown_2.Value;
            n2=app.DropDown_3.Value;
            n3=app.DropDown_4.Value;
            
            
            if  isempty(f.Children(n1).Children)
                ControlData=f.Children(n1);
                
            elseif isempty(f.Children(n1).Children(n2).Children)
                ControlData=f.Children(n1).Children(n2);
                
            else
                ControlData=f.Children(n1).Children(n2).Children(n3);
            end
            
            if   isempty(ControlData.NodeData)
                n=length(app.UITable.ColumnName);
                a1=(app.UITable.ColumnName);
                a2=num2cell(false(n,1));
                a3=cellstr(repmat('3σ-BoxCox',[n,1]));
                a4=cellstr(repmat('双侧',[n,1]));
                a5=cellstr(repmat(' ',[n,1]));
                a6=cellstr(repmat(' ',[n,1]));
                a7=cellstr(repmat(' ',[n,1]));
                a8=cellstr(repmat(' ',[n,1]));
                a9=cellstr(repmat(' ',[n,1]));
                a10=cellstr(repmat('0.5',[n,1]));
                %                 Method='控制方法';
                %                 Limits='控制限';
                
                vars={'参数名称','是否一致性','Method','Limits','修正下限','修正上限','规范下限','规范上限','单位','调整系数'};
                
                B=table(a1,a2,a3,a4,a5,a6,a7,a8,a9,a10,'VariableNames',vars);
                
                valueset1 = {'3σ-BoxCox', '3σ','AEC-PAT','修正'};
                B.Method = categorical(  B.Method ,valueset1);
                valueset2 = {'双侧', '上限', '下限'};
                B.Limits = categorical(  B.Limits,valueset2);
                A.Data=B;
            else
                A.Data=ControlData.NodeData;
            end
            
            
            function ParaButtonPushed(app,~)
                
                ControlData.NodeData= A.Data;
                app.Info=A.Data;
                %                 if ~isfolder('Data\初始化')
                %                 mkdir Data\初始化
                %                 end
                save('Data\初始化\DeviceSave.mat','f')
                
                close(f1)
            end
            
            function CancelButtonPushed(~,~)
                app.DropDown_2.ItemsData=[];
                app.DropDown_3.ItemsData=[];
                app.DropDown_4.ItemsData=[];
                
                q1=app.DropDown_2.Value ;
                q2=app.DropDown_3.Value ;
                q3=app.DropDown_4.Value;
                
                %             a6=app.DropDown_8.Value; % 控制环节
                
                dirname = string(strcat('Data\参数一致性数据\',q1,'\',q2,'\',q3,'\'));
                filename= strcat(dirname,[q1,'-',q2,'-',q3,'-参数设置信息']);
                try
                    mkdir (dirname)
                catch
                end
                [file, pathname] = uiputfile({'*.xlsx';'*.txt';'*.xls';},'选择保存路径',filename);
                try
                    writetable(A.Data,fullfile(pathname,file))
                    winopen(pathname)
                catch
                end
            end
            
            function ReloadButtonPushed(~,~)
                n=length(app.UITable.ColumnName);
                a1=(app.UITable.ColumnName);
                a2=num2cell(false(n,1));
                a3=cellstr(repmat('3σ-BoxCox',[n,1]));
                a4=cellstr(repmat('双侧',[n,1]));
                a5=cellstr(repmat(' ',[n,1]));
                a6=cellstr(repmat(' ',[n,1]));
                a7=cellstr(repmat(' ',[n,1]));
                a8=cellstr(repmat(' ',[n,1]));
                a9=cellstr(repmat(' ',[n,1]));
                a10=cellstr(repmat('0.5',[n,1]));
                %                 Method='控制方法';
                %                 Limits='控制限';
                
                vars={'参数名称','是否一致性','Method','Limits','修正下限','修正上限','规范下限','规范上限','单位','调整系数'};
                
                B=table(a1,a2,a3,a4,a5,a6,a7,a8,a9,a10,'VariableNames',vars);
                
                valueset1 = {'3σ-BoxCox', '3σ','AEC-PAT', '修正'};
                B.Method = categorical(  B.Method ,valueset1);
                valueset2 = {'双侧', '上限', '下限'};
                B.Limits = categorical(  B.Limits,valueset2);
                %                 B(1,2:end)=cellstr(repmat('',[1,9]));
                A.Data=B;
            end
            
            function AllCheckboxValueChanged(~,~)
                n=height(A.Data);
                %                     for i=1:n              ones(n,1,'logical')
                if app.AllCheckbox.Value==1
                    
                    a2=num2cell(true(n,1));
                    B=table(a2);
                    A.Data(:,2)=B;
                    %                     A.Data(1,2)= ones(1,1,'logical');
                else
                    %                     A.Data(:,2)= {'0'};
                    a2=num2cell(false(n,1));
                    B=table(a2);
                    A.Data(:,2)=B;
                end
                %                 A.Data.VariableTypes(2)={'logical'};
                %                     end
            end
            app.Button_38.Enable='on';
        end

        % Callback function
        function Button_32Pushed(app, event)
            
            %             try
            str= app.TextArea_2.Value;
            Dir=  string(str);
            
            if isfolder(Dir)
                
                latestfile = getlatestfileall(Dir);
                
                f = waitbar(0,'Importing data...','name','Message-Box') ;
                
                %                 Filename=strcat(Dir,'\',latestfile);
                
                %             app.EditField.Value=string(importdata(Filename));
                waitbar(0.2,f,'Importing data...') ;
                
                
                Data=readtable(latestfile,"PreserveVariableNames",true);
                
                app.parameter=Data.Properties.VariableNames;
                
                app.ndata=readmatrix(latestfile);
                
                waitbar(0.4,f,'Importing data...') ;
                %                     end
                
            elseif isfile(Dir)
                
                f = waitbar(0,'Importing data...') ;
                
                Data=readtable(Dir,"PreserveVariableNames",true);
                
                waitbar(0.2,f,'Importing data...') ;
                
                app.parameter=Data.Properties.VariableNames;
                
                app.ndata=readmatrix(Dir);
                
                waitbar(0.4,f,'Importing data...') ;
                
            elseif strcmp(Dir,'')
                uialert(app.UIFigure,'请先填入存放数据的文件夹路径','Message-Box','Icon','Info')
                return
            end
            
            
            set(app.UITable,'data', Data,'columnname',app.parameter);
            app.UITable.RowName='numbered';
            
            waitbar(0.6,f,'Importing data...') ;
            
            %                 app.DropDown.Items=  app.parameter;
            %                 app.DropDown.ItemsData = 1:length(app.DropDown.Items) ;
            %                 try
            %                     app.DropDown.Value=2;
            %                 catch
            %                 end
            %                 app.nn = app.DropDown.Value;
            
            Initiate(app)
            app.rpt=[];
            app.TabGroup.SelectedTab = app.Tab_3;
            
            waitbar(0.8,f,'Importing data...') ;
            
            %                 ColumnName=['SN',app.parameter];
            %                 size=[0,length(app.parameter)+1];
            %                 Vartype=repmat({'double'},1,length(app.parameter)+1);
            %                 app.outline=table('size',size,'VariableTypes',Vartype,'VariableNames',ColumnName);
            
            app.UITable3.Data=[];
            %                 mkdir('Data\初始化')
            %                 writecell(str,'Data\初始化\默认路径.txt') ;
            
            waitbar(1,f,'Importing data...') ;
            close (f)
            %             catch
            %
            %                 uialert(app.UIFigure,'请确认路径正确','Message-Box','Icon','Info')
            %             end
            %
        end

        % Button pushed function: Button_34
        function Button_34Pushed(app, event)
            if isempty(app.indice1)
                uialert(app.UIFigure,'请先选择要删除的行','Message-Box','Icon','warning')
            else
                app.UITable.Data(app.indice1(1),:)=[];
                
            end
            
        end

        % Button pushed function: Button_35
        function Button_35Pushed(app, event)
            if isempty(app.indice1)
                uialert(app.UIFigure,'请先选择要删除的列','Message-Box','Icon','warning')
            else
                n=app.indice1(2);
                
                app.UITable.Data(:,n)=[];
                app.UITable.ColumnName(n)=[];
                app.UITable.Data.Properties.VariableNames=app.UITable.ColumnName;
                %                 set( app.UITable,'data',app.UITable.Data,'ColumnName',app.parameter)
                
            end
        end

        % Callback function
        function Button_37Pushed(app, event)
            app.UITable4_2.Data=[];
            app.UITable4_2.ColumnName=[];
            app.Button_25.Enable='off';
            app.Button_28.Enable='off';
            app.Button_29.Enable='off';
            
        end

        % Button pushed function: Button_39
        function Button_39Pushed(app, event)
            try
                Dir=string(app.TextArea_2.Value);
                app.PathName=Dir;
                str=strcat(Dir,'\', app.ListBox.Value) ;
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'读取数据...') ;
                %                 f = waitbar(0,'Importing data...','name','Message-Box') ;
                %                 if  contains(FileName,'xlsx')||contains(FileName,'xls')
                %
                %                     [app.ndata,para]=xlsread(str);
                %                       app.parameter=para(1,:);
                %
                %                     %                     Data=array2table(app.ndata,'variablenames',app.parameter);
                %
                %                     %                     pause(0.2)
                %                 else
                
                Data=readtable(str,"PreserveVariableNames",true,'Sheet',str2double(app.SheetDropDown.Value));
                
                app.parameter= Data.Properties.VariableNames;
                
                app.ndata=readmatrix(str);
                
                %                 end
                
                %                       app.ndata=Data;
                %                 end
                
                f.Value=0.25;
                
                %                 nndata=array2table(app.ndata);
                %                 set(app.UITable,'data', Data);
                %                 Vartype=repmat({'numeric'},1,length(app.parameter));
                set(app.UITable,'data',Data,'columnname',app.parameter,'columnsortable',true,'columneditable',true);
                app.UITable.RowName='numbered';
                %                   app.ndata=table2array(app.UITable.Data);
                
                f.Value=0.5;
                
                %                 app.DropDown.Items=  app.parameter;
                %                 app.DropDown.ItemsData = 1:length(app.DropDown.Items);
                %                 try
                %                     app.DropDown.Value=2;
                %                 catch
                %                 end
                %                 app.nn = app.DropDown.Value;
                %                 app.ndata=table2array( app.ndata);
                %                 app.EditField.Value=str;
                %             guidata(hObject,handles);
                f.Value=0.75;
                
                %                 Initiate(app)
                app.TabGroup.SelectedTab = app.Tab_3;
                
                %                 ColumnName=['SN',app.parameter];
                %                 size=[0,length(app.parameter)+1];
                %                 Vartype=repmat({'double'},1,length(app.parameter)+1);
                %                 app.outline=table('size',size,'VariableTypes',Vartype,'VariableNames',ColumnName);
                %                 app.outline=table('size',size,'VariableNames',ColumnName);
                app.UITable2.Data=[];
                app.UITable3.Data=[];
                %                 mkdir('Data\初始化')
                %                 writecell(app.TextArea_2.Value,'Data\初始化\默认路径.txt') ;
                pause(0.1)
                f.Value=1;
                close(f)
                FileName=app.ListBox.Value;
                FileName=erase(FileName,'.xls');
                FileName=erase(FileName,'.xlsx');
                FileName=erase(FileName,'.txt');
                app.EditField_8.Value=FileName;
            catch
                uialert(app.UIFigure,'请确认路径正确并选择文件','Message-Box','Icon','Info')
                close(f)
            end
            
            %             app.EditField_8.Value=app.TextArea_2.Value(1:end-5);
        end

        % Button pushed function: Button_38
        function Button_38Pushed(app, event)
            try
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'运算中，请稍等...') ;
                app.UITable.Data.Properties.VariableNames=app.UITable.ColumnName;
                Data = app.UITable.Data;
                ColumnName= app.UITable.ColumnName;
                
                ParaInfo = app.Info;
                
                app.fig=figure('Name','批次内分布图','NumberTitle','off');
                set( app.fig,'position',[450,150,1000,800])
                j=1;
                %             outlier=zeros(1,height(app.Info));
                Outlier=[];
                badtit=[];
                testname=[];
                degree=[];
                %                 f=waitbar(0,'Processing');
                n=height(app.UITable.Data);
                lot=app.EditField_8.Value;
                if isempty(lot)
                    lot='-';
                end
                
                for i=1:height(app.Info)
                    f.Value=i/(height(app.Info)+1);
                    %                 if iscell(Data)
                    %                     Data=str2double(Data);
                    %                 end
                    nndata=Data(:,  strcmp(ColumnName,table2array(ParaInfo(i,1))) );
                    
                    %                     waitbar(i/height(app.Info),f,'djek')
                    %                     {'Processing Parameter No.',i} 'name','一致性分析'


                    if strcmp(  (string(table2array(ParaInfo(i,2)))),'true')&& ~isempty(nndata)
                        tit=nndata.Properties.VariableNames;
                        nndata=table2array(nndata);
                        
                        if iscell(nndata)
                            nndata=str2double(nndata);
                        end
                        method = string(table2array(ParaInfo(i,3)));
                        limit = string(table2array(ParaInfo(i,4)));
                        Low = (table2array(ParaInfo(i,5)));
                        High = (table2array(ParaInfo(i,6)));
                        
                        Standard1= (table2array(ParaInfo(i,7)));
                        Standard2= (table2array(ParaInfo(i,8)));
                        unit=(table2array(ParaInfo(i,9)));
                        Coeficient=(table2array(ParaInfo(i,10)));
                        
                        Stan1=str2double(Standard1);
                        Stan2=str2double(Standard2);
                        Coef=str2double(Coeficient);
                        
                        [m,s,r,U,L,P,ske,method,Ot,Cpk, badtits] = ParaHist(app,nndata,j,app.fig,tit,method,limit,Low,High,Stan1,Stan2,badtit,unit,Coef,lot);
                        Outlier=[Outlier transpose(Ot)];
                        
                        %                     Outlier=[Outlier;  table2cell(app.UITable.Data(transpose(Ot),:))];
                        testname=[testname repmat(tit,1,length(Ot))];
                        
                        dd=((nndata(Ot))-(U+L)/2)/((U-L)/6);
                        degree=[degree  transpose(dd)];
                        tabledata(j,:)=[tit,lot,m,s,r,U,L,P,ske,method,n,Cpk];
                        
                        %                         app.UITable2.Data=tabledata;
                        % %                         %                     set(app.UITable2,'data',tabledata,'columname')
                        %                          app.UITable2.Properties.VariableNames={'Name','Mean','Sigma','Ratio','UCL','LCL','kurtosis','Skewness','Method'};
                        %                         app.UITable2.ColumnName={'Name','Mean','Sigma','Ratio','UCL','LCL','kurtosis','Skewness','Method'};
                        j=j+1;
                        badtit=[badtit,badtits];
                    end
                    
                end
                %                ['离群参数','离群程度 (σ)',Data.Properties.VariableNames]
                
                try
                    set(app.UITable3,'data',[transpose(testname),array2table(transpose(degree)),Data(Outlier,:)],'columnname',['离群参数','离群程度 (σ)',Data.Properties.VariableNames],'rowname','numbered')
                    app.UITable3.Data.Properties.VariableNames(1)={'离群参数'};
                    app.UITable3.Data.Properties.VariableNames(2)={'离群程度 (σ)'};
                    
                    app.EditField_33.Value=string(height(unique(app.UITable3.Data(:,1:end-1))));
                    app.EditField_34.Value=string(height(unique(app.UITable3.Data(:,1:end-1)))/n);
                    
                catch
                    uialert(app.UIFigure,'请确保原始数据列名不为空','Message-Box','Icon','Info')
                end
                f.Value=1;
                close(f)
                
                try
                    if isempty(badtit)
                        msg=['  共分析',sprintf('%d',j-1),'个参数, ',app.EditField_33.Value,'只离群器件'];
                        
                        %                 msgbox(msg,'提示','custom',myicon)
                        %                     if app.EditField_33.Value=='0'
                        %                         %                     myicon = imread('C:\Users\dell\Desktop\参数一致性App\Icons\小蓝1.png');
                        %                     else
                        %                         myicon='success';
                        %                     end
                        uialert(app.UIFigure,msg,'提示','icon','success')
                    else
                        msg=['  共分析',sprintf('%d',j-1),'个参数，','其中',badtit,'数据格式可能存在异常'];
                        %                 myicon = imread('C:\Users\dell\Desktop\参数一致性App\Icons\小蓝2.png');
                        %                 msgbox(msg,'提示','custom',myicon)
                        uialert(app.UIFigure,msg,'提示','icon','info')
                    end
                catch
                end
                ColName={'参数名称','批次','均值','标准差','离群率','UCL','LCL','P值','偏度','控制方式','器件总数','Cpk'};
                
                tabledata=array2table(tabledata,"VariableNames",ColName);
                set(app.UITable2,'data',tabledata,'columnname',ColName)
                
                app.TabGroup.SelectedTab=app.Tab_8;
                app.Button_42.Enable='on';
                %
            catch
                close( app.fig)
                uialert(app.UIFigure,'竟然出错了，请确认参数控制方法是否正确（左边）','Message-Box','Icon','Info')
                try
                    close(f)
                catch
                end
            end
        end

        % Button pushed function: Button_41
        function Button_41Pushed(app, event)
            Dirc = dir(string(app.TextArea_2.Value));
            Dirc= Dirc(~cellfun(@isfolder,{Dirc(:).name}));
            Dirc=Dirc(contains({Dirc.name},{'.xlsx','xls','.txt','.csv','.TXT'}));
            %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
            %             string(struct2cell(dirc))
            app.ListBox.Items={};
            for i=1:length(Dirc)
                app.ListBox.Items(i)=  cellstr(Dirc(i).name);
            end
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            Device(app)
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            Device(app)
            
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            Device(app)
            
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            %             value = app.TextArea_2.Value;
            %             try
            %                 mkdir('Data\初始化')
            %                 writecell(value,'Data\初始化\默认路径.txt')
            %             catch
            %             end
        end

        % Button pushed function: Button_42
        function Button_42Pushed(app, event)
            
            try
                n=length(app.fig.Children);
                c=ceil((n-1)/4);
                saveFig(app,app.fig.Children,'全参数分布图',1,c,n)
            catch
                %                 myicon = imread('C:\Users\dell\Desktop\参数一致性App\Icons\小蓝2.png');
                uialert(app.UIFigure,'控制图不见了','提示','Icon','warn')
                
                return
            end
            
            try
                savexls(app,app.UITable2.Data,'全参数信息')
                
                
                %             savexls(app,app.UITable3.Data,'全参数信息',0,3,0)
                savexls(app,app.UITable3.Data,'总离群器件')
                app.UITable.Data.Properties.VariableNames=app.UITable.ColumnName;
                savexls(app,app.UITable.Data,'原始数据')
                
                winopen(app.PathName1)
            catch
                
            end
            app.Button_43.Enable='on';
        end

        % Button pushed function: Button_44
        function Button_44Pushed(app, event)
            try
                mkdir('Data\初始化')
                writecell(app.TextArea_2.Value,'Data\初始化\默认路径.txt')
                uialert(app.UIFigure,'已保存为预设路径','提示','Icon','success')
            catch
            end
            
        end

        % Button pushed function: Button_40
        function Button_40Pushed(app, event)
            DeviceManagement
        end

        % Button pushed function: Button_45
        function Button_45Pushed(app, event)
            try
                Dir=string(app.TextArea_2.Value);
                FileName=app.ListBox.Value ;
                str=string(strcat( Dir, '\',FileName));
                winopen(str)
            catch
                uialert(app.UIFigure,'先选择列表中的一个文件','Msg-Box','Icon','Info')
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            
        end

        % Button pushed function: Button_46
        function Button_46Pushed(app, event)
            A=cell2table(repmat({''},1,length(app.UITable.ColumnName)));
            try
                A.Properties.VariableNames=app.UITable.Data.Properties.VariableNames;
            catch
            end
            
            app.UITable.Data=[app.UITable.Data;A];
            %             cell2table({'','','',''})
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '批次内一致性';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '批次内一致性';
            app.Button_43 = uibutton(app.Panel, 'push');
            app.Button_43.ButtonPushedFcn = createCallbackFcn(app, @Button_43Pushed, true);
            app.Button_43.Text = '生成报告';
            app.Label_40 = uilabel(app.Panel);
            app.Label_40.Text = '元器件信息';
            app.Label_42 = uilabel(app.Panel);
            app.Label_42.Text = '类型';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Editable = 'on';
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_10Label = uilabel(app.Panel);
            app.DropDown_10Label.Text = '系列';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Editable = 'on';
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_11Label = uilabel(app.Panel);
            app.DropDown_11Label.Text = '规格';
            app.DropDown_4 = uidropdown(app.Panel);
            app.DropDown_4.Editable = 'on';
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '控制方法';
            app.Button_38 = uibutton(app.Panel, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @Button_38Pushed, true);
            app.Button_38.Text = '参数一致性';
            app.Button_40 = uibutton(app.Panel, 'push');
            app.Button_40.ButtonPushedFcn = createCallbackFcn(app, @Button_40Pushed, true);
            app.Button_40.Text = '元器件类型管理';
            app.Button_42 = uibutton(app.Panel, 'push');
            app.Button_42.ButtonPushedFcn = createCallbackFcn(app, @Button_42Pushed, true);
            app.Button_42.Text = '保存';
            app.EditField_35Label = uilabel(app.Panel);
            app.EditField_35Label.Text = '批次';
            app.EditField_8 = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '数据输入';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入数据';
            app.Label_37 = uilabel(app.Panel_2);
            app.Label_37.Text = '预设路径';
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.Label_41 = uilabel(app.Panel_2);
            app.Label_41.Text = '文件列表';
            app.ListBox = uilistbox(app.Panel_2);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.Button_39 = uibutton(app.Panel_2, 'push');
            app.Button_39.ButtonPushedFcn = createCallbackFcn(app, @Button_39Pushed, true);
            app.Button_39.Text = '导入';
            app.Button_41 = uibutton(app.Panel_2, 'push');
            app.Button_41.ButtonPushedFcn = createCallbackFcn(app, @Button_41Pushed, true);
            app.Button_41.Text = '刷新';
            app.Button_44 = uibutton(app.Panel_2, 'push');
            app.Button_44.ButtonPushedFcn = createCallbackFcn(app, @Button_44Pushed, true);
            app.Button_44.Text = '保存路径';
            app.Button_45 = uibutton(app.Panel_2, 'push');
            app.Button_45.ButtonPushedFcn = createCallbackFcn(app, @Button_45Pushed, true);
            app.Button_45.Text = '打开文件';
            app.SheetDropDownLabel = uilabel(app.Panel_2);
            app.SheetDropDownLabel.Text = 'Sheet';
            app.SheetDropDown = uidropdown(app.Panel_2);
            app.SheetDropDown.Items = {'1', '2', '3', '4', '5'};
            app.SheetDropDown.Value = '1';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '测试数据';
            app.UITable = uitable(app.Tab_3);
            app.UITable.ColumnName = {'序号'; '参数 1'; '参数 2'; '参数 3'};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Button_34 = uibutton(app.Tab_3, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Button_34Pushed, true);
            app.Button_34.Text = '删除行';
            app.Button_35 = uibutton(app.Tab_3, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Button_35Pushed, true);
            app.Button_35.Text = '删除列';
            app.Label_39 = uilabel(app.Tab_3);
            app.Label_39.Text = '数据表';
            app.Button_46 = uibutton(app.Tab_3, 'push');
            app.Button_46.ButtonPushedFcn = createCallbackFcn(app, @Button_46Pushed, true);
            app.Button_46.Text = '新增行';
            app.Tab_8 = uitab(app.TabGroup);
            app.Tab_8.Title = '批次内信息';
            app.UITable2 = uitable(app.Tab_8);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.Label_43 = uilabel(app.Tab_8);
            app.Label_43.Text = '分析结果';
            app.Tab_9 = uitab(app.TabGroup);
            app.Tab_9.Title = '离群器件';
            app.UITable3 = uitable(app.Tab_9);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.ColumnSortable = true;
            app.Label_34 = uilabel(app.Tab_9);
            app.Label_34.Text = '总离群器件';
            app.Button_31 = uibutton(app.Tab_9, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Button_31Pushed, true);
            app.Button_31.Text = '清空';
            app.Label_35 = uilabel(app.Tab_9);
            app.Label_35.Text = '总离群数';
            app.EditField_33 = uieditfield(app.Tab_9, 'text');
            app.Label_36 = uilabel(app.Tab_9);
            app.Label_36.Text = '总离群率';
            app.EditField_34 = uieditfield(app.Tab_9, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParaConsistency_IC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end