classdef ParaConsistency_Lot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        Panel              matlab.ui.container.Panel
        Label_40           matlab.ui.control.Label
        Label_42           matlab.ui.control.Label
        DropDown_2         matlab.ui.control.DropDown
        DropDown_10Label   matlab.ui.control.Label
        DropDown_3         matlab.ui.control.DropDown
        DropDown_11Label   matlab.ui.control.Label
        DropDown_4         matlab.ui.control.DropDown
        Button_8           matlab.ui.control.Button
        Button_38          matlab.ui.control.Button
        Button_42          matlab.ui.control.Button
        Label_44           matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        Button_43          matlab.ui.control.Button
        Button_49          matlab.ui.control.Button
        Label_45           matlab.ui.control.Label
        EditField_35       matlab.ui.control.EditField
        Panel_2            matlab.ui.container.Panel
        Button             matlab.ui.control.Button
        Label_37           matlab.ui.control.Label
        TextArea_2         matlab.ui.control.TextArea
        Label_41           matlab.ui.control.Label
        ListBox            matlab.ui.control.ListBox
        Button_39          matlab.ui.control.Button
        Button_41          matlab.ui.control.Button
        Button_44          matlab.ui.control.Button
        SheetButton        matlab.ui.control.Button
        Button_50          matlab.ui.control.Button
        DropDown_6         matlab.ui.control.DropDown
        CenterPanel        matlab.ui.container.Panel
        TabGroup           matlab.ui.container.TabGroup
        Tab_3              matlab.ui.container.Tab
        TabGroup2          matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        UITable            matlab.ui.control.Table
        Button_51          matlab.ui.control.Button
        Button_52          matlab.ui.control.Button
        Button_53          matlab.ui.control.Button
        Tab_8              matlab.ui.container.Tab
        UITable2           matlab.ui.control.Table
        Label_43           matlab.ui.control.Label
        Tab_9              matlab.ui.container.Tab
        UITable3           matlab.ui.control.Table
        Label_34           matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        TabGroup3          matlab.ui.container.TabGroup
        Tab_10             matlab.ui.container.Tab
        Tab_11             matlab.ui.container.Tab
        LampLabel          matlab.ui.control.Label
        Lamp               matlab.ui.control.Lamp
        EditField_35Label  matlab.ui.control.Label
        EditField_32       matlab.ui.control.EditField
        Label_46           matlab.ui.control.Label
        DropDown_5         matlab.ui.control.DropDown
        CheckBox           matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        parameter
        ndata              % 导入的数据
        nn                % 选中的参数列
        hh
        %         filename
        Path                % 元器件类型存储路径
        
        
        % Description
        
        ParaButton % Description
        CancelButton % Description
        Info % Description
        ReloadButton % Description
        fig % Description
        PathName % 导入时的路径/保存时的默认路径
        PathName1 % 保存时选中的路径
        AllCheckbox % Description
        subax
        subax2
        subax3
        indice1 % Description
        up % 批次间上限
        low % 批次间下限
        scatterax % 趋势图
        aaa
        bbb
        ccc
        ddd % Description
        FileName1 % Description
    end
    
    
    methods (Access = private)


        function saveFig(app,ax,figname,c,n)
            app.DropDown.ItemsData=[];
            %             app.DropDown_2.ItemsData=[];
            %             app.DropDown_3.ItemsData=[];
            %             app.DropDown_4.ItemsData=[];
            
            a=app.DropDown.Value;
            %             a1=app.DropDown_2.Value ;
            %             a2=app.DropDown_3.Value ;
            %             a3=app.DropDown_4.Value;
            a4=app.EditField_35.Value;
            %             a4=app.DatePicker.Value; % 日期
            %             a4=datestr(a4,'yyyy-mm-dd');
            
            dirname=string(strcat(app.PathName,a4,'\'));
            %             filename= strcat(dirname,[a4,'-',figname]);
            %             dirname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\',a4,'批','\'));
            filename= strcat(dirname,figname);
            try
                mkdir (dirname)
            catch
            end
            [app.FileName1, app.PathName1] = uiputfile({'*.png';'*.tif';'*.jpg';'*.fig';},'选择保存路径',filename);
            if app.FileName1~=0
                
                fz=6;
                
                if n-1<=2
                    l=450;
                    p=120;
                    c=1;
                    k=2;
                elseif n-1<=4
                    l=450;
                    p=220;
                    c=2;
                    k=2;
                elseif n-1<=6
                    l=450;
                    p=300;
                    c=3;
                    k=2;
                elseif n-1<=9
                    l=450;
                    p=260;
                    c=3;
                    k=3;
                elseif n-1<=12
                    l=480;
                    p=350;
                    c=4;
                    k=3;
                elseif n-1<=18
                    l=480;
                    p=500;
                    c=6;
                    k=3;
                elseif n-1<=21
                    l=480;
                    p=505;
                    c=7;
                    k=3;
                    fz=4;
                else
                    l=500;
                    p=505;
                    k=4;
                    fz=4;
                end
                fig1 = figure('Position',[0,0,l,p],'Units','normalized');
                fig1.Visible = 'off';
                f = uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'保存数据中...') ;
                for i=2:n
                    
                    aa1=subplot(c,k,i-1,'Parent',fig1);
                    
                    f.Value=(i-1)/n;
                    f.Message='待保存结束后生成报告...';
                    %                 waitbar(i-1/n-1,f,'待保存结束后生成报告...') ;
                    
                    copyobj(ax(n-i+2).Children, aa1)
                    
                    set(aa1,'Box','on','Title',ax(n-i+2).Title,'fontsize',fz)
                    
                    axis(aa1,axis(ax(n-i+2)));
                    %                 figAxes = axes(fig);
                    %                 ('Position',[0,0,l,p],'Units','normalized')
                    %                  'xlabel',ax(n-i+2).XLabel,
                    
                    %                 allChildren = ax.XAxis.Parent.Children;
                    %                 copyobj(allChildren, figAxes)
                    %                 set(figAxes,'Box','on','Title', ax.Title,'XTick', ax.XTick, 'XTickLabel', ax.XTickLabel,'XLabel', ax.XLabel,'yLabel', ax.YLabel)
                    %
                    %                 figAxes.XLim = ax.XLim;
                    %                 figAxes.YLim = ax.YLim;
                    %                 figAxes.ZLim = ax.ZLim;
                    %                 figAxes.DataAspectRatio = ax.DataAspectRatio;
                end
                
                
                %             %                 axis normal
                
                %             fig2 = figure;
                %             fig2.Visible = 'off';
                %             %             figAxes = axes(fig);
                %             aa1=subplot(3,1,1,'Parent',fig2);
                %             aa2=subplot(3,1,2,'Parent',fig2);
                %             aa3=subplot(3,1,3,'Parent',fig2);
                %             % Copy all UIAxes children, take over axes limits and aspect ratio.
                %
                %             %                  aa1=imresize(aa1,[50,100]);
                %             ax2=app.Tab_10.Children;
                %             %      ax2.Children(3)
                %             copyobj(ax2.Children(3).Children, aa1)
                %             copyobj(ax2.Children(2).Children, aa2)
                %             copyobj(ax2.Children(1).Children, aa3)
                %
                %             set(aa1,'Box','on','Title',ax2.Children(3).Title,'XTick',ax2.Children(3).XTick, 'XTickLabel',ax2.Children(3).XTickLabel,'XLabel',ax2.Children(3).XLabel,'yLabel',ax2.Children(3).YLabel,'FontSize',6)
                %             set(aa2,'Box','on','Title',ax2.Children(2).Title,'XTick',ax2.Children(2).XTick, 'XTickLabel',ax2.Children(2).XTickLabel,'XLabel',ax2.Children(2).XLabel,'yLabel',ax2.Children(2).YLabel,'FontSize',6)
                %             set(aa3,'Box','on','Title',ax2.Children(1).Title,'XTick',ax2.Children(1).XTick, 'XTickLabel',ax2.Children(1).XTickLabel,'XLabel',ax2.Children(1).XLabel,'yLabel',ax2.Children(1).YLabel,'FontSize',6)
                %
                %             axis(aa1,axis(ax2.Children(3)));
                %             axis(aa2,axis(ax2.Children(2)));
                %             axis(aa3,axis(ax2.Children(1)));
                %             aa1.XTickLabelRotation = 45;
                %             aa2.XTickLabelRotation = 45;
                %             aa3.XTickLabelRotation = 45;
                %             %
                %
                %             fig3 = figure;
                %             fig3.Visible = 'off';
                %             %             figAxes = axes(fig);
                %             aa1=subplot(3,1,1,'Parent',fig3);
                %             aa2=subplot(3,1,2,'Parent',fig3);
                %             aa3=subplot(3,1,3,'Parent',fig3);
                %             % Copy all UIAxes children, take over axes limits and aspect ratio.
                %
                %             %                  aa1=imresize(aa1,[50,100]);
                %             ax2=app.Tab_11.Children;
                %             %      ax2.Children(3)
                %             copyobj(ax2.Children(3).Children, aa1)
                %             copyobj(ax2.Children(2).Children, aa2)
                %             copyobj(ax2.Children(1).Children, aa3)
                %
                %             set(aa1,'Box','on','Title',ax2.Children(3).Title,'XTick',ax2.Children(3).XTick, 'XTickLabel',ax2.Children(3).XTickLabel,'XLabel',ax2.Children(3).XLabel,'yLabel',ax2.Children(3).YLabel,'FontSize',6)
                %             set(aa2,'Box','on','Title',ax2.Children(2).Title,'XTick',ax2.Children(2).XTick, 'XTickLabel',ax2.Children(2).XTickLabel,'XLabel',ax2.Children(2).XLabel,'yLabel',ax2.Children(2).YLabel,'FontSize',6)
                %             set(aa3,'Box','on','Title',ax2.Children(1).Title,'XTick',ax2.Children(1).XTick, 'XTickLabel',ax2.Children(1).XTickLabel,'XLabel',ax2.Children(1).XLabel,'yLabel',ax2.Children(1).YLabel,'FontSize',6)
                %
                %             axis(aa1,axis(ax2.Children(3)));
                %             axis(aa2,axis(ax2.Children(2)));
                %             axis(aa3,axis(ax2.Children(1)));
                %             aa1.XTickLabelRotation = 45;
                %             aa2.XTickLabelRotation = 45;
                %             aa3.XTickLabelRotation = 45;


                %                 F= getframe(fig);
                %                 img = F.cdata;
                %                 fig=getframe(fig);
                %                 imwrite(fig,fullfile(path,file))
                
                saveas(fig1, fullfile(app.PathName1,app.FileName1));
                
                %                 saveas(fig2,fullfile(app.PathName,['批次间控制图-',a,'.png']))
                %
                %                 saveas(fig3,fullfile(app.PathName,['批次间趋势图-',a,'.png']))
                exportgraphics(app.Tab_10.Children, fullfile(app.PathName1,['批次间控制图-',a,'.png']),'Resolution',100)
                
                exportgraphics(app.Tab_11.Children, fullfile(app.PathName1,['批次间趋势图-',a,'.png']),'Resolution',100)
                %                 imwrite(fig0,fullfile(path,file),'png')
                %                 exportgraphics(app.Tab_10.Children(1),fullfile(app.PathName,file))
                
                delete(fig1);
                f.Value=1;
                close(f)
            end
            
            %             delete(fig2);
            %             delete(fig3);
            
            %             app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            %             app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            
        end
        
        function savexls(app,xls,xlsname)
            
            if app.PathName1~=0
                filename= strcat(xlsname,'.xlsx');
                
                %                     xls.Data.Properties.VariableNames=xls.ColumnName;
                try
                    writetable(xls,fullfile(app.PathName1,filename))
                catch
                end
            end
        end


        function Transform(app,lamda)
            app.UIAxes_2.cla;
            if  ~isempty(app.nn)
                try
                    data=app.ndata(:,app.nn);
                    c=0;
                    if min(data)<=0
                        c=-min(data)+1;
                    end
                    app.transdata=((data+c).^lamda-1)/lamda;
                    %                     app.transdata
                    %                                     app.transdata=boxcox(app.ndata(:,app.nn),lamda);
                    if ~isempty(app.EditField_18.Value)
                        h = histfit(app.transdata,round(app.BinWidthSlider.Value));
                    else
                        h = histfit(app.transdata);
                    end
                    ax=app.UIAxes_2;
                    copyobj([h(2),h(1)],ax);
                    title(ax,['转换后分布拟合 ',app.EditField_8.Value]);
                    xlabel(ax,app.parameter(app.nn));
                    ylabel(ax,'频数');
                    
                    close ;
                    app.TabGroup.SelectedTab = app.Tab_2;
                    
                    ave1=nanmean(app.transdata);
                    app.sigma1=nanstd(app.transdata);
                    app.USL1=ave1+3*app.sigma1';
                    app.LSL1=ave1-3*app.sigma1';
                    
                    %                     ave=  (ave1*lamda+1).^(1/lamda);
                    
                    USL=(app.USL1*lamda+1).^(1/lamda)-c;
                    LSL=(app.LSL1*lamda+1).^(1/lamda)-c;
                    ave=(USL+LSL)/2;
                    sigma= (USL-LSL)/6;
                    
                    app.EditField_10.Value=ave;
                    app.EditField_11.Value=sigma;
                    app.LCLEditField_2.Value=LSL;
                    app.UCLEditField_2.Value=USL;
                    
                    k=kurtosis(app.transdata);
                    app.EditField_37.Value=k;
                    s= skewness(app.transdata);
                    app.EditField_28.Value=s;
                    
                    
                    box(app.UIAxes_2,'on')
                    app.Slider.Enable='On';
                catch
                end
            else
                
                s = sprintf('请导入数据先\n');
                msgbox(s,'Message-Box','warn' )
                
            end
        end
        
        function BatchChart(app,x,y1,y2,y3)
            %             try
            app.Tab_10.AutoResizeChildren='off';
            app.Tab_10.Scrollable='off';
            
            %                         x=table2array(app.UITable4_2.Data(:,1));
            %                         y1=table2array(app.UITable4_2.Data(:,3));
            %                         y2=table2array(app.UITable4_2.Data(:,4));
            %                         y3=table2array(app.UITable4_2.Data(:,7));
            bb=tiledlayout(app.Tab_10,3,1,"TileSpacing","compact","Padding","compact");
            
            app.subax = nexttile(bb);
            %                 app.subax=subplot(3,1,1,'Parent',app.Tab_10);
            Sub(app,app.subax,x,y1,'Mean','批次间均值_',1)
            
            app.subax2 = nexttile(bb);
            %                 app.subax2=subplot(3,1,2,'Parent',app.Tab_10);
            Sub(app,app.subax2,x,y2,'Sigma','批次间标准差_',1)
            
            app.subax3 = nexttile(bb);
            %                 app.subax3=subplot(3,1,3,'Parent',app.Tab_10);
            Sub(app,app.subax3,x,y3*100,'Ratio(%)','批次间离群率_',1)
            
            %                 hold on
            %                 app.TabGroup3.SelectedTab =app.Tab_10;
            
            app.Lamp.Color=[0.8 0.8 0.8];
            %
            %             catch
            %                 %
            %             end
        end
        
        function results = isMono(~,ax,x,a,m)
            %             b=diff(a);
            %             Inc=b>0;
            %             Dec=b<0;
            %             c=zeros(1,length(b)-n);
            %             d=zeros(1,length(b)-n);
            %             for i=1:(length(b)-n)
            %                 c(i)=all(Inc(i:i+n));
            %                 d(i)=all(Dec(i:i+n));
            %             end
            %             results=any(c)||any(d);
            
            x=1:length(x);
            n=m-2;
            b=diff(a);
            Inc=b>0;
            Dec=b<0;
            c=zeros(1,length(b)-n);
            d=zeros(1,length(b)-n);
            for i=1:(length(b)-n)
                c(i)=all(Inc(i:i+n));
                if c(i)
                    plot(ax,x(i:i+n+1),a(i:i+n+1),'color',[1,0.8,0.1],'Marker','.','MarkerSize',12)
                    %                     text(ax,x(i+n-3),a(i+n+1)*1.01,'连续6点以上递增')
                end
                d(i)=all(Dec(i:i+n));
                if d(i)
                    plot(ax,x(i:i+n+1),a(i:i+n+1),'color',[1,0.8,0.1],'Marker','.','MarkerSize',12)
                    %                     text(ax,x(i+n-3),a(i+n+1)*0.98,'连续6点以上递减')
                end
            end
            results=any(c)||any(d);


        end
        
        function t = DropdownItems(~,f)
            t=cell(1,length(f.Children));
            for i=1:length(f.Children)
                %             t=[t,cellstr(f.Children(i).Text)];
                t(i)=cellstr(f.Children(i).Text);
            end
        end
        
        function Device(app)
            
            load(app.Path,'f')
            
            t = DropdownItems(app,f);
            app.DropDown_2.Items=t;
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items); % 把ItemsdData变成数组
            n1=app.DropDown_2.Value;   %用数字表示下拉框的索引
            if isempty(n1)
                n1=1;
            end
            h=f.Children(n1);
            
            if ~isempty(h.Children)
                t = DropdownItems(app,h);
                app.DropDown_3.Items=t;
                app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
                
                n2=app.DropDown_3.Value;
                if isempty(n2)
                    app.DropDown_3.Value=1;
                    n2=1;
                end
                
                g=h.Children(n2);
                
                if  ~isempty(g.Children)
                    t = DropdownItems(app,g);
                    app.DropDown_4.Items=t;
                else
                    app.DropDown_4.Items={''};
                end
            else
                app.DropDown_3.Items={''};
                app.DropDown_4.Items={''};
                %                 return
            end
            
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
            n1=app.DropDown_2.Value;
            n2=app.DropDown_3.Value;
            n3=app.DropDown_4.Value;
            
            if  isempty(f.Children(n1).Children)
                ControlData=f.Children(n1);
                
            elseif isempty(f.Children(n1).Children(n2).Children)
                ControlData=f.Children(n1).Children(n2);
                
            else
                ControlData=f.Children(n1).Children(n2).Children(n3);
            end
            app.Info= ControlData.NodeData;
            
        end
        
        function [m,s,r,U,L,P,ske,method,outlier,Cpk,badtit] = ParaHist(app,data,j,f,tit,method,limit,Low,High,Stan1,Stan2,badtit,lot,unit,Coef)
            
            pause(0.01)
            ax=scrollsubplot(4,3,j);
            ax.Parent=f;
            
            if all(isnan(data))
                badtit=lot;
                m='-';s='-';r='-';U='-';L='-';P='-';ske='-';outlier=[];Cpk='-';
                return
            end
            
            h =  histfit(data);
            app.hh=copyobj([h(2),h(1)],ax);
            
            title(ax,lot,'Interpreter','none');
            if ~strcmp(unit,'')
                tit=strcat(tit,' (',unit,')');
            end
            xlabel(ax,tit,'Interpreter','none');
            
            m=nanmean(data);
            s=nanstd(data);
            [~,P] = jbtest(data);
            ske = skewness(data);
            
            hold(ax,'on')
            switch method
                case '3σ-BoxCox'
                    if abs(skewness(data))<=0.3
                        [~,L,U,~]=isoutlier(data,"mean");
                        s=nanstd(data);
                        L=L-Coef*s;
                        U=U+Coef*s;
                    else
                        [U,L] = sigma_boxcox(app,data,Coef);
                        
                    end
                    %                     c='[0 0.4470 0.7410]';
                    c='blue';
                    %                     leg='3σ-BoxCox';
                case '3σ'
                    %                     [~,L,U,~]=isoutlier(data,"quartiles");
                    [~,L,U,~]=isoutlier(data,"mean");
                    %                     ave=median(data);
                    %                     RS=(quantile(data,0.75)-quantile(data,0.25))/1.35;
                    %                     U=ave+6*RS;
                    %                     L=ave-6*RS;
                    c= '[0.99 0.9 0.03]';
                    %                     c='[0.9590 0.8840 0.0050]';
                    %                      c='yellow';
                    %                     leg='AEC-PAT';
                case'AEC-PAT'
                    ave=median(data);
                    RS=(quantile(data,0.75)-quantile(data,0.25))/1.35;
                    U=ave+6*RS;
                    L=ave-6*RS;
                    c='[0.4240 0.1840 0.5560]';
                case '修正'
                    L=str2double(Low);
                    U=str2double(High);
                    c='[0.4660 0.6740 0.1880]';
                    %                      leg='修正';
            end
            
            switch limit
                case '双侧'
                    plot(ax,[L,L],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    plot(ax,[U,U],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    
                    outlier=find(data>U|data<L);
                    %                     legend(ax,a,leg)
                    %                      Ot=
                    
                case '上限'
                    plot(ax,[U,U],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    outlier=find(data>U);
                    %                     legend(ax,a,leg)
                case '下限'
                    plot(ax,[L,L],get(ax,'Ylim'),'--','LineWidth', 1,'color',c);
                    outlier=find(data<L);
                    %                      legend(ax,a,leg)
            end
            
            
            plot(ax,[Stan1,Stan1],get(ax,'Ylim'),':','LineWidth', 1,'color','r');
            plot(ax,[Stan2,Stan2],get(ax,'Ylim'),':','LineWidth', 1,'color','r');
            
            if isnan(Stan1) &&  isnan(Stan2)          %Cpk
                Cpk='-';
            elseif isnan(Stan1)
                Cpk=(Stan2-m)/(3*s);
            elseif isnan(Stan2)
                Cpk=-(Stan1-m)/(3*s);
            else
                Cpk=min([(m-Stan1)/(3*s),(Stan2-m)/(3*s)]);
            end
            
            a=repmat(20,length(outlier),1);
            
            out=scatter(ax,data(outlier),a,10,'filled','DisplayName','Outliers','MarkerFaceColor','r');
            
            out.MarkerFaceAlpha = 0.5;
            
            axis(ax,'auto')
            
            r=length(outlier)/length(data);
            
        end
        
        
        function [USL,LSL] = sigma_boxcox(~,data,Coef)
            c=0;
            if min(data)<=0
                c=-min(data)+1;
            end
            [~,b]=boxcox(data+c);
            lamda=b*0.5;
            if b==0
                lamda=1;
            end
            %              b=-2.5;
            %             if skewness(data)>=0
            %                 lamda=b+0.5;
            %             elseif skewness(data)<0
            %                 lamda=b-0.5;
            %             end
            if lamda>10
                lamda=8;
            elseif lamda<-2
                lamda=-2;
            end
            
            transdata=((data+c).^lamda-1)/lamda;
            
            ave=nanmean(transdata);
            sigma=nanstd(transdata);
            USL1=ave+3*sigma;
            LSL1=ave-3*sigma';
            
            USL=(USL1*lamda+1).^(1/lamda)-c;
            LSL=(LSL1*lamda+1).^(1/lamda)-c;
            
            %                ave1=(ave*lamda+1).^(1/lamda)-c;
            sigma1=(USL-LSL)/6;
            
            USL=USL+Coef*sigma1;
            LSL=LSL-Coef*sigma1;
            
            if ~isreal(LSL) &&max(data)<0
                LSL=-abs(LSL);
            elseif ~isreal(LSL)&&max(data)>=0
                LSL=0;
            end
            if ~isreal(USL)&&max(data)<0
                USL=-abs(USL);
            elseif ~isreal(LSL)&&max(data)>=0
                USL=abs(USL);
            end


        end
        
        
        function Sub(app,ax,x,y,ylb,tit,m)
            
            app.DropDown.ItemsData=[];
            n=1:length(x);
            
            %             try
            %                 y=str2double(y);
            %             catch
            %             end
            
            plot(ax,n,y,'Marker','.','MarkerSize',12)
            set(ax,'XTick',n, 'XTickLabel',x,'FontSize',8)
            %             xlabel(ax,'Batch')
            
            ylabel(ax,ylb)
            ax.XTickLabelRotation = 45;
            
            para=app.DropDown.Value;
            if ~isempty(para) && m==1
                tit=[tit,para];
            end
            
            title(ax,tit,'FontWeight','bold',"Interpreter","none")
            a=[min(n),max(n)];
            b=get(ax,'Ylim');
            %                           axis(ax,'auto')  ,b(1),b(2)
            try
                axis(ax,[a(1),a(2),b(1),b(2)]);
            catch
            end
            %                  set(ax,'Box','on')  %???not working
            app.DropDown.ItemsData = 1:length(app.DropDown.Items);
            
        end
        
        function [mean_UCL, mean_LCL]=SubControl(~,ax,x,BatchData,m,UCL,LCL)
            x=1:length(x);
            BatchEnd=BatchData(end);
            BatchData= BatchData(1:end-1);
            if m==1
                
                BatchData=[transpose(BatchData(BatchData>LCL&BatchData<UCL)),BatchEnd];
                
            end
            
            mean_ave=mean(BatchData);
            mean_sigma=std(BatchData);
            mean_UCL= mean_ave+3*mean_sigma;
            mean_LCL= mean_ave-3*mean_sigma;
            mean_UCL1= mean_ave+mean_sigma;
            mean_LCL1= mean_ave-mean_sigma;
            mean_UCL2= mean_ave+2*mean_sigma;
            mean_LCL2= mean_ave-2*mean_sigma;
            
            plot(ax,get(ax,'Xlim'),[mean_UCL1,mean_UCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL1,mean_LCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_UCL2,mean_UCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL2,mean_LCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            
            plot(ax,get(ax,'Xlim'),[mean_UCL,mean_UCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[mean_LCL,mean_LCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[mean_ave, mean_ave],'--.','color','[0.1 0.7 0.1]','LineWidth', 1);
            axis(ax,'auto')
            %             a=get(ax,'Xlim');
            b=get(ax,'Ylim');
            try
                axis(ax,[min(x),max(x),b(1),b(2)]);
            catch
            end
            
            if m==1
                axis(ax,[min(x),max(x),mean_LCL,mean_UCL]);
            end
            
        end
        
        function [UCL,LCL]=SubControl2(app,ax,x,BatchData,m,UCL,LCL)
            
            if iscell(BatchData)
                BatchData=str2double(BatchData);
            end
            Number=table2array(app.UITable2.Data(1:end-1,11));
            try
                Number=str2double(Number);
            catch
            end
            x=1:length(x);
            
            BatchEnd=BatchData(end);
            BatchData= BatchData(1:end-1);
            
            if m==1
                
                BatchData0= BatchData(1:end-1);
                BatchData=transpose([transpose(BatchData0(BatchData0>LCL&BatchData0<UCL)),BatchEnd]);
                
                NumberEnd=Number(end);
                Number= Number(1:end-1);
                Number=transpose([transpose(Number(BatchData0>LCL&BatchData0<UCL)), NumberEnd]);
            end
            
            mean_ratio=(transpose(BatchData)*(Number)/sum(Number));
            %              mean_ratio
            
            mean_number=mean( Number);
            
            sigma=((mean_ratio*(1-mean_ratio))/mean_number)^(1/2)*1.35;
            
            UCL=mean_ratio+3*sigma;
            LCL=mean_ratio-3*sigma;
            mean_UCL1=  mean_ratio+sigma;
            mean_LCL1=  mean_ratio-sigma;
            mean_UCL2=  mean_ratio+2*sigma;
            mean_LCL2=  mean_ratio-2*sigma;
            
            UCL=UCL*100;
            LCL=LCL*100;
            mean_UCL1=   mean_UCL1*100;
            mean_LCL1=   mean_LCL1*100;
            mean_UCL2=   mean_UCL2*100;
            mean_LCL2=   mean_LCL2*100;
            mean_ratio=  mean_ratio*100;
            %             get(ax,'Xlim')
            %             [mean_UCL1,mean_UCL1]*100
            %             get(ax,'Xlim')
            %             [mean_UCL1,mean_UCL1]*100
            plot(ax,get(ax,'Xlim'),[mean_UCL1,mean_UCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL1,mean_LCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_UCL2,mean_UCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL2,mean_LCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            
            plot(ax,get(ax,'Xlim'),[UCL,UCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[LCL,LCL],'--.','color','[0.8 0.8 0.8]','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[ mean_ratio, mean_ratio],'--.','color','[0.1 0.7 0.1]','LineWidth', 1);
            axis(ax,'auto')
            %             a=get(ax,'Xlim');
            b=get(ax,'Ylim');
            try
                axis(ax,[min(x),max(x),0,b(2)]);
            catch
            end
            %             %             text(ax,a(2), mean_UCL1*100,'+1σ')
            %             %             text(ax,a(2), mean_UCL2*100,'+2σ')
            %             text(ax,a(2), UCL,' UCL')
            %             %             text(ax,a(2), mean_LCL1*100,' -1σ')
            %             %             text(ax,a(2), mean_LCL2*100,' -2σ')
            %             text(ax,a(2), 0,' LCL')
            %             text(ax,a(2), mean_ratio,' CL')
            
            if m==1
                try
                    axis(ax,[min(x),max(x),0,1.08*UCL]);
                catch
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Path=('Data\初始化\DeviceSave.mat');
            try
                app.TextArea_2.Value=string(importdata('Data\初始化\批次间默认路径.txt'));
                app.PathName=app.TextArea_2.Value;
            catch
            end
            Dir=string(app.TextArea_2.Value);
            dirc = dir(Dir);
            dirc= dirc(~cellfun(@isfolder,{dirc(:).name}));
            dirc=dirc(contains({dirc.name},{'.xlsx','xls','.txt','.csv','.TXT'}));
            %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
            %             string(struct2cell(dirc))
            
            for i=1:length(dirc)
                app.ListBox.Items(i)=  cellstr(dirc(i).name);
            end
            app.ListBox.Multiselect='on';
            Device(app)
            %             app.DatePicker.Value = datetime('today');
            
            %             format short
            %             app.DropDown_2.ItemsData=[];
            %             app.DropDown_3.ItemsData=[];
            %             app.DropDown_4.ItemsData=[];
            %
            %             a1=app.DropDown_2.Value ;
            %             a2=app.DropDown_3.Value ;
            %             a3=app.DropDown_4.Value;
            %             a4=app.EditField_8.Value; % 批次
            
            
            %             app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            %             app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            %             app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {836, 836, 836};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {836, 836};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {280, '1x', 609};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %             delete(app.TabGroup2.Children)
            
            Dir=string(app.TextArea_2.Value);
            
            if isfolder(Dir)
                
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls';'*.TXT'},'Choose a File',Dir,'multiselect','on');
            else
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls';'*.TXT'},'Choose a File','multiselect','on');
            end
            
            if   size(FileName,2)~=1
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'读取数据...') ;
                %  -------------  set listbox  -------------
                
                app.TextArea_2.Value=app.PathName;
                Dirc = dir(string(app.TextArea_2.Value));
                Dirc= Dirc(~cellfun(@isfolder,{Dirc(:).name}));
                Dirc=Dirc(contains({Dirc.name},{'.xlsx','xls','.txt','.csv'}));
                %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
                %             string(struct2cell(dirc))
                app.ListBox.Items={};
                for i=1:length(Dirc)
                    app.ListBox.Items(i)=  cellstr(Dirc(i).name);
                end
                %                 app.ListBox.Multiselect='on';
                %                 delete(app.UITable)
                %  --------------------------
                
                app.UITable(:)=[];
                delete(app.Tab)
                delete(app.UITable)
                
                if  iscell(FileName)
                    x=zeros(1,length(FileName));
                    try
                        if istable(app.UITable(1).Data)
                            app.Button_51.Enable='off';
                            app.Button_52.Enable='off';
                            delete(app.TabGroup2.Children)
                        end
                    catch
                    end
                    for i= 1:length(FileName)
                        
                        str=string(strcat(app.PathName, FileName(i)));
                        Data=readtable(str,"PreserveVariableNames",true,'Sheet',str2double(app.DropDown_6.Value));
                        app.parameter= Data.Properties.VariableNames;
                        Tabname=string(FileName(i));
                        
%                         a=1;
%                         if i==1
%                             try
%                                 a= app.UITable(1).Data;
%                                 a=table2array(app.UITable(1).Data);
%                                 
%                             catch
%                             end
%                         end
%                         
%                         
%                         if isempty(a)
%                             set(app.UITable(1),'data',Data,'columnname',app.parameter)
%                             %                             app.UITable.Data=Data;
%                             %                             app.UITable.ColumnName=app.parameter;
%                             app.UITable(1).RowName='numbered';
%                             x(i)=width(app.UITable(1).Data);
%                             f.Value=i/(length(FileName)+1);
%                             app.Tab(1).Title = Tabname;
%                             
%                       
%                         else
                            
                            app.Tab(i) = uitab(app.TabGroup2);
                            app.Tab(i).Title = Tabname;
                            
                            app.UITable(i) = uitable(app.Tab(i));
                            app.UITable(i).ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                            app.UITable(i).RowName = {};
                            app.UITable(i).Position = [10,12,562,710];
                            
                            
                            set(app.UITable(i),'data',Data,'columnname',app.parameter,'columnsortable',true,'columneditable',true)
                            app.UITable(i).RowName='numbered';
                            
                            x(i)=width(app.UITable(i).Data);
                            
                            f.Value=i/(length(FileName)+1);
%                         end
                    end
                    if max(x)-min(x)~=0
                        uialert(app.UIFigure,'请确保所有批次具有相同的测试参数','Message-Box','Icon','warning')
                    end
                    
                    BatchName=strcat(string(FileName(1)),'-',string(FileName(end)));
                    BatchName=erase(BatchName,'.xls');
                    BatchName=erase(BatchName,'.xlsx');
                    app.EditField_35.Value=BatchName;
                    
                    f.Value=1;
                    close(f)
                else
                    %                     delete(app.TabGroup2.Children)
                    %                     str=string(strcat(app.PathName, FileName));
                    %
                    %                     Data=readtable(str,"PreserveVariableNames",true);
                    %
                    %                     app.parameter= Data.Properties.VariableNames;
                    %                     Tabname=string(FileName);
                    %
                    %                     app.Tab = uitab(app.TabGroup2);
                    %                     app.Tab.Title = Tabname;
                    %
                    %                     app.UITable = uitable(app.Tab);
                    %                     app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                    %                     app.UITable.RowName = {};
                    %                     app.UITable.Position = [10,12,562,710];
                    %
                    %                     set(app.UITable,'data',Data,'columnname',app.parameter)
                    %                     app.UITable.RowName='numbered';
                    %
                    %
                    %                     BatchName=strcat(string(FileName));
                    %                     BatchName=erase(BatchName,'.xls');
                    %                     BatchName=erase(BatchName,'.xlsx');
                    %                     app.EditField_35.Value=BatchName;
                    uialert(app.UIFigure,'请导入多个批次的文件','Message-Box','Icon','warning')
                end
                app.DropDown.Items=app.parameter;
                app.DropDown.ItemsData=1:length(app.parameter);
                try
                    app.DropDown.Value=2;
                catch
                end
                
                %                 if istable(app.UITable(1).Data) || isempty(app.TabGroup2.Children)
                %                     app.Button_51.Enable='off';
                %                     app.Button_52.Enable='off';
                %                 end
                
                %                 batchname(strfind(batchname,'.xlsx'):end)=[];
                %                 batchname(strfind(batchname,'.txt'):end)=[];
                
                app.TabGroup.SelectedTab=app.Tab_3;
                app.Lamp.Color = [0.8 0.8 0.8];
                
            end
            
        end

        % Button pushed function: Button_43
        function Button_43Pushed(app, event)
            
            makeDOMCompilable();
            import mlreportgen.dom.*
            import mlreportgen.report.*
            try
                f = uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'生成报告...') ;
                app.DropDown.ItemsData=[];
                app.DropDown_2.ItemsData=[];                     % 生成报告
                app.DropDown_3.ItemsData=[];
                app.DropDown_4.ItemsData=[];
                
                a=app.DropDown.Value ;
                a1=app.DropDown_2.Value ;
                a2=app.DropDown_3.Value ;
                a3=app.DropDown_4.Value;
                a4=app.EditField_35.Value;
                %             a5=app.DropDown.Value ; % 参数
                %                 a6=app.DropDown_8.Value; % 控制环节
                
                %                 waitbar(0.2,f,'报告题目...') ;
                f.Value=0.2;
                f.Message='生成报告...';
                
                %                 docname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a3,'\',a4,'批','\',strcat(a3,'_',a4,'_',a,'_批次间一致性报告.docx')));
                %                 subtitle=strcat(a3,'-',a4,'-',a);
                %                 if strcmp(a2,'')
                %                     docname = string(strcat('Data\参数一致性数据\',a1,'\',a4,'批','\',strcat(a1,'_',a4,'_',a,'_批次间一致性报告.docx')));
                %                     subtitle=strcat(a1,'-',a4,'-',a);
                %                 elseif  strcmp(a3,'')
                %                     docname = string(strcat('Data\参数一致性数据\',a1,'\',a2,'\',a4,'批','\',strcat(a2,'_',a4,'_',a,'_批次间一致性报告.docx')));
                %                     subtitle=strcat(a2,'-',a4,'-',a);
                %                 end
                
                docname = string(strcat(app.PathName1,strcat(a3,'_',a4,'_',a,'_批次间一致性报告.docx')));
                subtitle=strcat(a3,'-',a4,'-',a);
                if strcmp(a2,'')
                    docname = string(strcat(app.PathName1,strcat(a1,'_',a4,'_',a,'_批次间一致性报告.docx')));
                    subtitle=strcat(a1,'-',a4,'-',a);
                elseif  strcmp(a3,'')
                    docname = string(strcat(app.PathName1,strcat(a2,'_',a4,'_',a,'_批次间一致性报告.docx')));
                    subtitle=strcat(a2,'-',a4,'-',a);
                end
                
                
                %               try
                rpt = Report(docname,'docx');
                %                 add(app.rpt,TitlePage('Title',strcat(a3,' lot#',a4,' Parameter Consistency Document')));
                tp = TitlePage();
                tp.Title = strcat('Parametric Consistency Document');
                tp.Subtitle = subtitle;
                tp.Author = '';
                tp.PubDate = date();
                add(rpt,tp);
                
                %                 waitbar(0.4,f,'生成分布图...') ;
                f.Value=0.4;
                f.Message='添加分布图...';
                
                title1=Text('批次间分布图');
                sec1=Section(title1);
                
                sec1.Numbered = false;
                image=Image(fullfile(app.PathName1,app.FileName1));
                %             image.Width='700';
                %             image.Height='450';
                add(sec1,image)
                add(rpt,sec1);
                
                %                 waitbar(0.6,f,'生成批次信息...') ;
                f.Value=0.6;
                f.Message='添加批次间信息...';
                
                title2=Text('批次间信息');
                sec2=Section(title2);
                sec2.Numbered = false;
                
                table1=Table(app.UITable2.Data(:,[1:5,10:14]));
                table1.Style = {...
                    RowSep('solid','black','1px'),...
                    ColSep('solid','black','1px'),};
                table1.Border = 'single';
                table1.TableEntriesStyle = {HAlign('center')};
                add(sec2,table1)
                
                add(rpt,sec2);
                
                %                 waitbar(0.8,f,'生成控制图...') ;
                f.Value=0.8;
                f.Message='添加控制图...';
                
                title3=Text('批次间控制图');
                sec3=Section(title3);
                sec3.Numbered = false;
                
                image2=Image(fullfile(app.PathName1,['批次间控制图-',a,'.png']));
                image3=Image(fullfile(app.PathName1,['批次间趋势图-',a,'.png']));
                %                 image2.Width='700';
                %                 image2.Height='575';
                %                 image3.Width='700';
                %                 image3.Height='575';
                
                add(sec3,image2)
                add(sec3,image3)
                
                add(rpt,sec3);
                
                
                title4=Text('离群器件');
                sec4=Section(title4);
                sec4.Numbered = false;
                
                if ~isempty(app.UITable3.Data)
                    t=(app.UITable3.Data(:,1:3));
                    
                    table2=Table(t);
                    table2.Style = {...
                        RowSep('solid','black','1px'),...
                        ColSep('solid','black','1px'),};
                    table2.Border = 'single';
                    table2.TableEntriesStyle = {HAlign('center')};
                    
                    add(sec4,table2)
                    add(rpt,sec4);
                else
                    add(rpt,sec4);
                end
                
                title5=Text('异常分析');
                sec5=Section(title5);
                sec5.Numbered = false;
                add(rpt,sec5);
                
                close(rpt);
                f.Value=1;
                close(f)
                
                %                 winopen(app.PathName)
                try
                    winopen(docname)
                catch
                end
                
            catch
                uialert(app.UIFigure,'请先进行保存并关闭已打开的报告文件','提示','Icon','Info')
                close(f)
            end
            %                         rptview(rpt.OutputPath);
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
            
            f1 = uifigure('Name','参数信息','Position',[500 500 555 480]);
            A = uitable(f1,'Position',[25 50 510 405],'ColumnEditable',true,'RowName','numbered');
            app.ParaButton=uibutton(f1,'push','Position',[260 10 80 30], 'Text', '确定');
            app.ParaButton.ButtonPushedFcn = createCallbackFcn(app, @ParaButtonPushed, true);
            
            app.CancelButton=uibutton(f1,'push','Position',[355 10 80 30], 'Text', '导出');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            
            app.ReloadButton=uibutton(f1,'push','Position',[450 10 80 30], 'Text', '重载');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            
            app.AllCheckbox=uicheckbox(f1, 'Text','  全选',...
                'Value', 0,...
                'Position',[180 10 80 30]);
            app.AllCheckbox.ValueChangedFcn=createCallbackFcn(app, @AllCheckboxValueChanged, true);
            
            load(app.Path,'f')
            
            n1=app.DropDown_2.Value;
            n2=app.DropDown_3.Value;
            n3=app.DropDown_4.Value;
            
            if  isempty(f.Children(n1).Children)
                ControlData=f.Children(n1);
                
            elseif isempty(f.Children(n1).Children(n2).Children)
                ControlData=f.Children(n1).Children(n2);
                
            else
                ControlData=f.Children(n1).Children(n2).Children(n3);
            end
            
            if   isempty(ControlData.NodeData)
                
                n=length(app.UITable(1).ColumnName);
                a1=(app.UITable.ColumnName);
                a2=num2cell(false(n,1));
                a3=cellstr(repmat('3σ-BoxCox',[n,1]));
                a4=cellstr(repmat('双侧',[n,1]));
                a5=cellstr(repmat(' ',[n,1]));
                a6=cellstr(repmat(' ',[n,1]));
                a7=cellstr(repmat(' ',[n,1]));
                a8=cellstr(repmat(' ',[n,1]));
                a9=cellstr(repmat(' ',[n,1]));
                a10=cellstr(repmat('0.5',[n,1]));
                %                 Method='控制方法';
                %                 Limits='控制限';
                
                vars={'参数名称','是否一致性','Method','Limits','修正下限','修正上限','规范下限','规范上限','单位','调整系数'};
                
                B=table(a1,a2,a3,a4,a5,a6,a7,a8,a9,a10,'VariableNames',vars);
                
                valueset1 = {'3σ-BoxCox', '3σ','AEC-PAT','修正'};
                B.Method = categorical(  B.Method ,valueset1);
                valueset2 = {'双侧', '上限', '下限'};
                B.Limits = categorical(  B.Limits,valueset2);
                A.Data=B;
            else
                A.Data=ControlData.NodeData;
            end
            
            
            function ParaButtonPushed(app,~)
                
                %                 n1=app.DropDown_2.Value;
                %                 n2=app.DropDown_3.Value;
                %                 n3=app.DropDown_4.Value;
                
                %              .Children(n3)
                ControlData.NodeData= A.Data;
                app.Info=A.Data;
                %                 if ~isfolder('Data\初始化')
                %                 mkdir Data\初始化
                %                 end
                try
                    save('Data\初始化\DeviceSave.mat','f')
                catch
                end
                close(f1)
            end
            
            function CancelButtonPushed(~,~)
                
                app.DropDown_2.ItemsData=[];
                app.DropDown_3.ItemsData=[];
                app.DropDown_4.ItemsData=[];
                
                q1=app.DropDown_2.Value ;
                q2=app.DropDown_3.Value ;
                q3=app.DropDown_4.Value;
                
                %             a6=app.DropDown_8.Value; % 控制环节
                
                dirname = string(strcat('Data\参数一致性数据\',q1,'\',q2,'\',q3,'\'));
                filename= strcat(dirname,[q1,'-',q2,'-',q3,'-参数设置信息']);
                try
                    mkdir (dirname)
                catch
                end
                [file, pathname] = uiputfile({'*.xlsx';'*.txt';'*.xls';},'选择保存路径',filename);
                try
                    writetable(A.Data,fullfile(pathname,file))
                    winopen(pathname)
                catch
                end
            end
            
            function ReloadButtonPushed(~,~)
                n=length(app.UITable(1).ColumnName);
                a1=(app.UITable(1).ColumnName);
                a2=num2cell(false(n,1));
                a3=cellstr(repmat('3σ-BoxCox',[n,1]));
                a4=cellstr(repmat('双侧',[n,1]));
                a5=cellstr(repmat(' ',[n,1]));
                a6=cellstr(repmat(' ',[n,1]));
                a7=cellstr(repmat(' ',[n,1]));
                a8=cellstr(repmat(' ',[n,1]));
                a9=cellstr(repmat(' ',[n,1]));
                a10=cellstr(repmat('0.5',[n,1]));
                %                 Method='控制方法';
                %                 Limits='控制限';
                
                vars={'参数名称','是否一致性','Method','Limits','修正下限','修正上限','规范下限','规范上限','单位','调整系数'};
                
                B=table(a1,a2,a3,a4,a5,a6,a7,a8,a9,a10,'VariableNames',vars);
                
                valueset1 = {'3σ-BoxCox', '3σ','AEC-PAT', '修正'};
                B.Method = categorical(  B.Method ,valueset1);
                valueset2 = {'双侧', '上限', '下限'};
                B.Limits = categorical(  B.Limits,valueset2);
                A.Data=B;
            end
            
            function AllCheckboxValueChanged(~,~)
                n=height(A.Data);
                
                %                     for i=1:n              ones(n,1,'logical')
                if app.AllCheckbox.Value==1
                    
                    %                     A.Data(:,2)= {'1'};
                    a2=num2cell(true(n,1));
                    B=table(a2);
                    A.Data(:,2)=B;
                else
                    %                     A.Data(:,2)= {'0'};
                    a2=num2cell(false(n,1));
                    B=table(a2);
                    A.Data(:,2)=B;
                end
                %                 A.Data.VariableTypes(2)={'logical'};
                %                     end
            end
        end

        % Button pushed function: Button_39
        function Button_39Pushed(app, event)
            try
                
                Dir=string(app.TextArea_2.Value);
                FileName=app.ListBox.Value ;
                app.PathName=Dir;
                %                 app.UITable(:)=[];
                delete(app.TabGroup2.Children)
                
                if  size(FileName,2)~=1
                    
                    f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'读取数据...') ;
                    
                    x=zeros(1,length(FileName));
                    
                    for i= 1:length(FileName)
                        
                        str=string(strcat( Dir,'\', FileName(i)));
                        
                        Data=readtable(str,"PreserveVariableNames",true,'Sheet',str2double(app.DropDown_6.Value) );
                        
                        app.parameter= Data.Properties.VariableNames;
                        Tabname=string(FileName(i));
                        
                        app.Tab(i) = uitab(app.TabGroup2);
                        app.Tab(i).Title = Tabname;
                        
                        app.UITable(i) = uitable(app.Tab(i));
                        app.UITable(i).ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                        app.UITable(i).RowName = {};
                        app.UITable(i).Position = [10,12,562,710];
                        
                        set(app.UITable(i),'data',Data,'columnname',app.parameter,'columnsortable',true,'columneditable',true)
                        app.UITable(i).RowName='numbered';
                        
                        x(i)=width(app.UITable(i).Data);
                        
                        f.Value=i/(length(FileName)+1);
                        
                    end
                    if max(x)-min(x)~=0
                        uialert(app.UIFigure,'请确保所有批次具有相同的测试参数','Message-Box','Icon','warning')
                    end
                    f.Value=1;
                    close(f)
                    
                    BatchName=strcat(string(FileName(1)),'-',string(FileName(end)));
                    BatchName=erase(BatchName,'.xls');
                    BatchName=erase(BatchName,'.xlsx');
                    app.EditField_35.Value=BatchName;
                else
                    
                    str=string(strcat(Dir, FileName));
                    
                    Data=readtable(str,"PreserveVariableNames",true);
                    
                    app.parameter= Data.Properties.VariableNames;
                    Tabname=string(FileName);
                    
                    app.Tab = uitab(app.TabGroup2);
                    app.Tab.Title = Tabname;
                    
                    app.UITable = uitable(app.Tab);
                    app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                    app.UITable.RowName = {};
                    app.UITable.Position = [10,12,562,710];
                    
                    set(app.UITable,'data',Data,'columnname',app.parameter)
                    app.UITable.RowName='numbered';
                    
                    BatchName=strcat(string(FileName));
                    BatchName=erase(BatchName,'.xls');
                    BatchName=erase(BatchName,'.xlsx');
                    app.EditField_35.Value=BatchName;
                end
                app.DropDown.Items=app.parameter;
                app.DropDown.ItemsData=1:length(app.parameter);
                app.DropDown.Value=2;
                
                app.Lamp.Color = [0.8 0.8 0.8];
                
            catch
                uialert(app.UIFigure,'请确认路径正确并选择相应文件','Message-Box','Icon','Info')
                close(f)
            end
            
            app.TabGroup.SelectedTab=app.Tab_3;
            app.Button_51.Enable='off';
            app.Button_52.Enable='off';
        end

        % Button pushed function: Button_38
        function Button_38Pushed(app, event)
%             try
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'运算中，请稍等...') ;
                
                app.DropDown.ItemsData=1:length(app.DropDown.Items);
                x=app.DropDown.Value;
                %             Data = app.UITable.Data;
                ParaInfo = app.Info(x,:);
                app.DropDown.ItemsData=[];
                tit=app.DropDown.Value;
                
                tt = ['批次间控制图_',tit];
                app.fig=figure('Name',tt,'NumberTitle','off');
                set( app.fig,'position',[450,150,1000,800])
                %             j=1;
                %             outlier=zeros(1,height(app.Info));
                Outlier=[];
                badtit=[];
                lotname=[];
                degree=[];
                nnndata=[];
                name=[];
                %                 f=waitbar(0,'Processing');
                
                n=length(app.UITable);
                %             tabledata='';
                
                for i=1:n
                    %                 if iscell(Data)
                    %                     Data=str2double(Data);
                    %                 end
                    f.Value=i/(n+1);
                    
                    nndata=app.UITable(i).Data(:,x);
                    lot= app.Tab(i).Title;
                    c=find(lot=='.');
                    if ~isempty(c)
                        lot=lot(1:c-1);
                    end
                    nnn=height(app.UITable(i).Data);
                    %                     waitbar(i/height(app.Info),f,'djek')
                    %                     {'Processing Parameter No.',i} 'name','一致性分析'
                    
                    nndata=table2array(nndata);
                    
                    if iscell(nndata)
                        nndata=str2double(nndata);
                    end
                    
                    method = string(table2array(ParaInfo(1,3)));
                    limit = string(table2array(ParaInfo(1,4)));
                    Low = (table2array(ParaInfo(1,5)));         % 修正下限
                    High = (table2array(ParaInfo(1,6)));           % 修正上限
                    Standard1= (table2array(ParaInfo(1,7)));     % 规格上限
                    Standard2= (table2array(ParaInfo(1,8)));       % 规格下限
                    unit=(table2array(ParaInfo(1,9)));
                    Coeficient=(table2array(ParaInfo(1,10)));
                    
                    Stan1=str2double(Standard1);
                    Stan2=str2double(Standard2);
                    Coef=str2double(Coeficient);
                    
                    [m,s,r,U,L,P,ske,method,Ot,Cpk,badtits] = ParaHist(app,nndata,i,app.fig,tit,method,limit,Low,High,Stan1,Stan2,badtit,lot,unit,Coef);


                    Outlier=[Outlier; table2cell(app.UITable(i).Data(transpose(Ot),:))];
                    %                       Outlier=[Outlier; table2array(app.UITable(i).Data(transpose(Ot),:))];
                    
                    
                    lotname=[lotname repmat(string(lot) ,1,length(Ot))];
                    dd=((nndata(Ot))-(U+L)/2)/((U-L)/6);
                    degree=[degree  transpose(dd)];
                    
                    tabledata(i,:)=[tit,lot,m,s,r,U,L,P,ske,method,nnn,Cpk,'-','-'];
                    nnndata=[nnndata;nndata];
                    
                    name=[name;string(repmat(lot,length(nndata),1))];
                    
                    badtit=[badtit,badtits];
                    
                end
                
                if isempty( app.fig.Children)
                    close( app.fig)
                    uialert(app.UIFigure,'请先选择相应的参数控制方法','Message-Box','Icon','Info')
                    return
                end
%                 Outlier=array2table(Outlier);
                 Zdata=array2table([transpose(lotname),transpose(degree),Outlier],"VariableNames",['批次','离群程度 (σ)',app.UITable(1).Data.Properties.VariableNames]);
                    set(app.UITable3,'data',Zdata,'columnname',['批次','离群程度 (σ)',app.UITable(1).Data.Properties.VariableNames],'rowname','numbered')
%                 set(app.UITable3,'data',[table(transpose(lotname),transpose(degree)),Outlier],'columnname',['批次','离群程度 (σ)',app.UITable(1).Data.Properties.VariableNames],'rowname','numbered')
%                 app.UITable3.Data.Properties.VariableNames(1)={'批次'};
%                 app.UITable3.Data.Properties.VariableNames(2)={'离群程度 (σ)'};
                %
                
                ColName={'参数名称','批次','均值','标准差','离群率','UCL','LCL','P值','偏度','控制方式','器件总数','Cpk','成品率','漏率'};
                tabledata=array2table(tabledata,"VariableNames",ColName);
                set(app.UITable2,'data',tabledata,'columnname',ColName,'rowname','numbered','ColumnEditable',true)
                
                app.TabGroup.SelectedTab=app.Tab_8;
                
                
                Data=table2array(app.UITable2.Data(:,2:5));
                %             if iscell(Data)
                %                 Data=str2double(Data);
                %             end
                t1=Data(:,1);
                t2=Data(:,2);
                t3=Data(:,3);
                t4=Data(:,4);
                try
                    t2=str2double(t2);
                    t3=str2double(t3);
                    t4=str2double(t4);
                catch
                end
                
                %                 if length(app.UITable) <2
                %                     msgbox('需积累2批以上数据','Message-Box','help')
                %                 else
                %                         Button_15Pushed(app, event)
                %                         t1=table2array(app.UITable4_2.Data(:,1));
                %                         t2=table2array(app.UITable4_2.Data(:,2));
                %                         t3=table2array(app.UITable4_2.Data(:,3));
                %                         t4=table2array(app.UITable4_2.Data(:,4));
                
                BatchChart(app,t1,t2,t3,t4)
                
                hold (app.subax,'on')
                hold (app.subax2,'on')
                hold (app.subax3,'on')
                
                pause(0.05)
                [a,b]=SubControl(app,app.subax,t1,t2,0);
                pause(0.05)
                [c,d]=SubControl(app,app.subax2,t1,t3,0);
                pause(0.05)
                [e,~]=SubControl2(app,app.subax3,t1,t4,0);
                
                results1 = isMono(app,app.subax,t1,t2,6);
                results2 = isMono(app,app.subax2,t1,t3,6);
                results3 = isMono(app,app.subax3,t1,t4,6);
                try
                    o2=[find(t2<=b),find(t2>=a)];
                    o3=[find(t3<=d),find(t3>=c)];
                    o4=find(t4*100>e);
                    
                    plot(app.subax,(o2),t2(o2),'o','color','r','LineWidth',1,'MarkerSize',8);
                    plot(app.subax2,(o3),t3(o3),'o','color','r','LineWidth',1,'MarkerSize',8);
                    plot(app.subax3,(o4),t4(o4)*100,'o','color','r','LineWidth',1,'MarkerSize',8);
                    
                catch
                end
                %                 | t4*100>e
                p=any( t2<b | t2>a | t3<d | t3>c );
                q=results1==1||results2==1||results3==1;
                
                if  p
                    app.Lamp.Color='r';
                    condition='存在异常，建议进行过程分析.';
                elseif q
                    app.Lamp.Color='y';
                    condition='存在部分异常.';
                else
                    app.Lamp.Color='g';
                    condition='正常.';
                end
                
                
                if  p && q
                    app.EditField_32.Value='(1)超出控制限；(2)连续上升或下降';
                elseif p
                    app.EditField_32.Value='超出控制限';
                elseif   q
                    app.EditField_32.Value='连续上升或下降';
                else
                    app.EditField_32.Value='无异常';
                end
                
                %                 app.mean_UCL=a;
                %                 app.mean_LCL=b;
                %                 app.sigma_UCL=c;
                %                 app.sigma_LCL=d;
                %                 app.ratio_UCL=e;
                %                 app.ratio_LCL=f;
                
                hold (app.subax,'off')
                hold (app.subax2,'off')
                hold (app.subax3,'off')
                %                 end
                
                
                %                 Data2=table2array(app.UITable2.Data(:,[8,12]));
                %
                %                 try
                %                     Data2=str2double(Data2);
                %                 catch
                %                 end
                
                %                 t6=Data2(:,2);
                app.DropDown.ItemsData=[];
                m=1:length(t1);
                
                bb=tiledlayout(app.Tab_11,3,1,"TileSpacing","compact","Padding","compact");
                ax1 = nexttile(bb);
                
                boxplot(ax1,nnndata,name, 'Symbol','' )
                %             plot(ax1,n,t5,'Marker','.','MarkerSize',12);    'OutlierSize',1,
                %             set(ax1,'XTick',n, 'XTickLabel',t1,'FontSize',8)
                %                         xlabel(ax1,'Batch')
                set(ax1,'FontSize',8)
                ylabel(ax1,tit,"Interpreter","none")
                ax1.XTickLabelRotation = 45;
                
                title(ax1,['箱线图_',tit],'FontWeight','bold',"Interpreter","none")
                %--------------
                %             size(nnndata,2)
                
                ax2 = nexttile(bb);
                nnnn=0;
                xx=zeros(1,n);
                for  i=1:n
                    nnndata=table2array(app.UITable(i).Data(:,x));
                    
                    if iscell(nnndata)
                        nnndata=str2double(nnndata);
                    end
                    nnn=1:length(nnndata);
                    if i==1
                        mmmm=nnnn+length(nnndata);
                        ave=(nnnn+mmmm)/2;
                        xx(i)=ave;
                    else
                        nnnn=nnnn+height(app.UITable(i-1).Data(:,x));
                        mmmm=nnnn+length(nnndata);
                        ave=(nnnn+mmmm)/2;
                        xx(i)=ave;
                    end
                    %                 c = linspace(1,10,length(nnn));
                    scatter(ax2,nnn+nnnn,nnndata,16,'filled','MarkerFaceAlpha',0.6);
                    
                    %                 b=get(ax2,'Ylimit');
                    
                    %                 legend(app.Tab(i).Title)
                    hold(ax2,'on')
                end
                %                     MarkerFaceAlpha = 0.5;
                
                
                app.up=a;    % 批次间控制上限
                app.low=b;   % 批次间控制下限
                
                
                set(ax2,'XTick',xx,'XtickLabel',t1,'FontSize',8)
%                 xlabel(ax2,'',"Interpreter","none")
                axis(ax2,[0 inf ax2.YLim(1) ax2.YLim(2)])
                hold(ax2,'off')
                %             set(ax2,'XTick',n, 'XTickLabel',t1,'FontSize',10)
                %                         xlabel(ax1,'Batch')
                box(ax2,'on')
                ylabel(ax2,tit,"Interpreter","none")
                ax2.XTickLabelRotation = 45;
                %                 get(ax2,'Xlim')
                
                title(ax2,['趋势图_',tit],'FontWeight','bold',"Interpreter","none")
                app.scatterax=ax2;
                %--------------
                
                ax3 = nexttile(bb);
                
                value = app.DropDown_5.Value;
                t=str2double(table2array(app.UITable2.Data(:,value)));
                
                plot(ax3,m,t,'Marker','.','MarkerSize',12);
                set(ax3,'XTick',m, 'XTickLabel',t1,'FontSize',8)
                
                
                ylabel(ax3,value,"Interpreter","none")
                ax3.XTickLabelRotation = 45;
                
                title(ax3,value,'FontWeight','bold',"Interpreter","none")
                
                %             plot(ax3,m,t5,'Marker','.','MarkerSize',12);
                %             set(ax3,'XTick',m, 'XTickLabel',t1,'FontSize',8)
                %             %                         xlabel(ax1,'Batch')
                %
                %             ylabel(ax3,'P 值')
                %             ax3.XTickLabelRotation = 45;
                %
                %             title(ax3,'P 值','FontWeight','bold')
                %
                %             app.DropDown_5.Value='P值';
                
                f.Value=1;
                close(f)
                
                try
                    if isempty(badtit)
                        msg=['分析了',sprintf('%d',i),'个批次参数 ',tit,'的批次间波动，', '受控状态为：',condition];
                        
                        uialert(app.UIFigure,msg,'提示','icon','success')
                    else
                        msg=['共分析',sprintf('%d',i),'个批次，','其中 ',badtit,' 数据格式可能存在异常'];
                        
                        uialert(app.UIFigure,msg,'提示','icon','info')
                    end
                catch
                end
                
%             catch
%                 
%                 uialert(app.UIFigure,'竟然出错了，请确认参数控制方法是否正确（左边）','Message-Box','Icon','warning')
%                 try
%                     close( app.fig)
%                     close(f)
%                 catch
%                 end
%             end
        end

        % Button pushed function: Button_41
        function Button_41Pushed(app, event)
            Dirc = dir(string(app.TextArea_2.Value));
            Dirc= Dirc(~cellfun(@isfolder,{Dirc(:).name}));
            Dirc=Dirc(contains({Dirc.name},{'.xlsx','xls','.txt','.csv','.TXT'}));
            %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
            %             string(struct2cell(dirc))
            app.ListBox.Items={};
            for i=1:length(Dirc)
                app.ListBox.Items(i)=  cellstr(Dirc(i).name);
            end
            
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            Device(app)
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            Device(app)
            
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            Device(app)
            
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            %             value = app.TextArea_2.Value;
            %             try
            %                 mkdir('Data\初始化')
            %                 writecell(value,'Data\初始化\批次间默认路径.txt')
            %             catch
            %             end
        end

        % Button pushed function: Button_42
        function Button_42Pushed(app, event)
            app.DropDown.ItemsData=[];
            a=app.DropDown.Value;
%             app.PathName1
            try
                n=length(app.fig.Children);
                c=ceil((n-1)/4);
                saveFig(app,app.fig.Children,['批次间分布图-',a],c,n)
                %
            catch
                
                uialert(app.UIFigure,'控制图不见了- -!','提示','Icon','warn')
                
                return
            end
            
            try
                savexls(app,app.UITable2.Data,['批次信息-',a])
                
                winopen(app.PathName1)
            catch
                
            end
            
            %             app.PathName=app.TextArea_2.Value;
        end

        % Button pushed function: Button_44
        function Button_44Pushed(app, event)
            try
                mkdir('Data\初始化')
                writecell(app.TextArea_2.Value,'Data\初始化\批次间默认路径.txt')
                uialert(app.UIFigure,'已保存为预设路径','提示','Icon','success')
            catch
            end
            
        end

        % Callback function
        function Button_40Pushed(app, event)
            DeviceManagement
        end

        % Button pushed function: SheetButton
        function SheetButtonPushed(app, event)
            Dir=string(app.TextArea_2.Value);
            
            if isfolder(Dir)
                
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls';'*.TXT'},'Choose a File',Dir,'multiselect','off');
            else
                [FileName,app.PathName]=uigetfile({'*.*';'*.xlsx';'*.txt';'*.csv';'*.xls';'*.TXT'},'Choose a File','multiselect','off');
            end
            
            if   ischar(FileName)
                
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'读取数据中...') ;
                %  -------------  set listbox  -------------
                
                app.TextArea_2.Value=app.PathName;
                Dirc = dir(string(app.TextArea_2.Value));
                Dirc= Dirc(~cellfun(@isfolder,{Dirc(:).name}));
                Dirc=Dirc(contains({Dirc.name},{'.xlsx','xls','.txt','.csv'}));
                %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
                %             string(struct2cell(dirc))
                app.ListBox.Items={};
                for i=1:length(Dirc)
                    app.ListBox.Items(i)=  cellstr(Dirc(i).name);
                end
                %                 app.ListBox.Multiselect='on';
                
                %  --------------------------
                app.UITable(:)=[];
                delete(app.TabGroup2.Children)
                %                 delete(app.UITable)
                %                 delete(app.Tab)
                
                str=string(strcat(app.PathName, FileName));
                sheetname=sheetnames(str);
                n=length(sheetnames(str));
                x=zeros(1,n);
                
                for i= 1:n
                    f.Value=1/(n+1);
                    
                    Data=readtable(str,"PreserveVariableNames",true,'sheet',i);
                    
                    app.parameter= Data.Properties.VariableNames;
                    Tabname=string(sheetname(i));
                    
                    
                    app.Tab(i) = uitab(app.TabGroup2);
                    app.Tab(i).Title = Tabname;
                    
                    app.UITable(i) = uitable(app.Tab(i));
                    app.UITable(i).ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
                    app.UITable(i).RowName = {};
                    app.UITable(i).Position = [10,12,562,710];
                    
                    set(app.UITable(i),'data',Data,'columnname',app.parameter,'columnsortable',true,'columneditable',true)
                    app.UITable(i).RowName='numbered';
                    
                    x(i)=width(app.UITable(i).Data);
                    
                end
                if max(x)-min(x)~=0
                    uialert(app.UIFigure,'请确保所有批次具有相同的测试参数','Message-Box','Icon','warning')
                end
                
                Data=readtable(str,"PreserveVariableNames",true,'sheet',1);
                
                app.parameter= Data.Properties.VariableNames;
                
                app.DropDown.Items=app.parameter;
                app.DropDown.ItemsData=1:length(app.parameter);
                app.DropDown.Value=2;
                
                BatchName=strcat(string(sheetname(1)),'-',string(sheetname(end)));
                BatchName=erase(BatchName,'.xls');
                BatchName=erase(BatchName,'.xlsx');
                app.EditField_35.Value=BatchName;
                
                app.TabGroup.SelectedTab=app.Tab_3;
                app.Lamp.Color = [0.8 0.8 0.8];
                
                pause(0.1)
                f.Value=1;
                close(f)
            end
            app.Button_51.Enable='off';
            app.Button_52.Enable='off';
            
        end

        % Callback function
        function MenuSelected(app, event)
            delete(app.TabGroup2.SelectedTab)
        end

        % Button pushed function: Button_50
        function Button_50Pushed(app, event)
            try
                Dir=string(app.TextArea_2.Value);
                FileName=app.ListBox.Value ;
                str=string(strcat( Dir,'\', FileName(1)));
                
                winopen(str);
            catch
                uialert(app.UIFigure,'先选择列表中的一个文件','Msg-Box','Icon','Info')
            end
            %              set(app.UITable,'Data',open(str))
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            ax3=app.Tab_11.Children.Children(1);
            t=str2double(table2array(app.UITable2.Data(:,value)));
            m=1:height(app.UITable2.Data);
            t1=table2array(app.UITable2.Data(:,2));
            
            
            plot(ax3,m,t,'Marker','.','MarkerSize',12);
            set(ax3,'XTick',m, 'XTickLabel',t1,'FontSize',8)
            
            
            ylabel(ax3,value)
            ax3.XTickLabelRotation = 45;
            
            title(ax3,value,'FontWeight','bold')
            app.TabGroup3.SelectedTab=app.Tab_11;
        end

        % Button pushed function: Button_51
        function Button_51Pushed(app, event)
            
            if isempty(app.indice1)
                uialert(app.UIFigure,'请在第一个数据表中选择要删除的行','Message-Box','Icon','warning')
            else
                for i=1:length(app.UITable)
                    app.UITable(i).Data(app.indice1(1),:)=[];
                end
            end
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.indice1=event.Indices;
        end

        % Button pushed function: Button_52
        function Button_52Pushed(app, event)
            if isempty(app.indice1)
                uialert(app.UIFigure,'请在第一个数据表中选择要删除的列','Message-Box','Icon','warning')
            else
                n=app.indice1(2);
                
                for i=1:length(app.UITable)
                    app.UITable(i).Data(:,n)=[];
                    app.parameter=app.UITable(i).Data.Properties.VariableNames;
                    app.DropDown.Items=app.parameter;
                    %                     app.parameter(n)=[];
                    set( app.UITable(i),'ColumnName',app.parameter)
                end
                
            end
        end

        % Button pushed function: Button_53
        function Button_53Pushed(app, event)
            %             for i=2:length(app.Tab)
            %                 delete(app.Tab(i))
            %             end
            %             app.UITable(1).Data=[];
            %             app.UITable(1).ColumnName={'Column 1','Column 2','Column 3','Column 4'};
            %             app.Tab(1).Title = 'Tab';
            delete(app.TabGroup2.Children)
            app.Button_51.Enable='off';
            app.Button_52.Enable='off';
        end

        % Button pushed function: Button_49
        function Button_49Pushed(app, event)
            DeviceManagement
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            %             a=1;b=1;c=1;d=1;
            if value==1
                hold(app.scatterax,'on')
                app.aaa = plot(app.scatterax,get(app.scatterax,'Xlim'),[app.up,app.up],'--.','color','[0.1 0.7 0.1]','LineWidth', 0.1);
                app.bbb = plot(app.scatterax,get(app.scatterax,'Xlim'),[app.low,app.low],'--.','color','[0.1 0.7 0.1]','LineWidth', 0.1);
                
                app.DropDown.ItemsData=1:length(app.DropDown.Items);
                x=app.DropDown.Value;
                %             Data = app.UITable.Data;
                ParaInfo = app.Info(x,:);
                app.DropDown.ItemsData=[];
                
                Stan1 = str2double(table2array(ParaInfo(1,7)));
                Stan2 = str2double(table2array(ParaInfo(1,8)));
                
                app.ccc = plot(app.scatterax,get(app.scatterax,'Xlim'),[Stan1,Stan1],':','LineWidth', 0.5,'color','r');
                app.ddd = plot(app.scatterax,get(app.scatterax,'Xlim'),[Stan2,Stan2],':','LineWidth', 0.5,'color','r');
                %                 axis(app.scatterax,[0 inf app.scatterax.YLim(1) app.scatterax.YLim(2)])
                axis(app.scatterax,'auto')
                
            elseif value==0
                delete(app.aaa);
                delete(app.bbb);
                delete(app.ccc);
                delete(app.ddd);
                axis(app.scatterax,'auto')
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '批次间一致性';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '批次内一致性';
            app.Label_40 = uilabel(app.Panel);
            app.Label_40.Text = '元器件信息';
            app.Label_42 = uilabel(app.Panel);
            app.Label_42.Text = '类型';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Editable = 'on';
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_10Label = uilabel(app.Panel);
            app.DropDown_10Label.Text = '系列';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Editable = 'on';
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_11Label = uilabel(app.Panel);
            app.DropDown_11Label.Text = '规格';
            app.DropDown_4 = uidropdown(app.Panel);
            app.DropDown_4.Editable = 'on';
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '控制方法';
            app.Button_38 = uibutton(app.Panel, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @Button_38Pushed, true);
            app.Button_38.Text = '参数一致性';
            app.Button_42 = uibutton(app.Panel, 'push');
            app.Button_42.ButtonPushedFcn = createCallbackFcn(app, @Button_42Pushed, true);
            app.Button_42.Text = '保存';
            app.Label_44 = uilabel(app.Panel);
            app.Label_44.Text = '参数名称';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.Button_43 = uibutton(app.Panel, 'push');
            app.Button_43.ButtonPushedFcn = createCallbackFcn(app, @Button_43Pushed, true);
            app.Button_43.Text = '生成报告';
            app.Button_49 = uibutton(app.Panel, 'push');
            app.Button_49.ButtonPushedFcn = createCallbackFcn(app, @Button_49Pushed, true);
            app.Button_49.Text = '元器件类型信息';
            app.Label_45 = uilabel(app.Panel);
            app.Label_45.Text = '批次名称';
            app.EditField_35 = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '数据输入';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '多文件导入';
            app.Label_37 = uilabel(app.Panel_2);
            app.Label_37.Text = '预设路径';
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.Label_41 = uilabel(app.Panel_2);
            app.Label_41.Text = '文件列表';
            app.ListBox = uilistbox(app.Panel_2);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.Button_39 = uibutton(app.Panel_2, 'push');
            app.Button_39.ButtonPushedFcn = createCallbackFcn(app, @Button_39Pushed, true);
            app.Button_39.Text = '导入';
            app.Button_41 = uibutton(app.Panel_2, 'push');
            app.Button_41.ButtonPushedFcn = createCallbackFcn(app, @Button_41Pushed, true);
            app.Button_41.Text = '刷新';
            app.Button_44 = uibutton(app.Panel_2, 'push');
            app.Button_44.ButtonPushedFcn = createCallbackFcn(app, @Button_44Pushed, true);
            app.Button_44.Text = '保存路径';
            app.SheetButton = uibutton(app.Panel_2, 'push');
            app.SheetButton.ButtonPushedFcn = createCallbackFcn(app, @SheetButtonPushed, true);
            app.SheetButton.Text = '多Sheet导入';
            app.Button_50 = uibutton(app.Panel_2, 'push');
            app.Button_50.ButtonPushedFcn = createCallbackFcn(app, @Button_50Pushed, true);
            app.Button_50.Text = '打开文件';
            app.DropDown_6 = uidropdown(app.Panel_2);
            app.DropDown_6.Items = {'1', '2', '3', '4', '5'};
            app.DropDown_6.Value = '1';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '测试数据';
            app.TabGroup2 = uitabgroup(app.Tab_3);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Button_51 = uibutton(app.Tab_3, 'push');
            app.Button_51.ButtonPushedFcn = createCallbackFcn(app, @Button_51Pushed, true);
            app.Button_51.Text = '删除行';
            app.Button_52 = uibutton(app.Tab_3, 'push');
            app.Button_52.ButtonPushedFcn = createCallbackFcn(app, @Button_52Pushed, true);
            app.Button_52.Text = '删除列';
            app.Button_53 = uibutton(app.Tab_3, 'push');
            app.Button_53.ButtonPushedFcn = createCallbackFcn(app, @Button_53Pushed, true);
            app.Button_53.Visible = 'off';
            app.Button_53.Text = '清空';
            app.Tab_8 = uitab(app.TabGroup);
            app.Tab_8.Title = '批次间信息';
            app.UITable2 = uitable(app.Tab_8);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.Label_43 = uilabel(app.Tab_8);
            app.Label_43.Text = '分析结果';
            app.Tab_9 = uitab(app.TabGroup);
            app.Tab_9.Title = '离群器件';
            app.UITable3 = uitable(app.Tab_9);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.ColumnSortable = true;
            app.Label_34 = uilabel(app.Tab_9);
            app.Label_34.Text = '总离群器件';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.Tab_10 = uitab(app.TabGroup3);
            app.Tab_10.Title = '控制图1';
            app.Tab_11 = uitab(app.TabGroup3);
            app.Tab_11.Title = '控制图2';
            app.LampLabel = uilabel(app.RightPanel);
            app.LampLabel.Text = '受控状态';
            app.Lamp = uilamp(app.RightPanel);
            app.EditField_35Label = uilabel(app.RightPanel);
            app.EditField_35Label.Text = '异常类型';
            app.EditField_32 = uieditfield(app.RightPanel, 'text');
            app.EditField_32.Editable = 'off';
            app.Label_46 = uilabel(app.RightPanel);
            app.Label_46.Text = '附加控制图';
            app.DropDown_5 = uidropdown(app.RightPanel);
            app.DropDown_5.Items = {'器件总数', 'P值', '偏度', 'Cpk', 'LCL', 'UCL', '成品率', '漏率'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = '器件总数';
            app.CheckBox = uicheckbox(app.RightPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '辅助线';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParaConsistency_Lot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end