classdef RreMSA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSAUIFigure    matlab.ui.Figure
        DropDownLabel  matlab.ui.control.Label
        DropDown_2     matlab.ui.control.DropDown
        Label          matlab.ui.control.Label
        DropDown_3     matlab.ui.control.DropDown
        Label_2        matlab.ui.control.Label
        DropDown_4     matlab.ui.control.DropDown
        MSALabel       matlab.ui.control.Label
        UITable        matlab.ui.control.Table
        Button         matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        Path % Description
    end
    
    methods (Access = private)
        
        function t = DropdownItems(~,f)
            t=cell(1,length(f.Children));
            for i=1:length(f.Children)
                %             t=[t,cellstr(f.Children(i).Text)];
                t(i)=cellstr(f.Children(i).Text);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      
        app.Path='Data\初始化\DeviceSave.mat';
            %             app.Path='C:\Users\tiger\Desktop\参数一致性APP\Data\DeviceSave.mat';
            
            load(app.Path,'f')
            
            t = DropdownItems(app,f);
            app.DropDown_2.Items=t;
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items); % 把ItemsdData变成数组
            n1=app.DropDown_2.Value;   %用数字表示下拉框的索引
            
            h=f.Children(n1);
            if ~isempty(h.Children)
                t = DropdownItems(app,h);
                app.DropDown_3.Items=t;
                app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
                n2=app.DropDown_3.Value;
            else
                app.DropDown_3.Items={};
                app.DropDown_4.Items={};
                return
            end
            
            g=h.Children(n2);
            if  ~isempty(g.Children)
                t = DropdownItems(app,g);
                app.DropDown_4.Items=t;
            else
                app.DropDown_4.Items={};
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.DropDown_2.ItemsData=[];
            app.DropDown_3.ItemsData=[];
            app.DropDown_4.ItemsData=[];
            
            p1=app.DropDown_2.Value ;
            %             app.DropDown_2.;
            p2=app.DropDown_3.Value ;
            p3=app.DropDown_4.Value;
            try
                dirname = string(strcat('Data\测量系统分析','\',p1,'\',p2,'\',p3,'\','MSA计算结果.xlsx'));
                [Data,para1,~]=xlsread(dirname);
                Data=array2table(Data);
                
                set(app.UITable,'data',Data,'ColumnName',para1(1,2:end),'RowName',para1(2:end,1))
            catch
                msgbox('未找到该型号MSA信息，请进行手动查询','msg-box','help')
            end
            
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            
            load( app.Path,'f')
            n1=app.DropDown_2.Value;   %用数字表示下拉框的索引
            
            if any(~ismember(n1,app.DropDown_2.ItemsData))||isempty(n1)  % 手动输入DropDown_2
                
                return
            end
            h=f.Children(n1);
            
            if ~isempty(h.Children)
                t = DropdownItems(app,h);
                app.DropDown_3.Items=t;
                
                n2=1;
                app.DropDown_3.Value=1;
            else
                
                app.DropDown_3.Items={};
                app.DropDown_4.Items={};
                return
            end
            
            g=h.Children(n2);
            
            if  ~isempty(g.Children)
                t = DropdownItems(app,g);
                app.DropDown_4.Items=t;
                app.DropDown_4.ItemsData = 1:length(app.DropDown_3.Items); % 把ItemsdData变成数组
                %                 app.DropDown_4.Items
                app.DropDown_4.Value=1;
            else
                app.DropDown_4.Items={};
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            
            load( app.Path,'f')
            n1=app.DropDown_2.Value;
            n2=app.DropDown_3.Value;   %用数字表示下拉框的索引
            if any(~ismember(n2,app.DropDown_3.ItemsData))||isempty(n2)  % 手动输入DropDown_2
                
                return
            end
            h=f.Children(n1);
            g=h.Children(n2);
            if  ~isempty(g.Children)
                t = DropdownItems(app,g);
                app.DropDown_4.Items=t;
                app.DropDown_4.Value=1;
            else
                app.DropDown_4.Items={};
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.DropDown_2.ItemsData=[];
            app.DropDown_3.ItemsData=[];
            app.DropDown_4.ItemsData=[];
            
            p1=app.DropDown_2.Value ;
            %             app.DropDown_2.;
            p2=app.DropDown_3.Value ;
            p3=app.DropDown_4.Value;
            
            dirname = string(strcat('测量系统分析','\',p1,'\',p2,'\',p3));
            
            [file,path] = uigetfile({'*.xlsx';'*.xls';'*.txt';'*.csv'},'选择相应的MSA数据',dirname);
            
            Filename=fullfile(path,file);
            
            if Filename~=0
                
                [Data,para1,~]=xlsread(Filename);
                Data=array2table(Data);
                
                set(app.UITable,'data',Data,'ColumnName',para1(1,2:end),'RowName',para1(2:end,1))
            end
            
            app.DropDown_2.ItemsData = 1:length(app.DropDown_2.Items);
            app.DropDown_3.ItemsData = 1:length(app.DropDown_3.Items);
            app.DropDown_4.ItemsData = 1:length(app.DropDown_4.Items);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MSAUIFigure = uifigure('Visible', 'off');
            app.MSAUIFigure.Name = '查询MSA';
            app.DropDownLabel = uilabel(app.MSAUIFigure);
            app.DropDownLabel.Text = '大类';
            app.DropDown_2 = uidropdown(app.MSAUIFigure);
            app.DropDown_2.Editable = 'on';
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.Label = uilabel(app.MSAUIFigure);
            app.Label.Text = '系列';
            app.DropDown_3 = uidropdown(app.MSAUIFigure);
            app.DropDown_3.Editable = 'on';
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.Label_2 = uilabel(app.MSAUIFigure);
            app.Label_2.Text = '规格';
            app.DropDown_4 = uidropdown(app.MSAUIFigure);
            app.DropDown_4.Editable = 'on';
            app.MSALabel = uilabel(app.MSAUIFigure);
            app.MSALabel.Text = '查询器件MSA信息';
            app.UITable = uitable(app.MSAUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Button = uibutton(app.MSAUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '自动查询';
            app.Button_2 = uibutton(app.MSAUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '手动查询';
            % Show the figure after all components are created
            app.MSAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RreMSA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSAUIFigure)
        end
    end
end