classdef SPC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        Button        matlab.ui.control.Button
        Button_2      matlab.ui.control.Button
        TextArea      matlab.ui.control.TextArea
        ListBoxLabel  matlab.ui.control.Label
        ListBox       matlab.ui.control.ListBox
        Button_3      matlab.ui.control.Button
        Button_4      matlab.ui.control.Button
        Button_5      matlab.ui.control.Button
        Label_2       matlab.ui.control.Label
        DropDown      matlab.ui.control.DropDown
        Label_3       matlab.ui.control.Label
        EditField     matlab.ui.control.EditField
        CenterPanel   matlab.ui.container.Panel
        UITable       matlab.ui.control.Table
        Label         matlab.ui.control.Label
        RightPanel    matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        PathName % Description
        FileName
    end
    
    methods (Access = private)
        
        function Sub(~,ax,x,y,ylb,tit)
            
            
            n=1:length(x);
            
            %             try
            %                 y=str2double(y);
            %             catch
            %             end
            
            plot(ax,n,y,'Marker','.','MarkerSize',12)
            set(ax,'XTick',n, 'XTickLabel',x,'FontSize',8)
            %             xlabel(ax,'Batch')
            %             xlabel(ax,'批次')
            ylabel(ax,ylb)
            ax.XTickLabelRotation = 45;


            title(ax,tit,'FontWeight','bold',"Interpreter","none")
            a=[min(n),max(n)];
            b=get(ax,'Ylim');
            %                           axis(ax,'auto')  ,b(1),b(2)
            try
                axis(ax,[a(1),a(2),b(1),b(2)]);
            catch
            end
            %                  set(ax,'Box','on')  %???not working
            
            
        end
        
        function [mean_UCL, mean_LCL]=SubControl(~,ax,x,BatchData)
            x=1:length(x);
            
            mean_ave=mean(BatchData);
            mean_sigma=std(BatchData);
            mean_UCL= mean_ave+3*mean_sigma;
            mean_LCL= mean_ave-3*mean_sigma;
            mean_UCL1= mean_ave+mean_sigma;
            mean_LCL1= mean_ave-mean_sigma;
            mean_UCL2= mean_ave+2*mean_sigma;
            mean_LCL2= mean_ave-2*mean_sigma;
            
            plot(ax,get(ax,'Xlim'),[mean_UCL1,mean_UCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL1,mean_LCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_UCL2,mean_UCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL2,mean_LCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            
            plot(ax,get(ax,'Xlim'),[mean_UCL,mean_UCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[mean_LCL,mean_LCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[mean_ave, mean_ave],'--.','color','[0.1 0.7 0.1]','LineWidth', 1);
            axis(ax,'auto')
            %             a=get(ax,'Xlim');
            b=get(ax,'Ylim');
            try
                axis(ax,[min(x),max(x),b(1),b(2)]);
            catch
            end
            
            %             if m==1
            %                 axis(ax,[min(x),max(x),mean_LCL,mean_UCL]);
            %             end
            
        end
        
        function SubControl2(~,ax,x,BatchData)
            x=1:length(x);
            
            mean_ave=mean(BatchData);
            mean_sigma=std(BatchData);
            mean_UCL= mean_ave+3*mean_sigma;
            %             mean_LCL= mean_ave-3*mean_sigma;
            mean_UCL1= mean_ave+mean_sigma;
            mean_LCL1= mean_ave-mean_sigma;
            mean_UCL2= mean_ave+2*mean_sigma;
            mean_LCL2= mean_ave-2*mean_sigma;
            
            plot(ax,get(ax,'Xlim'),[mean_UCL1,mean_UCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL1,mean_LCL1],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_UCL2,mean_UCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            plot(ax,get(ax,'Xlim'),[mean_LCL2,mean_LCL2],':','color','[0.7 0.7 0.7]','LineWidth', 0.5);
            
            plot(ax,get(ax,'Xlim'),[mean_UCL,mean_UCL],'--.r','LineWidth', 1);
            %             plot(ax,get(ax,'Xlim'),[mean_LCL,mean_LCL],'--.r','LineWidth', 1);
            plot(ax,get(ax,'Xlim'),[mean_ave, mean_ave],'--.','color','[0.1 0.7 0.1]','LineWidth', 1);
            axis(ax,'auto')
            %             a=get(ax,'Xlim');
            b=get(ax,'Ylim');
            try
                axis(ax,[min(x),max(x),b(1),b(2)]);
            catch
            end
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Dir=string(app.TextArea.Value);
            
            if isfolder(Dir)
                
                [app.FileName,app.PathName]=uigetfile({'*.xlsx';'*.txt';'*.csv';'*.xls';'.TXT'},'Choose a File',Dir);
            else
                [app.FileName,app.PathName]=uigetfile({'*.xlsx';'*.txt';'*.csv';'*.xls';'.TXT'},'Choose a File');
            end
            
            if  (ischar(app.FileName))
                f=uiprogressdlg(app.UIFigure,"Title",'Msg-Box',"Message",'读取数据...') ;
                
                str=[app.PathName app.FileName];
                
                %                 if strcmp(Dir,'')
                app.TextArea.Value=app.PathName;
                %                 end
                
                Dirc = dir(string(app.TextArea.Value));
                Dirc= Dirc(~cellfun(@isfolder,{Dirc(:).name}));
                Dirc=Dirc(contains({Dirc.name},{'.xlsx','xls','.txt','.csv'}));
                %                dirc = dirc((~cellfun(@isfolder,{dirc(:).name})));
                %             string(struct2cell(dirc))
                app.ListBox.Items={};
                for i=1:length(Dirc)
                    app.ListBox.Items(i)=  cellstr(Dirc(i).name);
                end
                
                
                set(app.Label,'Text',app.FileName)
                f.Value=0.2;
                %                 if  contains(FileName,'xlsx')||contains(FileName,'xls')
                %
                %                     [app.ndata,para]=xlsread(str);
                %                       app.parameter=para(1,:);
                %
                %                     %                     Data=array2table(app.ndata,'variablenames',app.parameter);
                %
                %                     %                     pause(0.2)
                %                 else
                
                Data=readtable(str,"PreserveVariableNames",true);
                
                parameter= Data.Properties.VariableNames;
                
                %                 app.ndata=readmatrix(str);
                
                %                 end
                
                %                       app.ndata=Data;
                %                 end
                
                f.Value=0.4;
                
                %                 nndata=array2table(app.ndata);
                %                 set(app.UITable,'data', Data);
                %                 Vartype=repmat({'numeric'},1,length(app.parameter));
                set(app.UITable,'data',Data,'columnname',parameter);
                
                app.UITable.RowName='numbered';
                %                   app.ndata=table2array(app.UITable.Data);
                
                f.Value=0.6;
                
                %                 app.DropDown.Items=  app.parameter;
                %                 app.DropDown.ItemsData = 1:length(app.DropDown.Items);
                %                 try
                %                     app.DropDown.Value=2;
                %                 catch
                %                 end
                %                 app.nn = app.DropDown.Value;
                %                 app.ndata=table2array( app.ndata);
                %                 app.EditField.Value=str;
                %             guidata(hObject,handles);
                f.Value=0.8;
                
                %                 Initiate(app)
                %                 app.rpt=[];
                %                 app.TabGroup.SelectedTab = app.Tab_3;
                
                %                 ColumnName=['SN',app.parameter];
                %                 size=[0,length(app.parameter)+1];
                %                 Vartype=repmat({'double'},1,length(app.parameter)+1);
                %                 app.outline=table('size',size,'VariableTypes',Vartype,'VariableNames',ColumnName);
                %                 app.outline=table('size',size,'VariableNames',ColumnName);
                %                 app.UITable2.Data=[];
                %                 app.UITable3.Data=[];
                %                'VariableNames',['Number',app.parameter]
                %                  'VariableNames',ColumnName
                f.Value=1;
                pause(0.1)
                close(f)
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %             n=1:height(app.UITable.Data);
            nn=width(app.UITable.Data);
            
            bb=tiledlayout(app.RightPanel,nn-1,1,"TileSpacing","compact","Padding","compact");
            x=table2array(app.UITable.Data(:,1));
            
            for i=2:nn
                data= table2array(app.UITable.Data(:,i));
                subax = nexttile(bb);
                                               
                if strcmp(app.DropDown.Value,'测试值')
                    Sub(app,subax,x,data,app.EditField.Value,app.UITable.ColumnName(i))
                    hold (subax,'on')
                    SubControl(app,subax,x,data);
                else
                    Sub(app,subax,x,data*100,'Ratio(%)',app.UITable.ColumnName(i))
                    hold (subax,'on')
                    SubControl2(app,subax,x,data*100);
                end
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            [filename, pathname] = uiputfile({'*.png';'*.tif';'*.jpg';'*.fig';},'选择保存路径',fullfile(app.PathName,app.FileName(1:end-5)));
         
            exportgraphics(app.RightPanel.Children, fullfile(pathname,filename),'Resolution',100)
            %             ['过程控制图','.png']
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {741, 741, 741};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {741, 741};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {261, '1x', 589};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '输入数据';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '过程控制';
            app.TextArea = uitextarea(app.LeftPanel);
            app.ListBoxLabel = uilabel(app.LeftPanel);
            app.ListBoxLabel.Text = '文件列表';
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '生成报告';
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.Text = '元器件类型管理';
            app.Button_5 = uibutton(app.LeftPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '保存';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '控制类型';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'测试值', '不合格率'};
            app.DropDown.Value = '测试值';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '测试项目';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.CenterPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.CenterPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '数据表';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end