classdef Transformer_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        Panel             matlab.ui.container.Panel
        Button            matlab.ui.control.Button
        TextAreaLabel     matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        Panel_2           matlab.ui.container.Panel
        J750Button        matlab.ui.control.Button
        UltraflexButton   matlab.ui.control.Button
        MicroflexButton   matlab.ui.control.Button
        CatalystButton    matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Label_6           matlab.ui.control.Label
        Label             matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        EditField_3       matlab.ui.control.EditField
        Label_4           matlab.ui.control.Label
        EditField_4       matlab.ui.control.EditField
        Label_5           matlab.ui.control.Label
        EditField_5       matlab.ui.control.EditField
        Label_7           matlab.ui.control.Label
        DropDown          matlab.ui.control.DropDown
        STS6020Button     matlab.ui.control.Button
        Panel_3           matlab.ui.container.Panel
        Button_3          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
        TabGroup          matlab.ui.container.TabGroup
        TestDataTab       matlab.ui.container.Tab
        UITable           matlab.ui.control.Table
        DataLabel         matlab.ui.control.Label
        ProcessDataTab    matlab.ui.container.Tab
        UITable_2         matlab.ui.control.Table
        TransformDataTab  matlab.ui.container.Tab
        UITable_3         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Description
        PathName
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.TextArea.Value=string(importdata('Data\初始化\格式转换默认路径.txt'));
            catch
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Dir=string(app.TextArea.Value);
            
            
            [FileName,app.PathName]=uigetfile({'*.*';'*.txt';'*.xlsx';'*.csv';'*.xls'},'Choose a File',Dir);
            
            if  (ischar(FileName))
                
                if strcmp(Dir,'')
                    app.TextArea.Value=app.PathName;
                end
                
                f = waitbar(0,'Loading...','Name','Data Input') ;
                str=[app.PathName FileName];
                %            "Delimiter",['\t',' ','  ','   ']
                
                %                 if contains(FileName,'txt','IgnoreCase',true)
                %                     waitbar(0.25,f,'Loading...') ;
                
                
                %                         Data = readtable(str, 'PreserveVariableNames',true,"Delimiter",['\t',' ','',',']);
                
                %                     Data=readvars(str,'Delimiter','  ','EmptyLineRule','read','ConsecutiveDelimitersRule','join');,'PreserveVariableNames',true,"NumHeaderLines",0
                
                %                 else
                
                waitbar(0.25,f,'Loading...') ;
                
                Data=readtable(str,'format','auto');
                %                 end
                waitbar(0.5,f,'Loading...') ;
                %                 set(app.UITable,'data',Data);
                parameter= Data.Properties.VariableNames;
                %
                set(app.UITable,'data',Data,'columnname',parameter);
                
                waitbar(0.75,f,'Loading...') ;
                
                app.DataLabel.Text=FileName;
                
                waitbar(1,f,'Loading...') ;
                close(f)
                
                app.TabGroup.SelectedTab = app.TestDataTab;
                if width(Data)<=3
                    msgbox('部分txt格式文件导入有误，请转换为Excel后再导入','Message-Box','help')
                end
                try
                    mkdir('Data\初始化')
                    writecell(app.TextArea.Value,'Data\初始化\格式转换默认路径.txt')
                catch
                end
            end
        end

        % Button pushed function: J750Button
        function J750ButtonPushed(app, event)
            c_data=10;
            Data=app.UITable.Data;
%             try
%                 Data=table2array(Data);
%             catch 
%                 
%             end
            f = waitbar(0,'Numbering...','Name','Data Cleaning') ;
            d=contains(table2cell(Data(:,2)),'Device');
            %             set(app.UITable_3,'data',Data(d,3),'columnname',Data(d,3).Properties.VariableNames);
            %             直接将器件编号放入UITable3
            Data.Properties.VariableNames(3)={'Device'};
            NewData= Data(d,3);
            %             NewData.Properties.VariableNames(1)='Device';
            waitbar(0.25,f,'Numbering...') ;
            %             Data(not(cellfun(@isnumeric,table2cell(Data(:,2)))),:)=[];
            Data(cellfun(@isempty,table2cell(Data(:,15))),:)=[];
            
            
            waitbar(0.5,f,'Removing Empty Cells...') ;
            
            %                 A=unique(table2cell(Data(:,5)));
            %                 A=A(not(cellfun(@isempty,A)));
            %
            %                 B=unique(table2cell(Data(:,6)));
            %                   B=B(not(cellfun(@isempty,B)));
            
            TestName=  strcat(table2cell(Data(:,5)),' - ',table2cell(Data(:,6)),' - ',table2cell(Data(:,2)));
            TestName1=unique(TestName,'stable');
            
            waitbar(0.75,f,'Generating Test Names...') ;
            
            %             n=width(Data);
            Data(:,1)= TestName;
            set(app.UITable_2,'data',Data,'columnname',app.UITable.Data.Properties.VariableNames);
            
            waitbar(1,f,'Moving data...') ;
            close(f)
            
            m=length(TestName1);
            f = waitbar(0,'Moving data...','Name','Data Formating') ;
            
            BadData=[];
            for i=1:m
                
                t=strcmp(table2cell(Data(:,1)),TestName1(i));
                %                 NewData=Data(t,10);
                if strcmp(app.DropDown.Value,'精确识别')
                    
                    try
                        T=str2double(table2array(Data(t,9:width(Data)-2)));
                        
                        E=UniqueM(T);
                        [~,x]=max(E);
                        c_data=x+8;
                    catch
                    end
                end
                try
                    Data.Properties.VariableNames( c_data)=TestName1(i);
                    NewData =[NewData,Data(t,c_data)];
                catch
                    BadData=[BadData,string(TestName1(i))];
                end
                
                %                 app.UITable_3.Data(:,i+1)=Data(t,10);
                %                 app.UITable_3.Data.Properties.VariableNames(:,i+1)=TestName1(i);
                
                waitbar(i/m,f,sprintf('Moving Parameter No.%d...',i)) ;
            end
            
            set(  app.UITable_3,'data',NewData,'columnname',NewData.Properties.VariableNames);
            waitbar(1,f,'Succeed :)') ;
            close(f)
            app.TabGroup.SelectedTab = app.TransformDataTab;
            
            if ~isempty(BadData)
                msg = ['以下参数存在异常情况，请检查或手动转换', ...
                    BadData ];
                msgbox(msg,'Message-Box','help')
            end
        end

        % Button pushed function: UltraflexButton
        function UltraflexButtonPushed(app, event)
            c_device=2;
            c_empty=14;
            c_testname=4;
            c_pin=5;
            c_vol=2;
            c_data=9;
            
            Data=app.UITable.Data;
            f = waitbar(0,'Numbering...','Name','Data Cleaning') ;
            d=contains(table2cell(Data(:,c_device)),'Device');
            
            Data.Properties.VariableNames(c_device+1)={'Device'};
            NewData= Data(d,c_device+1);
            
            waitbar(0.25,f,'Numbering...') ;
            %             Data(not(cellfun(@isnumeric,table2cell(Data(:,2)))),:)=[];
            Data(cellfun(@isempty,table2cell(Data(:,c_empty))),:)=[];
            %             Data(1,:)=[];
            set(app.UITable_2,'data',Data);
            
            waitbar(0.5,f,'Removing Empty Cells...') ;
            
            %                 A=unique(table2cell(Data(:,5)));
            %                 A=A(not(cellfun(@isempty,A)));
            %
            %                 B=unique(table2cell(Data(:,6)));
            %                   B=B(not(cellfun(@isempty,B)));
            
            TestName=  strcat(table2cell(Data(:,c_testname)),' - ',table2cell(Data(:,c_pin)),' - ',table2cell(Data(:, c_vol)));
            
            %             DevceTestName =  strcat(table2cell(NewData),' - ',table2cell(Data(:,c_testname)),' - ',table2cell(Data(:,c_pin)));
            
            
            TestName1=unique(TestName,'stable');
            
            waitbar(0.75,f,'Generating Test Names...') ;
            
            %             n=width(Data);
            Data(:,1)= TestName;
            set(app.UITable_2,'data',Data);
            
            waitbar(1,f,'Moving data...') ;
            close(f)
            
            m=length(TestName1);
            f = waitbar(0,'Moving data...','Name','Data Formating') ;
            
            BadData=[];
            for i=1:m
                
                t=strcmp(table2cell(Data(:,1)),TestName1(i));
                if strcmp(app.DropDown.Value,'精确识别')
                    
                    try
                        
                        T=str2double(table2array(Data(t,8:11)));
                        E=UniqueM(T);
                        [~,x]=max(E);
                        c_data=x+7;
                        
                    catch
                    end
                end
                
                try
                    Data.Properties.VariableNames(c_data)=TestName1(i);
                    NewData =[NewData,Data(t,c_data)];
                catch
                    BadData=[BadData,string(TestName1(i))];
                end
                waitbar(i/m,f,sprintf('Moving Parameter No.%d...',i)) ;
            end
            %
            %                 set(  app.UITable_3,'data',NewData,'columnname',NewData.Properties.VariableNames);
            %                 close(f)
            %                  f = waitbar(0,'Moving data...','Name','处理不同行数参数...') ;
            %                 for j=1:m
            %                     t=strcmp(table2cell(Data(:,1)),TestName1(j));
            %                     Data.Properties.VariableNames(c_data)=TestName1(j);
            %                     app.UITable_3.Data(:,j)=Data(t,c_data);
            %                     waitbar(j/m,f,sprintf('Moving Parameter No.%d...',i)) ;
            %                 end
            %             end
            
            set(  app.UITable_3,'data',NewData,'columnname',NewData.Properties.VariableNames);
            waitbar(1,f,'Succeed :)') ;
            close(f)
            app.TabGroup.SelectedTab = app.TransformDataTab;
            
            if ~isempty(BadData)
                msg = ['以下参数存在异常情况，请检查或手动转换', ...
                    BadData ];
                msgbox(msg,'Message-Box','help')
            end
        end

        % Button pushed function: MicroflexButton
        function MicroflexButtonPushed(app, event)
            
            
            c_device=2;
            c_empty=14;
            c_testname=4;
            c_pin=5;
            c_vol=2;
            c_data=8;
            
            Data=app.UITable.Data;
            f = waitbar(0,'Numbering...','Name','Data Cleaning') ;
            d=contains(table2cell(Data(:,c_device)),'Device');
            
            Data.Properties.VariableNames(c_device+1)={'Device'};
            NewData= Data(d,c_device+1);
            
            %              b=1:length(d);
            
            
            waitbar(0.25,f,'Numbering...') ;
            %             Data(not(cellfun(@isnumeric,table2cell(Data(:,2)))),:)=[];
            Data(cellfun(@isempty,table2cell(Data(:,c_empty))),:)=[];
            %             Data(1,:)=[];
            set(app.UITable_2,'data',Data);
            
            waitbar(0.5,f,'Removing Empty Cells...') ;
            
            TestName=  strcat(table2cell(Data(:,c_testname)),' - ',table2cell(Data(:,c_pin)),' - ',table2cell(Data(:, c_vol)));
            
            %             DevceTestName =  strcat(table2cell(NewData),' - ',table2cell(Data(:,c_testname)),' - ',table2cell(Data(:,c_pin)));
            
            
            TestName1=unique(TestName,'stable');
            
            waitbar(0.75,f,'Generating Test Names...') ;
            
            %             n=width(Data);
            Data(:,1)= TestName;
            set(app.UITable_2,'data',Data);
            
            waitbar(1,f,'Moving data...') ;
            close(f)
            
            m=length(TestName1);
            f = waitbar(0,'Moving data...','Name','Data Formating') ;
            
            BadData=[];
            for i=1:m
                t=strcmp(table2cell(Data(:,1)),TestName1(i));
                %                 NewData=Data(t,10);
                if strcmp(app.DropDown.Value,'精确识别')
                    try
                        
                        T=str2double(table2array(Data(t,7:10)));
                        E=UniqueM(T);
                        [~,x]=max(E);
                        c_data=x+6;
                        
                    catch
                    end
                end
                
                try
                    Data.Properties.VariableNames(c_data)=TestName1(i);
                    NewData =[NewData,Data(t,c_data)];
                catch
                    
                    BadData=[BadData,string(TestName1(i))];
                end
                waitbar(i/m,f,sprintf('Moving Parameter No.%d...',i)) ;
            end
            
            set(  app.UITable_3,'data',NewData,'columnname',NewData.Properties.VariableNames);
            waitbar(1,f,'Succeed :)') ;
            close(f)
            app.TabGroup.SelectedTab = app.TransformDataTab;
            
            if ~isempty(BadData)
                msg = ['以下参数存在异常情况，请检查或手动转换', ...
                    BadData ];
                msgbox(msg,'Message-Box','help')
            end
        end

        % Button pushed function: CatalystButton
        function CatalystButtonPushed(app, event)
            c_device=1;
            c_empty=9;
            c_testname=3;
            c_pin=4;
            c_vol=2;
            c_data=9;
            
            Data=app.UITable.Data;
            f = waitbar(0,'Numbering...','Name','Data Cleaning') ;
            d=contains(table2cell(Data(:,c_device)),'Device');
            
            Data.Properties.VariableNames(c_device+1)={'Device'};
            NewData= Data(d,c_device+1);
            
            %              b=1:length(d);
            
            waitbar(0.25,f,'Numbering...') ;
            %             Data(not(cellfun(@isnumeric,table2cell(Data(:,2)))),:)=[];
            Data(cellfun(@isempty,table2cell(Data(:,c_empty))),:)=[];
            Data(contains(table2cell(Data(:,c_testname)),'Station'),:)=[];
            Data(contains(table2cell(Data(:,c_testname)),'Tester'),:)=[];
            %             Data(1,:)=[];
            set(app.UITable_2,'data',Data);
            
            waitbar(0.5,f,'Removing Empty Cells...') ;
            
            TestName=  strcat(table2cell(Data(:,c_testname)),' - ',table2cell(Data(:,c_pin)),' - ',table2cell(Data(:, c_vol)));
            
            %             DevceTestName =  strcat(table2cell(NewData),' - ',table2cell(Data(:,c_testname)),' - ',table2cell(Data(:,c_pin)));
            
            
            TestName1=unique(TestName,'stable');
            
            waitbar(0.75,f,'Generating Test Names...') ;
            
            %             n=width(Data);
            Data(:,1)= TestName;
            set(app.UITable_2,'data',Data);
            
            waitbar(1,f,'Moving data...') ;
            close(f)
            
            m=length(TestName1);
            f = waitbar(0,'Moving data...','Name','Data Formating') ;
            BadData=[];
            
            for i=1:m
                t=strcmp(table2cell(Data(:,1)),TestName1(i));
                %                 NewData=Data(t,10);
                if strcmp(app.DropDown.Value,'精确识别')
                    try
                        
                        T=str2double(table2array(Data(t,4:width(Data)-1)));
                        E=UniqueM(T);
                        [~,x]=max(E);
                        c_data=x+3;
%                          c_data
                    catch
                    end
                end
                try
                    
                    %                     if  contains(TestName1(i),'VIH')||contains(TestName1(i),'VIL')||contains(TestName1(i),'Vref')||contains(TestName1(i),'Isc7')
                    %                         c_data=9;
                    %                     elseif contains(TestName1(i),'RXD')||contains(TestName1(i),'T1V')
                    %                         c_data=10;
                    %                     elseif contains(TestName1(i),'rxd-test')
                    %                         c_data=8;
                    %                     elseif contains(TestName1(i),'Isc6')
                    %                         c_data=5;
                    %                     elseif contains(TestName1(i),'ICC')
                    %                         c_data=6;
                    %                     end
                    Data.Properties.VariableNames(c_data)=TestName1(i);
                    NewData =[NewData,Data(t,c_data)];
                catch
                    BadData=[BadData,string(TestName1(i))];
                    
                end
                waitbar(i/m,f,sprintf('Moving Parameter No.%d...',i)) ;
            end
            
            set(  app.UITable_3,'data',NewData,'columnname',NewData.Properties.VariableNames);
            waitbar(1,f,'Succeed :)') ;
            close(f)
            app.TabGroup.SelectedTab = app.TransformDataTab;
            
            if ~isempty(BadData)
                msg = ['以下参数存在异常情况，请检查或手动转换', ...
                    BadData ];
                msgbox(msg,'Message-Box','help')
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
          if isempty(app.EditField.Value)|| isempty(app.EditField_2.Value)|| isempty(app.EditField_3.Value)|| isempty(app.EditField_4.Value)|| isempty(app.EditField_5.Value)
              msg = '请先在下方框中输入关键信息';
                msgbox(msg,'Message-Box','help')
                return
          end
            
            c_device=str2double(app.EditField.Value)-1;
            c_empty=str2double(app.EditField_5.Value);
            c_testname=str2double(app.EditField_2.Value);
            c_pin=str2double(app.EditField_2.Value)+1;
            c_vol=str2double(app.EditField_3.Value);
            c_data=str2double(app.EditField_4.Value);
            
            Data=app.UITable.Data;
            f = waitbar(0,'Numbering...','Name','Data Cleaning') ;
            d=contains(table2cell(Data(:,c_device)),'Device');
            
            Data.Properties.VariableNames(c_device+1)={'Device'};
            NewData= Data(d,c_device+1);
            
            %              b=1:length(d);
            
            
            waitbar(0.25,f,'Numbering...') ;
            %             Data(not(cellfun(@isnumeric,table2cell(Data(:,2)))),:)=[];
            Data(cellfun(@isempty,table2cell(Data(:,c_empty))),:)=[];
            Data(contains(table2cell(Data(:,c_testname)),'Station'),:)=[];
            Data(contains(table2cell(Data(:,c_testname)),'Tester'),:)=[];
            %             Data(1,:)=[];
            set(app.UITable_2,'data',Data);
            
            waitbar(0.5,f,'Removing Empty Cells...') ;
            
            TestName=  strcat(table2cell(Data(:,c_testname)),' - ',table2cell(Data(:,c_pin)),' - ',table2cell(Data(:, c_vol)));
            
            %             DevceTestName =  strcat(table2cell(NewData),' - ',table2cell(Data(:,c_testname)),' - ',table2cell(Data(:,c_pin)));
            
            
            TestName1=unique(TestName,'stable');
            
            waitbar(0.75,f,'Generating Test Names...') ;
            
            %             n=width(Data);
            Data(:,1)= TestName;
            set(app.UITable_2,'data',Data);
            
            waitbar(1,f,'Moving data...') ;
            close(f)
            
            m=length(TestName1);
            f = waitbar(0,'Moving data...','Name','Data Formating') ;
            
            BadData=[];
            for i=1:m
                t=strcmp(table2cell(Data(:,1)),TestName1(i));
                %                 NewData=Data(t,10);
                if strcmp(app.DropDown.Value,'精确识别')
                    try
                        
                        T=str2double(table2array(Data(t,4:width(Data)-1)));
                        E=UniqueM(T);
                        [~,x]=max(E);
                        c_data=x+3;
                        
                    catch
                    end
                end
                
                try
                    Data.Properties.VariableNames(c_data)=TestName1(i);
                    NewData =[NewData,Data(t,c_data)];
                catch
                    BadData=[BadData,string(TestName1(i))];
                end
                waitbar(i/m,f,sprintf('Moving Parameter No.%d...',i)) ;
            end
            
            set(  app.UITable_3,'data',NewData,'columnname',NewData.Properties.VariableNames);
            waitbar(1,f,'Succeed :)') ;
            close(f)
            app.TabGroup.SelectedTab = app.TransformDataTab;
            
            if ~isempty(BadData)
                msg = ['以下参数存在异常情况，请检查或手动转换', ...
                    BadData ];
                msgbox(msg,'Message-Box','help')
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            filename= strcat(app.PathName,'\','Transform_',app.DataLabel.Text);
            
            [file,path] = uiputfile({'*.xlsx';'*.txt';'*.csv'},'选择保存路径',filename);
            if file~=0
                %                     mkdir (dirname)
                
                try
                    writetable(app.UITable_3.Data,fullfile(path,file))
                    winopen(app.PathName)
                catch
                end
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            ParaTable(ParaConsistency_IC,app.UITable_3.Data)
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
       try
            Data=str2double(table2array(app.UITable_3.Data));
       catch
           Data=app.UITable_3.Data;
       end
       
            t=UniqueM(Data);
            
            n=t<2;
            n(1)=0;
            if ~any(n)
                uialert(app.UIFigure,'未检测到无效数据','提示','icon','success')
            else
                nn=app.UITable_3.Data.Properties.VariableNames(n);
                selection = uiconfirm(app.UIFigure,['以下参数的测试值可能是无效数据，是否清除？',nn],'Message-Box',...
                    'Icon','Info', 'Options',{'清除','取消'},...
                    'DefaultOption',1,'CancelOption',2);
                
                if strcmp(selection,'清除')
                    
                    app.UITable_3.Data(:,n)=[];
                end
            end
            
        end

        % Button pushed function: STS6020Button
        function STS6020ButtonPushed(app, event)
            c_device=1;
            c_empty=9;
            c_testname=3;
            c_pin=4;
            c_vol=2;
            c_data=9;
            
            Data=app.UITable.Data;
            
            f = waitbar(0,'Numbering...','Name','Data Cleaning') ;
            d=contains(table2cell(Data(:,c_device)),'Device');
            
            %             d=['#1',d];
            %             Data.Properties.VariableNames(c_device+4)={'Device'};
            NewData = Data(d,c_device+4);
            
            waitbar(0.25,f,'Numbering...') ;
            for i=1:size(Data,1)-1
                Data(size(Data,1)+1-i,1)=Data(size(Data,1)-i,1);
            end
            Data(cellfun(@isempty,table2cell(Data(:,c_empty))),:)=[];
            Data(contains(table2cell(Data(:,c_empty)),'Test'),:)=[];
            
            set(app.UITable_2,'data',Data);
            
            close(f)
            %             Data(cellfun(@isempty,table2cell(Data(:,c_empty))),:)=[];
            %             Data(contains(table2cell(Data(:,c_testname)),'Station'),:)=[];
            %             Data(contains(table2cell(Data(:,c_testname)),'Tester'),:)=[];
            %             %             Data(1,:)=[];
            %             set(app.UITable_2,'data',Data);
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            
              try
            ndata=str2double(table2array(app.UITable_3.Data));
       catch
           ndata=app.UITable_3.Data;
              end
            
            n=(max(ndata)-min(ndata)) >900;
            n(1)=0;
            
            if ~any(n)
                uialert(app.UIFigure,'未检测到异常单位的参数','提示','icon','success')
            else
                nn=app.UITable_3.Data.Properties.VariableNames(n);
                selection = uiconfirm(app.UIFigure,['以下参数可能含有不同单位的测试值，请选择处理方式',nn],'Message-Box',...
                    'Icon','Info', 'Options',{'清除','手动修改'},...
                    'DefaultOption',1,'CancelOption',2);
                
                if strcmp(selection,'清除')
                    
                    app.UITable_3.Data(:,nn)=[];
                end
            end
            %              data= ndata(:,n);
            
            
            %             for i=1:size(data,2)
            %
            %                    data(data(:,i)<mean(data(:,i))/100,i) =  data(data(:,i)<mean(data(:,i))/100,i)*1000;
            %             figure(i)  =  histogram( data(:,i));
            %
            %             end
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {903, 903};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {238, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '数据格式转换';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = '输入';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {'若txt文件读取有误，转换为Excel再进行读取'};
            app.Button.Text = '导入数据';
            app.TextAreaLabel = uilabel(app.Panel);
            app.TextAreaLabel.Text = '预设路径';
            app.TextArea = uitextarea(app.Panel);
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.Title = '转换';
            app.J750Button = uibutton(app.Panel_2, 'push');
            app.J750Button.ButtonPushedFcn = createCallbackFcn(app, @J750ButtonPushed, true);
            app.J750Button.Text = 'J750';
            app.UltraflexButton = uibutton(app.Panel_2, 'push');
            app.UltraflexButton.ButtonPushedFcn = createCallbackFcn(app, @UltraflexButtonPushed, true);
            app.UltraflexButton.Text = 'Ultraflex';
            app.MicroflexButton = uibutton(app.Panel_2, 'push');
            app.MicroflexButton.ButtonPushedFcn = createCallbackFcn(app, @MicroflexButtonPushed, true);
            app.MicroflexButton.Text = 'Microflex';
            app.CatalystButton = uibutton(app.Panel_2, 'push');
            app.CatalystButton.ButtonPushedFcn = createCallbackFcn(app, @CatalystButtonPushed, true);
            app.CatalystButton.Text = 'Catalyst';
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '自定义';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '请输入相应列编号';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '器件编号';
            app.EditField = uieditfield(app.Panel_2, 'text');
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '参数名称';
            app.EditField_2 = uieditfield(app.Panel_2, 'text');
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '参数序号';
            app.EditField_3 = uieditfield(app.Panel_2, 'text');
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '测试数据';
            app.EditField_4 = uieditfield(app.Panel_2, 'text');
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '最大空列';
            app.EditField_5 = uieditfield(app.Panel_2, 'text');
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '模式';
            app.DropDown = uidropdown(app.Panel_2);
            app.DropDown.Items = {'精确识别', '快速识别'};
            app.DropDown.Value = '精确识别';
            app.STS6020Button = uibutton(app.Panel_2, 'push');
            app.STS6020Button.ButtonPushedFcn = createCallbackFcn(app, @STS6020ButtonPushed, true);
            app.STS6020Button.Enable = 'off';
            app.STS6020Button.Text = 'STS-6020';
            app.Panel_3 = uipanel(app.LeftPanel);
            app.Panel_3.Title = '处理和保存';
            app.Button_3 = uibutton(app.Panel_3, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '保存';
            app.Button_4 = uibutton(app.Panel_3, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '参数一致性';
            app.Button_5 = uibutton(app.Panel_3, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '移除无效数据';
            app.Button_6 = uibutton(app.Panel_3, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '校正单位';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TestDataTab = uitab(app.TabGroup);
            app.TestDataTab.Title = 'TestData';
            app.UITable = uitable(app.TestDataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DataLabel = uilabel(app.TestDataTab);
            app.DataLabel.Text = 'Data';
            app.ProcessDataTab = uitab(app.TabGroup);
            app.ProcessDataTab.Title = 'ProcessData';
            app.UITable_2 = uitable(app.ProcessDataTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.TransformDataTab = uitab(app.TabGroup);
            app.TransformDataTab.Title = 'TransformData';
            app.UITable_3 = uitable(app.TransformDataTab);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_3.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transformer_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end